classdef BatchStatsTableView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        TabGroup         matlab.ui.container.TabGroup
        BatchStatsTable  matlab.ui.container.Tab
        UIBatchTable     matlab.ui.control.Table
    end

    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = public)
        
        function highlightrow(app,name)
            
            idx = find(ismember(app.UIBatchTable.Data.UID,name));            
            removeStyle(app.UIBatchTable);
            s3 = uistyle;
            s3.BackgroundColor = [152,251,152]/255;
            addStyle(app.UIBatchTable,s3,'row',idx)            
            scroll(app.UIBatchTable,'row',idx)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, BatchStatsTable, options)
            
            arguments
                app
                mainapp
                BatchStatsTable
                options.Name = 'Batch Statistics Table'
                options.TableName = 'Batch Statistics Table: Median (Lower Quartile,Upper Quartile)'                
            end
            
            if ismac
                changeFont(app.UIFigure,'Avenir');
            else
                changeFont(app.UIFigure,'MS Sans Serif');
            end
            app.UIFigure.Name = options.Name;
            app.BatchStatsTable.Title = options.TableName;
            app.CallingApp = mainapp;
            
           % Header = BatchStatsTable.Properties.VariableNames;
            Header = {'Batch','qcRSD',' ','sampleRSD',' ','refRSD',' ','dRatio',' ','BR',' ','missingQC',' '};
            
            app.UIBatchTable.Data = BatchStatsTable;
            app.UIBatchTable.ColumnName = Header;
            app.UIBatchTable.ColumnSortable = true;
            app.UIBatchTable.ColumnEditable = [false, true, false, false, false, false, false, false, false, false,false, false, false, false];
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ViewBatchTableButton.Enable = 'on';
            %app.CallingApp.LOADDATAButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BatchStatsTable = uitab(app.TabGroup);
            app.BatchStatsTable.Title = 'Batch Stats Table';
            app.UIBatchTable = uitable(app.BatchStatsTable);
            app.UIBatchTable.ColumnName = '';
            app.UIBatchTable.RowName = {};
            app.UIBatchTable.ColumnSortable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatchStatsTableView(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end