classdef Cheatsheet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        FeatureInclusionPrefilterTab  matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        Image7                        matlab.ui.control.Image
        QCCorrectionParametersTab     matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        Image8                        matlab.ui.control.Image
        CleanExploreTab               matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        Image6                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if ismac
                changeFont(app.UIFigure,'Avenir');
            else
                changeFont(app.UIFigure,'MS Sans Serif');
            end
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            left = screenWidth*0.4;
            bottom = screenHeight*0.1;
            width = screenWidth*0.4;
            height = screenHeight*0.8;            
            app.UIFigure.Position = [left bottom width height];
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'CHEATSHEET';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.FeatureInclusionPrefilterTab = uitab(app.TabGroup);
            app.FeatureInclusionPrefilterTab.Title = 'Feature Inclusion Prefilter';
            app.GridLayout3 = uigridlayout(app.FeatureInclusionPrefilterTab);
            app.Image7 = uiimage(app.GridLayout3);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'res', 'FeatureInclusionPrefilterX3.png');
            app.QCCorrectionParametersTab = uitab(app.TabGroup);
            app.QCCorrectionParametersTab.Title = 'QC Correction Parameters';
            app.GridLayout2 = uigridlayout(app.QCCorrectionParametersTab);
            app.Image8 = uiimage(app.GridLayout2);
            app.Image8.ImageSource = fullfile(pathToMLAPP, 'res', 'QCCorrectionParametersX3.png');
            app.CleanExploreTab = uitab(app.TabGroup);
            app.CleanExploreTab.Title = 'Clean & Explore';
            app.GridLayout4 = uigridlayout(app.CleanExploreTab);
            app.Image6 = uiimage(app.GridLayout4);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'res', 'CleanAndExploreX3.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cheatsheet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end