classdef ImportDataFeatureFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Button                     matlab.ui.control.Button
        ExcelPanel                 matlab.ui.container.Panel
        ChooseExcelButton          matlab.ui.control.Button
        ExcelFileLabel             matlab.ui.control.Label
        DataTableDropDown          matlab.ui.control.DropDown
        FeatureTableDropDown       matlab.ui.control.DropDown
        FeatureTableDropDownLabel  matlab.ui.control.Label
        DataTableDropDownLabel     matlab.ui.control.Label
        CSVPanel                   matlab.ui.container.Panel
        ProjectNameEditField       matlab.ui.control.EditField
        ProjectNameEditFieldLabel  matlab.ui.control.Label
        ChooseDataCSVButton        matlab.ui.control.Button
        CSVdataLabel               matlab.ui.control.Label
        ChooseFeatureCSVButton     matlab.ui.control.Button
        CSVfeatureLabel            matlab.ui.control.Label
        ImportButton               matlab.ui.control.Button
        ORLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
        isXLS
        xlFullPathfilename
        ProjectName
        FileType
        DataName
        FeatureName
        DataTable 
        FeatureTable
        csvFullPathDatafilename
        csvFullPathFeaturefilename
        CurrentFolder
    end
    
    properties (Access = public)
        
    end
    
    methods (Access = private)


        function reset(app)
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {'-'};
            app.FeatureTableDropDown.Items = {'-'};
            app.CSVdataLabel.Text = '';
            app.CSVfeatureLabel.Text = '';
            app.PRojectName = '';
            app.CurrentFolder = '';
            app.xlFullPathfilename = '';
            app.ProjectNameEditField.Value = '';
            app.FileType = '';
        end

        function suggestCSVprojectName(app,label)
            try
                if isempty(app.ProjectNameEditField.Value)
                    if endsWith(lower(label),{'data.csv','peak.csv','feat.csv'})
                        app.ProjectName = label(1:end-8);
                    elseif endsWith(lower(label),'feature.csv')
                        app.ProjectName = label(1:end-11);
                    elseif endsWith(lower(label),'.csv')
                        app.ProjectName = label(1:end-4);
                    else
                        app.ProjectName = 'Project';
                    end
                    if endsWith(app.ProjectName,'_')
                        app.ProjectName = app.ProjectName(1:end-1);
                    end
                    app.ProjectName = [app.ProjectName,'_CSV'];
                    app.ProjectNameEditField.Value = app.ProjectName;
                else
                    app.ProjectName = app.ProjectNameEditField.Value;
                end
                app.ProjectName = regexprep(app.ProjectName, '[^a-zA-Z0-9._-]', '_');
                app.ProjectNameEditField.Value = app.ProjectName;
            catch
                app.ProjectName = 'Project_CSV';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            app.UIFigure.Name = 'Import Data Table & Feature Table';
            app.CallingApp = mainapp;
            if ~isempty(app.CallingApp.WorkingDirectory)
                cd(app.CallingApp.WorkingDirectory);
                app.CurrentFolder = app.CallingApp.WorkingDirectory;
            else
                app.CurrentFolder = '~';
            end
        end

        % Button pushed function: ChooseExcelButton
        function ChooseExcelButtonPushed(app, event)
            app.isXLS = true;
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {'-'};
            app.FeatureTableDropDown.Items = {'-'};
            app.CSVdataLabel.Text = '';
            app.CSVfeatureLabel.Text = '';
            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('*.xlsx','Select an Excel File',fullfile(app.CurrentFolder,'*.xlsx'));
            
            
            figure(app.CallingApp.UIFigure);
            app.UIFigure.Visible = 'on';        
            figure(app.UIFigure);
            set(app.UIFigure, 'Pointer','watch');
            
            if isequal(file,0)
                 set(app.UIFigure, 'Pointer','arrow');
                 return
            end
            app.ExcelFileLabel.Text = file;
            temp = regexprep(file, '[^a-zA-Z0-9._-]', '_');
            app.ProjectName = temp; 
            app.ProjectNameEditField.Editable = "off";
            app.FileType = 'xlsx'; 
            app.CurrentFolder = path;
            cd(path);
            app.xlFullPathfilename = fullfile(path,file);
            xlSheetNames = sheetnames(app.xlFullPathfilename);          
            app.DataTableDropDown.Items = ['-';xlSheetNames];
            app.FeatureTableDropDown.Items = ['-';xlSheetNames];
            set(app.UIFigure, 'Pointer','arrow');
        end

        % Button pushed function: ChooseDataCSVButton
        function ChooseDataCSVButtonPushed(app, event)
            app.ProjectNameEditField.Editable = "on";
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {''};
            app.FeatureTableDropDown.Items = {''};
            app.isXLS = false;

            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('*.csv','Select a Data Table .csv file',fullfile(app.CurrentFolder,'*.csv'));
            
            figure(app.CallingApp.UIFigure);
            app.UIFigure.Visible = 'on';
            figure(app.UIFigure);
            set(app.UIFigure, 'Pointer','watch');
                          
            if isequal(file,0)
                set(app.UIFigure, 'Pointer','arrow');
                 return
            end

            app.CSVdataLabel.Text = file; 
            app.FileType = 'csv'; 
            %temp = split(file,'.');
            app.DataName = file;
            app.CurrentFolder = path;
            cd(path);

            app.CSVdataLabel.Text = file;
            app.csvFullPathDatafilename = fullfile(path,file);         
            opts = detectImportOptions(app.csvFullPathDatafilename);
            if ~all(ismember({'SampleType','Batch','Order'},opts.VariableNames))
                pause(0.2);
                uialert(app.UIFigure,'Selection is not a DataTable','Warning','Icon','warning');
                app.CSVdataLabel.Text = '';
                return;
            end
            suggestCSVprojectName(app,file);
            set(app.UIFigure, 'Pointer','arrow');
        end

        % Button pushed function: ChooseFeatureCSVButton
        function ChooseFeatureCSVButtonPushed(app, event)
            app.ExcelFileLabel.Text = '';
            app.DataTableDropDown.Items = {''};
            app.FeatureTableDropDown.Items = {''};
            app.isXLS = false;
            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('*.csv','Select a Feature Table .csv file',fullfile(app.CurrentFolder,'*.csv')); 
            

            figure(app.CallingApp.UIFigure);
            app.UIFigure.Visible = 'on';  
            figure(app.UIFigure);
            set(app.UIFigure, 'Pointer','watch');

            if isequal(file,0)
                set(app.UIFigure, 'Pointer','arrow');
                 return
            end
            app.CSVfeatureLabel.Text = file;            
            app.FileType = 'csv'; 
            %temp = split(file,'.');
            app.FeatureName = file;
            app.CSVfeatureLabel.Text = file;
            app.csvFullPathFeaturefilename = fullfile(path,file);
            opts = detectImportOptions(app.csvFullPathFeaturefilename);
            if ~all(ismember({'UID','Name'},opts.VariableNames))
                pause(0.2);
                uialert(app.UIFigure,'Selection is not a Feature Table','Warning','Icon','warning');
                app.CSVfeatureLabel.Text = '';
                return
            end   
            suggestCSVprojectName(app,file);
            set(app.UIFigure, 'Pointer','arrow');
        end

        % Value changed function: DataTableDropDown
        function DataTableDropDownValueChanged(app, event)
            app.DataName = app.DataTableDropDown.Value;
        end

        % Value changed function: FeatureTableDropDown
        function FeatureTableDropDownValueChanged(app, event)
            app.FeatureName = app.FeatureTableDropDown.Value;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
                if ismember('-',app.DataTableDropDown.Value)
                    uialert(app.UIFigure,'You need to select a Data Sheet','Failed to import data','Icon','error');
                    return;
                end
                if ismember('-',app.FeatureTableDropDown.Value)
                    uialert(app.UIFigure,'You need to select a Feature Sheet','Failed to import data','Icon','error');
                    return;
                end  
                if isempty(app.ProjectName) | any(regexp(app.ProjectName, '[^a-zA-Z0-9._-]' ))
                    uialert(app.UIFigure,"Project Name must be alphanumeric with spaces as '_'",'Failed to import data','Icon','error');
                    return;
                end  
                try
                    if app.isXLS
                        [Data,Feature] = readDataXLapp(app.xlFullPathfilename,DataSheet=app.DataName,FeatureSheet=app.FeatureName,fighandle=app.UIFigure);                        
                    else
                        [Data,Feature] = readDataCSVapp(app.csvFullPathDatafilename,app.csvFullPathFeaturefilename,fighandle=app.UIFigure);                                         
                    end
                catch ME
                    uialert(app.UIFigure,ME.message,'Failed to import data','Icon','error');
                    reset(app);
                    return;
                end

                Data.iiidx___ = 1:height(Data);

                keep = ismember(Data.SampleType,'Ignore');
                DataX = Data(keep,:);
                Data = Data(~keep,:);

                app.CallingApp.WorkingDirectory = app.CurrentFolder;
                app.CallingApp.ProjectName = app.ProjectName;
                app.CallingApp.DataTable = Data;
                app.CallingApp.DataTableX = DataX;
                app.CallingApp.FeatureTable = Feature;
                app.CallingApp.DataName = app.DataName;
                app.CallingApp.FeatureName = app.FeatureName;
                app.CallingApp.FileType = app.FileType;
                app.CallingApp.ImportSuccess = true;
                
%                 app.CallingApp.Restart();
            UIFigureCloseRequest(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           delete(app)      
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            web('https://github.com/broadhurstdavid/TidyMet');
        end

        % Value changed function: ProjectNameEditField
        function ProjectNameEditFieldValueChanged(app, event)
            app.ProjectName = app.ProjectNameEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ORLabel = uilabel(app.UIFigure);
            app.ORLabel.Text = 'OR';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.CSVPanel = uipanel(app.UIFigure);
            app.CSVPanel.Title = 'CSV:';
            app.CSVfeatureLabel = uilabel(app.CSVPanel);
            app.CSVfeatureLabel.Text = '';
            app.ChooseFeatureCSVButton = uibutton(app.CSVPanel, 'push');
            app.ChooseFeatureCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFeatureCSVButtonPushed, true);
            app.ChooseFeatureCSVButton.Text = 'Select Feature Table CSV';
            app.CSVdataLabel = uilabel(app.CSVPanel);
            app.CSVdataLabel.Text = '';
            app.ChooseDataCSVButton = uibutton(app.CSVPanel, 'push');
            app.ChooseDataCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseDataCSVButtonPushed, true);
            app.ChooseDataCSVButton.Text = 'Select Data Table CSV';
            app.ProjectNameEditFieldLabel = uilabel(app.CSVPanel);
            app.ProjectNameEditFieldLabel.Text = 'Project Name:';
            app.ProjectNameEditField = uieditfield(app.CSVPanel, 'text');
            app.ProjectNameEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectNameEditFieldValueChanged, true);
            app.ExcelPanel = uipanel(app.UIFigure);
            app.ExcelPanel.Title = 'Excel:';
            app.DataTableDropDownLabel = uilabel(app.ExcelPanel);
            app.DataTableDropDownLabel.Text = 'Data Table:';
            app.FeatureTableDropDownLabel = uilabel(app.ExcelPanel);
            app.FeatureTableDropDownLabel.Text = 'Feature Table:';
            app.FeatureTableDropDown = uidropdown(app.ExcelPanel);
            app.FeatureTableDropDown.Items = {''};
            app.FeatureTableDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureTableDropDownValueChanged, true);
            app.FeatureTableDropDown.Value = '';
            app.DataTableDropDown = uidropdown(app.ExcelPanel);
            app.DataTableDropDown.Items = {};
            app.DataTableDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTableDropDownValueChanged, true);
            app.DataTableDropDown.Value = {};
            app.ExcelFileLabel = uilabel(app.ExcelPanel);
            app.ExcelFileLabel.Text = '';
            app.ChooseExcelButton = uibutton(app.ExcelPanel, 'push');
            app.ChooseExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExcelButtonPushed, true);
            app.ChooseExcelButton.Text = 'Select Excel File';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'res', 'TidyMetqcx.png');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImportDataFeatureFile(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end