classdef InputDialogApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WithinBatchModeDropDown       matlab.ui.control.DropDown
        WithinBatchModeDropDownLabel  matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        gammavalueSpinner             matlab.ui.control.Spinner
        gammavalueLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        gamma % Description
        gammaStart
        batchIndex
        callingApp % Description
    end
    
    properties (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, xloc, yloc, batchIndex, gammaVal)

            app.UIFigure.Name = 'Spline Correction';
            pos = app.UIFigure.Position;
            pos(1) = xloc;
            pos(2) = yloc;
            app.UIFigure.Position = pos;
            app.batchIndex = batchIndex;
            app.gamma = gammaVal;
            app.gammavalueSpinner.Value = app.gamma;
            app.gammaStart = app.gamma;         
            app.callingApp = callingApp;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: gammavalueSpinner
        function gammavalueSpinnerValueChanged(app, event)
            app.gamma = app.gammavalueSpinner.Value;
            %uiresume(app.UIFigure);
            ExternalDrivenSplineCorrection(app.callingApp,app.gamma,app.batchIndex);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.gamma = app.gammaStart;
            %uiresume(app.UIFigure);
            ExternalDrivenSplineCorrection(app.callingApp,app.gamma,app.batchIndex);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.gammavalueLabel = uilabel(app.UIFigure);
            app.gammavalueLabel.Interpreter = 'latex';
            app.gammavalueLabel.Text = '\gamma-value:';
            app.gammavalueSpinner = uispinner(app.UIFigure);
            app.gammavalueSpinner.Limits = [0 15];
            app.gammavalueSpinner.RoundFractionalValues = 'on';
            app.gammavalueSpinner.ValueChangedFcn = createCallbackFcn(app, @gammavalueSpinnerValueChanged, true);
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.WithinBatchModeDropDownLabel = uilabel(app.UIFigure);
            app.WithinBatchModeDropDownLabel.Text = 'Within-Batch Mode:';
            app.WithinBatchModeDropDown = uidropdown(app.UIFigure);
            app.WithinBatchModeDropDown.Items = {'Sample', 'Median', 'Linear', 'Spline', 'Spline-C2', 'Spline-C4', 'Spline-C6'};
            app.WithinBatchModeDropDown.Value = 'Spline';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InputDialogApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end