classdef QCRSCconfigExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image2_2                       matlab.ui.control.Image
        BlankRatioPanel                matlab.ui.container.Panel
        RelativeLOQEditField           matlab.ui.control.NumericEditField
        ThresholdLabel                 matlab.ui.control.Label
        BlankRatioMethodDropDown       matlab.ui.control.DropDown
        MethodDropDownLabel_2          matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        StatisticsBeforeAfter95CIAfterPanel  matlab.ui.container.Panel
        sampleRSDLabel                 matlab.ui.control.Label
        SampleRSDBeforeAfterData       matlab.ui.control.Label
        blankRatioLabel                matlab.ui.control.Label
        BlankRatioData                 matlab.ui.control.Label
        qcRSDLabel                     matlab.ui.control.Label
        QCRSDBeforeAfterData           matlab.ui.control.Label
        dRatioLabel                    matlab.ui.control.Label
        dRatioBeforeAfterData          matlab.ui.control.Label
        refRSDLabel                    matlab.ui.control.Label
        RefRSDBeforeAfterData          matlab.ui.control.Label
        OutlierDetectionPanel          matlab.ui.container.Panel
        QCOutlierDetectionCIEditField  matlab.ui.control.NumericEditField
        CIEditFieldLabel               matlab.ui.control.Label
        QCOutlierReplacementStrategyDropDown  matlab.ui.control.DropDown
        PosthocDropDownLabel           matlab.ui.control.Label
        OutlierApplyButton             matlab.ui.control.Button
        MethodDropDownLabel            matlab.ui.control.Label
        QCOutlierDetectionMethodDropDown  matlab.ui.control.DropDown
        FeaturePanel                   matlab.ui.container.Panel
        RESETButton                    matlab.ui.control.Button
        ViewFeatureTableButton         matlab.ui.control.Button
        FeatureEditField               matlab.ui.control.EditField
        FeatureLabel                   matlab.ui.control.Label
        FeatureDropDown                matlab.ui.control.DropDown
        BatchDropDown                  matlab.ui.control.DropDown
        LogTransformedCorrectionCheckBox  matlab.ui.control.CheckBox
        RemoveZerosCheckBox            matlab.ui.control.CheckBox
        BatchDropDownLabel             matlab.ui.control.Label
        FeatureDropDownLabel           matlab.ui.control.Label
        RandomFeatureButton            matlab.ui.control.Button
        Panel_Optimise                 matlab.ui.container.Panel
        WithinBatchCorrectionModeDropDown  matlab.ui.control.DropDown
        ModeDropDownLabel              matlab.ui.control.Label
        QCRSCCrossValidationMethodDropDown  matlab.ui.control.DropDown
        CrossValidationDropDownLabel   matlab.ui.control.Label
        OptimiseButton                 matlab.ui.control.Button
        MCrepsLabel                    matlab.ui.control.Label
        QCRSCMonteCarlorepetitionsEditField  matlab.ui.control.NumericEditField
        Panel_CVplot                   matlab.ui.container.Panel
        gammavalueLabel                matlab.ui.control.Label
        gammaValueSlider               matlab.ui.control.Slider
        UIAxesMSE                      matlab.ui.control.UIAxes
        Panel_BeforeAfter              matlab.ui.container.Panel
        IgnoreBlanksCheckBox           matlab.ui.control.CheckBox
        UIAxesBefore                   matlab.ui.control.UIAxes
        UIAxesAfter                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CurrentFolder   %
        DataTable       % Description
        FeatureTable       % Feature Table - primarily to access P.UID
        ProjectName     % Project Name
        ExcelName       % Full Path of Excel File.
        DataTableName        
        FeatureTableName
        ImportSuccess
        FileType        % csv or xls
        dotSize
    end
    
    properties (Access = private)
        
        CallerApp
        SpreadsheetApp  % popup app to view (edit) Data & Feature tables       
                
        feature            % Name of feature selected to view (e.g. CP1002) 
        feature_old        % Previous feature
        name
        
        jsonTxt         % current json config.
        jsonDefault     % default json config for use during reset.
        baseConfig      % Data Structure containing all the configuration options
        callerConfig
        configFilename  % Config filename
        
        y               % raw metabolite feature data
        t               % injection order
        isQC            % y data that is a pooled QC
        isSample        % y data that is a biological sample
        isBlank         % y data that is a blank sample
        isReference     % y data that a reference (i.e. external test)
        SampleID 
        SampleType
        
        yqc             % parsed QC-only metabolite feature data
        tqc             % parsed QC-only injection order numbers
        toutlier        % time-points in which yqc is considered an outlier
        
        gammaRange      % range of values used to search for the optimal spline fit to tqc vs yqc
        epsilon         % scaling factor to make the CPAPS algorithm tractable.
        gammaVal        % optimal smoothing value
        cvMse           % cross validation Mean Square Error for the spline fit
        minVal          % minimum value of cvMse
        
        yspline         % the optimal spline fit
        z               % the corrected y data z=f(y) where f = spline model.
        mpv             % mean feature QC value to "re-inflate" the corrected data
        
        h_cvMSE         % figure handle for the cvMSE plot
        h_Before        % figure handle for the "before" correction spline (yspline)
        h_Before1       % figure handle for the "before"correction data
        h_Before2       % figure handle for the "before"correction data
        h_Before3       % figure handle for the "before"correction data
        h_Before4       % figure handle for the "before"correction data
        h_Before5       % figure handle for the "before"correction data
        h_After1        % figure handle for the "after" correction data
        h_After2        % figure handle for the "after" correction data
        h_After3        % figure handle for the "after" correction data
        h_After4        % figure handle for the "after" correction data
        h_After5        % figure handle for the "after" correction data
        
        InsufficientDataFlag % flag to abort any attempt at correction when low number of QCs
    end
    
    methods (Access = private)
        
        % helper function to select the correct feature (or a random feature)
        
        function getFeature(app)
            app.feature_old = app.feature;
            app.feature = app.FeatureDropDown.Value;
            if strcmp(app.feature,'Random')
                idx = randperm(length(app.FeatureTable.UID)-1)+1;
                app.feature = app.FeatureTable.UID{idx(1)};
            end
            temp = ismember(app.FeatureTable.UID,app.feature);
            app.name = app.FeatureTable.Name{temp};
        end


        % helper function to call external OutlierFilter function.
        
        function change = OutlierDetect(app)
            
            old = length(app.toutlier);
            try
                switch app.QCOutlierDetectionMethodDropDown.Value
                    case 'None'
                        app.toutlier = [];
                    case 'Percentile'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'prctile',app.QCOutlierDetectionCIEditField.Value);
                    case 'Linear'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'poly1',app.QCOutlierDetectionCIEditField.Value);
                    case 'Quadratic'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'poly2',app.QCOutlierDetectionCIEditField.Value);
                    case 'Cubic'
                        [app.tqc,app.yqc,app.toutlier] = OutlierFilter(app.tqc,app.yqc,'poly3',app.QCOutlierDetectionCIEditField.Value);
                    otherwise
                        error('This outlier method does not exist');
                end
            catch
               app.toutlier = [];
            end
         
            if length(app.toutlier) == old
                change = false;
            else
                change = true;
            end
            
        end
        
        % meat and potatoes of the app. 
        % helper function to call optimiseCSAPS
        
        function optQCRSC(app)       
                                                
            switch app.QCRSCCrossValidationMethodDropDown.Value
                case '3-Fold', nfold = 3;
                case '5-Fold', nfold = 5;
                case '7-Fold', nfold = 7;
                case 'Leaveout', nfold = -1;
            end
            
            switch app.WithinBatchCorrectionModeDropDown.Value
                case 'Sample'
                    app.gammaVal = 10000; % code to trigger NoQC regression
                    app.epsilon = NaN;
                    app.cvMse = 0;
                    app.minVal = 0;
                case 'Median'
                    app.gammaVal = NaN;
                    app.epsilon = NaN;
                    app.cvMse = 0;
                    app.minVal = 0;
                case 'Linear'
                    %app.gammaVal = 11;
                    %avDist = median(app.tqc(2:end) - app.tqc(1:end-1));
                    %app.epsilon = avDist^3/16;
                    app.gammaVal = 1000; % code to trigger Robust regression
                    app.epsilon = NaN;
                    app.cvMse = 0;
                    app.minVal = 0;
                otherwise                    
                    [app.gammaVal,app.epsilon,app.cvMse,app.minVal] = ...
                    optimiseCSAPS(app.tqc,app.yqc,app.gammaRange,nfold,app.QCRSCMonteCarlorepetitionsEditField.Value);
                    switch app.WithinBatchCorrectionModeDropDown.Value
                        case 'Spline-C2'              
                            app.gammaVal = max(app.gammaVal,2);
                        case 'Spline-C4'
                            app.gammaVal = max(app.gammaVal,4);
                        case 'Spline-C6'
                            app.gammaVal = max(app.gammaVal,6);
                    end

            end
            if isscalar(app.gammaRange) || ~ismember(app.WithinBatchCorrectionModeDropDown.Value,{'Spline','Spline-C2','Spline-C4','Spline-C6'})
                app.gammaValueSlider.Enable = 'off';
            else
                app.gammaValueSlider.Enable = 'on';
                app.gammaValueSlider.Limits = [min(app.gammaRange),max(app.gammaRange)];
                app.gammaValueSlider.MajorTicks = min(app.gammaRange):max(app.gammaRange);
                app.gammaValueSlider.Value = app.gammaVal;         
            end
            
        end
        
        % helper function to call external calcStats
        % calculates RSD, D-Ratio and Blank-Ratio
        
        function displayStats(app)
            
            found = ismember(app.t,app.toutlier);
            yy = app.y;
            switch app.QCOutlierReplacementStrategyDropDown.Value 
                case 'Ignore'
                case 'Median'
                    yy(found) = median(yy(app.isQC));
                case 'NaN'
                    yy(found) = nan;
                otherwise
                    ME = MException('QCRSC:UnexpectedQCRSCoutlierAction',"QCRSC OutlierAction '%s' does not exist",app.QCOutlierReplacementStrategyDropDown.Value);
                    throw(ME);    
            end        
            zz = app.z;
            
            isLog = app.LogTransformedCorrectionCheckBox.Value;
            BRM = app.baseConfig.BlankRatioMethod;

            [StatBefore] = calcStats(yy,app.isQC,app.isSample,app.isBlank,app.isReference,Logged=isLog,BlankRatioMethod=BRM);
            [StatAfter]  = calcStats(zz,app.isQC,app.isSample,app.isBlank,app.isReference,Logged=isLog,BlankRatioMethod=BRM); 

            if isnan(StatBefore.qcRSD)
                app.QCRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
            else
                app.QCRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',StatBefore.qcRSD,StatAfter.qcRSD,StatAfter.qcRSDlower95CI,StatAfter.qcRSDupper95CI);           
            end

            if isnan(StatBefore.sampleRSD)
                app.SampleRSDBeforeAfterData.Text = sprintf('Insufficient Data');         
            else
                app.SampleRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',StatBefore.sampleRSD,StatAfter.sampleRSD,StatAfter.sampleRSDlower95CI,StatAfter.sampleRSDupper95CI);           
            end

            if isnan(StatBefore.refRSD)
                app.RefRSDBeforeAfterData.Text = sprintf('Insufficient Data');
            else
                app.RefRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',StatBefore.refRSD,StatAfter.refRSD,StatAfter.refRSDlower95CI,StatAfter.refRSDupper95CI);           
            end

            if isnan(StatBefore.dRatio)           
                app.dRatioBeforeAfterData.Text = sprintf('Insufficient Data');
            else                                     
                app.dRatioBeforeAfterData.Text = sprintf('[%.2f] %.2f',StatBefore.dRatio,StatAfter.dRatio);
            end
            
            if isnan(StatBefore.blankRatio)
                app.BlankRatioData.Text = sprintf('Insufficient Data');
            else
                app.BlankRatioData.Text = sprintf('(%.2f) %.2f',StatBefore.blankRatio,StatAfter.blankRatio);
            end  

            
            % [QCrsdBefore,~,~,SAMPLErsdBefore,~,~,REFrsdBefore,~,~,dRatioBefore,blankRatioBefore] = calcStats(yy,app.isQC,app.isSample,app.isBlank,app.isReference,Logged=isLog,BlankRatioMethod=BRM);
            % [QCrsdAfter,QCrsdAfterlower,QCrsdAfterupper,SAMPLErsdAfter,SAMPLErsdAfterlower,SAMPLErsdAfterupper,REFrsdAfter,REFrsdAfterlower,REFrsdAfterupper,dRatioAfter,blankRatioAfter] = calcStats(app.z,app.isQC,app.isSample,app.isBlank,app.isReference,Logged=isLog,BlankRatioMethod=BRM);
            % 
            % if isnan(QCrsdBefore)
            %     app.QCRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
            % else
            %     app.QCRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',QCrsdBefore,QCrsdAfter,QCrsdAfterlower,QCrsdAfterupper);           
            % end 
            % 
            % if isnan(SAMPLErsdBefore)
            %     app.SampleRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
            % else
            %     app.SampleRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',SAMPLErsdBefore,SAMPLErsdAfter,SAMPLErsdAfterlower,SAMPLErsdAfterupper);           
            % end
            % 
            % if isnan(REFrsdBefore)
            %     app.RefRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
            % else
            %     app.RefRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',REFrsdBefore,REFrsdAfter,REFrsdAfterlower,REFrsdAfterupper);           
            % end
            % 
            % if isnan(dRatioBefore)
            %     app.dRatioBeforeAfterData.Text = sprintf('Insufficient Data');
            % else
            %     app.dRatioBeforeAfterData.Text = sprintf('[%.2f] %.2f',dRatioBefore,dRatioAfter);
            % end
            % 
            % if isnan(blankRatioBefore)
            %     app.BlankRatioData.Text = sprintf('Insufficient Data');
            % else
            %     app.BlankRatioData.Text = sprintf('[%.2f] %.2f',blankRatioBefore,blankRatioAfter);
            % end
            
        end
       
        % plotting the results of the optimization
        % cvMSE = cross validated Mean Squared Error of fit.
        
        function plot_cvMSE(app)
            
            axInnerPos = app.UIAxesMSE.InnerPosition;                  
            
            cla(app.UIAxesMSE);
            plot(app.UIAxesMSE,app.gammaRange,app.cvMse,'b-o','linewidth',1,'MarkerFaceColor','w','MarkerSize',4);
            hold(app.UIAxesMSE,'on');
            plot(app.UIAxesMSE,app.gammaVal,app.minVal,'ko','linewidth',1,'MarkerFaceColor',[0.7,0.7,0.7],'MarkerSize',8);
            app.h_cvMSE = plot(app.UIAxesMSE,app.gammaVal,app.minVal,'ko','linewidth',1,'MarkerFaceColor','r','MarkerSize',6);
            if isscalar(app.gammaRange)
                xlim(app.UIAxesMSE,[app.gammaVal-1,app.gammaVal+1]);
                ylim(app.UIAxesMSE,[app.minVal,2*app.minVal]);
            elseif isscalar(app.cvMse)
                xlim(app.UIAxesMSE,[min(app.gammaRange),max(app.gammaRange)]);
                ylim(app.UIAxesMSE,[-1,1]);
                if ismember(app.WithinBatchCorrectionModeDropDown.Value,{'Spline','Spline-C2','Spline-C4','Spline-C6'})
                    text(app.UIAxesMSE,mean(app.gammaRange),0.5,{'Nothing to optimise: ';'Too few QCs for Spline Correction'},"HorizontalAlignment","center");
                else
                    text(app.UIAxesMSE,mean(app.gammaRange),0.5,{'Nothing to optimise: ';[app.WithinBatchCorrectionModeDropDown.Value,' Correction']},"HorizontalAlignment","center");
                end
            else
                xlim(app.UIAxesMSE,[min(app.gammaRange),max(app.gammaRange)]);
                ylim(app.UIAxesMSE,[min(app.cvMse),max(app.cvMse)]); 
            end
                
            hold(app.UIAxesMSE,'off');
            innerPosDiff = axInnerPos - app.UIAxesMSE.InnerPosition;
            
            app.UIAxesMSE.Position = app.UIAxesMSE.Position + innerPosDiff;

        end
        
        %Plot the data and optimal spline before correction
        % note the handles are required for the interactive changes to gamma
        
        function plot_before(app)
            
            isLog = app.LogTransformedCorrectionCheckBox.Value;
            found = ismember(app.t,app.toutlier);
            youtlier = app.y(found);

            if ismac
                dot_size = 6;
                lw = 1.7;
                ms = 5;
            else
                dot_size = 5;
                lw = 1.5;
                ms = 4;
            end

            temp = app.y(app.isBlank);
            if isempty(temp)
                temp = NaN;
            else
                if isLog
                    temp = power(10,temp);
                    temp = max(temp,[],1,'omitnan');
                    temp = temp*app.baseConfig.RelativeLOQ;
                    temp = log10(temp);
                else
                    temp = max(temp,[],1,'omitnan');
                    temp = temp*app.baseConfig.RelativeLOQ;
                end
            end
            
          
            cla(app.UIAxesBefore);
            app.h_Before5 = line(app.UIAxesBefore,[app.t(1) app.t(end)],[temp temp],'LineStyle',':','Color','m','LineWidth',lw-1);
            datatip(app.h_Before5,'Visible','off');
            app.h_Before5.DataTipTemplate.DataTipRows(1) = dataTipTextRow('blank threshold','');
            app.h_Before5.DataTipTemplate.DataTipRows(2) = [];
            hold(app.UIAxesBefore,'on');           
            app.h_Before1 = plot(app.UIAxesBefore,app.t(~app.isQC),app.y(~app.isQC),'ko','MarkerFaceColor',[0,191,255]./255,'MarkerSize',dot_size);   
            app.h_Before1.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(~app.isQC));
            app.h_Before1.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(~app.isQC));            
            plot(app.UIAxesBefore,app.toutlier,youtlier,'ks','MarkerSize',dot_size+4,'MarkerFaceColor','k','LineWidth',lw);
            app.h_Before2 = plot(app.UIAxesBefore,app.t(app.isQC),app.y(app.isQC),'ko','MarkerFaceColor','r','MarkerSize',dot_size);
            if sum(app.isQC) ~= 0
                app.h_Before2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isQC));
                app.h_Before2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isQC));
            end
            app.h_Before3 = plot(app.UIAxesBefore,app.t(app.isBlank),app.y(app.isBlank),'ko','MarkerFaceColor','m','MarkerSize',dot_size); 
            if sum(app.isBlank) ~= 0
                app.h_Before3.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isBlank));
                app.h_Before3.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleType:',app.SampleType(app.isBlank));   
            end
            app.h_Before4 = plot(app.UIAxesBefore,app.t(app.isReference),app.y(app.isReference),'ko','MarkerFaceColor','y','MarkerSize',dot_size);           
            if sum(app.isReference) ~= 0
                app.h_Before4.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isReference));
                app.h_Before4.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isReference));      
            end
            plot(app.UIAxesBefore,app.t,app.yspline,'--','MarkerSize',ms,'LineWidth',lw-1,'color',[0.7,0.7,0.7]);
            app.h_Before = plot(app.UIAxesBefore,app.t,app.yspline,'r--','MarkerSize',ms,'LineWidth',lw-1);
            app.h_Before.DataTipTemplate.DataTipRows(1) = dataTipTextRow('correction spline','');
            app.h_Before.DataTipTemplate.DataTipRows(2) = [];
            axis(app.UIAxesBefore,[min(app.t)-1, max(app.t)+1, 0.99*nanmin([app.y;app.z]),1.01*nanmax([app.y;app.z])]);
            hold(app.UIAxesBefore,'off');
            cleanName = strrep(app.name,'_',' ');
            title(app.UIAxesBefore,['Original: ',num2str(app.feature),': ',cleanName])
            
        end
        
        %Plot the data and optimal spline after correction
        % note the handles are required for the interactive changes to gamma
        
        function plot_after(app)

            if ismac
                dot_size = 6;
                lw = 1.7;
                ms = 5;
            else
                dot_size = 5;
                lw = 1.5;
                ms = 4;
            end
            
            cla(app.UIAxesAfter); 
            app.h_After5 = line(app.UIAxesAfter,[app.t(1) app.t(end)],[app.mpv app.mpv],'LineStyle','--','Color','r','LineWidth',lw-1);
            datatip(app.h_After5,'Visible','off');
            app.h_After5.DataTipTemplate.DataTipRows(1) = dataTipTextRow('QC Grand Mean','');
            app.h_After5.DataTipTemplate.DataTipRows(2) = [];
            hold(app.UIAxesAfter,'on');
            plot(app.UIAxesAfter,app.t(~app.isQC),app.z(~app.isQC),'o','MarkerSize',dot_size,'color','k','MarkerFaceColor',[0.96,0.96,0.96]);
            plot(app.UIAxesAfter,app.t(app.isQC),app.z(app.isQC),'o','MarkerSize',dot_size,'color','k','MarkerFaceColor',[0.96,0.96,0.96]);
            app.h_After1 = plot(app.UIAxesAfter,app.t(~app.isQC),app.z(~app.isQC),'ko','MarkerFaceColor',[0,191,255]./255,'MarkerSize',dot_size);
            app.h_After1.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(~app.isQC));
            app.h_After1.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(~app.isQC));
            app.h_After2 = plot(app.UIAxesAfter,app.t(app.isQC),app.z(app.isQC),'ko','MarkerFaceColor','r','MarkerSize',dot_size);
            if sum(app.isQC) ~= 0
                app.h_After2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isQC));
                app.h_After2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isQC));
            end
            app.h_After3 = plot(app.UIAxesAfter,app.t(app.isBlank),app.z(app.isBlank),'ko','MarkerFaceColor','m','MarkerSize',dot_size);
            if sum(app.isBlank) ~= 0
                app.h_After3.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isBlank));
                app.h_After3.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isBlank));
            end
            app.h_After4 = plot(app.UIAxesAfter,app.t(app.isReference),app.z(app.isReference),'ko','MarkerFaceColor','y','MarkerSize',dot_size);
            if sum(app.isReference) ~= 0
                app.h_After4.DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',app.SampleID(app.isReference));
                app.h_After4.DataTipTemplate.DataTipRows(2) = dataTipTextRow('SampleType:',app.SampleType(app.isReference));
            end
            axis(app.UIAxesAfter,[min(app.t)-1, max(app.t)+1, 0.99*nanmin([app.y;app.z]),1.01*nanmax([app.y;app.z])]);
            hold(app.UIAxesAfter,'off');
            cleanName = strrep(app.name,'_',' ');
            title(app.UIAxesAfter,['Corrected: ',num2str(app.feature),': ',cleanName])
        end
        
        % helper function to transfer the data from the baseConfig
        % structure to the GUI
        
        
        % update all the various feature data when the feature name is changed
        % and account for new radio button selection
        % any changes in the FeaturePanel required a new acquisition of data from
        % source Data Table.
        
        function UpdateData(app)
            
            Data = app.DataTable(:,{'Order','QC','SampleID','SampleType','Sample','Blank','Reference','Batch',app.feature});
             
            if app.IgnoreBlanksCheckBox.Value
                Data = Data(Data.Blank == 0,:); 
            end
            keep = Data.Batch == app.BatchDropDown.Value;
            Data = Data(keep,:);
            
            app.y = Data.(app.feature);
            app.t = Data.Order;
            app.isQC = logical(Data.QC);
            app.isSample = logical(Data.Sample);
            app.isBlank = logical(Data.Blank);
            app.isReference = logical(Data.Reference);
            app.SampleID = Data.SampleID;
            app.SampleType = Data.SampleType;
            
           
            if app.RemoveZerosCheckBox.Value
                app.y(app.y <= 0) = NaN;
            end
            
            if app.LogTransformedCorrectionCheckBox.Value
                app.y(app.y <= 0) = NaN;
                app.RemoveZerosCheckBox.Value = true;
                app.y = log10(app.y);
                ylabel(app.UIAxesBefore,'log_{10}(Feature Value)');
                ylabel(app.UIAxesAfter,'log_{10}(Feature Value)');
            else
                ylabel(app.UIAxesBefore,'Feature Value');
                ylabel(app.UIAxesAfter,'Feature Value');
            end
           
            
            missing = isnan(app.y);
            app.isQC(missing) = false;
            app.tqc = app.t(app.isQC);
            app.yqc = app.y(app.isQC);
            app.mpv = median(app.yqc);
            
            if length(app.tqc) < 3
                %uialert(app.UIFigure,'Too few QC points to construct a correction curve. QCRSC ignored','Warning','Icon','warning');
                app.gammaValueSlider.Enable = 'off';
                app.OptimiseButton.Enable = 'off';
                app.OutlierApplyButton.Enable = 'off';
                app.cvMse = [];
                app.InsufficientDataFlag = true;
            else
                app.gammaValueSlider.Enable = 'on';
                app.OptimiseButton.Enable = 'on';
                app.OutlierApplyButton.Enable = 'on';
                app.InsufficientDataFlag = false;
            end
        end
        
        function [outConfig,changed] = updateConfigStruct(app,inConfig)
            outConfig = inConfig;
            outConfig.LogTransformedCorrection = app.LogTransformedCorrectionCheckBox.Value;
            outConfig.RemoveZeros = app.RemoveZerosCheckBox.Value;        
            outConfig.OutlierDetectionMethod = app.QCOutlierDetectionMethodDropDown.Value;
            outConfig.OutlierDetectionCI = app.QCOutlierDetectionCIEditField.Value;
            outConfig.OutlierReplacementStrategy = app.QCOutlierReplacementStrategyDropDown.Value;
            outConfig.WithinBatchCorrectionMode = app.WithinBatchCorrectionModeDropDown.Value;            
            outConfig.QCRSCcvMethod = app.QCRSCCrossValidationMethodDropDown.Value;
            outConfig.QCRSCmcReps = app.QCRSCMonteCarlorepetitionsEditField.Value;
            outConfig.BlankRatioMethod = app.BlankRatioMethodDropDown.Value;
            outConfig.RelativeLOQ = app.RelativeLOQEditField.Value;
            changed = ~isequaln(inConfig,outConfig);
        end

        function setConfigFields(app,configStruct)
            arguments
                app
                configStruct = app.baseConfig;
            end
            app.LogTransformedCorrectionCheckBox.Value = configStruct.LogTransformedCorrection;
            app.RemoveZerosCheckBox.Value = configStruct.RemoveZeros;
            app.QCOutlierDetectionMethodDropDown.Value = configStruct.OutlierDetectionMethod;
            app.QCOutlierDetectionCIEditField.Value = configStruct.OutlierDetectionCI;
            app.QCOutlierReplacementStrategyDropDown.Value = configStruct.OutlierReplacementStrategy;
            app.WithinBatchCorrectionModeDropDown.Value = configStruct.WithinBatchCorrectionMode;            
            app.QCRSCCrossValidationMethodDropDown.Value = configStruct.QCRSCcvMethod;
            app.QCRSCMonteCarlorepetitionsEditField.Value = configStruct.QCRSCmcReps;
            app.BlankRatioMethodDropDown.Value = configStruct.BlankRatioMethod; 
            app.RelativeLOQEditField.Value = configStruct.RelativeLOQ;

        end    

    end


    methods (Access = public)
            
        function Restart(app)
            ffile = fullfile(app.CurrentFolder,app.ProjectName);
            app.UIFigure.Name =  ffile;
            %app.UIFigure.Name = app.ProjectName;
            app.FeatureDropDown.Items = [app.FeatureTable.UID;'Random'];
            app.BatchDropDown.Items = arrayfun(@(x) ['Batch ',num2str(x)],unique(app.DataTable.Batch),'UniformOutput',false);
            app.BatchDropDown.ItemsData = unique(app.DataTable.Batch);
            RESETButtonPushed(app);         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp)
            
            if ismac
                changeFont(app.UIFigure,'Avenir');
            else
                changeFont(app.UIFigure,'MS Sans Serif');
            end
            app.CallerApp = callerApp;

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            width = screenWidth*0.8;
            if screenWidth < 1200
                app.UIFigure.Position = [50 50 width 682];
                app.UIFigure.Scrollable = 'on';
            end
            
            app.gammavalueLabel.Text = [char(947), ' value'];
            %app.gammarangeLabel.Text = [char(947), ' range:'];      
            %app.gammarangeLabel.Text = [char(947), ' range:'];
            %app.gminLabel.Text = [char(947), 'lower limit:'];
            
            if isempty(app.CallerApp.DataTable)
                [app.DataTable,app.FeatureTable] = generateMetabExamplesNew();
                app.ProjectName = 'Demo (25 LC-MS C18+ Features, single batch)';
                [app.DataTable,app.FeatureTable] = validatingDataFeatureTables(app.DataTable,app.FeatureTable,fighandle = app.UIFigure,ColumnWarningFlag=false);              
                app.configFilename = '';
                app.CurrentFolder = '';
            else
                app.DataTable = app.CallerApp.DataTable;
                app.FeatureTable = app.CallerApp.FeatureTable(app.CallerApp.preProcKeep,:);
                [app.DataTable,app.FeatureTable] = validatingDataFeatureTables(app.DataTable,app.FeatureTable,fighandle = app.UIFigure,ColumnWarningFlag=false);
                app.configFilename = app.CallerApp.configFilename;
                app.ProjectName = app.CallerApp.ProjectName; 
                app.CurrentFolder = app.CallerApp.WorkingDirectory;
            end

            app.callerConfig = validateConfig(app.CallerApp.baseConfig);
            app.baseConfig = app.callerConfig;
            setConfigFields(app);

            ffile = fullfile(app.CurrentFolder,app.ProjectName);
            app.UIFigure.Name =  ffile;
            
            app.DataTableName = app.callerConfig.DataTable;
            app.FeatureTableName = app.callerConfig.FeatureTable;
            
            app.FeatureDropDown.Items = [app.FeatureTable.UID;'Random'];
            app.BatchDropDown.Items = arrayfun(@(x) ['Batch ',num2str(x)],unique(app.DataTable.Batch),'UniformOutput',false);
            app.BatchDropDown.ItemsData = unique(app.DataTable.Batch);
            app.gammaRange = 0:15;
            
            RESETButtonPushed(app);

            if ~isempty(app.CallerApp.SpreadsheetApp)
                if isvalid(app.CallerApp.SpreadsheetApp)
                    app.ViewFeatureTableButton.Enable = 'off';
                    app.SpreadsheetApp = app.CallerApp.SpreadsheetApp;
                end
            end
            
            getFeature(app);
            app.FeatureEditField.Value = app.feature;
            UpdateData(app);
            OutlierDetect(app);            
            OptimiseButtonPushed(app); 
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            app.baseConfig = app.callerConfig;            
            app.BatchDropDown.Value = app.BatchDropDown.ItemsData(1);
            app.FeatureDropDown.Value = app.FeatureDropDown.Items(1);
            setConfigFields(app);            
            GetFeatureUpdateData(app);
        end

        % Button pushed function: ViewFeatureTableButton
        function ViewFeatureTableButtonPushed(app, event)
          app.ViewFeatureTableButton.Enable = 'off';  
          ExternalViewFeatureTableButtonPushed(app.CallerApp);
          %app.SpreadsheetApp = TidyFeatureTableView(app, app.FeatureTable, Name=app.ProjectName,FeatureTableName=app.FeatureTableName);
          
        end

        % Value changed function: FeatureEditField
        function FeatureEditFieldValueChanged(app, event)
            oldvalue = app.FeatureDropDown.Value;
            value = app.FeatureEditField.Value;
            if ~ismember(value,app.FeatureDropDown.Items)
                uialert(app.UIFigure,'Feature does not exist','Warning','Icon','warning');
                %warndlg('Feature does not exist','Warning');
                value = oldvalue;
            end
            app.FeatureDropDown.Value = value;
            GetFeatureUpdateData(app);
        end

        % Button pushed function: RandomFeatureButton
        function RandomFeatureButtonPushed(app, event)
            app.FeatureDropDown.Value = 'Random';
            GetFeatureUpdateData(app);
        end

        % Value changed function: FeatureDropDown
        function GetFeatureUpdateData(app, event)
            %value = app.FeatureDropDown.Value;
            getFeature(app);
            app.FeatureEditField.Value = app.feature;
            UpdateDataChanged(app);
            
            if ~isempty(app.SpreadsheetApp)         
                if isvalid(app.SpreadsheetApp)
                    highlightrow(app.SpreadsheetApp,app.feature);
                end
            end
        end

        % Value changed function: BatchDropDown
        function BatchDropDownValueChanged(app, event)
            UpdateDataChanged(app);
        end

        % Value changed function: IgnoreBlanksCheckBox, 
        % ...and 2 other components
        function UpdateDataChanged(app, event)
            UpdateData(app);
            OutlierDetect(app);
            OptimiseButtonPushed(app);
        end

        % Callback function: OutlierApplyButton, 
        % ...and 2 other components
        function OutlierApplyButtonPushed(app, event)
            UpdateData(app);
            changed = OutlierDetect(app);
            if changed
                OptimiseButtonPushed(app);
            end
        end

        % Callback function: OptimiseButton, 
        % ...and 3 other components
        function OptimiseButtonPushed(app, event)
           if app.InsufficientDataFlag  
                app.yspline = app.mpv*ones(length(app.y),1);
                app.z = app.y;
                cla(app.UIAxesMSE);
                xlim(app.UIAxesMSE,[-1,1]);
                ylim(app.UIAxesMSE,[-1,1]);
                text(app.UIAxesMSE,0,0.5,{'Too Few QC samples';'QCRSC ignored'},"HorizontalAlignment","center");

                displayStats(app);
           else
               if ~ismember(app.WithinBatchCorrectionModeDropDown.Value,{'Spline','Spline-C2','Spline-C4','Spline-C6'})
                    app.QCRSCCrossValidationMethodDropDown.Visible = "off";
                    app.CrossValidationDropDownLabel.Enable = "off";
                    app.QCRSCMonteCarlorepetitionsEditField.Visible = "off";
                    app.MCrepsLabel.Enable = "off";
               else                   
                    app.QCRSCCrossValidationMethodDropDown.Visible = "on";
                    app.CrossValidationDropDownLabel.Enable = "on";
                    if strcmp(app.QCRSCCrossValidationMethodDropDown.Value,'Leaveout')
                        app.QCRSCMonteCarlorepetitionsEditField.Visible = "off";
                        app.MCrepsLabel.Enable = "off";
                    else
                        app.QCRSCMonteCarlorepetitionsEditField.Visible = "on";
                        app.MCrepsLabel.Enable = "on";
                    end  
               end

                %app.gammaRange = 0:15;

                
                optQCRSC(app);
                plot_cvMSE(app);
                if app.LogTransformedCorrectionCheckBox.Value
                    CorrectionType = 'Subtract';
                else
                    CorrectionType = 'Divide';
                end
                [app.z,app.yspline] = QCRSC3(app.t,app.y,app.isQC,app.isSample,app.mpv,app.epsilon,app.gammaVal,...
                    app.toutlier,CorrectionType,app.QCOutlierReplacementStrategyDropDown.Value); 
                app.z(app.isBlank) = app.y(app.isBlank);
                displayStats(app);
            end
            plot_before(app);
            plot_after(app);
            linkaxes([app.UIAxesBefore app.UIAxesAfter],'xy');
        end

        % Value changed function: QCOutlierReplacementStrategyDropDown
        function rerunQCRSC(app, event)
            if app.LogTransformedCorrectionCheckBox.Value
                CorrectionType = 'Subtract';
            else
                CorrectionType = 'Divide';
            end
            [app.z,app.yspline] = QCRSC3(app.t,app.y,app.isQC,app.isSample,app.mpv,app.epsilon,app.gammaVal,...
                app.toutlier,CorrectionType,app.QCOutlierReplacementStrategyDropDown.Value);
            app.z(app.isBlank) = app.y(app.isBlank);
            displayStats(app);
            plot_after(app);
        end

        % Value changing function: gammaValueSlider
        function gammaValueSliderValueChanging(app, event)
            changingValue = event.Value;
            %app.gammaRange = 0:15;
            
            app.h_cvMSE.YData = interp1(app.gammaRange,app.cvMse,changingValue);
            app.h_cvMSE.XData = changingValue;

            gammaValue = (changingValue-2)/2; 
            
            pp = 1/(1+app.epsilon*10^(gammaValue));
            yy = csaps(app.tqc,app.yqc,pp,app.t);

            if app.LogTransformedCorrectionCheckBox.Value
                app.z = (app.y-yy)+app.mpv;
            else
                app.z = (app.y./yy).*app.mpv;
            end
            app.h_Before.YData = yy;
            
            app.h_After1.YData = app.z(~app.isQC);
            app.h_After2.YData = app.z(app.isQC);
            if ~isempty(app.z(app.isBlank)), app.h_After3.YData = app.z(app.isBlank); end
            if ~isempty(app.z(app.isReference)), app.h_After4.YData = app.z(app.isReference); end
            
            displayStats(app)
        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            [app.baseConfig,changed] = updateConfigStruct(app,app.baseConfig);
            if changed
                res = uiconfirm(app.UIFigure,'Do you want to transfer this configuration to the main application?','Confirm Action','Options',{'Yes','No'});
                if strcmp(res,'Yes') 
                    try      
                        app.baseConfig = validateConfig(app.baseConfig);
                        app.CallerApp.baseConfig = app.baseConfig; 
                        app.CallerApp.setConfigFields(mode = 'qcrsc');                          
                    catch ME                 
                        uialert(app.UIFigure,ME.message,'Error transfering config to main application. Reverting to original','Icon','error');
                        app.CallerApp.baseConfig = app.callerConfig;
                    end
                end
            end
            
            delete(app.SpreadsheetApp)           
            delete(app)
        end

        % Value changed function: BlankRatioMethodDropDown
        function BlankRatioMethodDropDownValueChanged(app, event)
            app.baseConfig.BlankRatioMethod = app.BlankRatioMethodDropDown.Value;
            displayStats(app);
        end

        % Value changed function: RelativeLOQEditField
        function RelativeLOQEditFieldValueChanged(app, event)
            app.baseConfig.RelativeLOQ = app.RelativeLOQEditField.Value;            
            plot_before(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_BeforeAfter = uipanel(app.UIFigure);
            app.UIAxesAfter = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesAfter, 'Corrected')
            xlabel(app.UIAxesAfter, 'Injection order')
            ylabel(app.UIAxesAfter, 'log_{10}(Feature Value)')
            zlabel(app.UIAxesAfter, 'Z')
            app.UIAxesBefore = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesBefore, 'Original')
            xlabel(app.UIAxesBefore, 'Injection order')
            ylabel(app.UIAxesBefore, 'log_{10}(Feature Value)')
            zlabel(app.UIAxesBefore, 'Z')
            app.IgnoreBlanksCheckBox = uicheckbox(app.Panel_BeforeAfter);
            app.IgnoreBlanksCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDataChanged, true);
            app.IgnoreBlanksCheckBox.Text = 'Ignore Blanks';
            app.Panel_CVplot = uipanel(app.UIFigure);
            app.Panel_CVplot.Tooltip = {'A plot of the cross-validation Mean Sqaure Error (cvMSE) for the range of smoothing values. The minimum cvMSE indicates the best smoothing value.'};
            app.Panel_CVplot.Title = 'Check Overfitting:';
            app.UIAxesMSE = uiaxes(app.Panel_CVplot);
            title(app.UIAxesMSE, '\gamma optimisation')
            ylabel(app.UIAxesMSE, 'cvMSE')
            zlabel(app.UIAxesMSE, 'Z')
            app.UIAxesMSE.XTickLabel = '';
            app.gammaValueSlider = uislider(app.Panel_CVplot);
            app.gammaValueSlider.Limits = [0 15];
            app.gammaValueSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.gammaValueSlider.ValueChangingFcn = createCallbackFcn(app, @gammaValueSliderValueChanging, true);
            app.gammaValueSlider.Tooltip = {'Move the slider to see how changing the smoothing value (Gamma) changes the correction curve, which in turn changes the corrected data.'};
            app.gammavalueLabel = uilabel(app.Panel_CVplot);
            app.gammavalueLabel.Text = 'g value';
            app.Panel_Optimise = uipanel(app.UIFigure);
            app.Panel_Optimise.Title = 'QC Correction:';
            app.QCRSCMonteCarlorepetitionsEditField = uieditfield(app.Panel_Optimise, 'numeric');
            app.QCRSCMonteCarlorepetitionsEditField.Limits = [1 100];
            app.QCRSCMonteCarlorepetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.QCRSCMonteCarlorepetitionsEditField.Tooltip = {'The number of Monte Carlo (random) resamples of the k-fold cross validation. The resulting cvMSE the mean of the generated set of cvMSEs.'};
            app.QCRSCMonteCarlorepetitionsEditField.Value = 20;
            app.MCrepsLabel = uilabel(app.Panel_Optimise);
            app.MCrepsLabel.Text = 'MCreps:';
            app.OptimiseButton = uibutton(app.Panel_Optimise, 'push');
            app.OptimiseButton.ButtonPushedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.OptimiseButton.Text = 'Optimise';
            app.CrossValidationDropDownLabel = uilabel(app.Panel_Optimise);
            app.CrossValidationDropDownLabel.Text = 'Cross Validation:';
            app.QCRSCCrossValidationMethodDropDown = uidropdown(app.Panel_Optimise);
            app.QCRSCCrossValidationMethodDropDown.Items = {'3-Fold', '5-Fold', '7-Fold', 'Leaveout'};
            app.QCRSCCrossValidationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.QCRSCCrossValidationMethodDropDown.Tooltip = {'The cross-validation method used to avoid overfitting the correction curve to the QC data. ''3-Fold'' is recommended.'};
            app.QCRSCCrossValidationMethodDropDown.Value = '5-Fold';
            app.ModeDropDownLabel = uilabel(app.Panel_Optimise);
            app.ModeDropDownLabel.Text = 'Mode:';
            app.WithinBatchCorrectionModeDropDown = uidropdown(app.Panel_Optimise);
            app.WithinBatchCorrectionModeDropDown.Items = {'Sample', 'Linear', 'Median', 'Spline', 'Spline-C2', 'Spline-C4', 'Spline-C6'};
            app.WithinBatchCorrectionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimiseButtonPushed, true);
            app.WithinBatchCorrectionModeDropDown.Tooltip = {'Three correction modes. "Spline" is the default QCRSC algorithm that requires optimisation of the smoothing parameter. "Linear" is a simple (interpolated) linear regression based on the QC values & as such requires no smoothing optimisation. "Median" equalises the QC mean across batches & ignores within batch systematic change. "Sample"  performs a linear regression based on only the Samples (the QCs are not used to model the regression).'};
            app.WithinBatchCorrectionModeDropDown.Value = 'Spline';
            app.FeaturePanel = uipanel(app.UIFigure);
            app.RandomFeatureButton = uibutton(app.FeaturePanel, 'push');
            app.RandomFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @RandomFeatureButtonPushed, true);
            app.RandomFeatureButton.Text = 'Random Feature';
            app.FeatureDropDownLabel = uilabel(app.FeaturePanel);
            app.FeatureDropDownLabel.Text = 'Feature:';
            app.BatchDropDownLabel = uilabel(app.FeaturePanel);
            app.BatchDropDownLabel.Text = 'Batch:';
            app.RemoveZerosCheckBox = uicheckbox(app.FeaturePanel);
            app.RemoveZerosCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDataChanged, true);
            app.RemoveZerosCheckBox.Text = 'Remove Zeros';
            app.LogTransformedCorrectionCheckBox = uicheckbox(app.FeaturePanel);
            app.LogTransformedCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateDataChanged, true);
            app.LogTransformedCorrectionCheckBox.Text = 'Log Transform';
            app.LogTransformedCorrectionCheckBox.Value = true;
            app.BatchDropDown = uidropdown(app.FeaturePanel);
            app.BatchDropDown.Items = {'No Data'};
            app.BatchDropDown.ValueChangedFcn = createCallbackFcn(app, @BatchDropDownValueChanged, true);
            app.BatchDropDown.Value = 'No Data';
            app.FeatureDropDown = uidropdown(app.FeaturePanel);
            app.FeatureDropDown.Items = {'No Data'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @GetFeatureUpdateData, true);
            app.FeatureDropDown.Value = 'No Data';
            app.FeatureLabel = uilabel(app.FeaturePanel);
            app.FeatureLabel.Text = 'Feature:';
            app.FeatureEditField = uieditfield(app.FeaturePanel, 'text');
            app.FeatureEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureEditFieldValueChanged, true);
            app.ViewFeatureTableButton = uibutton(app.FeaturePanel, 'push');
            app.ViewFeatureTableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFeatureTableButtonPushed, true);
            app.ViewFeatureTableButton.Text = 'View Feature Table';
            app.RESETButton = uibutton(app.FeaturePanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.OutlierDetectionPanel = uipanel(app.UIFigure);
            app.OutlierDetectionPanel.Title = 'Outlier Detection:';
            app.QCOutlierDetectionMethodDropDown = uidropdown(app.OutlierDetectionPanel);
            app.QCOutlierDetectionMethodDropDown.Items = {'None', 'Percentile', 'Linear', 'Quadratic', 'Cubic'};
            app.QCOutlierDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OutlierApplyButtonPushed, true);
            app.QCOutlierDetectionMethodDropDown.Tooltip = {'The ''linear''/quadratic/cubic'' options fit a simple polynomial function to the QC data using robust least squares regression. Points outside the population confidence interval are deemed outliers. The ''percentile'' option implements the standard non-parametric 1.5*IQR outlier detection method on the QC samples.'};
            app.QCOutlierDetectionMethodDropDown.Value = 'Percentile';
            app.MethodDropDownLabel = uilabel(app.OutlierDetectionPanel);
            app.MethodDropDownLabel.Text = 'Method:';
            app.OutlierApplyButton = uibutton(app.OutlierDetectionPanel, 'push');
            app.OutlierApplyButton.ButtonPushedFcn = createCallbackFcn(app, @OutlierApplyButtonPushed, true);
            app.OutlierApplyButton.Text = 'Apply';
            app.PosthocDropDownLabel = uilabel(app.OutlierDetectionPanel);
            app.PosthocDropDownLabel.Text = 'Post-hoc:';
            app.QCOutlierReplacementStrategyDropDown = uidropdown(app.OutlierDetectionPanel);
            app.QCOutlierReplacementStrategyDropDown.Items = {'Median', 'Ignore', 'NaN'};
            app.QCOutlierReplacementStrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @rerunQCRSC, true);
            app.QCOutlierReplacementStrategyDropDown.Tooltip = {'For the corrected data. Either ignore outliers or replace with the QC median feature value or remove (replace with "not a number" NaN).'};
            app.QCOutlierReplacementStrategyDropDown.Value = 'Median';
            app.CIEditFieldLabel = uilabel(app.OutlierDetectionPanel);
            app.CIEditFieldLabel.Text = '95% CI:';
            app.QCOutlierDetectionCIEditField = uieditfield(app.OutlierDetectionPanel, 'numeric');
            app.QCOutlierDetectionCIEditField.Limits = [0.9 0.999];
            app.QCOutlierDetectionCIEditField.ValueChangedFcn = createCallbackFcn(app, @OutlierApplyButtonPushed, true);
            app.QCOutlierDetectionCIEditField.Value = 0.95;
            app.StatisticsBeforeAfter95CIAfterPanel = uipanel(app.UIFigure);
            app.StatisticsBeforeAfter95CIAfterPanel.Title = 'Statistics: [Before] After (95% CI After)';
            app.RefRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.RefRSDBeforeAfterData.Text = '[NaN] NaN';
            app.refRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.refRSDLabel.Tooltip = {''};
            app.refRSDLabel.Text = '%refRSD :';
            app.dRatioBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.dRatioBeforeAfterData.Text = '[NaN] NaN';
            app.dRatioLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.dRatioLabel.Tooltip = {''};
            app.dRatioLabel.Text = '%dRatio :';
            app.QCRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.QCRSDBeforeAfterData.Text = '[NaN] NaN';
            app.qcRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.qcRSDLabel.Tooltip = {''};
            app.qcRSDLabel.Text = '%qcRSD :';
            app.BlankRatioData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.BlankRatioData.Text = '[NaN] NaN';
            app.blankRatioLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.blankRatioLabel.Tooltip = {''};
            app.blankRatioLabel.Text = '%blankRatio :';
            app.SampleRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.SampleRSDBeforeAfterData.Text = '[NaN] NaN';
            app.sampleRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.sampleRSDLabel.Tooltip = {''};
            app.sampleRSDLabel.Text = '%sampleRSD :';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'QCRSCexplrX.png');
            app.BlankRatioPanel = uipanel(app.UIFigure);
            app.BlankRatioPanel.Title = 'Blank Ratio';
            app.MethodDropDownLabel_2 = uilabel(app.BlankRatioPanel);
            app.MethodDropDownLabel_2.Text = 'Method:';
            app.BlankRatioMethodDropDown = uidropdown(app.BlankRatioPanel);
            app.BlankRatioMethodDropDown.Items = {'QC', 'Median', 'Percentile'};
            app.BlankRatioMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @BlankRatioMethodDropDownValueChanged, true);
            app.BlankRatioMethodDropDown.Tooltip = {'BlankRatio = 100*max(BlankValue)/SampleReferenceValue. ''QC'' sets the SampleReferenceValue as the median QC value; ''Median'' sets the SampleReferenceValue as the median Sample value; ''Percentile'' sets BlankRatio = % of Samples < max(BlankValue)*RelativeLOQ (RelativeLOQ is a user defined constant - default 1.2). In this instance missing values are considered = Blank value'};
            app.BlankRatioMethodDropDown.Value = 'QC';
            app.ThresholdLabel = uilabel(app.BlankRatioPanel);
            app.ThresholdLabel.Text = 'RelativeLOQ:';
            app.RelativeLOQEditField = uieditfield(app.BlankRatioPanel, 'numeric');
            app.RelativeLOQEditField.ValueChangedFcn = createCallbackFcn(app, @RelativeLOQEditFieldValueChanged, true);
            app.RelativeLOQEditField.Tooltip = {'The RelativeLOQ is a multiplier (relative to the Blank) used to set a value below which a feature is considered background noise. i.e., "LOQ* = max(Blank) x relativeLOQ"'};
            app.RelativeLOQEditField.Value = 1.5;
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'res', 'triangleLogo.svg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCRSCconfigExplorer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end