classdef QCRSCfeatureExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button                         matlab.ui.control.Button
        Image2_2                       matlab.ui.control.Image
        QCCorrectionParametersPanel    matlab.ui.container.Panel
        BlankRatioMethodDropDown       matlab.ui.control.DropDown
        BlankRatioMethodDropDownLabel  matlab.ui.control.Label
        LogTransformedCorrectionCheckBox  matlab.ui.control.CheckBox
        RemoveZerosCheckBox            matlab.ui.control.CheckBox
        QCRSCMonteCarlorepetitionsEditField  matlab.ui.control.NumericEditField
        QCRSCMonteCarlorepetitionsEditFieldLabel  matlab.ui.control.Label
        QCRSCCrossValidationMethodDropDown  matlab.ui.control.DropDown
        QCRSCCrossValidationMethodDropDownLabel  matlab.ui.control.Label
        BetweenBatchCorrectionModeDropDown  matlab.ui.control.DropDown
        BetweenBatchCorrectionModeDropDownLabel  matlab.ui.control.Label
        WithinBatchCorrectionModeDropDown  matlab.ui.control.DropDown
        WithinBatchCorrectionModeDropDownLabel  matlab.ui.control.Label
        QCOutlierReplacementStrategyDropDown  matlab.ui.control.DropDown
        QCOutlierReplacementStrategyDropDownLabel  matlab.ui.control.Label
        QCOutlierDetectionCIEditField  matlab.ui.control.NumericEditField
        QCOutlierDetectionCIEditFieldLabel  matlab.ui.control.Label
        QCOutlierDetectionMethodDropDown  matlab.ui.control.DropDown
        QCOutlierDetectionMethodDropDownLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        FeaturePanel                   matlab.ui.container.Panel
        ReprocessButton                matlab.ui.control.Button
        ViewFeatureTableButton         matlab.ui.control.Button
        RESETButton                    matlab.ui.control.Button
        FeatureEditField               matlab.ui.control.EditField
        FeatureEditFieldLabel          matlab.ui.control.Label
        FeatureDropDown                matlab.ui.control.DropDown
        FeatureLabel                   matlab.ui.control.Label
        RandomFeatureButton            matlab.ui.control.Button
        Panel_CVplot                   matlab.ui.container.Panel
        UIAxesMSE                      matlab.ui.control.UIAxes
        StatisticsBeforeAfter95CIAfterPanel  matlab.ui.container.Panel
        sampleRSDLabel                 matlab.ui.control.Label
        SampleRSDBeforeAfterData       matlab.ui.control.Label
        ViewBatchStatisticsButton      matlab.ui.control.Button
        blankRatioLabel                matlab.ui.control.Label
        BlankRatioData                 matlab.ui.control.Label
        qcRSDLabel                     matlab.ui.control.Label
        QCRSDBeforeAfterData           matlab.ui.control.Label
        dRatioLabel                    matlab.ui.control.Label
        dRatioBeforeAfterData          matlab.ui.control.Label
        refRSDLabel                    matlab.ui.control.Label
        RefRSDBeforeAfterData          matlab.ui.control.Label
        Panel_BeforeAfter              matlab.ui.container.Panel
        StretchScrollXAxisCheckBox     matlab.ui.control.CheckBox
        IgnoreBlanksCheckBox           matlab.ui.control.CheckBox
        UIAxesAfter                    matlab.ui.control.UIAxes
        UIAxesBefore                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        CurrentFolder   %
        DataTable       % Data Table - Public so it can be edited by app1
        FeatureTable       % Feature Table - primarily to access P.UID
        ExcelName
        ProjectName     % Project Name
        ImportSuccess
        FileType        % 'xls' or 'csv'
        baseConfig      % Data Structure containing all the operable configuration options
        DataTableName
        FeatureTableName
        
    end

    
    properties (Access = private)
        
        % Setup and import
        CallerApp
        SpreadsheetApp  % popup app to view (edit) Data & Feature tables
        StatsTableApp   % popup app to view Batch Stats Table    
        StatsTable      % Stats Table for Batch RSD D-Ratio etc stats.
        feature            % Name of feature selected to view (e.g. CP1002)
        name
        feature_old        % Previous feature      
        jsonTxt         % current raw json config.
        jsonDefault     % default json config for use during reset.
        jsonView        % formatted json file for printing to screen.
        
        configFilename  % config filename
        callerConfig
        defaultwidth
                                 
        % Data for selected feature
        
        Data            % Temp table for chosen feature and associated cleaned meta data
                
        % Results

        MPV
        MPVref
        NumOutliers
        GammaVals
        EpsilonVals
        
        OptStruct       % Data Structure with {gamma,epsilon,cvMse,minVal} for each batch
                        %         batchNumber
                        %         correction type
                        %         Kfold
                        %         MC Reps
                        %         gammaVal optimal smoothing value
                        %         cvMse    (vector) cross validation Mean Square Error
                        %         minVal   minimum value vector of cvMse
        
        
        InsufficientDataFlag % flag to abort any attempt at correction when low number of QCs
        IgnoreBlanks % Description
        cmap
        cmapName
        noQCflag
        noREFflag
        NumSamples
        isOutlier % Description
        isOutlierREF
        dialog % Description
        isOutlierAuto % Description
        isOutlierRefAuto
        OptStructAuto

        changed % Description
    end
    
    methods (Access = private)
        
        % helper function to select the correct feature (or a random feature)
        
        function getFeature(app)
            app.feature_old = app.feature;
            app.feature = app.FeatureDropDown.Value;
            temp = ismember(app.FeatureTable.UID,app.feature);
            app.name = app.FeatureTable.Name{temp};           
        end
                
        % helper function to call external calcStats
        % calculates RSD, D-Ratio and Blank-Ratio
        
        function displayStats(app)
            

            found = app.Data.Outlier;
            yy = app.Data.Y;
            switch app.baseConfig.OutlierReplacementStrategy 
                case 'Ignore'
                case 'Median'
                    yy(found) = median(yy(app.Data.QC));
                case 'NaN'
                    yy(found) = nan;
                otherwise
                    ME = MException('QCRSC:UnexpectedQCRSCoutlierAction',"QCRSC OutlierAction '%s' does not exist",app.baseConfig.OutlierReplacementStrategy);
                    throw(ME);
            end        
            zz = app.Data.Z;

            found = app.Data.Reference & app.Data.Outlier;
            switch app.baseConfig.OutlierReplacementStrategy 
                case 'Ignore'
                case 'Median'
                    zz(found) = median(zz(app.Data.Reference));
                case 'NaN'
                    zz(found) = nan;
                otherwise
                    ME = MException('QCRSC:UnexpectedQCRSCoutlierAction',"QCRSC OutlierAction '%s' does not exist",app.baseConfig.OutlierReplacementStrategy);
                    throw(ME);
            end 
            app.Data.Z = zz;
            
            isLog = app.baseConfig.LogTransformedCorrection;
            BRM = app.baseConfig.BlankRatioMethod;
            
            
            [StatBefore] = calcStats(yy,app.Data.QC,app.Data.Sample,app.Data.Blank,app.Data.Reference,Logged=isLog,BlankRatioMethod=BRM);
            [StatAfter]  = calcStats(zz,app.Data.QC,app.Data.Sample,app.Data.Blank,app.Data.Reference,Logged=isLog,BlankRatioMethod=BRM);  

            if isnan(StatBefore.qcRSD)
                app.QCRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
            else
                app.QCRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',StatBefore.qcRSD,StatAfter.qcRSD,StatAfter.qcRSDlower95CI,StatAfter.qcRSDupper95CI);           
            end

            if isnan(StatBefore.sampleRSD)
                app.SampleRSDBeforeAfterData.Text = sprintf('Insufficient Data');         
            else
                app.SampleRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',StatBefore.sampleRSD,StatAfter.sampleRSD,StatAfter.sampleRSDlower95CI,StatAfter.sampleRSDupper95CI);           
            end

            if isnan(StatBefore.refRSD)
                app.RefRSDBeforeAfterData.Text = sprintf('Insufficient Data');
            else
                app.RefRSDBeforeAfterData.Text = sprintf('[%.2f] %.2f (%.2f-%.2f)',StatBefore.refRSD,StatAfter.refRSD,StatAfter.refRSDlower95CI,StatAfter.refRSDupper95CI);           
            end

            if isnan(StatBefore.dRatio)           
                app.dRatioBeforeAfterData.Text = sprintf('Insufficient Data');
            else                                     
                app.dRatioBeforeAfterData.Text = sprintf('[%.2f] %.2f',StatBefore.dRatio,StatAfter.dRatio);
            end
            
            if isnan(StatBefore.blankRatio)
                app.BlankRatioData.Text = sprintf('Insufficient Data');
            else
                app.BlankRatioData.Text = sprintf('[%.2f] %.2f',StatBefore.blankRatio,StatAfter.blankRatio);
            end       
            
            BatchStatsBefore = BatchCalcStats(yy,app.Data.Batch,app.Data.QC,app.Data.Sample,app.Data.Blank,app.Data.Reference,Logged=isLog,BlankRatioMethod=BRM);
            BatchStatsAfter = BatchCalcStats(app.Data.Z,app.Data.Batch,app.Data.QC,app.Data.Sample,app.Data.Blank,app.Data.Reference,Logged=isLog,BlankRatioMethod=BRM);
                        
            
            %BatchStatsBefore{:,:} = compose("%.2f", BatchStatsBefore{:,:});
            %BatchStatsAfter{:,:} = compose("%.2f", BatchStatsAfter{:,:});
            % n_decimal = 2; 
            % % Convert numeric columns to formatted strings
            % BatchStatsBeforeString = varfun(@(x) num2str(x, ['%' sprintf('.%df', n_decimal)]), BatchStatsBefore);
            % BatchStatsBeforeString.Properties.VariableNames = BatchStatsBefore.Properties.VariableNames;
            % BatchStatsAfterString = varfun(@(x) num2str(x, ['%' sprintf('.%df', n_decimal)]), BatchStatsAfter);
            % BatchStatsAfterString.Properties.VariableNames = BatchStatsAfter.Properties.VariableNames;
            % 
            % BatchStatsAfter = BatchStatsAfterString;
            % BatchStatsBefore = BatchStatsBeforeString;

            BatchStatsBefore = convertvars(BatchStatsBefore, BatchStatsBefore.Properties.VariableNames(2:end), @(x) compose("%.3g", x));
            BatchStatsAfter = convertvars(BatchStatsAfter, BatchStatsAfter.Properties.VariableNames(2:end), @(x) compose("%.3g", x));

            BatchStatsBefore = BatchStatsBefore([1,4,7,10:end],:);
            BatchStatsAfter = BatchStatsAfter([1,4,7,10:end],:);

            %BatchStatsBefore = convertvars(BatchStatsBefore,BatchStatsBefore.Properties.VariableNames,'string');
            %BatchStatsAfter = convertvars(BatchStatsAfter,BatchStatsAfter.Properties.VariableNames,'string');
            
            app.StatsTable = BatchStatsBefore;

            
            for i = 1:height(app.StatsTable)
                for j = 2:width(app.StatsTable)
                    if ismissing(app.StatsTable{i,j})
                        app.StatsTable{i,j} = "Insufficient Data";
                    else
                        app.StatsTable{i,j} = strjoin(["[",BatchStatsBefore{i,j},"] ",BatchStatsAfter{i,j}],'');                   
                    end
                end
            end
            
            if ~isempty(app.StatsTableApp) && isvalid(app.StatsTableApp)         
                updateTable(app.StatsTableApp,app.StatsTable);                     
            end
        
        end

        function plotControlcharts(app)
            cleanName = strrep(app.name,'_',' ');
            titleBefore = ['Original: ',num2str(app.feature),': ',cleanName];
            titleAfter = ['Corrected: ',num2str(app.feature),': ',cleanName];
            
            controlChart2(app,app.UIAxesBefore,cmap='batch',title=titleBefore);
            controlChart2(app,app.UIAxesAfter,cmap='batch',before=false,title=titleAfter);

            linkaxes([app.UIAxesBefore app.UIAxesAfter],'xy');   
            app.UIAxesBefore.Legend.Visible = true;            
        end
       
        % plotting the results of the optimization
        % cvMSE = cross validated Mean Squared Error of fit.
        
        function batchPlotCVmse(app)
            
            axInnerPos = app.UIAxesMSE.InnerPosition;                  
                     
            cla(app.UIAxesMSE);
            
            batchNumTotal = numel(unique(app.Data.Batch));
            
            if ismember(app.baseConfig.WithinBatchCorrectionMode,{'Spline', 'Spline-C2', 'Spline-C4', 'Spline-C6'})
                switch app.baseConfig.WithinBatchCorrectionMode                 
                    case 'Spline-C2'
                        gammaRange = 2:15;
                    case 'Spline-C4'
                        gammaRange = 4:15;
                    case 'Spline-C6'
                        gammaRange = 6:15;
                    otherwise                        
                        gammaRange = 0:15;
                end
                for i = 1:batchNumTotal
                    plot(app.UIAxesMSE,gammaRange,app.OptStruct(i).cvMse,'b-o','linewidth',1,'MarkerFaceColor','w','MarkerSize',5);
                    hold(app.UIAxesMSE,'on');
                    plot(app.UIAxesMSE,app.OptStruct(i).gamma,app.OptStruct(i).minVal,'ko','linewidth',1,'MarkerFaceColor','r','MarkerSize',8);
                    text(app.UIAxesMSE,app.OptStruct(i).gamma,app.OptStruct(i).minVal,num2str(i),'HorizontalAlignment',"center",'VerticalAlignment',"middle","Color",'w');
                end
            end
                
            hold(app.UIAxesMSE,'off');
            innerPosDiff = axInnerPos - app.UIAxesMSE.InnerPosition;           
            app.UIAxesMSE.Position = app.UIAxesMSE.Position + innerPosDiff;
            
            app.UIAxesMSE.YLimMode = 'auto';
            app.UIAxesMSE.XLimMode = 'auto';
            app.UIAxesMSE.YLimitMethod = 'tight';
            app.UIAxesMSE.XLimitMethod = 'tight';

        end
        
        % update all the various feature data when the feature name is changed
        % and account for new radio button selection
        % any changes in the FeaturePanel required a new acquisition of data from
        % source Data Table.
        
        function updateData(app)
            
            app.Data = app.DataTable(:,{'SampleID','SampleType','Order','QC','Blank','Batch','Reference','Sample'});
            
            y = app.DataTable.(app.feature);

            if app.baseConfig.RemoveZeros
                y(y <= 0) = NaN;
            end

            if app.baseConfig.LogTransformedCorrection
                y(y<=0) = NaN;
                app.baseConfig.RemoveZeros = true;
                y = log10(y);
            end

            label = app.DataTable.Batch;
            if isnumeric(label)
                label = cellstr(num2str(label));
            end
            label = strrep(label,'_',' ');
            label = cellfun(@(x) ['Batch ',x],label,'UniformOutput',false);   

            app.Data.Y = y;
            app.Data.Label = label;

            missing = isnan(y);
            app.Data.QC(missing) = false;
            
            %if app.IgnoreBlanksCheckBox.Value
            %    app.Data = app.Data(app.Data.Blank == 0,:); 
            %end
            
        end           
        
        function correctFeature(app)
            t = app.Data.Order;
            y = app.Data.Y;
            batch = app.Data.Batch;
            isQC = app.Data.QC;
            isSample = app.Data.Sample;
            isBlank = app.Data.Blank;
            isREF = app.Data.Reference;       

            if strcmp(app.baseConfig.WithinBatchCorrectionMode,'Manual')
                [z,yspline] = OptimiseAndCorrectFeatureXManual2(app.baseConfig,t,y,batch,isQC,isSample,isBlank,isREF,app.isOutlier,app.GammaVals,app.EpsilonVals);
                app.Data.Outlier = app.isOutlier;
                app.Data.OutlierREF = app.isOutlierREF;
            else
                [z,yspline,gamma,epsilon,toutlier,app.OptStruct,mpvQC] = OptimiseAndCorrectFeatureX(app.baseConfig,t,y,batch,isQC,isSample,isBlank,isREF,app.isOutlier,app.isOutlierREF);
                app.GammaVals = gamma;
                app.EpsilonVals = epsilon;
                app.MPV = repmat(mpvQC,1,length(y));
                yref = y(isREF);
                tref = t(isREF);
                app.MPVref  = repmat(median(yref),1,length(y));              
                zref = z(isREF);
                if strcmp(app.baseConfig.OutlierDetectionMethod,'Manual')
                    app.Data.Outlier = app.isOutlier;
                    app.Data.OutlierREF = app.isOutlierREF;
                else
                    [~,~,toutlierRef] = OutlierFilter(tref,zref,'poly1',0.99);
                    app.Data.Outlier = ismember(t,toutlier);
                    app.Data.OutlierREF = ismember(t,toutlierRef);
                    app.isOutlierAuto = app.Data.Outlier;
                    app.isOutlierRefAuto = app.Data.OutlierREF;
                    app.OptStructAuto = app.OptStruct;   
                end

            end
                                 
            app.Data.Yspline = yspline;
            
            switch app.baseConfig.OutlierReplacementStrategy
                case 'Median'
                    z(app.Data.OutlierREF) = median(app.MPVref(1));                          
                case 'NaN'
                    z(app.Data.OutlierREF) = nan;                          
            end  

            app.Data.Z = z;
            app.NumOutliers = sum(app.Data.Outlier) + sum(app.Data.OutlierREF);


        end

        function correctFeatureDirect(app)
            t = app.Data.Order;
            y = app.Data.Y;
            batch = app.Data.Batch;
            isQC = app.Data.QC;
            isSample = app.Data.Sample;
            isBlank = app.Data.Blank;
            isREF = app.Data.Reference;

            [z,yspline] = OptimiseAndCorrectFeatureXManual2(app.baseConfig,t,y,batch,isQC,isSample,isBlank,isREF,app.isOutlier,app.GammaVals,app.EpsilonVals);
            app.Data.Yspline = yspline;
            app.Data.Z = z;
        end

        function checkBetweenBatchValid(app)

            if app.noQCflag 
                app.baseConfig.WithinBatchCorrectionMode = 'Sample';                               
                app.WithinBatchCorrectionModeDropDown.Value = 'Sample';
                if ~strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Sample')
                        app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
                        app.BetweenBatchCorrectionModeDropDown.Value = 'Sample'; 
                        uialert(app.UIFigure,'There are to few QCs in a least one batch, so switching to within/between batch "Sample" correction.','Config. correction','Icon','warning');
                end
            end
    
            if strcmp(app.baseConfig.WithinBatchCorrectionMode,'Sample')
                if ~strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Sample')
                    app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
                    app.BetweenBatchCorrectionModeDropDown.Value = 'Sample';
                    uialert(app.UIFigure,'If within-batch correction is set to "Sample", then between-batch correction must also be set to "Sample".','Config. correction','Icon','warning');
                end
            end
    
            if strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Reference')
                if app.noREFflag
                        app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
                        app.BetweenBatchCorrectionModeDropDown.Value = 'Sample';
                        uialert(app.UIFigure,'There are to few Reference samples (in a least one batch), so switching to between-batch "Sample" correction.','Config. correction','Icon','warning');
                        
                end
            end
    
            if ~strcmp(app.baseConfig.BlankRatioMethod,'QC') & app.noQCflag
                app.baseConfig.BlankRatioMethod = 'Median';
                app.CleanBlankRatioMethodDropDown.Value = 'Median';
                uialert(app.UIFigure,'There are to few QCs in a least one batch to calculate a QC based blankRatio, so switching to "Median".','Config. correction','Icon','warning');
            end
        end

        % function checkBetweenBatchValid2(app)
        %     if app.noQCflag 
        %          app.baseConfig.WithinBatchCorrectionMode = 'Sample';
        %         app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
        %         app.baseConfig.BlankRatioMethod = 'Median';
        %         app.WithinBatchCorrectionModeDropDown.Value = 'Sample';
        %         app.BetweenBatchCorrectionModeDropDown.Value = 'Sample';
        %         app.BlankRatioMethodDropDown.Value = 'Median';
        %         baseException = MException('QCRSC:WrongBetweenBatchCorrectionMode','There are <3 QCs per batch so switching to "Sample" correction. WithinBatchCorrectionMode, BetweenBatchCorrectionMode & BlankRatioMethod changed.');
        %         throw(baseException)
        %     end
        % 
        %     if strcmp(app.baseConfig.WithinBatchCorrectionMode,'Sample')
        %         if ~strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Sample')
        %             app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
        %             app.baseConfig.BlankRatioMethod = 'Median';
        %             app.BetweenBatchCorrectionModeDropDown.Value = 'Sample';
        %             app.BlankRatioMethodDropDown.Value = 'Median';
        %             baseException = MException('QCRSC:WrongBetweenBatchCorrectionMode','When using Sample-based within-batch correction the between-batch correction must be also be set to "Sample" (and blankRatio set to "Median"). BetweenBatchCorrectionMode & BlankRatioMethod changed.');
        %             throw(baseException)
        %         end
        %     end
        %     % if strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Sample')
        %     %     if ~strcmp(app.baseConfig.WithinBatchCorrectionMode,'Sample')
        %     %         app.baseConfig.BetweenBatchCorrectionMode = 'QC';
        %     %         app.BetweenBatchCorrectionModeDropDown.Value = 'QC';
        %     %         baseException = MException('QCRSC:WrongBetweenBatchCorrectionMode','When when using QC intra-batch correction the inter-batch correction cannot be set to "Sample". BetweenBatchCorrectionMode changed to "QC".');
        %     %         throw(baseException)
        %     %     end
        %     % end
        %     if strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Reference')
        %         ubatch = unique(app.DataTable.Batch);         
        %         NumOfBatches = numel(ubatch);
        %         for i = 1:NumOfBatches
        %             batchRef = app.DataTable.Reference(app.DataTable.Batch == ubatch(i));
        %             batchRefsum(i) = sum(batchRef);
        %         end
        %         if any(batchRefsum == 0)
        %             app.baseConfig.BetweenBatchCorrectionMode = 'QC';
        %             app.BetweenBatchCorrectionModeDropDown.Value = 'QC';
        %             baseException = MException('QCRSC:WrongBetweenBatchCorrectionMode','There must be at least one Reference sample per batch for Reference-based between-batch correction. BetweenBatchCorrectionMode changed to "QC".');
        %             throw(baseException)
        %         % else
        %         %     app.BatchwiseSwitch.Value = true;
        %         %     app.BatchwiseSwitch.Enable = "off";
        %         %     app.FilterBankModeDropDown.Enable = "on";
        %         end
        %     end
        % end

        function setConfigFields(app,configStruct)
            arguments
                app
                configStruct = app.baseConfig;
            end
            app.LogTransformedCorrectionCheckBox.Value = configStruct.LogTransformedCorrection;
            app.RemoveZerosCheckBox.Value = configStruct.RemoveZeros;
            app.QCOutlierDetectionMethodDropDown.Value = configStruct.OutlierDetectionMethod;
            app.QCOutlierDetectionCIEditField.Value = configStruct.OutlierDetectionCI;
            app.QCOutlierReplacementStrategyDropDown.Value = configStruct.OutlierReplacementStrategy;
            app.WithinBatchCorrectionModeDropDown.Value = configStruct.WithinBatchCorrectionMode;
            app.BetweenBatchCorrectionModeDropDown.Value = configStruct.BetweenBatchCorrectionMode;
            app.QCRSCCrossValidationMethodDropDown.Value = configStruct.QCRSCcvMethod;
            app.QCRSCMonteCarlorepetitionsEditField.Value = configStruct.QCRSCmcReps;
            app.BlankRatioMethodDropDown.Value = configStruct.BlankRatioMethod;
            checkStuff(app);
        end  

        
        function checkStuff(app)

            %try
                checkBetweenBatchValid(app);
            % catch ME
            %     uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
            % end
            
            if ~ismember(app.baseConfig.WithinBatchCorrectionMode,{'Spline', 'Spline-C2', 'Spline-C4', 'Spline-C6'})                
                app.QCRSCCrossValidationMethodDropDownLabel.Enable = "off";
                app.QCRSCCrossValidationMethodDropDown.Visible = "off";
                app.QCRSCMonteCarlorepetitionsEditField.Visible = "off";
                app.QCRSCMonteCarlorepetitionsEditFieldLabel.Visible = "off";
            else
                app.QCRSCCrossValidationMethodDropDown.Visible = "on";
                app.QCRSCCrossValidationMethodDropDownLabel.Visible = "on";
                if strcmp(app.QCRSCCrossValidationMethodDropDown.Value,'Leaveout')
                    app.QCRSCMonteCarlorepetitionsEditField.Visible = "off";
                    app.QCRSCMonteCarlorepetitionsEditFieldLabel.Visible = "off";
                else
                    app.QCRSCMonteCarlorepetitionsEditField.Visible = "on";
                    app.QCRSCMonteCarlorepetitionsEditFieldLabel.Visible = "on";
                end  
            end
            if app.LogTransformedCorrectionCheckBox.Value
                app.RemoveZerosCheckBox.Value = true;
                app.baseConfig.RemoveZeros = app.RemoveZerosCheckBox.Value;
            end
            app.baseConfig = validateConfig(app.baseConfig);
           
            try
                checkBetweenBatchValid(app);
            catch ME
                uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
            end
        end
        
        
        function controlChart2(app,axishandle,option)

            arguments
                app
                axishandle
                option.before = true
                option.cmap = lines
                option.title = ''
                option.ignoreBlanks = false;
            end

            DataPlot = app.Data;
            
            if app.IgnoreBlanksCheckBox.Value
               DataPlot = DataPlot(DataPlot.Blank == 0,:); 
            end
            
            isbatch = false;

            islog=app.baseConfig.LogTransformedCorrection;
            RelativeLOQ=app.baseConfig.RelativeLOQ;

            if strcmp(option.cmap,'batch')
                cols = {[0, 150, 255]./255,[125, 249, 255]./255};
                labels = [true,false];
                hash = containers.Map(labels,cols);
                tt = logical(rem(unique(DataPlot.Batch),2));
                ccmap = arrayfun(@(x) hash(x),tt,'UniformOutput',false);
                ccmap = cell2mat(ccmap);
                isbatch = true;
            else
                ccmap = option.cmap;
            end

            ms = 6;
            lw = 2;   

            Label = DataPlot.SampleType;
            index = ismember(Label,'Sample');
            Label(index) = DataPlot.Label(index);

            if option.before
                y = DataPlot.Y;
                spline = DataPlot.Yspline;
                if strcmp(app.baseConfig.WithinBatchCorrectionMode,'Sample')
                    lineColor = 'b';
                else
                    lineColor = 'r';
                end
            else
                y = DataPlot.Z;
                switch app.baseConfig.BetweenBatchCorrectionMode
                    case 'Sample'
                        mpv = median(DataPlot.Y(DataPlot.Sample),'omitnan');
                        lineColor = 'b';
                    case 'Reference'
                        mpv = median(DataPlot.Y(DataPlot.Reference),'omitnan');
                        lineColor = 'y';
                    otherwise
                        mpv = median(DataPlot.Y(DataPlot.QC),'omitnan');
                        lineColor = 'r';
                end
                spline = repmat(mpv,numel(y),1);
            end

            
            t = DataPlot.Order;
            youtlier = y(DataPlot.Outlier);
            ttoutlier = t(DataPlot.Outlier);
            youtlierRef = y(DataPlot.OutlierREF);
            ttoutlierRef = t(DataPlot.OutlierREF);
            batches = unique(DataPlot.Batch);
            batchNumTotal = numel(batches);

            dot_size = 6;

            temp = y(DataPlot.Blank);
            if isempty(temp)
                temp = NaN;
            else
                if islog
                    temp = power(10,temp);
                    temp = max(temp,[],1,'omitnan');
                    temp = temp*RelativeLOQ;
                    temp = log10(temp);
                else
                    temp = max(temp,[],1,'omitnan');
                    temp = temp*RelativeLOQ;
                end
            end
            

            cla(axishandle);
            hold(axishandle,'on');

            if option.before  
                f = line(axishandle,[t(1) t(end)],[temp temp],'LineStyle',':','Color','m','LineWidth',lw-1); 
                f.PickableParts = 'none';
            end            
            
            grps = unique(Label);

            tf = ismember(grps,{'Blank','QC','Reference'});
            grps = [grps(tf);grps(~tf)];
            numx = sum(tf);

            if option.before
                if sum(DataPlot.Outlier) > 0
                    plot(axishandle,ttoutlier,youtlier,'ks','MarkerSize',dot_size+4,'MarkerFaceColor','k','LineWidth',lw);
              %      h.DataPlotTipTemplate.DataPlotTipRows(1) = dataTipTextRow('SampleID:',DataPlot.SampleID(DataPlot.Outlier));
              %      h.DataPlotTipTemplate.DataPlotTipRows(2) = dataTipTextRow('Outlier',repmat({''},size(h.XData)));
                end
                if sum(DataPlot.OutlierREF) > 0
                    plot(axishandle,ttoutlierRef,youtlierRef,'ks','MarkerSize',dot_size+4,'MarkerFaceColor','k','LineWidth',lw);
              %      h.DataPlotTipTemplate.DataPlotTipRows(1) = dataTipTextRow('SampleID:',DataPlot.SampleID(DataPlot.Outlier));
              %      h.DataPlotTipTemplate.DataPlotTipRows(2) = dataTipTextRow('Outlier',repmat({''},size(h.XData)));
                end
            end        
            count = 1;
            numOfGrps = length(grps);
            for i = 1:numOfGrps
                index = ismember(Label,grps(i));
                if option.before
                    if ismember('QC',grps(i))
                        b(i) = plot(axishandle,t(index),y(index),'ko','MarkerFaceColor','r','MarkerSize',dot_size,'ButtonDownFcn',@(src,event)app.MouseClick(src,event));                    
                    elseif ismember('Blank',grps(i))
                        b(i) = plot(axishandle,t(index),y(index),'ko','MarkerFaceColor','m','MarkerSize',dot_size); 
                    elseif ismember('Reference',grps(i))
                        b(i) = plot(axishandle,t(index),y(index),'ko','MarkerFaceColor','y','MarkerSize',dot_size,'ButtonDownFcn',@(src,event)app.MouseClickREF(src,event)); 
                    else
                        b(i) = plot(axishandle,t(index),y(index),'s','MarkerEdgeColor','k','MarkerFaceColor',ccmap(count,:),'MarkerSize',dot_size+1);
                        count = count+1;
                    end
                else
                    if ismember('QC',grps(i))
                        b(i) = plot(axishandle,t(index),y(index),'ko','MarkerFaceColor','r','MarkerSize',dot_size);                    
                    elseif ismember('Blank',grps(i))
                        b(i) = plot(axishandle,t(index),y(index),'ko','MarkerFaceColor','m','MarkerSize',dot_size); 
                    elseif ismember('Reference',grps(i))
                        b(i) = plot(axishandle,t(index),y(index),'ko','MarkerFaceColor','y','MarkerSize',dot_size); 
                    else
                        b(i) = plot(axishandle,t(index),y(index),'s','MarkerEdgeColor','k','MarkerFaceColor',ccmap(count,:),'MarkerSize',dot_size+1);
                        count = count+1;
                    end
                end

                b(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('SampleID:',DataPlot.SampleID(index));
                b(i).DataTipTemplate.DataTipRows(2) = dataTipTextRow('Label:',Label(index));
            end

            if option.before
                axishandle.ButtonDownFcn = createCallbackFcn(app,@(src,event)app.MouseClick2(src,event), true);
            end
            
           % if option.before
                for i = 1:batchNumTotal
                    if option.before
                        s(i) = plot(axishandle,t(DataPlot.Batch==batches(i)),spline(DataPlot.Batch==batches(i)),lineColor,'MarkerSize',ms,'LineWidth',lw-1,'ButtonDownFcn',@(src,event)app.MouseClickLine(src));
                    else
                        s(i) = plot(axishandle,t(DataPlot.Batch==batches(i)),spline(DataPlot.Batch==batches(i)),lineColor,'MarkerSize',ms,'LineWidth',lw-1);
                    end
                    s(i).UserData = i;
                    %s(i).PickableParts = 'none';
                end
                
               
                if isbatch
                    h = legend(axishandle,b(1:numx),grps(1:numx));
                else
                    h = legend(axishandle,b,grps);
                end
                pos = get(h,'Position');
                posx = 0.92;
                posy = 0.46;
                set(h,'Position',[posx posy pos(3) pos(4)]);
           
            if ~all(isnan(y))
            axis(axishandle,[min(t)-1, max(t)+1, 0.99*min([DataPlot.Y;DataPlot.Z],[],'omitnan'),1.01*max([DataPlot.Y;DataPlot.Z],[],'omitnan')]);
            end

            
            xlabel(axishandle, 'Injection order')
            if islog
                ylabel(axishandle, 'log_{10}(Feature Value)')
            else
                ylabel(axishandle, 'Feature Value')
            end
  
            hold(axishandle,'off');
            title(axishandle,option.title);
        end

        function MouseClick2(app,~,event)
            vector1 = app.Data.Order(app.Data.QC);
            vector2 = app.Data.Order(app.Data.Reference);
            [dist1, idx1] = min(abs(vector1 - event.IntersectionPoint(1)));
            [dist2, idx2] = min(abs(vector2 - event.IntersectionPoint(1)));
            if dist1 < dist2
                nearest_value = vector1(idx1);
                if app.Data.Outlier(app.Data.Order == nearest_value)
                    app.Data.Outlier(app.Data.Order == nearest_value) = false;
                else
                    app.Data.Outlier(app.Data.Order == nearest_value) = true;
                end
            else
                nearest_value = vector2(idx2);
                if app.Data.OutlierREF(app.Data.Order == nearest_value)
                    app.Data.OutlierREF(app.Data.Order == nearest_value) = false;
                else
                    app.Data.OutlierREF(app.Data.Order == nearest_value) = true;
                end
            end
            app.isOutlier = app.Data.Outlier;
            app.isOutlierREF = app.Data.OutlierREF;
            %plotControlcharts(app);
            if isequal(app.isOutlierAuto,app.isOutlier) & isequal(app.isOutlierRefAuto,app.isOutlierREF)
                app.baseConfig.OutlierDetectionMethod = app.callerConfig.OutlierDetectionMethod;               
                app.QCOutlierDetectionMethodDropDown.Enable = "on";
                app.QCOutlierDetectionMethodDropDownLabel.Enable = "on";
                app.QCOutlierDetectionCIEditField.Enable = "on";
                app.QCOutlierDetectionCIEditFieldLabel.Enable = "on";
            else
                app.baseConfig.OutlierDetectionMethod = 'Manual';
                app.QCOutlierDetectionMethodDropDown.Enable = "off";
                app.QCOutlierDetectionMethodDropDownLabel.Enable = "off";
                app.QCOutlierDetectionCIEditField.Enable = "off";
                app.QCOutlierDetectionCIEditFieldLabel.Enable = "off";
            end
            if ~isempty(app.dialog) && isvalid(app.dialog)
                delete(app.dialog);
                app.Panel_CVplot.Enable = "on";
            end
            app.changed = true;
            ReprocessButtonPushed(app);   
        end
        
        function MouseClick(app,~,event)
            idx = int32(round(event.IntersectionPoint(1)));
            if app.Data.Outlier(app.Data.Order == idx)
                app.Data.Outlier(app.Data.Order == idx) = false;
            else
                app.Data.Outlier(app.Data.Order == idx) = true;
            end
            

            app.isOutlier = app.Data.Outlier;
            app.isOutlierREF = app.Data.OutlierREF;
            %plotControlcharts(app);
            if isequal(app.isOutlierAuto,app.isOutlier) & isequal(app.isOutlierRefAuto,app.isOutlierREF)
                app.baseConfig.OutlierDetectionMethod = app.callerConfig.OutlierDetectionMethod;               
                app.QCOutlierDetectionMethodDropDown.Enable = "on";
                app.QCOutlierDetectionMethodDropDownLabel.Enable = "on";
                app.QCOutlierDetectionCIEditField.Enable = "on";
                app.QCOutlierDetectionCIEditFieldLabel.Enable = "on";
            else
                app.baseConfig.OutlierDetectionMethod = 'Manual';
                app.QCOutlierDetectionMethodDropDown.Enable = "off";
                app.QCOutlierDetectionMethodDropDownLabel.Enable = "off";
                app.QCOutlierDetectionCIEditField.Enable = "off";
                app.QCOutlierDetectionCIEditFieldLabel.Enable = "off";
            end
            if ~isempty(app.dialog) && isvalid(app.dialog)
                delete(app.dialog);
                app.Panel_CVplot.Enable = "on";
            end
            app.changed = true;
            ReprocessButtonPushed(app);        
        end

        function MouseClickREF(app,~,event)
            idx = int32(round(event.IntersectionPoint(1)));
            if app.Data.OutlierREF(app.Data.Order == idx)
                app.Data.OutlierREF(app.Data.Order == idx) = false;
            else
                app.Data.OutlierREF(app.Data.Order == idx) = true;
            end
            

            app.isOutlierREF = app.Data.OutlierREF;
            app.isOutlier = app.Data.Outlier;
            %plotControlcharts(app);
            if isequal(app.isOutlierRefAuto,app.isOutlierREF) & isequal(app.isOutlierAuto,app.isOutlier)
                app.baseConfig.OutlierDetectionMethod = app.callerConfig.OutlierDetectionMethod;               
                app.QCOutlierDetectionMethodDropDown.Enable = "on";
                app.QCOutlierDetectionMethodDropDownLabel.Enable = "on";
                app.QCOutlierDetectionCIEditField.Enable = "on";
                app.QCOutlierDetectionCIEditFieldLabel.Enable = "on";
            else
                app.baseConfig.OutlierDetectionMethod = 'Manual';
                app.QCOutlierDetectionMethodDropDown.Enable = "off";
                app.QCOutlierDetectionMethodDropDownLabel.Enable = "off";
                app.QCOutlierDetectionCIEditField.Enable = "off";
                app.QCOutlierDetectionCIEditFieldLabel.Enable = "off";
            end
            if ~isempty(app.dialog) && isvalid(app.dialog)
                delete(app.dialog);
                app.Panel_CVplot.Enable = "on";
            end
            app.changed = true;
            ReprocessButtonPushed(app);            
        end

        function MouseClickLine(app,source)
            %figPos = getpixelposition(source.Parent, true);
            % if ismember(app.baseConfig.WithinBatchCorrectionMode,{'Sample','Median','Linear'})
            %     return;
            % end
            app.baseConfig.WithinBatchCorrectionMode = 'Manual';
            app.WithinBatchCorrectionModeDropDown.Enable ="off";
            app.WithinBatchCorrectionModeDropDownLabel.Enable = "off";
            app.QCRSCCrossValidationMethodDropDown.Enable = "off";
            app.QCRSCCrossValidationMethodDropDownLabel.Enable = "off";
            app.QCRSCMonteCarlorepetitionsEditField.Enable = "off";
            app.QCRSCMonteCarlorepetitionsEditFieldLabel.Enable = "off";
            app.LogTransformedCorrectionCheckBox.Enable = "off";
            app.RemoveZerosCheckBox.Enable = "off";
            app.Panel_CVplot.Enable = "off";

            figPos = app.UIFigure.Position;
            relPos = app.UIFigure.CurrentPoint;
            xpos = relPos(1) + figPos(1) - 50;
           % ypos = relPos(2) + figPos(2) - 100;            
            ypos = figPos(2) + 150; 
            batchIndex = source.UserData;
            gammaVal = app.GammaVals(batchIndex);
            app.changed = true;

            if app.StretchScrollXAxisCheckBox.Value
                xpos = figPos(1) + 800;
            end
                app.dialog = InputDialogApp(app,xpos,ypos,batchIndex,gammaVal);             
        end
    end
    
    methods (Access = public)
               
        function Restart(app)
            app.UIFigure.Name = app.ProjectName;
            app.FeatureDropDown.Items = [app.FeatureTable.UID;'Random'];
            RESETButtonPushed(app);         
        end

        function ExternalSetFeature(app,uid,config,gammaVals,epsilonVals,mpv,mpvREF,Outliers,OutliersREF,numOutliers,isManual)            
            if ~ismember(uid,app.FeatureDropDown.Items)
                uialert(app.UIFigure,'Feature does not exist','Warning','Icon','warning');                
                return;
            end
            app.FeatureDropDown.Value = uid;
            app.FeatureEditField.Value = uid;
            app.feature  = uid;

            updateData(app);

            app.Data.Outlier = Outliers;   
            app.Data.OutlierREF = OutliersREF;  
            app.NumOutliers = numOutliers;
            app.GammaVals = gammaVals;
            app.EpsilonVals = epsilonVals;
            app.MPVref = mpvREF';
            app.MPV = mpv';
            app.isOutlier = Outliers;
            app.isOutlierREF = OutliersREF;
            app.isOutlierAuto = Outliers;
            app.isOutlierRefAuto = OutliersREF;

            app.baseConfig = config;
            setConfigFields(app);

            if isManual == 1 || isManual == 11
                app.baseConfig.OutlierDetectionMethod = 'Manual';
                app.QCOutlierDetectionMethodDropDown.Enable = "off";
                app.QCOutlierDetectionMethodDropDownLabel.Enable = "off";
                app.QCOutlierDetectionCIEditField.Enable = "off";
                app.QCOutlierDetectionCIEditFieldLabel.Enable = "off";
            end

            if isManual == 10 || isManual == 11
                app.baseConfig.WithinBatchCorrectionMode = 'Manual';
                app.WithinBatchCorrectionModeDropDown.Enable ="off";
                app.WithinBatchCorrectionModeDropDownLabel.Enable = "off";
                app.QCRSCCrossValidationMethodDropDown.Enable = "off";
                app.QCRSCCrossValidationMethodDropDownLabel.Enable = "off";
                app.QCRSCMonteCarlorepetitionsEditField.Enable = "off";
                app.QCRSCMonteCarlorepetitionsEditFieldLabel.Enable = "off";
                app.LogTransformedCorrectionCheckBox.Enable = "off";
                app.RemoveZerosCheckBox.Enable = "off";
                app.Panel_CVplot.Enable = "off";
            end

            correctFeatureDirect(app);
            displayStats(app);
            plotControlcharts(app); 

            if ~isempty(app.SpreadsheetApp) && isvalid(app.SpreadsheetApp)        
                highlightrow(app.SpreadsheetApp,app.feature);
            end         
            app.Button.Visible = "on";
            app.Button.Enable = "off";
            app.changed = false;
        end


        function ExternalDrivenSplineCorrection(app,gammaVal,batchIndex)
            app.GammaVals(batchIndex) = gammaVal;           
            ReprocessButtonPushed(app);        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, UID)
            
            if ismac
                changeFont(app.UIFigure,'Avenir');
            else
                changeFont(app.UIFigure,'MS Sans Serif');
            end
            app.CallerApp = callerApp;
            app.UIFigure.Name = '';

            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);            
            width = screenWidth*0.9 - app.UIFigure.Position(1);
            app.defaultwidth = app.Panel_BeforeAfter.Position(3);
            if screenWidth < 1600             
                app.UIFigure.Position(3) = width;
                app.UIFigure.Scrollable = 'on';
                app.UIFigure.AutoResizeChildren = "on";
            end

            %app.Button.Visible = "off";
            app.changed = false;
            
            if isempty(app.CallerApp.DataTable)
                [app.DataTable,app.FeatureTable] = generateMetabExamplesNew();
                app.ProjectName = '';
                [app.DataTable,app.FeatureTable] = validatingDataFeatureTables(app.DataTable,app.FeatureTable,fighandle = app.UIFigure,ColumnWarningFlag=false);
            else
                app.DataTable = app.CallerApp.DataTable;
                app.FeatureTable = app.CallerApp.FeatureTable(app.CallerApp.preProcKeep,:);
                %app.FeatureTable = app.CallerApp.FeatureTable;
                [app.DataTable,app.FeatureTable] = validatingDataFeatureTables(app.DataTable,app.FeatureTable,fighandle = app.UIFigure,ColumnWarningFlag=false);             
                app.configFilename = app.CallerApp.configFilename;
                app.ProjectName = app.CallerApp.ProjectName; 
                app.CurrentFolder = app.CallerApp.WorkingDirectory;                
            end
            
            app.callerConfig = validateConfig(app.CallerApp.baseConfig);
            app.baseConfig = app.callerConfig;
            setConfigFields(app);

            app.DataTableName = app.callerConfig.DataTable;
            app.FeatureTableName = app.callerConfig.FeatureTable;

            if height(app.DataTable) < 400
                app.StretchScrollXAxisCheckBox.Visible = "off";
            end
            
            app.UIFigure.Name = app.ProjectName;
                      
            app.FeatureDropDown.Items = app.FeatureTable.UID;         

            if UID == -1            
                getFeature(app);
                app.FeatureEditField.Value = app.feature;
            else
                app.feature = UID;
                app.FeatureEditField.Value = app.feature;
                app.feature_old = app.feature;
                app.FeatureDropDown.Value = app.feature;
                temp = ismember(app.FeatureTable.UID,app.feature);
                app.name = app.FeatureTable.Name{temp}; 
                app.FeatureEditField.Value = app.feature;
            end
            RESETButtonPushed(app);


            maxNumQCperBatch = checkNumberInBatch(app.DataTable,'QC');
            [~,minNumREFinAnyBatch] = checkNumberInBatch(app.DataTable,'Reference');
            app.noQCflag = maxNumQCperBatch < 3;
            app.noREFflag = minNumREFinAnyBatch < 1;
            try
                checkBetweenBatchValid(app);
            catch ME
                uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
            end

            if ~isempty(app.CallerApp.SpreadsheetApp) && isvalid(app.CallerApp.SpreadsheetApp)   
                app.ViewFeatureTableButton.Enable = 'off';
                app.SpreadsheetApp = app.CallerApp.SpreadsheetApp;
            end


            %updateData(app);
            %ReprocessButtonPushed(app);             
        end

        % Button pushed function: ViewFeatureTableButton
        function ViewFeatureTableButtonPushed(app, event)
          app.ViewFeatureTableButton.Enable = 'off';          
          ExternalViewFeatureTableButtonPushed(app.CallerApp);
          app.SpreadsheetApp = app.CallerApp.SpreadsheetApp;
          %app.SpreadsheetApp = TidyFeatureTableView(app,app.FeatureTable,Name=app.ProjectName,FeatureTableName=app.FeatureTableName);
        end

        % Value changed function: FeatureEditField
        function FeatureEditFieldValueChanged(app, event)
            oldvalue = app.FeatureDropDown.Value;
            value = app.FeatureEditField.Value;
            if ~ismember(value,app.FeatureDropDown.Items)
                uialert(app.UIFigure,'Feature does not exist','Warning','Icon','warning');                
                value = oldvalue;
            end
            if strcmp(app.baseConfig.OutlierDetectionMethod,'Manual') || strcmp(app.baseConfig.WithinBatchCorrectionMode,'Manual')
                RESETButtonPushed(app);
            end
            app.FeatureDropDown.Value = value;
            GetFeatureAndUpdateData(app);
        end

        % Value changed function: IgnoreBlanksCheckBox
        function IgnoreBlanksCheckBoxValueChanged(app, event)
            plotControlcharts(app);
           % ReprocessButtonPushed(app);            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)

            app.QCOutlierDetectionMethodDropDown.Enable = "on";
            app.QCOutlierDetectionMethodDropDownLabel.Enable = "on";
            app.QCOutlierDetectionCIEditField.Enable = "on";
            app.QCOutlierDetectionCIEditFieldLabel.Enable = "on";
            app.WithinBatchCorrectionModeDropDown.Enable = "on";
            app.WithinBatchCorrectionModeDropDownLabel.Enable = "on";
            app.QCRSCCrossValidationMethodDropDown.Enable = "on";
            app.QCRSCCrossValidationMethodDropDownLabel.Enable = "on";
            app.QCRSCMonteCarlorepetitionsEditField.Enable = "on";
            app.QCRSCMonteCarlorepetitionsEditFieldLabel.Enable = "on";
            app.LogTransformedCorrectionCheckBox.Enable = "on";
            app.RemoveZerosCheckBox.Enable = "on";

            app.Panel_CVplot.Enable = "on";
            app.baseConfig = app.callerConfig;
            setConfigFields(app);

            % list = app.DataTable.Properties.VariableNames;
            % listx = {};
            % h = height(app.DataTable);
            % for i = 1:length(list)
            %     temp = length(unique(app.DataTable.(list{i})));
            %     if temp ~=h && temp > 1 && temp < h/2, listx = [listx;list{i}]; end                             
            % end
            % rm = ismember(listx,{'Batch','QC','Sample','Blank','Reference'});
            % listx(rm) = [];
            % rm = ismember(listx,app.FeatureTable.UID);
            % listx(rm) = [];
            % listx = ['Batch';listx];
            % app.LabelplotbyDropDown.Items = listx;
            % app.LabelplotbyDropDown.Value = 'Batch';
            app.cmapName = 'quoka';
            app.cmap = mapRcolors(app.cmapName); 

            GetFeatureAndUpdateData(app);   
            app.isOutlier = false(height(app.Data),1);
            app.isOutlierREF = false(height(app.Data),1);
        end

        % Value changed function: FeatureDropDown
        function GetFeatureAndUpdateData(app, event)
            if strcmp(app.baseConfig.OutlierDetectionMethod,'Manual') || strcmp(app.baseConfig.WithinBatchCorrectionMode,'Manual')
                RESETButtonPushed(app);
            end
            getFeature(app);
            app.FeatureEditField.Value = app.feature;
            ReprocessButtonPushed(app);
                      
            if ~isempty(app.SpreadsheetApp) && isvalid(app.SpreadsheetApp)       
                highlightrow(app.SpreadsheetApp,app.feature);
            end
        end

        % Button pushed function: ReprocessButton
        function ReprocessButtonPushed(app, event)
            if app.CallerApp.complete
                app.Button.Visible = "on";
            else
                app.Button.Visible = "off";
            end
            if app.changed
                app.Button.Enable = "on";
            else
                app.Button.Enable = "off";
            end

            updateData(app);
            correctFeature(app);
            batchPlotCVmse(app)
            displayStats(app);
            plotControlcharts(app);            
        end

        % Button pushed function: RandomFeatureButton
        function RandomFeatureButtonPushed(app, event)
            
            if strcmp(app.baseConfig.OutlierDetectionMethod,'Manual') || strcmp(app.baseConfig.WithinBatchCorrectionMode,'Manual')
                RESETButtonPushed(app);
            end
            
            idx = randperm(length(app.FeatureTable.UID)-1)+1;
            app.feature = app.FeatureTable.UID{idx(1)};               
            app.FeatureDropDown.Value = app.feature;
            GetFeatureAndUpdateData(app);
            if app.CallerApp.complete
                ExternalHighlightAllTabs(app.CallerApp,app.feature);
            end
        end

        % Button pushed function: ViewBatchStatisticsButton
        function ViewBatchStatisticsButtonPushed(app, event)
            app.StatsTableApp = BatchStatsTablePeak(app, app.StatsTable, app.feature);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            if ~app.CallerApp.complete && ~app.changed
                if ~isequaln(app.baseConfig,app.callerConfig)
                    res = uiconfirm(app.UIFigure,'Do you want to transfer this configuration to the main application?','Confirm Action','Options',{'Yes','No'});
                    if strcmp(res,'Yes')
                        try      
                            app.baseConfig = validateConfig(app.baseConfig);
                            app.CallerApp.baseConfig = app.baseConfig;
                            app.CallerApp.setConfigFields(mode = 'qcrsc');                   
                        catch ME                 
                            uialert(app.UIFigure,ME.message,'Error transfering config to main application. Reverting to original','Icon','error');
                            app.CallerApp.baseConfig = app.callerConfig;
                        end
                    end
                end
            end
            if ~isempty(app.SpreadsheetApp) && isvalid(app.SpreadsheetApp)       
                unhighlightrow(app.SpreadsheetApp);
            end
            if isempty(app.CallerApp.SpreadsheetApp)
                delete(app.SpreadsheetApp)
            end
            delete(app.StatsTableApp)            
            delete(app)
        end

        % Value changed function: BetweenBatchCorrectionModeDropDown, 
        % ...and 9 other components
        function CorrectionConfigValueChanged(app, event)
            app.baseConfig.LogTransformedCorrection = app.LogTransformedCorrectionCheckBox.Value;
            app.baseConfig.RemoveZeros = app.RemoveZerosCheckBox.Value;
            if app.QCOutlierDetectionMethodDropDown.Enable
                app.baseConfig.OutlierDetectionMethod = app.QCOutlierDetectionMethodDropDown.Value;
            end
            app.baseConfig.OutlierDetectionCI = app.QCOutlierDetectionCIEditField.Value;
            app.baseConfig.OutlierReplacementStrategy = app.QCOutlierReplacementStrategyDropDown.Value;
            if app.WithinBatchCorrectionModeDropDown.Enable
                app.baseConfig.WithinBatchCorrectionMode = app.WithinBatchCorrectionModeDropDown.Value;
            end
            app.baseConfig.BetweenBatchCorrectionMode = app.BetweenBatchCorrectionModeDropDown.Value;            
            app.baseConfig.QCRSCcvMethod = app.QCRSCCrossValidationMethodDropDown.Value;
            app.baseConfig.QCRSCmcReps = app.QCRSCMonteCarlorepetitionsEditField.Value;
            app.baseConfig.BlankRatioMethod = app.BlankRatioMethodDropDown.Value;
            checkStuff(app);
            ReprocessButtonPushed(app);
        end

        % Value changed function: StretchScrollXAxisCheckBox
        function StretchScrollXAxisCheckBoxValueChanged(app, event)
            if app.StretchScrollXAxisCheckBox.Value
                temp = round(height(app.DataTable)/400);
                if temp == 0
                    app.StretchScrollXAxisCheckBox.Value = false;
                    return;
                end
                %app.Panel_BeforeAfter.AutoResizeChildren = 'on';
                app.Panel_BeforeAfter.Position(3) = app.defaultwidth*temp;
                %app.Panel_BeforeAfter.AutoResizeChildren = 'off';
                %app.Panel_BeforeAfter.Scrollable = 'on'; 
                app.UIFigure.Scrollable = 'on';
                app.UIFigure.Position(4) = app.UIFigure.Position(4)+20;
                app.UIFigure.Position(2) = app.UIFigure.Position(2)-20;
            else
                %app.Panel_BeforeAfter.AutoResizeChildren = 'on';
                app.Panel_BeforeAfter.Position(3) = app.defaultwidth;
                app.UIFigure.Scrollable = 'off';
                app.UIFigure.Position(4) = app.UIFigure.Position(4)-20;
                app.UIFigure.Position(2) = app.UIFigure.Position(2)+20;
               % ReprocessButtonPushed(app);
            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.baseConfig.LogTransformedCorrection
                ZZ = power(10,app.Data.Z);
            else
                ZZ = Z;
            end
            Outliers = app.Data.Outlier;   
            OutliersREF = app.Data.OutlierREF;  
            numOutliers = app.NumOutliers;
            gammaVals = app.GammaVals;
            mpvREF = app.MPVref';
            mpv = app.MPV';
            Feature = app.feature;

            code = 0;
            if strcmp(app.baseConfig.OutlierDetectionMethod,'Manual')
                code = code + 1;
            end
            if strcmp(app.baseConfig.WithinBatchCorrectionMode,'Manual')
                code = code + 10;
            end

            ExternalUpdateCorrectedTable(app.CallerApp,Feature,ZZ,mpv,mpvREF,gammaVals,Outliers,OutliersREF,numOutliers,code);            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_BeforeAfter = uipanel(app.UIFigure);
            app.UIAxesBefore = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesBefore, 'Original')
            xlabel(app.UIAxesBefore, 'Injection order')
            ylabel(app.UIAxesBefore, 'log_{10}(Feature Value)')
            zlabel(app.UIAxesBefore, 'Z')
            app.UIAxesAfter = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesAfter, 'Corrected')
            xlabel(app.UIAxesAfter, 'Injection order')
            ylabel(app.UIAxesAfter, 'log_{10}(Feature Value)')
            zlabel(app.UIAxesAfter, 'Z')
            app.IgnoreBlanksCheckBox = uicheckbox(app.Panel_BeforeAfter);
            app.IgnoreBlanksCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoreBlanksCheckBoxValueChanged, true);
            app.IgnoreBlanksCheckBox.Text = 'Ignore Blanks';
            app.StretchScrollXAxisCheckBox = uicheckbox(app.Panel_BeforeAfter);
            app.StretchScrollXAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @StretchScrollXAxisCheckBoxValueChanged, true);
            app.StretchScrollXAxisCheckBox.Text = 'Stretch & Scroll X-Axis ';
            app.StatisticsBeforeAfter95CIAfterPanel = uipanel(app.UIFigure);
            app.StatisticsBeforeAfter95CIAfterPanel.Title = 'Statistics: [Before] After (95% CI After)';
            app.RefRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.RefRSDBeforeAfterData.Text = '[NaN] NaN';
            app.refRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.refRSDLabel.Tooltip = {''};
            app.refRSDLabel.Text = '%refRSD :';
            app.dRatioBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.dRatioBeforeAfterData.Text = '[NaN] NaN';
            app.dRatioLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.dRatioLabel.Tooltip = {''};
            app.dRatioLabel.Text = '%dRatio :';
            app.QCRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.QCRSDBeforeAfterData.Text = '[NaN] NaN';
            app.qcRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.qcRSDLabel.Tooltip = {''};
            app.qcRSDLabel.Text = '%qcRSD :';
            app.BlankRatioData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.BlankRatioData.Text = '[NaN] NaN';
            app.blankRatioLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.blankRatioLabel.Tooltip = {''};
            app.blankRatioLabel.Text = '%blankRatio :';
            app.ViewBatchStatisticsButton = uibutton(app.StatisticsBeforeAfter95CIAfterPanel, 'push');
            app.ViewBatchStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewBatchStatisticsButtonPushed, true);
            app.ViewBatchStatisticsButton.Text = {'View '; 'Batch'; 'Statistics'};
            app.SampleRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.SampleRSDBeforeAfterData.Text = '[NaN] NaN';
            app.sampleRSDLabel = uilabel(app.StatisticsBeforeAfter95CIAfterPanel);
            app.sampleRSDLabel.Tooltip = {''};
            app.sampleRSDLabel.Text = '%sampleRSD :';
            app.Panel_CVplot = uipanel(app.UIFigure);
            app.Panel_CVplot.Tooltip = {''};
            app.Panel_CVplot.Title = 'Check Overfitting:';
            app.UIAxesMSE = uiaxes(app.Panel_CVplot);
            title(app.UIAxesMSE, '\gamma optimisation')
            xlabel(app.UIAxesMSE, '\gamma value')
            ylabel(app.UIAxesMSE, 'cvMSE')
            zlabel(app.UIAxesMSE, 'Z')
            app.FeaturePanel = uipanel(app.UIFigure);
            app.RandomFeatureButton = uibutton(app.FeaturePanel, 'push');
            app.RandomFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @RandomFeatureButtonPushed, true);
            app.RandomFeatureButton.Text = 'Random Feature';
            app.FeatureLabel = uilabel(app.FeaturePanel);
            app.FeatureLabel.Text = 'Feature:';
            app.FeatureDropDown = uidropdown(app.FeaturePanel);
            app.FeatureDropDown.Items = {'No Data'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @GetFeatureAndUpdateData, true);
            app.FeatureDropDown.Value = 'No Data';
            app.FeatureEditFieldLabel = uilabel(app.FeaturePanel);
            app.FeatureEditFieldLabel.Text = 'Feature:';
            app.FeatureEditField = uieditfield(app.FeaturePanel, 'text');
            app.FeatureEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureEditFieldValueChanged, true);
            app.RESETButton = uibutton(app.FeaturePanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ViewFeatureTableButton = uibutton(app.FeaturePanel, 'push');
            app.ViewFeatureTableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFeatureTableButtonPushed, true);
            app.ViewFeatureTableButton.Text = 'View Feature Table';
            app.ReprocessButton = uibutton(app.FeaturePanel, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessButton.Text = 'Reprocess';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'MultiBatch2.png');
            app.QCCorrectionParametersPanel = uipanel(app.UIFigure);
            app.QCCorrectionParametersPanel.Title = 'QC Correction Parameters:';
            app.QCOutlierDetectionMethodDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCOutlierDetectionMethodDropDownLabel.Text = 'QC Outlier Detection Method:';
            app.QCOutlierDetectionMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCOutlierDetectionMethodDropDown.Items = {'None', 'Percentile', 'Linear', 'Quadratic', 'Cubic'};
            app.QCOutlierDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCOutlierDetectionMethodDropDown.Value = 'None';
            app.QCOutlierDetectionCIEditFieldLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCOutlierDetectionCIEditFieldLabel.Text = 'QC Outlier Detection %CI:';
            app.QCOutlierDetectionCIEditField = uieditfield(app.QCCorrectionParametersPanel, 'numeric');
            app.QCOutlierDetectionCIEditField.Limits = [0.9 0.999];
            app.QCOutlierDetectionCIEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCOutlierDetectionCIEditField.Value = 0.95;
            app.QCOutlierReplacementStrategyDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCOutlierReplacementStrategyDropDownLabel.Text = 'QC Outlier Replacement Strategy:';
            app.QCOutlierReplacementStrategyDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCOutlierReplacementStrategyDropDown.Items = {'Median', 'Ignore', 'NaN'};
            app.QCOutlierReplacementStrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCOutlierReplacementStrategyDropDown.Value = 'Median';
            app.WithinBatchCorrectionModeDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.WithinBatchCorrectionModeDropDownLabel.Text = 'Within-Batch Correction Mode:';
            app.WithinBatchCorrectionModeDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.WithinBatchCorrectionModeDropDown.Items = {'Sample', 'Median', 'Linear', 'Spline', 'Spline-C2', 'Spline-C4', 'Spline-C6'};
            app.WithinBatchCorrectionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.WithinBatchCorrectionModeDropDown.Value = 'Spline';
            app.BetweenBatchCorrectionModeDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.BetweenBatchCorrectionModeDropDownLabel.Text = 'Between-Batch Correction Mode:';
            app.BetweenBatchCorrectionModeDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.BetweenBatchCorrectionModeDropDown.Items = {'QC', 'Reference', 'Sample'};
            app.BetweenBatchCorrectionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.BetweenBatchCorrectionModeDropDown.Value = 'QC';
            app.QCRSCCrossValidationMethodDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCCrossValidationMethodDropDownLabel.Text = 'QCRSC Cross Validation Method:';
            app.QCRSCCrossValidationMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCRSCCrossValidationMethodDropDown.Items = {'3-Fold', '5-Fold', '7-Fold', 'Leaveout'};
            app.QCRSCCrossValidationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCRSCCrossValidationMethodDropDown.Value = '3-Fold';
            app.QCRSCMonteCarlorepetitionsEditFieldLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCMonteCarlorepetitionsEditFieldLabel.Text = 'QCRSC Monte Carlo repetitions:';
            app.QCRSCMonteCarlorepetitionsEditField = uieditfield(app.QCCorrectionParametersPanel, 'numeric');
            app.QCRSCMonteCarlorepetitionsEditField.Limits = [1 Inf];
            app.QCRSCMonteCarlorepetitionsEditField.RoundFractionalValues = 'on';
            app.QCRSCMonteCarlorepetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCRSCMonteCarlorepetitionsEditField.Value = 10;
            app.RemoveZerosCheckBox = uicheckbox(app.QCCorrectionParametersPanel);
            app.RemoveZerosCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.RemoveZerosCheckBox.Text = 'Remove Zeros';
            app.RemoveZerosCheckBox.Value = true;
            app.LogTransformedCorrectionCheckBox = uicheckbox(app.QCCorrectionParametersPanel);
            app.LogTransformedCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.LogTransformedCorrectionCheckBox.Text = 'Log Transformed Correction';
            app.LogTransformedCorrectionCheckBox.Value = true;
            app.BlankRatioMethodDropDownLabel = uilabel(app.QCCorrectionParametersPanel);
            app.BlankRatioMethodDropDownLabel.Text = 'Blank Ratio Method:';
            app.BlankRatioMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.BlankRatioMethodDropDown.Items = {'QC', 'Median', 'Percentile'};
            app.BlankRatioMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.BlankRatioMethodDropDown.Value = 'QC';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'res', 'triangleLogo.svg');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'res', 'SendBack.png');
            app.Button.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCRSCfeatureExplorer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end