classdef QCRSCpeakExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        LegendCheckBox              matlab.ui.control.CheckBox
        LabelplotbyDropDown         matlab.ui.control.DropDown
        LabelplotbyDropDownLabel    matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        PeakPanel                   matlab.ui.container.Panel
        IgnoreBlanksCheckBox        matlab.ui.control.CheckBox
        ReprocessButton             matlab.ui.control.Button
        ViewPeakTableButton         matlab.ui.control.Button
        RESETButton                 matlab.ui.control.Button
        PeakEditField               matlab.ui.control.EditField
        PeakEditFieldLabel          matlab.ui.control.Label
        PeakDropDown                matlab.ui.control.DropDown
        PeakDropDownLabel           matlab.ui.control.Label
        RandomPeakButton            matlab.ui.control.Button
        ConfigPanel                 matlab.ui.container.Panel
        SAVEConfigButton            matlab.ui.control.Button
        LOADConfigButton            matlab.ui.control.Button
        configFileTextArea          matlab.ui.control.TextArea
        Panel_CVplot                matlab.ui.container.Panel
        UIAxesMSE                   matlab.ui.control.UIAxes
        StatisticsBeforeAfterPanel  matlab.ui.container.Panel
        ViewBatchStatisticsButton   matlab.ui.control.Button
        blankRatioLabel             matlab.ui.control.Label
        BlankRatioData              matlab.ui.control.Label
        qcRSDLabel                  matlab.ui.control.Label
        QCRSDBeforeAfterData        matlab.ui.control.Label
        dRatioLabel                 matlab.ui.control.Label
        logDratioBeforeAfterData    matlab.ui.control.Label
        refRSDLabel                 matlab.ui.control.Label
        RefRSDBeforeAfterData       matlab.ui.control.Label
        Panel_BeforeAfter           matlab.ui.container.Panel
        UIAxesBefore                matlab.ui.control.UIAxes
        UIAxesAfter                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        CurrentFolder   %
        DataTable       % Data Table - Public so it can be edited by app1
        PeakTable       % Peak Table - primarliy to access P.UID
        ExcelName
        ProjectName     % Project Name
        DataName
        PeakName
        ImportSuccess
        FileType        % 'xls' or 'csv'
        baseConfig      % Data Structure containing all the operable configuration options
%                         app.baseConfig.LogTransform;
%                         app.baseConfig.RemoveZeros;             
%                         app.baseConfig.OutlierMethod;
%                         app.baseConfig.OutlierCI;
%                         app.baseConfig.OutlierPostHoc;           
%                         app.baseConfig.QCRSCgammaRange;
%                         app.baseConfig.QCRSCcvMethod;
%                         app.baseConfig.QCRSCmcReps;
%                         app.baseConfig.QCRSCtype;            
%                         app.baseConfig.StatsParametric;
%                         app.baseConfig.ParallelProcess;
    end

    
    properties (Access = private)
        
        % Setup and import
         
        SpreadsheetApp  % popup app to view (edit) Data & Peak tables
        StatsTableApp   % popup app to view Batch Stats Table    
        StatsTable      % Stats Table for Batch RSD D-Ratio etc stats.
        peak            % Name of peak selected to view (e.g. CP1002) 
        peak_old        % Previous peak      
        jsonTxt         % current raw json config.
        jsonDefault     % default json config for use during reset.
        jsonView        % formatted json file for printing to screen.
        
        configFilename  % config filename
                                 
        % Data for selected peak
        
        Data            % Temp table for chosen peak and associated cleaned meta data
                
        % Results
        
        OptStruct       % Data Structure with {gamma,epsilon,cvMse,minVal} for each batch
                        %         gammaVal optimal smoothing value
                        %         cvMse    (vector) cross validation Mean Square Error
                        %         minVal   minimum value vector of cvMse
        
        
        InsufficientDataFlag % flag to abort any atempt at correction when low number of QCs
        IgnoreBlanks % Description
        cmap
        cmapName
    end
    
    methods (Access = private)
        
        % helper function to select the correct peak (or a random peak)
        
        function getPeak(app)
            app.peak_old = app.peak;
            app.peak = app.PeakDropDown.Value;
            if strcmp(app.peak,'Random')
                idx = randperm(length(app.PeakTable.UID)-1)+1;
                app.peak = app.PeakTable.UID{idx(1)};
            end
        end
                
        % helper function to call external calcStats
        % caclulates RSD, D-Ratio and Blank-Ratio
        
        function displayStats(app)
            

            found = ismember(app.Data.Order,app.Data.Outlier);
            yy = app.Data.Y;
            yy(found) = nan;
            
            isLog = app.baseConfig.LogTransform;
            isParametric = app.baseConfig.StatsParametric;
            BRM = app.baseConfig.BlankRatioMethod;
            
            [QCrsdBefore,DratioBefore,REFrsdBefore,blankRatioBefore] = calcStats(yy,app.Data.QC,app.Data.Blank,app.Data.Reference,Logged=isLog,Parametric=isParametric,BlankRatioMethod=BRM);
            [QCrsdAfter,DratioAfter,REFrsdAfter,blankRatioAfter] = calcStats(app.Data.Z,app.Data.QC,app.Data.Blank,app.Data.Reference,Logged=isLog,Parametric=isParametric,BlankRatioMethod=BRM);             
            if isnan(REFrsdBefore)
                app.RefRSDBeforeAfterData.Text = sprintf('Insufficient Data');
            else
                app.RefRSDBeforeAfterData.Text = sprintf('(%.2f) %.2f',REFrsdBefore,REFrsdAfter);
            end
            
            if isnan(blankRatioBefore)
                app.BlankRatioData.Text = sprintf('Insufficient Data');
            else
                app.BlankRatioData.Text = sprintf('(%.2f) %.2f',blankRatioBefore,blankRatioAfter);
            end
          
            if isnan(QCrsdBefore)
                app.QCRSDBeforeAfterData.Text = sprintf('Insufficient Data');           
                app.logDratioBeforeAfterData.Text = sprintf('Insufficient Data');
            else
                app.QCRSDBeforeAfterData.Text = sprintf('(%.2f) %.2f',QCrsdBefore,QCrsdAfter);           
                app.logDratioBeforeAfterData.Text = sprintf('(%.2f) %.2f',DratioBefore,DratioAfter);               
            end
            
            StatsBefore = BatchCalcStats(yy,app.Data.Batch,app.Data.QC,app.Data.Blank,app.Data.Reference,Logged=isLog,Parametric=isParametric,BlankRatioMethod=BRM);
            StatsAfter = BatchCalcStats(app.Data.Z,app.Data.Batch,app.Data.QC,app.Data.Blank,app.Data.Reference,Logged=isLog,Parametric=isParametric,BlankRatioMethod=BRM);
                        
            StatsBefore = convertvars(StatsBefore,StatsBefore.Properties.VariableNames,'string');
            StatsAfter = convertvars(StatsAfter,StatsAfter.Properties.VariableNames,'string');
            
            app.StatsTable = StatsBefore;
            
            for i = 1:height(app.StatsTable)
                for j = 2:width(app.StatsTable)
                    if ismissing(app.StatsTable{i,j})
                        app.StatsTable{i,j} = "Insufficient Data";
                    else
                        app.StatsTable{i,j} = strjoin(["(",StatsBefore{i,j},") ",StatsAfter{i,j}],'');                   
                    end
                end
            end
            
            if ~isempty(app.StatsTableApp)         
                if isvalid(app.StatsTableApp)
                     updateTable(app.StatsTableApp,app.StatsTable);
                end
            end
        
        end
       
        % plotting the results of the optimisation
        % cvMSE = cross validated Mean Squared Error of fit.
        
        function batchPlotCVmse(app)
            
            axInnerPos = app.UIAxesMSE.InnerPosition;                  
                     
            cla(app.UIAxesMSE);
            
            batchNumTotal = numel(unique(app.Data.Batch));
            
            if strcmp(app.baseConfig.IntraBatchMode,'Spline')
                for i = 1:batchNumTotal
                    plot(app.UIAxesMSE,app.baseConfig.QCRSCgammaRange,app.OptStruct(i).cvMse,'b-o','linewidth',1,'MarkerFaceColor','w','MarkerSize',4);
                    hold(app.UIAxesMSE,'on');
                    plot(app.UIAxesMSE,app.OptStruct(i).gamma,app.OptStruct(i).minVal,'ko','linewidth',1,'MarkerFaceColor','r','MarkerSize',10);
                    text(app.UIAxesMSE,app.OptStruct(i).gamma,app.OptStruct(i).minVal,num2str(i),'HorizontalAlignment',"center",'VerticalAlignment',"middle","Color",'w');
                end
            end
                
            hold(app.UIAxesMSE,'off');
            innerPosDiff = axInnerPos - app.UIAxesMSE.InnerPosition;           
            app.UIAxesMSE.Position = app.UIAxesMSE.Position + innerPosDiff;
            
            app.UIAxesMSE.YLimMode = 'auto';
            app.UIAxesMSE.XLimMode = 'auto';
            app.UIAxesMSE.YLimitMethod = 'tight';
            app.UIAxesMSE.XLimitMethod = 'tight';

        end
        
        % update all the various peak data when the peak name is changed
        % and account for new radio button selection
        % any changes in the PeakPanel required a new aqusition of data from
        % source Data Table.
        
        function updateData(app)
            
            app.Data = app.DataTable(:,{'SampleID','SampleType','Order','QC','Blank','Batch','Reference','Sample'});
            
            y = app.DataTable.(app.peak);

            if app.baseConfig.RemoveZeros
                y(y == 0) = NaN;
            end

            if app.baseConfig.LogTransform
                y(y==0) = NaN;
                app.baseConfig.RemoveZeros = true;
                y = log10(y);
            end

            label = app.DataTable.(app.LabelplotbyDropDown.Value);
            if isnumeric(label)
                label = cellstr(num2str(label));
            end
            label = strrep(label,'_',' ');

            if strcmp('Batch',app.LabelplotbyDropDown.Value)
                label = cellfun(@(x) ['Batch ',x],label,'UniformOutput',false);   
            end

            app.Data.Y = y;
            app.Data.Label = label;
            
            missing = isnan(y);
            app.Data.QC(missing) = false;
            
            if app.IgnoreBlanksCheckBox.Value
                app.Data = app.Data(app.Data.Blank == 0,:); 
            end
            
        end           
        
        function correctPeak(app)
            t = app.Data.Order;
            y = app.Data.Y;
            batch = app.Data.Batch;
            isQC = app.Data.QC;
            isREF = app.Data.Reference;

            [z,yspline,~,toutlier,app.OptStruct] = OptimiseAndCorrectPeakX(app.baseConfig,t,y,batch,isQC,isREF);

            app.Data.Z = z;
            app.Data.Yspline = yspline;
            app.Data.Outlier = ismember(t,toutlier);                 
        end
    end
    
    methods (Access = public)
               
        function Restart(app)
            app.UIFigure.Name = app.ProjectName;
            app.PeakDropDown.Items = [app.PeakTable.UID;'Random'];
            RESETButtonPushed(app);         
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            
            
            app.UIFigure.Name = '';

            jsonD = ['{' newline ...
                '"LogTransform": true,' newline ...
                '"RemoveZeros": true,' newline ...
                '"OutlierScope": "Local",' newline ...
                '"OutlierMethod": "Linear",' newline ...
                '"OutlierCI": 0.95,' newline ...
                '"OutlierPostHoc": "MPV",' newline ...
                '"IntraBatchMode": "Spline",' newline ...
                '"InterBatchMode": "QC",' newline ... 
                '"QCRSCgammaRange": "1:0.5:4",' newline ...
                '"QCRSCcvMethod": "5-Fold",' newline ...
                '"QCRSCmcReps": 5,' newline ...
                '"CorrectionType": "Subtract",' newline ...
                '"BlankRatioMethod": "QC",' newline ...
                '"RelativeLOD": 1.5,' newline ...
                '"StatsParametric": true,' newline ...
                '"ParallelProcess": true ' newline ...
                '}'];
                
            
%             app.jsonTxt = app.jsonDefault;
%             app.jsonView = strsplit(app.jsonTxt,'\n')';
%             app.baseConfig = jsondecode(app.jsonDefault);            
%             app.configFileTextArea.Value = app.jsonView;
            
            switch nargin
                case 3
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    app.ProjectName = 'Matlab Mode';
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = jsonD;
                    app.configFilename = 'defaultConfig.json';
                case 4
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    app.ProjectName = varargin{3};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = jsonD;
                    app.configFilename = 'defaultConfig.json';
                case 5
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = varargin{3};
                    app.configFilename = varargin{4};
                    app.ProjectName = 'Matlab Mode';                    
                case 6
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure);
                    app.jsonDefault = varargin{3};
                    app.configFilename = varargin{4};
                    app.ProjectName = varargin{5};                   
                case 7
                    app.DataTable = varargin{1};
                    app.PeakTable = varargin{2};
                    [app.DataTable,app.PeakTable] = validatingDataPeakTables(app.DataTable,app.PeakTable,fighandle = app.UIFigure,ColumnWarningFlag=varargin{6});
                    app.jsonDefault = varargin{3};
                    app.configFilename = varargin{4};
                    app.ProjectName = varargin{5};                    
                otherwise
                    [app.DataTable,app.PeakTable] = generateMetabExamples();
                    app.ProjectName = 'Demo (25 LC-MS C18+ Peaks, single batch)';
                    app.jsonDefault = jsonD;
                    app.configFilename = 'defaultConfig.json';
            end
            
            app.UIFigure.Name = app.ProjectName;
            app.DataName = 'Data';
            app.PeakName = 'Peak';


            app.PeakDropDown.Items = [app.PeakTable.UID;'Random'];
     
            RESETButtonPushed(app);
            
            getPeak(app);
            app.PeakEditField.Value = app.peak;
            %updateData(app);
            ReprocessButtonPushed(app);             
        end

        % Button pushed function: ViewPeakTableButton
        function ViewPeakTableButtonPushed(app, event)
          app.ViewPeakTableButton.Enable = 'off';          
          app.SpreadsheetApp = TidyPeakTableView(app,app.PeakTable,Name=app.ProjectName,PeakName=app.PeakName);
        end

        % Value changed function: PeakEditField
        function PeakEditFieldValueChanged(app, event)
            oldvalue = app.PeakDropDown.Value;
            value = app.PeakEditField.Value;
            if ~ismember(value,app.PeakDropDown.Items)
                uialert(app.UIFigure,'Peak does not exist','Warning','Icon','warning');                
                value = oldvalue;
            end
            app.PeakDropDown.Value = value;
            GetPeakAndUpdateData(app);
        end

        % Value changed function: IgnoreBlanksCheckBox
        function IgnoreBlanksCheckBoxValueChanged(app, event)
            ReprocessButtonPushed(app);            
        end

        % Button pushed function: LOADConfigButton
        function LOADConfigButtonPushed(app, event)
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView;
            
            app.UIFigure.Visible = 'off';
            [file, path] = uigetfile('.json');
            app.UIFigure.Visible = 'on';
            if ~isequal(file,0)
                app.jsonTxt = fileread(fullfile(path,file));
                app.baseConfig = jsondecode(app.jsonTxt);
                try
                    app.baseConfig = validateConfig(app.baseConfig);
                    app.jsonDefault = app.jsonTxt;
                    app.jsonView = strsplit(app.jsonTxt,'\n')';
                    app.configFilename = file;
                catch ME
                    uialert(app.UIFigure,{ME.message;char('Failed to import config file.')},'Error: Config Error','Icon','error');
                    app.jsonTxt = oldJSON;
                    app.jsonView = oldJSONview;
                    app.baseConfig = oldConfig;
                end
                app.configFileTextArea.Value = app.jsonView;                
                ReprocessButtonPushed(app);
            else
                uialert(app.UIFigure,'Failed to import config file','Config Error','Icon','error');
            end   
        end

        % Button pushed function: SAVEConfigButton
        function SAVEConfigButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            [file,path]=uiputfile('.json','Save QCRSC config file',app.configFilename);
            app.UIFigure.Visible = 'on';
            if ~isequal(file,0)                
                cfg = jsondecode(app.jsonTxt);
                jsontxt = jsonencode(cfg,'PrettyPrint',true);
                fid=fopen(fullfile(path,file),'w');
                fprintf(fid,jsontxt);
                fclose(fid); 
            else
                uialert(app.UIFigure,'Failed to save config file','Error','Icon','error');
            end
        end

        % Value changed function: configFileTextArea
        function configFileTextAreaValueChanged(app, event)
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView;
            app.jsonView = app.configFileTextArea.Value;
            app.jsonTxt = strjoin(app.jsonView,'\n');  
            
            try
                app.baseConfig = jsondecode(app.jsonTxt);
            catch
                uialert(app.UIFigure,{char('Configuration file is invalid.');char('Please check text values are surrounded by quotes.'); char('*** Reverting to last valid configuration ***')},'Warning: Config Error','Icon','warning');
                app.baseConfig = oldConfig;
                app.jsonView = oldJSONview;
                app.configFileTextArea.Value = app.jsonView;
                app.jsonTxt = oldJSON;
                return;
            end
            try
                app.baseConfig = validateConfig(app.baseConfig);
            catch ME
                uialert(app.UIFigure,{ME.message; char('*** Reverting to last valid configuration ***')},'Warning: Config Error','Icon','warning');
                app.baseConfig = oldConfig;
                app.jsonView = oldJSONview;
                app.configFileTextArea.Value = app.jsonView;
                app.jsonTxt = oldJSON;
            end
            ReprocessButtonPushed(app);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.jsonTxt = app.jsonDefault;
            app.jsonView = strsplit(app.jsonTxt,'\n')';
            app.configFileTextArea.Value = app.jsonView;
            app.PeakDropDown.Value = app.PeakDropDown.Items(1);
            app.baseConfig = jsondecode(app.jsonTxt);
            app.baseConfig = validateConfig(app.baseConfig);
            app.IgnoreBlanksCheckBox.Value = false;

            list = app.DataTable.Properties.VariableNames;
            listx = {};
            h = height(app.DataTable);
            for i = 1:length(list)
                temp = length(unique(app.DataTable.(list{i})));
                if temp ~=h && temp > 1 && temp < h/2, listx = [listx;list{i}]; end                             
            end
            rm = ismember(listx,{'Batch','QC','Sample','Blank','Reference'});
            listx(rm) = [];
            rm = ismember(listx,app.PeakTable.UID);
            listx(rm) = [];
            listx = ['Batch';listx];
            app.LabelplotbyDropDown.Items = listx;
            app.LabelplotbyDropDown.Value = 'Batch';
            app.cmapName = 'quoka';
            app.cmap = mapRcolors(app.cmapName); 

            GetPeakAndUpdateData(app);            
        end

        % Value changed function: PeakDropDown
        function GetPeakAndUpdateData(app, event)
            getPeak(app);
            app.PeakEditField.Value = app.peak;
            ReprocessButtonPushed(app);
                      
            if ~isempty(app.SpreadsheetApp)         
                if isvalid(app.SpreadsheetApp)
                    highlightrow(app.SpreadsheetApp,app.peak);
                end
            end
        end

        % Button pushed function: ReprocessButton
        function ReprocessButtonPushed(app, event)
            updateData(app);
            correctPeak(app);
            batchPlotCVmse(app)
            displayStats(app);
            titleBefore = [num2str(app.peak),': Original'];
            titleAfter = [num2str(app.peak),': Corrected'];
            if strcmp(app.LabelplotbyDropDown.Value,'Batch')
                controlChart(app.UIAxesBefore,app.Data,cmap='batch', ...
                    islog=app.baseConfig.LogTransform,title=titleBefore, ...
                    RelativeLOD=app.baseConfig.RelativeLOD);
                controlChart(app.UIAxesAfter,app.Data,cmap='batch', ...
                    before=false,islog=app.baseConfig.LogTransform,title=titleAfter, ...
                    RelativeLOD=app.baseConfig.RelativeLOD);
            else
                controlChart(app.UIAxesBefore,app.Data,cmap=app.cmap, ...
                    islog=app.baseConfig.LogTransform,title=titleBefore, ...
                    RelativeLOD=app.baseConfig.RelativeLOD);
                controlChart(app.UIAxesAfter,app.Data,cmap=app.cmap, ...
                    before=false,islog=app.baseConfig.LogTransform,title=titleAfter, ...
                    RelativeLOD=app.baseConfig.RelativeLOD);
            end
            linkaxes([app.UIAxesBefore app.UIAxesAfter],'xy');

            if app.LegendCheckBox.Value
                app.UIAxesBefore.Legend.Visible = true;
            else
                app.UIAxesBefore.Legend.Visible = false;
            end
        end

        % Button pushed function: RandomPeakButton
        function RandomPeakButtonPushed(app, event)
            app.PeakDropDown.Value = 'Random';
            GetPeakAndUpdateData(app);
        end

        % Button pushed function: ViewBatchStatisticsButton
        function ViewBatchStatisticsButtonPushed(app, event)
            app.StatsTableApp = BatchStatsTable(app, app.StatsTable);
        end

        % Value changed function: LabelplotbyDropDown
        function LabelplotbyDropDownValueChanged(app, event)
            ReprocessButtonPushed(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.SpreadsheetApp)
            delete(app.StatsTableApp)            
            delete(app)
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            if app.LegendCheckBox.Value
                app.UIAxesBefore.Legend.Visible = true;
            else
                app.UIAxesBefore.Legend.Visible = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_BeforeAfter = uipanel(app.UIFigure);
            app.UIAxesAfter = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesAfter, 'Corrected')
            xlabel(app.UIAxesAfter, 'Injection order')
            ylabel(app.UIAxesAfter, 'log_{10}(Peak Value)')
            zlabel(app.UIAxesAfter, 'Z')
            app.UIAxesBefore = uiaxes(app.Panel_BeforeAfter);
            title(app.UIAxesBefore, 'Original')
            xlabel(app.UIAxesBefore, 'Injection order')
            ylabel(app.UIAxesBefore, 'log_{10}(Peak Value)')
            zlabel(app.UIAxesBefore, 'Z')
            app.StatisticsBeforeAfterPanel = uipanel(app.UIFigure);
            app.StatisticsBeforeAfterPanel.Title = 'Statistics: (Before) After';
            app.RefRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfterPanel);
            app.RefRSDBeforeAfterData.Text = '(NaN) NaN';
            app.refRSDLabel = uilabel(app.StatisticsBeforeAfterPanel);
            app.refRSDLabel.Tooltip = {''};
            app.refRSDLabel.Text = '%refRSD :';
            app.logDratioBeforeAfterData = uilabel(app.StatisticsBeforeAfterPanel);
            app.logDratioBeforeAfterData.Text = '(NaN) NaN';
            app.dRatioLabel = uilabel(app.StatisticsBeforeAfterPanel);
            app.dRatioLabel.Tooltip = {''};
            app.dRatioLabel.Text = '%dRatio* :';
            app.QCRSDBeforeAfterData = uilabel(app.StatisticsBeforeAfterPanel);
            app.QCRSDBeforeAfterData.Text = '(NaN) NaN';
            app.qcRSDLabel = uilabel(app.StatisticsBeforeAfterPanel);
            app.qcRSDLabel.Tooltip = {''};
            app.qcRSDLabel.Text = '%qcRSD :';
            app.BlankRatioData = uilabel(app.StatisticsBeforeAfterPanel);
            app.BlankRatioData.Text = '(NaN) NaN';
            app.blankRatioLabel = uilabel(app.StatisticsBeforeAfterPanel);
            app.blankRatioLabel.Tooltip = {''};
            app.blankRatioLabel.Text = '%blankRatio :';
            app.ViewBatchStatisticsButton = uibutton(app.StatisticsBeforeAfterPanel, 'push');
            app.ViewBatchStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewBatchStatisticsButtonPushed, true);
            app.ViewBatchStatisticsButton.Text = {'View '; 'Batch'; 'Statistics'};
            app.Panel_CVplot = uipanel(app.UIFigure);
            app.Panel_CVplot.Tooltip = {''};
            app.Panel_CVplot.Title = 'Check Overfitting:';
            app.UIAxesMSE = uiaxes(app.Panel_CVplot);
            title(app.UIAxesMSE, '\gamma optimisation')
            xlabel(app.UIAxesMSE, '\gamma value')
            ylabel(app.UIAxesMSE, 'cvMSE')
            zlabel(app.UIAxesMSE, 'Z')
            app.ConfigPanel = uipanel(app.UIFigure);
            app.ConfigPanel.Title = 'Config:';
            app.configFileTextArea = uitextarea(app.ConfigPanel);
            app.configFileTextArea.ValueChangedFcn = createCallbackFcn(app, @configFileTextAreaValueChanged, true);
            app.LOADConfigButton = uibutton(app.ConfigPanel, 'push');
            app.LOADConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LOADConfigButtonPushed, true);
            app.LOADConfigButton.Text = 'LOAD Config';
            app.SAVEConfigButton = uibutton(app.ConfigPanel, 'push');
            app.SAVEConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEConfigButtonPushed, true);
            app.SAVEConfigButton.Text = 'SAVE Config';
            app.PeakPanel = uipanel(app.UIFigure);
            app.RandomPeakButton = uibutton(app.PeakPanel, 'push');
            app.RandomPeakButton.ButtonPushedFcn = createCallbackFcn(app, @RandomPeakButtonPushed, true);
            app.RandomPeakButton.Text = 'Random Peak';
            app.PeakDropDownLabel = uilabel(app.PeakPanel);
            app.PeakDropDownLabel.Text = 'Peak:';
            app.PeakDropDown = uidropdown(app.PeakPanel);
            app.PeakDropDown.Items = {'No Data'};
            app.PeakDropDown.ValueChangedFcn = createCallbackFcn(app, @GetPeakAndUpdateData, true);
            app.PeakDropDown.Value = 'No Data';
            app.PeakEditFieldLabel = uilabel(app.PeakPanel);
            app.PeakEditFieldLabel.Text = 'Peak:';
            app.PeakEditField = uieditfield(app.PeakPanel, 'text');
            app.PeakEditField.ValueChangedFcn = createCallbackFcn(app, @PeakEditFieldValueChanged, true);
            app.RESETButton = uibutton(app.PeakPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.ViewPeakTableButton = uibutton(app.PeakPanel, 'push');
            app.ViewPeakTableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPeakTableButtonPushed, true);
            app.ViewPeakTableButton.Text = 'View Peak Table';
            app.ReprocessButton = uibutton(app.PeakPanel, 'push');
            app.ReprocessButton.ButtonPushedFcn = createCallbackFcn(app, @ReprocessButtonPushed, true);
            app.ReprocessButton.Text = 'Reprocess';
            app.IgnoreBlanksCheckBox = uicheckbox(app.PeakPanel);
            app.IgnoreBlanksCheckBox.ValueChangedFcn = createCallbackFcn(app, @IgnoreBlanksCheckBoxValueChanged, true);
            app.IgnoreBlanksCheckBox.Text = 'Ignore Blanks';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'peX2.png';
            app.Panel = uipanel(app.UIFigure);
            app.LabelplotbyDropDownLabel = uilabel(app.Panel);
            app.LabelplotbyDropDownLabel.Text = 'Label plot by:';
            app.LabelplotbyDropDown = uidropdown(app.Panel);
            app.LabelplotbyDropDown.Items = {'Batch'};
            app.LabelplotbyDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelplotbyDropDownValueChanged, true);
            app.LabelplotbyDropDown.Value = 'Batch';
            app.LegendCheckBox = uicheckbox(app.Panel);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.LegendCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCRSCpeakExplorer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end