classdef QCmXP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        QualityControlMultivariateExplorerVersion40Label  matlab.ui.control.Label
        CHEATSHEETButton                matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        Hyperlink                       matlab.ui.control.Hyperlink
        Image                           matlab.ui.control.Image
        IncomingDataPanel               matlab.ui.container.Panel
        ProjectNameLabel                matlab.ui.control.Label
        DataTableLabel                  matlab.ui.control.Label
        FeatureTableLabel               matlab.ui.control.Label
        NumberofBlanksLabel             matlab.ui.control.Label
        NumberofReferenceLabel          matlab.ui.control.Label
        NumberofQCLabel                 matlab.ui.control.Label
        NumberofBatchesLabel            matlab.ui.control.Label
        LOADDATAButton                  matlab.ui.control.Button
        ViewFeatureTableButton          matlab.ui.control.Button
        NumberofFeaturesLabel           matlab.ui.control.Label
        NumberofSamplesLabel            matlab.ui.control.Label
        DefaultConfigurationPanel       matlab.ui.container.Panel
        ResetConfigButton               matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        FeatureInclusionPreFilterPanel  matlab.ui.container.Panel
        NbatchEditField                 matlab.ui.control.NumericEditField
        QCsmissingperfeatureLabel_2     matlab.ui.control.Label
        drinkMeButton                   matlab.ui.control.StateButton
        missingQCperFeatureEditField    matlab.ui.control.NumericEditField
        QCsmissingperfeatureLabel       matlab.ui.control.Label
        PreFilterDropDown               matlab.ui.control.DropDown
        LOQalphaEditField               matlab.ui.control.NumericEditField
        aLabel                          matlab.ui.control.Label
        xmaxBlankLabel                  matlab.ui.control.Label
        QCLOQmissingCheckBox            matlab.ui.control.CheckBox
        featuresremainingLabel_2        matlab.ui.control.Label
        UIAxesPreprocessing             matlab.ui.control.UIAxes
        LaunchAlgorithmExplorer         matlab.ui.control.Button
        QCCorrectionParametersPanel     matlab.ui.container.Panel
        BlankRatioMethodDropDown        matlab.ui.control.DropDown
        BlankRatioMethodDropDown_2Label  matlab.ui.control.Label
        LogTransformedCorrectionCheckBox  matlab.ui.control.CheckBox
        RemoveZerosCheckBox             matlab.ui.control.CheckBox
        QCOutlierDetectionMethodDropDown  matlab.ui.control.DropDown
        QCOutlierDetectionCIEditField   matlab.ui.control.NumericEditField
        QCOutlierReplacementStrategyDropDown  matlab.ui.control.DropDown
        WithinBatchCorrectionModeDropDown  matlab.ui.control.DropDown
        BetweenBatchCorrectionModeDropDown  matlab.ui.control.DropDown
        QCRSCCrossValidationMethodDropDown  matlab.ui.control.DropDown
        QCRSCMonteCarlorepetitionsEditField  matlab.ui.control.NumericEditField
        QCRSCMCRepsLabel                matlab.ui.control.Label
        QCRSCCVMethodLabel              matlab.ui.control.Label
        BetweenBatchMethodLabel         matlab.ui.control.Label
        IntraBatchModeLabel             matlab.ui.control.Label
        OutlierPostHocLabel             matlab.ui.control.Label
        OutlierCILabel                  matlab.ui.control.Label
        OutlierMethodLabel              matlab.ui.control.Label
        LaunchFeatureExplorer           matlab.ui.control.Button
        QCCorrectionEnginePanel         matlab.ui.container.Panel
        ParallelProcessingCheckBox      matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        AbortButton                     matlab.ui.control.Button
        BypassButton                    matlab.ui.control.Button
        featuresCorrectedGauge          matlab.ui.control.Gauge
        RunningLamp                     matlab.ui.control.Lamp
        CompleteLamp                    matlab.ui.control.Lamp
        CompleteLampLabel               matlab.ui.control.Label
        RunningLampLabel                matlab.ui.control.Label
        peakscorrectedLabel             matlab.ui.control.Label
        SavePanel                       matlab.ui.container.Panel
        CorrectedSuffixEditField        matlab.ui.control.EditField
        suffixLabel                     matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        CleanExplorePanel               matlab.ui.container.Panel
        BatchExploredLabel              matlab.ui.control.Label
        SwitchBeforeAfter               matlab.ui.control.Switch
        TabGroup2                       matlab.ui.container.TabGroup
        FilterTab                       matlab.ui.container.Tab
        MissingSamplesFilterPanel       matlab.ui.container.Panel
        SampleFilterDropDown            matlab.ui.control.DropDown
        featuresremainingLabel          matlab.ui.control.Label
        SampleFilterPercentEditField    matlab.ui.control.NumericEditField
        missingperfeatureLabel          matlab.ui.control.Label
        PanelKnobs                      matlab.ui.container.Panel
        CleanFilterOnQC                 matlab.ui.control.Switch
        FilterTypeLabel                 matlab.ui.control.Label
        FilterBankModeDropDown          matlab.ui.control.DropDown
        FilterBankLamp                  matlab.ui.control.Lamp
        FilterBankSwitch                matlab.ui.control.RockerSwitch
        FilterBankSwitchLabel           matlab.ui.control.Label
        SampleRSDKnob                   matlab.ui.control.DiscreteKnob
        SampleRSDKnobLabel              matlab.ui.control.Label
        BlankRatioKnobLabel             matlab.ui.control.Label
        BlankRatioKnob                  matlab.ui.control.DiscreteKnob
        DRatioKnob                      matlab.ui.control.DiscreteKnob
        DRatioKnobLabel                 matlab.ui.control.Label
        QCRSDKnob                       matlab.ui.control.DiscreteKnob
        QCRSDKnobLabel                  matlab.ui.control.Label
        BatchSelectTab                  matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ViewBatchTableButton            matlab.ui.control.Button
        SendButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        InvertButton                    matlab.ui.control.Button
        RevertButton                    matlab.ui.control.Button
        MPQLabel                        matlab.ui.control.Label
        MPQ95Label                      matlab.ui.control.Label
        CleanFeaturesRemainingLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PCATab                          matlab.ui.container.Tab
        ScalingButtonGroup              matlab.ui.container.ButtonGroup
        ParetoscaleButton               matlab.ui.control.RadioButton
        AutoscaleButton                 matlab.ui.control.RadioButton
        MeanCenterOnlyButton            matlab.ui.control.RadioButton
        pcaPreprocessingPanel           matlab.ui.container.Panel
        Log10TransformCheckBox          matlab.ui.control.CheckBox
        MissingValueImputationButtonGroup  matlab.ui.container.ButtonGroup
        kSpinner                        matlab.ui.control.Spinner
        kSpinnerLabel                   matlab.ui.control.Label
        blank20minButton                matlab.ui.control.RadioButton
        KNNrowButton                    matlab.ui.control.RadioButton
        KNNcolumnButton                 matlab.ui.control.RadioButton
        IncludeQCSamplesCheckBox        matlab.ui.control.CheckBox
        IncludeReferenceSamplesCheckBox  matlab.ui.control.CheckBox
        IncludeBlankSamplesCheckBox     matlab.ui.control.CheckBox
        PCStatsSwitch                   matlab.ui.control.Switch
        PopOutButton                    matlab.ui.control.Button
        LabelbyDropDown                 matlab.ui.control.DropDown
        LabelbyDropDownLabel            matlab.ui.control.Label
        VariationExplainedbyeachPCLabel  matlab.ui.control.Label
        DataPointsCheckBox              matlab.ui.control.CheckBox
        ConfidenceIntervalsCheckBoxB    matlab.ui.control.CheckBox
        ConfidenceIntervalsCheckBoxA    matlab.ui.control.CheckBox
        LegendCheckBox                  matlab.ui.control.CheckBox
        YAxisDropDown                   matlab.ui.control.DropDown
        YAxisDropDownLabel              matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisDropDownLabel              matlab.ui.control.Label
        UIAxesPVE2                      matlab.ui.control.UIAxes
        UIAxesPVE                       matlab.ui.control.UIAxes
        UIAxesPCA                       matlab.ui.control.UIAxes
        ForestPlotTab                   matlab.ui.container.Tab
        ForestDisableButton             matlab.ui.control.Button
        ForestLabelByDropDown           matlab.ui.control.DropDown
        LabelBy                         matlab.ui.control.Label
        ForestSaveButton                matlab.ui.control.Button
        ForestFilteredOnlyCheckBox      matlab.ui.control.CheckBox
        ForestBootNumEditField          matlab.ui.control.NumericEditField
        BootNumLabel                    matlab.ui.control.Label
        ForestUpDownButton              matlab.ui.control.StateButton
        ForestSortByDropDown            matlab.ui.control.DropDown
        SortByDropDownLabel             matlab.ui.control.Label
        ForestQCRefSwitch               matlab.ui.control.Switch
        ForestToolTipLabel              matlab.ui.control.Label
        UIAxesPooledForest              matlab.ui.control.UIAxes
        UIAxesForest                    matlab.ui.control.UIAxes
        ImperialRSDplotTab              matlab.ui.container.Tab
        ImperialToolTipLabel            matlab.ui.control.Label
        QCCheckBox                      matlab.ui.control.CheckBox
        SortbySwitchLabel               matlab.ui.control.Label
        RefCheckBox                     matlab.ui.control.CheckBox
        SortbySwitch                    matlab.ui.control.Switch
        UIAxesImperialRSD               matlab.ui.control.UIAxes
        RSDScatterplotTab               matlab.ui.container.Tab
        compareRSDPlotByQC              matlab.ui.control.Switch
        ComparisonToolTipLabel          matlab.ui.control.Label
        UIAxesRSDComparison             matlab.ui.control.UIAxes
        RSDTab                          matlab.ui.container.Tab
        RSDDropDown                     matlab.ui.control.DropDown
        SmoothRSDslider                 matlab.ui.control.Slider
        SmoothSlider_2Label             matlab.ui.control.Label
        SwitchRSDpdf                    matlab.ui.control.Switch
        SwitchRSDPlot                   matlab.ui.control.Switch
        UIAxesRSDpdf                    matlab.ui.control.UIAxes
        dRatioTab                       matlab.ui.container.Tab
        SmoothDRATIOslider              matlab.ui.control.Slider
        SmoothSlider_2Label_2           matlab.ui.control.Label
        SwitchDRATIOpdf                 matlab.ui.control.Switch
        SwitchDratioPlot                matlab.ui.control.Switch
        UIAxesDRATIOpdf                 matlab.ui.control.UIAxes
        blankRatioTab                   matlab.ui.container.Tab
        CleanBlankRatioMethodDropDown   matlab.ui.control.DropDown
        BlankRatioMethodDropDownLabel   matlab.ui.control.Label
        SmoothBLANKslider               matlab.ui.control.Slider
        SmoothSlider_2Label_3           matlab.ui.control.Label
        SwitchBlankpdf                  matlab.ui.control.Switch
        SwitchBlankPlot                 matlab.ui.control.Switch
        UIAxesBLANKpdf                  matlab.ui.control.UIAxes
        QCRSCgTab                       matlab.ui.container.Tab
        UIAxesQCRSCpdf                  matlab.ui.control.UIAxes
        SaveCleanPanel                  matlab.ui.container.Panel
        AlsosavescaledimputeddataCheckBox  matlab.ui.control.CheckBox
        suffixLabel_2                   matlab.ui.control.Label
        SaveCleanButton                 matlab.ui.control.Button
        SaveCleanSuffixEditField        matlab.ui.control.EditField
        Image2                          matlab.ui.control.Image
    end

    
    properties (Access = public)

        % Setup and import
        WorkingDirectory   % 
        SpreadsheetApp  % popup app to view (edit) Data & Feature tables
        BatchSpreadsheetApp
        PopOutPlotApp   % pop-out scatter plot for PCA stats
        ImportApp       % popup app to import Feature/Data tables 
        configExplorerApp
	    featureExplorerApp
        CheatSheetApp
        jsonTxt         % current json config.
        jsonDefault     % default json config for use during reset. 
        jsonView        % formatted json file for printing to screen.
        configFilename  % config filename


        DataTable       % Data Table - Public so it can be edited by app1
        FeatureTable       % Feature Table - primarily to access P.UID
        ProjectName     % Excel Filename or 'CSV Project Name'
        DataName        % Excel Sheet or CSV name
        FeatureName        % Excel Sheet or CSV name
        FileType        % csv or xlsx
        ImportSuccess
        baseConfig      % Data Structure containing all the operable configuration options
        importedConfig
        oldConfig
        preProcKeep     % Features to keep after preQCRSC clean 
        DataX           % Corrected Data Table
        
        GammaVals
        EpsilonVals
        Outliers
        OutliersREF
        NumOutliers
        MPV
        MPVref
        %FeatureXBefore     % Uncorrected Feature Table + Batch Stats
        %FeatureXAfter      % % Corrected Feature Table + Batch Stats

        FeatureX           % Corrected Feature Table + Stats
        FeatureXS          % Corrected Feature Table + Reduced Stats
        FeatureXL          % Corrected Feature Table + Batch Stats
        

        paused          % is QCRSC auto paused?
        aborted         % is QCRSC auto aborted?
        complete        % is QCRSC complete?
        bypass          % is QCRSC bypassed?

        BeforeDataTable % Description
        
        PlotFeatureTable   % Table for Plotting (remade every dial change)        
        PlotDataTable   % Table for Plotting (remade every dial change)
        cmap            % colormap for plots
        cmapName
        

        scatterX        % X data for pop-out scatterplot (2 cols)
        scatterY        % y axis data for pop-out scatterplot (1 col)

        PCAmodel
        anyNAN
        lowQCflag
        noQCflag
        noREFflag

        ImputationMethod
        ScaleMethod
        QCRSCcvMethod
        OutlierDetectionMethod

        ifig
        ImperialLegendItems
        ImperialLegendLabels

        QCRSCsaved

        configImported

        BatchSummaryStats

    end
    
    properties (Access = private)
        FlashTimer % Timer object for flashing
        OriginalColor % Store the button's original color
        FlashColor % The color to flash
        IsTabGroup2Disabled
        CurrentTab2
        BatchListToPlot
        UITable_Old
        IncludeTable
        ForestStatsTableQC
        ForestStatsTableREF
        ForestFeatureTable
        ForestFeatureTableX
        forestWindowSize % Description
        ForestIdx % Description
        ForestEnabled % Description
        CompareFeatureTable % Description
        highlightUID % Description
        
    end

    methods (Access = public) 

        function setConfigFields(app,options)
            arguments
                app
                options.Config = app.baseConfig;
                options.mode = 'all';
            end

  
            if ismember(options.mode,{'all','qcrsc'})  

                app.missingQCperFeatureEditField.Value = options.Config.MissingQCPreFilterPercentage;
                app.PreFilterDropDown.Value = options.Config.MissingQCPreFilterMode;
                app.NbatchEditField.Value = options.Config.MissingQCPreFilterBatchN;
                app.QCLOQmissingCheckBox.Value = options.Config.LOQfilter;
                app.LOQalphaEditField.Value = options.Config.RelativeLOQ;
    
                app.LogTransformedCorrectionCheckBox.Value = options.Config.LogTransformedCorrection;
                app.RemoveZerosCheckBox.Value = options.Config.RemoveZeros;
                app.QCOutlierDetectionMethodDropDown.Value = options.Config.OutlierDetectionMethod;
                app.QCOutlierDetectionCIEditField.Value = options.Config.OutlierDetectionCI;
                app.QCOutlierReplacementStrategyDropDown.Value = options.Config.OutlierReplacementStrategy;
                app.WithinBatchCorrectionModeDropDown.Value = options.Config.WithinBatchCorrectionMode;
                app.BetweenBatchCorrectionModeDropDown.Value = options.Config.BetweenBatchCorrectionMode;                
                app.QCRSCCrossValidationMethodDropDown.Value = options.Config.QCRSCcvMethod;
                app.QCRSCMonteCarlorepetitionsEditField.Value = options.Config.QCRSCmcReps;
                
    
                app.ParallelProcessingCheckBox.Value = options.Config.ParallelProcess;

                app.BlankRatioMethodDropDown.Value = options.Config.BlankRatioMethod;
                app.CleanBlankRatioMethodDropDown.Value = options.Config.BlankRatioMethod;
                checkStuff(app);
       
            end

            if ismember(options.mode,{'all','clean'})

                app.CorrectedSuffixEditField.Value = options.Config.CorrectedFileSuffix;
                app.CorrectedSuffixEditField.Tooltip = {options.Config.CorrectedFileSuffix};
                app.SampleFilterDropDown.Value = options.Config.CleanMissingSampleFilterMode;
                app.SampleFilterPercentEditField.Value = options.Config.CleanMissingSampleFilterPercentage;
                
                try
                    app.FilterBankModeDropDown.Value = options.Config.CleanFilterBankMode;
                catch
                    options.Config.CleanFilterBankMode = 'Complete';
                    app.FilterBankModeDropDown.Value = 'Complete';
                end

                app.CleanFilterOnQC.Value = options.Config.CleanFilterOnQC;
    
                app.QCRSDKnob.Value = options.Config.CleanFilterQCRSD;
                app.SampleRSDKnob.Value = options.Config.CleanFilterSampleRSD;
                app.DRatioKnob.Value = options.Config.CleanFilterDRatio;
                app.BlankRatioKnob.Value = options.Config.CleanFilterBlankRatio;
                
    
                app.SaveCleanSuffixEditField.Value = options.Config.CleanFileSuffix;
                app.SaveCleanSuffixEditField.Tooltip = {options.Config.CleanFileSuffix};
                app.Log10TransformCheckBox.Value = options.Config.CleanLogTransform;
    
                switch options.Config.CleanScaleMethod
                    case 'Autoscale'
                        app.AutoscaleButton.Value = true;
                    case 'Paretoscale'
                        app.ParetoscaleButton.Value = true;
                    case 'None'
                        app.MeanCenterOnlyButton.Value = true;
                end
    
                switch options.Config.CleanImputationMethod
                    case 'KNNcolumn'
                        app.KNNcolumnButton.Value = true;
                    case 'KNNrow'
                        app.KNNrowButton.Value = true;
                    case 'blank20'
                        app.blank20minButton.Value = true;
                end
     
                app.kSpinner.Value = options.Config.CleanImputationK;
                      
            end

            
        end


        function CheckLessThanZero(app)

            features = app.FeatureTable.UID(app.preProcKeep);
            X = app.DataTable{:,features};
            count = sum(sum(X < 0) > 0);
            if count > 0
                uialert(app.UIFigure,['You have ',num2str(count),' prefiltered features with values < 0. It is impossible to have negative concentrations. You may wish to add in a baseline offset to make all data positive or set these data to missing using the "Remove Zeros" tick-box.'],'Warning','Icon','warning');
            end           
        end
        
        function ExternalViewFeatureTableButtonPushed(app)
            app.ViewFeatureTableButton.Enable = 'off';
            app.SpreadsheetApp = TidyFeatureTableView(app, app.FeatureTable);
        end

        function ExternalEnableViewFeatureTable(app)
            app.ViewFeatureTableButton.Enable = 'on';
            if ~isempty(app.featureExplorerApp) && isvalid(app.featureExplorerApp)
                app.featureExplorerApp.ViewFeatureTableButton.Enable = 'on';
            end
            if ~isempty(app.configExplorerApp) && isvalid(app.configExplorerApp)
                app.configExplorerApp.ViewFeatureTableButton.Enable = 'on';
            end
        end
            
        
        function ExternalUpdateCorrectedTable(app,feature,ZZ,mpv,mpvREF,gammaVals,Outliers,OutliersREF,numOutliers,code)
            app.DataX.(feature) = ZZ;
            found = find(ismember(app.FeatureX.UID,feature));
            app.MPV(:,found) = mpv;
            app.MPVref(:,found) = mpvREF;
            app.Outliers(:,found) = Outliers;
            app.OutliersREF(:,found) = OutliersREF;
            app.NumOutliers(found) = numOutliers;
            app.GammaVals(found,:) = gammaVals;
            app.FeatureX.ManualCorrection(found) = code;

            calculateStats(app);
            CleanAndPlot(app);
            HighlightAllTabs(app,feature);
            app.QCRSCsaved = false;

            % if app.QCRSCsaved
            %     TempFeature = app.FeatureX;
            %     TempFeature.cleanPeaks = [];
            %     suffix = app.CorrectedSuffixEditField.Value;            
            %     SaveData(app,TempFeature,app.FeatureName,suffix); 
            %     SaveData(app,app.FeatureXS,app.FeatureName,[suffix,'_Stats']);
            %     saveConfig(app);
            % end         
        end
    end
    
    
    methods (Access = private)  
        
        function ResetData(app)
                app.ViewFeatureTableButton.Enable = 'on';
                app.FeatureInclusionPreFilterPanel.Enable = 'on';  
                app.QCCorrectionParametersPanel.Enable = 'on';
                app.DefaultConfigurationPanel.Enable = 'on';
                app.LaunchFeatureExplorer.Enable = 'on';

                app.QCLOQmissingCheckBox.Enable = "on";
                app.LOQalphaEditField.Enable = "on";
                app.xmaxBlankLabel.Enable = "on";
                app.aLabel.Enable = 'on';
               
                if strcmp(app.baseConfig.FileType,'csv')
                    app.ProjectNameLabel.Text = ['<b>ProjectName:</b> ',app.baseConfig.ProjectName];
                    app.ProjectNameLabel.Tooltip = app.ProjectName;
                    app.DataTableLabel.Text = ['<b>DataTable:</b> ',app.baseConfig.DataTable];
                    app.DataTableLabel.Tooltip = app.baseConfig.DataTable;
                    app.FeatureTableLabel.Text = ['<b>FeatureTable:</b> ',app.baseConfig.FeatureTable];
                    app.FeatureTableLabel.Tooltip = app.baseConfig.FeatureTable;
                else
                    app.ProjectNameLabel.Text = ['<b>ProjectName:</b> ',app.baseConfig.ProjectName];
                    app.ProjectNameLabel.Tooltip = app.ProjectName;
                    app.DataTableLabel.Text = ['<b>DataTable:</b> ',app.baseConfig.DataTable];
                    app.DataTableLabel.Tooltip = app.baseConfig.DataTable;
                    app.FeatureTableLabel.Text = ['<b>FeatureTable:</b> ',app.baseConfig.FeatureTable];
                    app.FeatureTableLabel.Tooltip = app.baseConfig.FeatureTable;
                end
                
                app.NumberofFeaturesLabel.Text = ['<b>Number of Features:</b> ',num2str(height(app.FeatureTable))];
                app.NumberofBatchesLabel.Text = ['<b>Number of Batches:</b> ',num2str(numel(unique(app.DataTable.Batch)))];
                app.NumberofQCLabel.Text = ['<b>Number of QC:</b> ',num2str(sum(app.DataTable.QC))];
                app.NumberofReferenceLabel.Text = ['<b>Number of Reference:</b> ',num2str(num2str(sum(app.DataTable.Reference)))];
                app.NumberofBlanksLabel.Text = ['<b>Number of Blank:</b> ',num2str(num2str(sum(app.DataTable.Blank)))];
                app.NumberofSamplesLabel.Text = ['<b>Number of Samples:</b> ',num2str(sum(ismember(app.DataTable.SampleType,'Sample')))];                
                
                app.QCCorrectionEnginePanel.Enable = 'on';
                app.RunButton.Enable = 'on';
                app.BypassButton.Enable = 'on';
                app.PauseButton.Enable = 'off';
                app.AbortButton.Enable = 'off';
                app.featuresCorrectedGauge.Value = 0; 
                app.paused = false;
                app.aborted = false;
                app.bypass = false;
                app.RunningLamp.Enable = 'off';
                app.CompleteLamp.Enable = 'off';
                
                Batch = unique(app.DataTable.Batch);
                %Batch = (1:100)';
                Include = true(length(Batch),1);
                
                %Batch = arrayfun(@(x) ['Batch',num2str(x)],batch,'UniformOutput',false);
                %app.BatchDropDown.Items = ['All';Batch];
                %app.BatchDropDown.ItemsData = [-1;batch];
                app.IncludeTable = table(Batch,Include);
                app.UITable.Data = app.IncludeTable;


                Batch = arrayfun(@(x) ['Batch',num2str(x)],Batch,'UniformOutput',false);
                app.FilterBankModeDropDown.Items = ['Complete';'Median';'Min';'Max';Batch];
                app.FilterBankModeDropDown.ItemsData = ['Complete';'Median';'Min';'Max';Batch];
   

                list = app.DataTable.Properties.VariableNames;
                listx = {};
                h = height(app.DataTable);
                for i = 1:length(list)
                    temp = length(unique(app.DataTable.(list{i})));
                    if temp ~= h && temp > 1, listx = [listx;list{i}]; end
                end
                rm = ismember(listx,{'QC','Sample','Blank','Reference','SampleType'});
                listx(rm) = [];
                rm = ismember(listx,app.FeatureTable.UID);
                listx(rm) = [];

                app.LabelbyDropDown.Items = ['SampleType';'BatchQC';listx];
                app.LabelbyDropDown.Value = 'SampleType';
                app.drinkMeButton.Value = false;

                if sum(app.DataTable.Blank) == 0
                    app.QCLOQmissingCheckBox.Value = false;
                    app.QCLOQmissingCheckBox.Enable = 'off';
                    app.xmaxBlankLabel.Enable = 'off';
                    app.LOQalphaEditField.Enable = 'off';
                else
                    app.QCLOQmissingCheckBox.Enable = 'on';
                    app.xmaxBlankLabel.Enable = 'on';
                    app.LOQalphaEditField.Enable = 'on';
                end

                if any(app.DataTable.Reference)
                    app.RSDDropDown.Items = {'qcRSD','refRSD','sampleRSD'};
                    app.RSDDropDown.Value = 'qcRSD';
                elseif any(app.DataTable.QC)
                    app.RSDDropDown.Items = {'qcRSD','sampleRSD'};
                    app.RSDDropDown.Value = 'qcRSD';
                else
                    app.RSDDropDown.Items = {'sampleRSD'};
                    app.RSDDropDown.Value = 'sampleRSD';
                end

                if ~any(app.DataTable.Reference)
                    app.compareRSDPlotByQC.Value = true;
                    app.CleanFilterOnQC.Value = true;
                    app.compareRSDPlotByQC.Visible = "off";
                    app.CleanFilterOnQC.Visible = "off";

                else
                    app.compareRSDPlotByQC.Visible = "on";
                    app.CleanFilterOnQC.Visible = "on";
                end

                app.QCRSCsaved = false;
                app.highlightUID = -1;
        end

        
        function drinkMe(app)
                set(app.UIFigure, 'Pointer','watch');
                switchAllPanelsA(app,'off');
                switchAllPanelsB(app,'off');
                drawnow();
                if sum(app.DataTable.Blank==1) == 0
                    uialert(app.UIFigure,'There are no Blank samples in the data set','Warning','Icon','warning');
                    app.drinkMeButton.Value = false;
                    set(app.UIFigure, 'Pointer','arrow');
                    switchAllPanelsA(app,'on');
                    if app.complete
                        switchAllPanelsB(app,'on');
                    end
                    return
                end

                ubatch = unique(app.DataTable.Batch);         
                NumOfBatches = numel(ubatch);
                Xall = app.DataTable{:,app.FeatureTable.UID};
                for i = 1:NumOfBatches
                    found = app.DataTable.Blank~=1 & app.DataTable.Batch == ubatch(i);
                    foundBlank = app.DataTable.Blank==1 & app.DataTable.Batch == ubatch(i);
                    XU = Xall(found,:);
                    BU = Xall(foundBlank,:);
                    XU = LowerThanBlankAsNaN(XU,BU,app.baseConfig.RelativeLOQ);
                    Xall(found,:) = XU;
                end
                app.DataTable{:,app.FeatureTable.UID} = Xall;
                nanAll = isnan(Xall(app.DataTable.Sample==1,:));
                remove = sum(nanAll) == height(nanAll);
                removeUIDs = app.FeatureTable.UID(remove);
                app.FeatureTable(remove,:) = [];
                app.DataTable(:,removeUIDs) = [];
                app.NumberofFeaturesLabel.Text = ['Number of Features: ',num2str(height(app.FeatureTable))];
     
                set(app.UIFigure, 'Pointer','arrow');
                switchAllPanelsA(app,'on');
                if app.complete
                    switchAllPanelsB(app,'on');
                    uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an effect.','Warning','Icon','warning');
                end
                drawnow();
        end


        function preQCRSCclean(app)
            %cla(app.UIAxesPreprocessing,'reset');
            cla(app.UIAxesPreprocessing);

            if app.noQCflag
                keep = true(height(app.FeatureTable),1);
                app.preProcKeep = keep;
                found = sum(keep);
                app.featuresremainingLabel_2.Text = ['# features remaining: ',num2str(found)];
                return;
            end
           
            DataQC = app.DataTable(app.DataTable.QC==1,:);
            Xqc = DataQC{:,app.FeatureTable.UID};
            Xqc(Xqc<=0) = nan;

            ubatch = unique(DataQC.Batch);         
            NumOfBatches = numel(ubatch);
            pmissing = zeros(height(app.FeatureTable),NumOfBatches);
            numMissing = zeros(height(app.FeatureTable),NumOfBatches);

            if sum(app.DataTable.Blank==1) == 0
                app.QCLOQmissingCheckBox.Enable = "off";
                app.LOQalphaEditField.Enable = "off";
                app.xmaxBlankLabel.Enable = "off";
                app.aLabel.Enable = 'off';
                for i = 1:NumOfBatches
                    XU = Xqc(DataQC.Batch == ubatch(i),:);
                    pmissing(:,i) = (100*sum(isnan(XU))./height(XU))';
                    numMissing(:,i) = sum(isnan(XU));
                end
            else
                if app.QCLOQmissingCheckBox.Value
                    DataBlank = app.DataTable(app.DataTable.Blank==1,:);
                    Xblank = DataBlank{:,app.FeatureTable.UID};
                    Xblank(Xblank<=0) = nan;              
                end             
                for i = 1:NumOfBatches
                    XU = Xqc(DataQC.Batch == ubatch(i),:);
                    if app.QCLOQmissingCheckBox.Value
                        BU = Xblank(DataBlank.Batch == ubatch(i),:);
                        XU = LowerThanBlankAsNaN(XU,BU,app.baseConfig.RelativeLOQ);
                    end
                    pmissing(:,i) = (100*sum(isnan(XU))./height(XU))';
                    numMissing(:,i) = sum(isnan(XU));
                end
            end

            total_pmissing = 100*sum(numMissing,2)./height(DataQC);
            
            if all(pmissing == 0)
                text(app.UIAxesPreprocessing,mean(app.UIAxesPreprocessing.XLim),mean(app.UIAxesPreprocessing.YLim),'No missing QCs',"HorizontalAlignment","center"); 
                keep = true(height(app.FeatureTable),1);
            else
                [n(1,:),edges] = histcounts(pmissing(:,1));
                if length(edges) > 20
                    clear n;
                    [n(1,:),edges] = histcounts(pmissing(:,1),10);
                end
                if NumOfBatches > 1
                    for i = 2:NumOfBatches
                        [n(i,:)] = histcounts(pmissing(:,i),edges);
                    end
                end
                center = edges(1:end-1) + diff(edges) / 2;
                pp = bar(app.UIAxesPreprocessing,center,n);
                app.UIAxesPreprocessing.XTick = edges;
                app.UIAxesPreprocessing.XTickLabel = round(edges);
                app.UIAxesPreprocessing.XLim = [min(edges),max(edges)];

                
                tmp = string(round(10*edges)/10);
                temp = cellfun(@(x,y) strcat('(',x,'<=x<',y,')'),tmp(1:end-1),tmp(2:end),'UniformOutput',false);

                for i = 1:NumOfBatches            
                    pp(i).FaceColor = 'none';
                    blabel = ones(length(center),1)*ubatch(i);
                    pp(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('% missing',temp);
                    pp(i).DataTipTemplate.DataTipRows(2).Label = '#Features';
                    pp(i).DataTipTemplate.DataTipRows(3) = dataTipTextRow('Batch#',blabel);
                end
   
                temp = pmissing <= app.missingQCperFeatureEditField.Value;
    
                switch app.PreFilterDropDown.Value
                    case 'EveryBatch'
                        app.baseConfig.MissingQCPreFilterBatchN = NumOfBatches;
                        keep = all(temp,2);
                        %keep = sum(pmissing <= app.missingQCperFeatureEditField.Value,2) == width(pmissing);
                    case 'AnyBatch'
                        app.baseConfig.MissingQCPreFilterBatchN = 1;
                        keep = any(temp,2);
                    case 'Complete'
                        app.baseConfig.MissingQCPreFilterBatchN = 0;
                        keep = total_pmissing <= app.missingQCperFeatureEditField.Value;
                    case 'NBatch'
                        app.baseConfig.MissingQCPreFilterBatchN = min(app.baseConfig.MissingQCPreFilterBatchN,NumOfBatches);
                        keep = sum(temp,2) >= app.baseConfig.MissingQCPreFilterBatchN;
                    otherwise % Disabled
                        app.baseConfig.MissingQCPreFilterBatchN = -1;
                        keep = true(height(app.FeatureTable),1);
                end
                app.NbatchEditField.Value = app.baseConfig.MissingQCPreFilterBatchN;

                for i = 1:NumOfBatches
                    [n(i,:)] = histcounts(pmissing(keep,i),edges);
                end
                hold(app.UIAxesPreprocessing,'on');
                bar(app.UIAxesPreprocessing,center,n);
                
            end


            % if ~strcmp(app.PreFilterDropDown.Value,'Disable')       
            %     for i = 1:NumOfBatches            
            %         %found = edges(1:end-1) > app.missingQCperFeatureEditField.Value;
            %         %pp(i).FaceColor = 'flat';
            %         %pp(i).CData(found,:) = repmat([1,1,1],sum(found),1);
            % 
            %         blabel = ones(length(center),1)*ubatch(i);
            %         pp(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('% missing',temp);
            %         pp(i).DataTipTemplate.DataTipRows(2).Label = '#Features';
            %         pp(i).DataTipTemplate.DataTipRows(3) = dataTipTextRow('Batch#',blabel);
            %     end
            % end


            % 
            % if app.BatchwiseCheckBox.Value
            %     keep = sum(pmissing <= app.missingQCperFeatureEditField.Value,2) == width(pmissing);
            % 
            % else
            %     %keep = sum(pmissing <= app.missingQCperFeatureEditField.Value,2) > 0;
            %     keep = total_pmissing <= app.missingQCperFeatureEditField.Value;
            % end
            
            found = sum(keep);
            app.featuresremainingLabel_2.Text = ['# features remaining: ',num2str(found)];
            
            app.preProcKeep = keep;

            xlabel(app.UIAxesPreprocessing, '% QC missing (per batch)');

            CheckLessThanZero(app);

        end
              
        function optimiseAndCorrectTable(app)
            app.QCCorrectionEnginePanel.Title = sprintf('QC Correction Engine:');            
            app.FeatureX = app.FeatureTable(app.preProcKeep,:);
            app.FeatureX.ManualCorrection = zeros(height(app.FeatureX),1);
            app.DataX = app.DataTable;
            list = setdiff(app.FeatureTable.UID,app.FeatureX.UID);
            app.DataX(:,list) = [];            

            if ~app.bypass
                      
                X = app.DataX{:,app.FeatureX.UID};
                t = app.DataX.Order;
                Batch = app.DataX.Batch;
                isQC = logical(app.DataX.QC);
                isSample = logical(app.DataX.Sample);
                isREF = logical(app.DataX.Reference);
                isBlank = logical(app.DataX.Blank);
                
                ubatch = unique(Batch);
                NumOfBatches = numel(ubatch);
                NumOfFeatures = width(X);
                NumOfObjects = height(X);
                
                if app.baseConfig.RemoveZeros
                    X(X <= 0) = NaN;
                end
                
                if app.baseConfig.LogTransformedCorrection
                    X(X <= 0) = NaN;
                    X = log10(X);
                end
                
                Z = nan(size(X));
                gammaVals = nan(NumOfFeatures,NumOfBatches);
                epsilonVals = nan(NumOfFeatures,NumOfBatches);
                outliers = false(NumOfObjects,NumOfFeatures);  
                outliersREF = false(NumOfObjects,NumOfFeatures);
                numOutliers = nan(NumOfFeatures,1);
                mpvQC = zeros(NumOfObjects,NumOfFeatures);
                mpvREF = zeros(NumOfObjects,NumOfFeatures);
                
                set(app.UIFigure, 'Pointer','watch')
                drawnow();
    
                if app.baseConfig.ParallelProcess
                    app.PauseButton.Enable = 'off';
                    app.AbortButton.Enable = 'off';
                    poolobj = gcp('nocreate');
                    if isempty(poolobj)
                        d = uiprogressdlg(app.UIFigure,'Title','Parallel Processor Admin.');
                        d.Message = "Starting parallel pool (parpool) using the 'local' profile ...";
                        d.Value = 0.5;
                        %poolobj = parpool("Threads");
                        poolobj = parpool("Processes");
                        d.Message = sprintf('Connected to the parallel pool (number of workers: %u).',poolobj.NumWorkers);                       
                        d.Value = 1;
                        pause(2);
                        close(d);
                    end
                                    
                    
                    config = app.baseConfig;  
                    
                    
                    try
                        parfor i =1:10
                            OptimiseAndCorrectFeatureX(config,t,X(:,i),Batch,isQC,isSample,isBlank,isREF,-1,-1);
                        end
                        tnum = poolobj.NumWorkers*5;                      
                        tic;
                        parfor i =1:tnum
                            OptimiseAndCorrectFeatureX(config,t,X(:,i),Batch,isQC,isSample,isBlank,isREF,-1,-1);
                        end
                        elapsedTime = toc;
                        expectedTime = elapsedTime*NumOfFeatures/tnum;                    
                    catch
                        expectedTime = -1;
                    end
                    if expectedTime == -1
                        RunningTimeText = 'Estimated running time is unknown';                                    
                    elseif expectedTime < 60
                        seconds = round(expectedTime);
                        RunningTimeText = sprintf('Estimated running time is %d seconds',seconds);                    
                    elseif expectedTime < 3600
                        mins = round(expectedTime/60);
                        RunningTimeText = sprintf('Estimated running time is %d minutes',mins);      
                    else
                        hrs = floor(expectedTime/3600);
                        mins = round(rem(expectedTime,3600)/60);
                        RunningTimeText = sprintf('Estimated running time is %d hours, %d mins',hrs,mins);
                    end
                    app.QCCorrectionEnginePanel.Title = ['QC Correction Parallel Engine: ',RunningTimeText];
                    

                    tic;
                    F(1:NumOfFeatures) = parallel.FevalFuture;
                    for i = 1:NumOfFeatures
                        F(i) = parfeval(poolobj,@OptimiseAndCorrectFeatureX,7,config,t,X(:,i),Batch,isQC,isSample,isBlank,isREF,-1,-1);                    
                    end
                    
                    set(app.UIFigure, 'Pointer','arrow');
                    app.RunningLamp.Enable = 'on';
                    app.CompleteLamp.Enable = 'off';
                    app.AbortButton.Enable = 'on';                
                    NumCompleted = 0;
                    drawnow();
    
                    while NumCompleted < NumOfFeatures
                        NumCompleted = sum({F.State}=="finished");
                        percDone = 100*NumCompleted/NumOfFeatures;
                        app.featuresCorrectedGauge.Value = percDone;
                        app.RunningLamp.Enable = 'off';
                        pause(0.1);
                        app.RunningLamp.Enable = 'on';
                        if app.aborted
                            app.AbortButton.Enable = 'off';
                            set(app.UIFigure, 'Pointer','watch')
                            drawnow();
                            cancel(F);
                            app.featuresCorrectedGauge.Value = 0;
                           % delete(gcp('nocreate'));  
                            delete(poolobj);  
                            app.RunningLamp.Enable = 'off';
                            app.CompleteLamp.Enable = 'off';
                            app.QCCorrectionEnginePanel.Title = 'QC Correction Engine: ';
                            set(app.UIFigure, 'Pointer','arrow');
                            return
                        end
                        pause(1);
                    end
    
                    for i = 1:NumOfFeatures
                        Z(:,i) = F(i).OutputArguments{1};
                        gammaVals(i,:) = F(i).OutputArguments{3};
                        epsilonVals(i,:) = F(i).OutputArguments{4};
                        outliers(:,i) = ismember(t,F(i).OutputArguments{5});
                        numOutliers(i,:) = length(F(i).OutputArguments{5});
                        mpvQC(:,i) = F(i).OutputArguments{7};
                    end

                    elapsedTime = toc;               
                    if elapsedTime < 60
                        seconds = round(elapsedTime);
                        TimeText = sprintf('Completed in %d seconds',seconds);                     
                    elseif elapsedTime < 3600
                        mins = floor(elapsedTime/60);
                        seconds = round(rem(elapsedTime,60));
                        TimeText = sprintf('Completed in %d minutes, %d seconds',mins,seconds);      
                    else
                        hrs = floor(elapsedTime/3600);
                        mins = round(rem(elapsedTime,3600)/60);
                        TimeText = sprintf('Completed in %d hours, %d minutes',hrs,mins);
                    end
                    app.QCCorrectionEnginePanel.Title = ['QC Correction Parallel Engine: ',TimeText];

                    d = uiprogressdlg(app.UIFigure,'Title','Parallel Processor Admin.');
                    d.Message = "Stopping parallel processor ...";
                    d.Value = 0.5;
                    message = evalc("delete(poolobj)");
                    %message = evalc("delete(gcp('nocreate'))");
                    d.Message = message;
                    d.Value = 1;
                    pause(2);
                    close(d);
         
                else

                    
                    try
                        tic;
                        for i =1:10
                            OptimiseAndCorrectFeatureX(app.baseConfig,t,X(:,i),Batch,isQC,isSample,isBlank,isREF,-1,-1);
                        end
                        elapsedTime = toc;
                        expectedTime = elapsedTime*NumOfFeatures/10;
                    catch
                        expectedTime = -1;
                    end
    
                    if expectedTime == -1
                        RunningTimeText = 'Estimated execution time is unknown';                    
                    elseif expectedTime < 60
                        seconds = round(expectedTime);
                        RunningTimeText = sprintf('Estimated execution time is %d seconds',seconds);                    
                    elseif expectedTime < 3600
                        mins = round(expectedTime/60);
                        RunningTimeText = sprintf('Estimated execution time is %d minutes',mins);      
                    else
                        hrs = floor(expectedTime/3600);
                        mins = round(rem(expectedTime,3600)/60);
                        RunningTimeText = sprintf('Estimated execution time is %d hours, %d mins',hrs,mins);
                    end
                    app.QCCorrectionEnginePanel.Title = ['QC Correction Engine: ',RunningTimeText];
                    drawnow();
                    
                    
                    set(app.UIFigure, 'Pointer','arrow')
                    app.RunningLamp.Enable = 'on';
                    app.CompleteLamp.Enable = 'off';
                    
                    lampflag = true;
                
                    tic;    
                    for i = 1:NumOfFeatures
                        lampflag = not(lampflag);
                        if lampflag
                            app.RunningLamp.Enable = 'on';
                        else
                            app.RunningLamp.Enable = 'off';
                        end
                        while app.paused
                            app.RunningLamp.Enable = 'off';
                            pause(0.5);
                            app.PauseButton.BackgroundColor = [0.80,0.80,0.80];
                            pause(0.5); 
                            app.PauseButton.BackgroundColor = [1.00,1.00,0.07];                    
                        end
                         if app.aborted
                            app.AbortButton.Enable = 'off';
                            app.featuresCorrectedGauge.Value = 0;
                            app.RunningLamp.Enable = 'off';
                            app.CompleteLamp.Enable = 'off';
                            app.QCCorrectionEnginePanel.Title = 'QC Correction Engine: ';
                            return
                         end                       
                        [Z(:,i),~,gammaVals(i,:),epsilonVals(i,:),oo,~,mpvQC(:,i)] = OptimiseAndCorrectFeatureX(app.baseConfig,t,X(:,i),Batch,isQC,isSample,isBlank,isREF,-1,-1);
                        outliers(:,i) = ismember(t,oo);
                        %mpv(:,i) = mpvi;
                        numOutliers(i,:) = length(oo);
                        percDone = 100*i/NumOfFeatures;
                        app.featuresCorrectedGauge.Value = percDone;
                        drawnow();
                                                        
                    end
                    elapsedTime = toc;               
                    if elapsedTime < 60
                        seconds = round(elapsedTime);
                        TimeText = sprintf('Completed in %d seconds',seconds);                    
                    elseif elapsedTime < 3600
                        mins = floor(elapsedTime/60);
                        seconds = round(rem(elapsedTime,60));
                        TimeText = sprintf('Completed in %d minutes, %d seconds',mins,seconds);      
                    else
                        hrs = floor(elapsedTime/3600);
                        mins = round(rem(elapsedTime,3600)/60);
                        TimeText = sprintf('Completed in %d hours, %d minutes',hrs,mins);
                    end
                    app.QCCorrectionEnginePanel.Title = ['QC Correction Engine: ',TimeText];
                end

                %isREF = logical(app.DataX.Reference);
                %t = app.DataX.Order;


                for i = 1:NumOfFeatures
                    y = Z(:,i);
                    yref = y(isREF);
                    tref = t(isREF);
                    mpvREF(:,i) = median(yref);
                    [~,~,toutlier] = OutlierFilter(tref,yref,'poly1',0.99);
                    found = ismember(t,toutlier);
                    outliersREF(:,i) = found;
                    switch app.baseConfig.OutlierReplacementStrategy
                        case 'Median'
                            Z(found,i) = median(yref);                          
                        case 'NaN'
                            Z(found,i) = nan;                          
                    end  
                    numOutliers(i,:) = numOutliers(i,:) + length(toutlier);
                end


            else
                Z = app.DataX{:,app.FeatureX.UID};
                if app.baseConfig.LogTransformedCorrection
                    Z = log10(Z);
                end
                Batch = app.DataX.Batch;
                ubatch = unique(Batch);
                NumOfBatches = numel(ubatch);
                NumOfFeatures = width(Z);
                NumOfObjects = height(Z);
                gammaVals = nan(NumOfFeatures,NumOfBatches);  
                epsilonVals = nan(NumOfFeatures,NumOfBatches);
                outliers = false(NumOfObjects,NumOfFeatures);
                outliersREF = false(NumOfObjects,NumOfFeatures);
                numOutliers = nan(NumOfFeatures,1);              
            end
            
 
            if app.baseConfig.LogTransformedCorrection
                Z = power(10,Z);
                mpvQC = power(10,mpvQC);
            end 

            app.MPV = mpvQC;
            app.MPVref = mpvREF;
            app.GammaVals = gammaVals;
            app.EpsilonVals = epsilonVals;
            app.Outliers = outliers;
            app.OutliersREF = outliersREF;
            app.NumOutliers = numOutliers;

                      
            app.DataX{:,app.FeatureX.UID} = Z;
            app.CompleteLamp.Enable = 'on';
            app.RunningLamp.Enable = 'off';
            calculateStats(app);   
        end

        function calculateStats(app)

            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();

            [app.FeatureX,app.FeatureXL,app.FeatureXS,app.BatchSummaryStats] = calcStats(app,app.DataX,app.FeatureX,app.GammaVals,app.NumOutliers); 

            app.RunButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
            app.AbortButton.Enable = 'off';
            app.BypassButton.Enable = 'on';
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();      
        end


        function [Feature,FeatureXL,FeatureXS,BatchSummaryStats] = calcStats(app,Data,Feature,gammaVals,numOutliers)

            Batch = Data.Batch;
            ubatch = unique(Batch);
            NumOfBatches = numel(ubatch);
            NumOfFeatures = height(Feature);

            gammaVars = cellfun(@(x) strcat('gammaB',x),string(ubatch'),'UniformOutput',false);
            gammaTable = array2table([gammaVals,numOutliers],'variablenames',[gammaVars,'NumOfQCoutliers']);

            QCRSDVals = nan(NumOfFeatures,NumOfBatches);
            QCRSDlowVals = nan(NumOfFeatures,NumOfBatches);
            QCRSDuppVals = nan(NumOfFeatures,NumOfBatches);
            SampleRSDVals = nan(NumOfFeatures,NumOfBatches);
            SampleRSDlowVals = nan(NumOfFeatures,NumOfBatches);
            SampleRSDuppVals = nan(NumOfFeatures,NumOfBatches);
            RefRSDVals = nan(NumOfFeatures,NumOfBatches);
            RefRSDlowVals = nan(NumOfFeatures,NumOfBatches);
            RefRSDuppVals = nan(NumOfFeatures,NumOfBatches);
            blankRatioVals = nan(NumOfFeatures,NumOfBatches);
            DRatioVals = nan(NumOfFeatures,NumOfBatches);
            SampleMissingVals = nan(NumOfFeatures,NumOfBatches);
            QCMissingVals = nan(NumOfFeatures,NumOfBatches);

            for i = 1:NumOfBatches
                BatchStats(i).Tbl = BatchCalcStatsTable(Data,Feature,ubatch(i),app.baseConfig);
                QCRSDVals(:,i) = BatchStats(i).Tbl.qcRSD;
                QCRSDlowVals(:,i) = BatchStats(i).Tbl.qcRSDlower95CI; 
                QCRSDuppVals(:,i) = BatchStats(i).Tbl.qcRSDupper95CI; 
                SampleRSDVals(:,i) = BatchStats(i).Tbl.sampleRSD;
                SampleRSDlowVals(:,i) = BatchStats(i).Tbl.sampleRSDlower95CI;
                SampleRSDuppVals(:,i) = BatchStats(i).Tbl.sampleRSDupper95CI;
                RefRSDVals(:,i) = BatchStats(i).Tbl.refRSD;
                RefRSDlowVals(:,i) = BatchStats(i).Tbl.refRSDlower95CI;
                RefRSDuppVals(:,i) = BatchStats(i).Tbl.refRSDupper95CI;
                DRatioVals(:,i) = BatchStats(i).Tbl.dRatio;
                blankRatioVals(:,i) = BatchStats(i).Tbl.blankRatio;
                SampleMissingVals(:,i) = BatchStats(i).Tbl.sampleMissingPerc;
                QCMissingVals(:,i) = BatchStats(i).Tbl.qcMissingPerc;
            end 

            % BatchSummaryStats
            
            medianData = nan(NumOfBatches,6);
            per25Data = nan(NumOfBatches,6);
            per75Data = nan(NumOfBatches,6);
            for i = 1:NumOfBatches
                Temp = BatchStats(i).Tbl;
                medianData(i,:) = varfun(@(x) median(x,"omitnan"), Temp,"InputVariables",["qcRSD","sampleRSD","refRSD","dRatio","blankRatio","qcMissingPerc"],"OutputFormat","uniform");
                per25Data(i,:) = varfun(@(x) prctile(x,25), Temp,"InputVariables",["qcRSD","sampleRSD","refRSD","dRatio","blankRatio","qcMissingPerc"],"OutputFormat","uniform");
                per75Data(i,:) = varfun(@(x) prctile(x,75), Temp,"InputVariables",["qcRSD","sampleRSD","refRSD","dRatio","blankRatio","qcMissingPerc"],"OutputFormat","uniform");                
            end

            qcRSDsummary = arrayfun(@(y,z) ['(',num2str(y,'%.2f'),',',num2str(z,'%.2f'),')'],per25Data(:,1),per75Data(:,1),'UniformOutput',false);
            sampleRSDsummary = arrayfun(@(y,z) ['(',num2str(y,'%.2f'),',',num2str(z,'%.2f'),')'],per25Data(:,2),per75Data(:,2),'UniformOutput',false);
            refRSDsummary = arrayfun(@(y,z) ['(',num2str(y,'%.2f'),',',num2str(z,'%.2f'),')'],per25Data(:,3),per75Data(:,3),'UniformOutput',false);
            dRatiosummary = arrayfun(@(y,z) ['(',num2str(y,'%.2f'),',',num2str(z,'%.2f'),')'],per25Data(:,4),per75Data(:,4),'UniformOutput',false);
            blankRatiosummary = arrayfun(@(y,z) ['(',num2str(y,'%.2f'),',',num2str(z,'%.2f'),')'],per25Data(:,5),per75Data(:,5),'UniformOutput',false);
            qcMissingPercsummary = arrayfun(@(y,z) ['(',num2str(y,'%.2f'),',',num2str(z,'%.2f'),')'],per25Data(:,6),per75Data(:,6),'UniformOutput',false);
            
            qcRSDc = num2cell(medianData(:,1));
            sampleRSDc = num2cell(medianData(:,2));
            refRSDc = num2cell(medianData(:,3));
            dRatioc = num2cell(medianData(:,4));
            blankRatioc = num2cell(medianData(:,5));
            qcMissingPercRSDc = num2cell(medianData(:,6));
            a = num2cell(ubatch);
            BatchSumData = [a,qcRSDc,qcRSDsummary,sampleRSDc,sampleRSDsummary,refRSDc,refRSDsummary,dRatioc,dRatiosummary,blankRatioc,blankRatiosummary,qcMissingPercRSDc,qcMissingPercsummary];
            
            BatchSummaryStats = cell2table(BatchSumData,"VariableNames",{'Batch','qcRSD(med)','qcRSD(lq,uq)','sampleRSD(med)','sampleRSD(lq,uq)','refRSD(med)','refRSD(lq,uq)','dRatio(med)','dRatio(lq,uq)','blankRatio(med)','blankRatio(lq,uq)','qcMissingPerc(med)','qcMissingPerc(lq,uq)'});                        

            % HEAVY LIFTING FUNCTION
            
            stats = BatchCalcStatsTable(Data,Feature,-1,app.baseConfig);

            % qcRSD table

            qcRSDVars = cellfun(@(x) strcat('qcRSDB',x),string(ubatch'),'UniformOutput',false);
            qcRSDTable = array2table(QCRSDVals,'variablenames',qcRSDVars);
            qcRSDVars = cellfun(@(x) strcat('qcRSDupper95CIB',x),string(ubatch'),'UniformOutput',false);
            tempUpper = array2table(QCRSDuppVals,'variablenames',qcRSDVars);
            qcRSDVars = cellfun(@(x) strcat('qcRSDlower95CIB',x),string(ubatch'),'UniformOutput',false);
            tempLower = array2table(QCRSDlowVals,'variablenames',qcRSDVars);
            qcRSDTable = [qcRSDTable,tempLower,tempUpper];
            qcRSDTable.qcRSD = stats.qcRSD;
            qcRSDTable.qcRSDlower95CI = stats.qcRSDlower95CI;
            qcRSDTable.qcRSDupper95CI = stats.qcRSDupper95CI;

            % sampleRSD table

            sampleRSDVars = cellfun(@(x) strcat('sampleRSDB',x),string(ubatch'),'UniformOutput',false);
            sampleRSDTable = array2table(SampleRSDVals,'variablenames',sampleRSDVars);
            sampleRSDVars = cellfun(@(x) strcat('sampleRSDupper95CIB',x),string(ubatch'),'UniformOutput',false);
            tempUpper = array2table(SampleRSDuppVals,'variablenames',sampleRSDVars);
            sampleRSDVars = cellfun(@(x) strcat('sampleRSDlower95CIB',x),string(ubatch'),'UniformOutput',false);
            tempLower = array2table(SampleRSDlowVals,'variablenames',sampleRSDVars);
            sampleRSDTable = [sampleRSDTable,tempLower,tempUpper];
            sampleRSDTable.sampleRSD = stats.sampleRSD;
            sampleRSDTable.sampleRSDlower95CI = stats.sampleRSDlower95CI;
            sampleRSDTable.sampleRSDupper95CI = stats.sampleRSDupper95CI;

            % refRSD table

            refRSDVars = cellfun(@(x) strcat('refRSDB',x),string(ubatch'),'UniformOutput',false);
            refRSDTable = array2table(RefRSDVals,'variablenames',refRSDVars);
            refRSDVars = cellfun(@(x) strcat('refRSDupper95CIB',x),string(ubatch'),'UniformOutput',false);
            tempUpper = array2table(RefRSDuppVals,'variablenames',refRSDVars);
            refRSDVars = cellfun(@(x) strcat('refRSDlower95CIB',x),string(ubatch'),'UniformOutput',false);
            tempLower = array2table(RefRSDlowVals,'variablenames',refRSDVars);
            refRSDTable = [refRSDTable,tempLower,tempUpper];
            refRSDTable.refRSD = stats.refRSD;
            refRSDTable.refRSDlower95CI = stats.refRSDlower95CI;
            refRSDTable.refRSDupper95CI = stats.refRSDupper95CI;

            % dRatio table

            dRatioVars = cellfun(@(x) strcat('dRatioB',x),string(ubatch'),'UniformOutput',false);
            dRatioTable = array2table(DRatioVals,'variablenames',dRatioVars);            
            dRatioTable.dRatio = stats.dRatio;

            % blankRatio table
            % force "complete" blankRatio to be the max of the batch blankRatio

            blankRatioVars = cellfun(@(x) strcat('blankRatioB',x),string(ubatch'),'UniformOutput',false);
            blankRatioTable = array2table(blankRatioVals,'variablenames',blankRatioVars);
            blankRatioTable.blankRatioMax = max(blankRatioVals,[],2);            
            blankRatioTable.blankRatioMean = mean(blankRatioVals,2,"omitmissing");
            blankRatioTable.blankRatioMedian = median(blankRatioVals,2,"omitmissing");
            %blankRatioTable.blankRatio = stats.blankRatio;
            blankRatioTable.blankRatio = max(blankRatioVals,[],2);
            stats.blankRatio = max(blankRatioVals,[],2);

            % sampleMissing table

            sampleMissingVars = cellfun(@(x) strcat('sampleMissingPercB',x),string(ubatch'),'UniformOutput',false);            
            sampleMissingTable = array2table(SampleMissingVals,'variablenames',sampleMissingVars);
            sampleMissingTable.sampleMissingPerc = stats.sampleMissingPerc;

            % qcMissing table

            qcMissingVars = cellfun(@(x) strcat('qcMissingPercB',x),string(ubatch'),'UniformOutput',false);
            qcMissingTable = array2table(QCMissingVals,'variablenames',qcMissingVars);
            qcMissingTable.qcMissingPerc = stats.qcMissingPerc;

            Feature(:,ismember(Feature.Properties.VariableNames,{'qcRSD','refRSD','sampleRSD','dRatio','blankRatio','qcMissingPerc','sampleMissingPerc'})) = [];

            FeatureXL = [Feature,gammaTable,qcRSDTable,sampleRSDTable,refRSDTable,dRatioTable,blankRatioTable,sampleMissingTable,qcMissingTable];

            stats = stats(:,{'qcRSD','refRSD','sampleRSD','dRatio','blankRatio','qcMissingPerc','sampleMissingPerc'});
            Feature = [Feature,stats];
            Feature.qcRSD = round(Feature.qcRSD,2);
            Feature.refRSD = round(Feature.refRSD,2);
            Feature.sampleRSD = round(Feature.sampleRSD,2);
            Feature.dRatio = round(Feature.dRatio,2);
            Feature.blankRatio = round(Feature.blankRatio,2);
            Feature.qcMissingPerc = round(Feature.qcMissingPerc,2);
            Feature.sampleMissingPerc = round(Feature.sampleMissingPerc,2);
            
            Temp = Feature(:,{'UID','Name'});
            FeatureXS = [Temp,gammaTable,qcRSDTable,sampleRSDTable,refRSDTable,dRatioTable,blankRatioTable,sampleMissingTable,qcMissingTable];

            set(app.UIFigure, 'Pointer','arrow');
            app.complete = true;
 
        end

        function plotForestRSD(app)

            if ~app.ForestEnabled
                return;
            end
            
            list = app.FeatureX.Properties.VariableNames;

            if all(app.PlotFeatureTable.cleanPeaks)
                app.ForestFilteredOnlyCheckBox.Visible = false;
            else
                app.ForestFilteredOnlyCheckBox.Visible = true;
            end

            app.ForestFeatureTable = app.PlotFeatureTable;%(:,list);

            %list = app.PeakFeatures;
            keep = cellfun(@(x) numel(unique(app.ForestFeatureTable.(x)))>1,list);
            list = list(keep);
            %app.ForestFeatureTable = app.ForestFeatureTable(:,list);
            
            
            listx = {'meanDeltaCV'};
                       
            None = (1:height(app.ForestFeatureTable))';
            app.ForestFeatureTable.None = None;
            app.ForestFeatureTable = movevars(app.ForestFeatureTable, 'None', 'Before', 1);
            listA = ['None',list,listx];    
                     
            app.ForestSortByDropDown.Items = listA;
            app.ForestSortByDropDown.Value = 'None';
            
            app.ForestLabelByDropDown.Items = list;
            app.ForestLabelByDropDown.Value = 'Name'; 
            
            
            childObjects = findobj(app.ForestPlotTab.Children, 'Type', 'uicontrol');
            delete(childObjects);
            
            app.ForestToolTipLabel.Visible = "off";
             
            disableDefaultInteractivity(app.UIAxesForest);
            disableDefaultInteractivity(app.UIAxesPooledForest);
            
            app.forestWindowSize = 40;
            maxLength = 30;
            app.ForestFeatureTable.ShortName = cellfun(@(x) pad(x(1:min(length(x), maxLength)),maxLength,"left"), app.ForestFeatureTable.Name, 'UniformOutput', false);
            app.ForestFeatureTable.ShortName = strrep(app.ForestFeatureTable.ShortName,'_',' ');

            bootNum = app.ForestBootNumEditField.Value;

            app.ForestUpDownButton.Icon = 'up-arrow.png';
            app.ForestUpDownButton.Value = false;

            if app.ForestFilteredOnlyCheckBox.Value
                app.ForestFeatureTableX = app.ForestFeatureTable(app.PlotFeatureTable.cleanPeaks,:);
                app.ForestFeatureTableX.None = (1:height(app.ForestFeatureTableX))';
            else
                app.ForestFeatureTableX = app.ForestFeatureTable;
            end

            app.ForestStatsTableQC = calcForestStats(app.ForestFeatureTableX,app.BeforeDataTable,app.PlotDataTable,'QC',0.05,app.baseConfig.LogTransformedCorrection,bootNum);  
            if any(app.PlotDataTable.Reference)
                app.ForestQCRefSwitch.Visible = "on";
                app.ForestStatsTableREF = calcForestStats(app.ForestFeatureTableX,app.BeforeDataTable,app.PlotDataTable,'Reference',0.05,app.baseConfig.LogTransformedCorrection,bootNum);
            else
                app.ForestQCRefSwitch.Value = true;
                app.ForestQCRefSwitch.Visible = 'off';             
            end
            app.ForestIdx = app.ForestFeatureTableX.None;
            if app.ForestQCRefSwitch.Value
                plotForestwithSlider(app,'QC'); 
            else
                plotForestwithSlider(app,'REF');
            end
            app.ForestToolTipLabel.BackgroundColor = [252, 164, 168]/255;
                      
        end

        function plotImperialRSD(app)

            red = [220,20,60]/255;
            grey = [0.7,0.7,0.7];
            cornflowerblue = [100,149,237]/255;
            gold = [212,175,55]/255;
            
            cla(app.UIAxesImperialRSD,'reset');
            app.ImperialToolTipLabel.Visible = "off";           
           
            ms = 3;
           
            if any(app.PlotDataTable.Reference)
                app.RefCheckBox.Visible = "on";
                app.QCCheckBox.Visible = 'on';
            else
                app.RefCheckBox.Visible = 'off';
                app.QCCheckBox.Visible = 'off';
            end

            bin = (1:height(app.PlotFeatureTable))';
            app.PlotFeatureTable.idx0123 = bin;
            if strcmp(app.SortbySwitch.Value,'sample')
                app.PlotFeatureTable = sortrows(app.PlotFeatureTable,'sampleRSD','ascend');                
            else
                app.PlotFeatureTable = sortrows(app.PlotFeatureTable,'qcRSD','ascend'); 
            end
            app.PlotFeatureTable.sortIdx = bin;
            app.PlotFeatureTable = sortrows(app.PlotFeatureTable,'idx0123','ascend');
            app.PlotFeatureTable.idx0123 = [];


            in = app.PlotFeatureTable.cleanPeaks;
           
            ll = [];
            lt = {};
            if any(~in)
                h2outA = semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.sampleRSD(~in),app.PlotFeatureTable.sortIdx(~in),'s','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event));
                h2outA.MarkerSize = ms+0.6;
                h2outA.MarkerEdgeColor = grey;               
                hold(app.UIAxesImperialRSD,"on");
            
                if app.QCCheckBox.Value
                    h2outB = semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.qcRSD(~in),app.PlotFeatureTable.sortIdx(~in),'s','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event));
                    h2outB.MarkerSize = ms+0.6;
                    h2outB.MarkerEdgeColor = grey;               
                    hold(app.UIAxesImperialRSD,"on");
                end
                if app.RefCheckBox.Value
                    h2outC = semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.refRSD(~in),app.PlotFeatureTable.sortIdx(~in),'s','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event));
                    h2outC.MarkerSize = ms+0.6;
                    h2outC.MarkerEdgeColor = grey;               
                    hold(app.UIAxesImperialRSD,"on");
                end
            end

            if any(in)
                h2inA = semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.sampleRSD(in),app.PlotFeatureTable.sortIdx(in),'s','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event));
                h2inA.MarkerSize = ms;
                h2inA.MarkerFaceColor = cornflowerblue;
                h2inA.MarkerEdgeColor = 'none';               
                hold(app.UIAxesImperialRSD,"on");
                ll = [ll,h2inA];
                lt = [lt,{'Sample'}];
            
                if app.QCCheckBox.Value
                    h2inB = semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.qcRSD(in),app.PlotFeatureTable.sortIdx(in),'s','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event));
                    h2inB.MarkerSize = ms;
                    h2inB.MarkerFaceColor = red;
                    h2inB.MarkerEdgeColor = 'none';               
                    hold(app.UIAxesImperialRSD,"on");

                    ll = [ll,h2inB];
                    lt = [lt,{'QC'}];
                end
                if app.RefCheckBox.Value
                    h2inC = semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.refRSD(in),app.PlotFeatureTable.sortIdx(in),'s','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event));
                    h2inC.MarkerSize = ms;
                    h2inC.MarkerFaceColor = gold;
                    h2inC.MarkerEdgeColor = 'none';               
                    hold(app.UIAxesImperialRSD,"on");

                    ll = [ll,h2inC];
                    lt = [lt,{'Reference'}];
                end
            end
                % -------------------- Plot Loess Curves --------------------------------
                
            if strcmp(app.SortbySwitch.Value,'sample')
                if app.QCCheckBox.Value
                    y0 = app.PlotFeatureTable.qcRSD;
                    x = app.PlotFeatureTable.sortIdx;
                    found = ~isnan(y0) & in;
                    y0 = y0(found);
                    x = x(found);
                    [x,idx] = sort(x);
                    y0 = y0(idx);
                    y = smooth(x,log10(y0),0.8,'rloess');              
                    y = power(10,y);                    
                    fx = semilogx(app.UIAxesImperialRSD,y,x,'k--','LineWidth',2);
                    ll = [ll,fx];
                    lt = [lt,{'Loess QC trend'}];
                end                
            else
                y0 = app.PlotFeatureTable.sampleRSD;
                x = app.PlotFeatureTable.sortIdx;
                found = ~isnan(y0) & in;
                y0 = y0(found);
                x = x(found);
                [x,idx] = sort(x);
                y0 = y0(idx);
                y = smooth(x,log10(y0),0.8,'rloess');              
                y = power(10,y);                    
                fx = semilogx(app.UIAxesImperialRSD,y,x,'k--','LineWidth',2);
                ll = [ll,fx];
                lt = [lt,{'Loess Sample trend'}];
            end
                          
            if app.RefCheckBox.Value        
                y0 = app.PlotFeatureTable.refRSD;
                x = app.PlotFeatureTable.sortIdx;
                found = ~isnan(y0) & in;
                y0 = y0(found);
                x = x(found);
                [x,idx] = sort(x);               
                y0 = y0(idx);
                y = smooth(x,log10(y0),0.8,'rloess');              
                y = power(10,y);                    
                fx2 = semilogx(app.UIAxesImperialRSD,y,x,'k-.','LineWidth',2);                
                ll = [ll,fx2];
                lt = [lt,{'Loess Reference trend'}];
            end

            
            xlabel(app.UIAxesImperialRSD,'%RSD');
            ylabel(app.UIAxesImperialRSD,'Cumulative Number of Features');
            legend(app.UIAxesImperialRSD,ll,lt,'location','southeast');
            app.ImperialLegendLabels = ll;
            app.ImperialLegendItems = lt;
            
            app.UIAxesImperialRSD.Box = 'on';
            ylim(app.UIAxesImperialRSD,[0,1.05*max(app.PlotFeatureTable.sortIdx)])
            

            app.UIAxesImperialRSD.XGrid = 'on';            
            app.UIAxesImperialRSD.YGrid = 'on';            
            app.UIAxesImperialRSD.MinorGridLineStyle = '-';
            app.UIAxesImperialRSD.MinorGridAlpha = 0.2;
            xlim(app.UIAxesImperialRSD,[0.9*min([1;app.PlotFeatureTable.qcRSD;app.PlotFeatureTable.sampleRSD]),1.1*max([app.PlotFeatureTable.qcRSD;app.PlotFeatureTable.sampleRSD])])                
            app.UIAxesImperialRSD.XTickLabel = humaniseLogTickLabel(app.UIAxesImperialRSD.XTickLabel);

            app.UIAxesImperialRSD.ButtonDownFcn = createCallbackFcn(app,@(src,event)app.MouseClick2(src,event), true);
        end


        function ImperialMouseClick(app,source,event)                           
            k = dsearchn([source.XData;source.YData]',event.IntersectionPoint(:,1:2));            
            list = app.PlotFeatureTable.UID(ismember(app.PlotFeatureTable.sortIdx,source.YData));
            app.highlightUID = list{k};            
            HighlightAllTabs(app,app.highlightUID);            
        end

        function highlightImperialValue(app,UID)

            childObjects = findobj(app.ImperialRSDplotTab, 'UserData', -1);
            delete(childObjects);              

            k = find(ismember(app.PlotFeatureTable.UID,UID));

            gold = [218,165,32]/255;
            red = [220,20,60]/255; 
            red2 = [252, 164, 168]/255;
            cornflowerblue = [100,149,237]/255;
            blue2 = [135,206,235]/255;
            grey = [0.8,0.8,0.8];


            if app.PlotFeatureTable.cleanPeaks(k)
                colqc = red;
                colsample = cornflowerblue;
                colref = gold;
                if strcmp(app.SortbySwitch.Value,'sample')
                    app.ImperialToolTipLabel.BackgroundColor = red2;
                else
                    app.ImperialToolTipLabel.BackgroundColor = blue2;
                end
            else
                colqc = grey;
                colsample = grey;
                colref = grey;
                app.ImperialToolTipLabel.BackgroundColor = grey;
            end

            
            semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.sampleRSD(k),app.PlotFeatureTable.sortIdx(k),'o','Color',colsample,'LineWidth',1,'UserData',-1);
            semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.sampleRSDupper95CI(k),app.PlotFeatureTable.sortIdx(k),'|','Color',colsample,'LineWidth',3,'UserData',-1);
            semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.sampleRSDlower95CI(k),app.PlotFeatureTable.sortIdx(k),'|','Color',colsample,'LineWidth',3,'UserData',-1);
            semilogx(app.UIAxesImperialRSD,[app.PlotFeatureTable.sampleRSDlower95CI(k),app.PlotFeatureTable.sampleRSDupper95CI(k)],[app.PlotFeatureTable.sortIdx(k),app.PlotFeatureTable.sortIdx(k)],'-','Color',colsample,'LineWidth',1,'UserData',-1);
            if app.QCCheckBox.Value
                semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.qcRSD(k),app.PlotFeatureTable.sortIdx(k),'o','Color',colqc,'LineWidth',1,'UserData',-1);
                semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.qcRSDupper95CI(k),app.PlotFeatureTable.sortIdx(k),'|','Color',colqc,'LineWidth',3,'UserData',-1);
                semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.qcRSDlower95CI(k),app.PlotFeatureTable.sortIdx(k),'|','Color',colqc,'LineWidth',3,'UserData',-1);
                semilogx(app.UIAxesImperialRSD,[app.PlotFeatureTable.qcRSDlower95CI(k),app.PlotFeatureTable.qcRSDupper95CI(k)],[app.PlotFeatureTable.sortIdx(k),app.PlotFeatureTable.sortIdx(k)],'-','Color',colqc,'LineWidth',1,'UserData',-1);
            end
            if app.RefCheckBox.Value
                semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.refRSD(k),app.PlotFeatureTable.sortIdx(k),'o','Color',colref,'LineWidth',1,'UserData',-1);
                semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.refRSDupper95CI(k),app.PlotFeatureTable.sortIdx(k),'|','Color',colref,'LineWidth',3,'UserData',-1);
                semilogx(app.UIAxesImperialRSD,app.PlotFeatureTable.refRSDlower95CI(k),app.PlotFeatureTable.sortIdx(k),'|','Color',colref,'LineWidth',3,'UserData',-1);
                semilogx(app.UIAxesImperialRSD,[app.PlotFeatureTable.refRSDlower95CI(k),app.PlotFeatureTable.refRSDupper95CI(k)],[app.PlotFeatureTable.sortIdx(k),app.PlotFeatureTable.sortIdx(k)],'-','Color',colref,'LineWidth',1,'UserData',-1); 
            end
            
            app.ImperialToolTipLabel.Visible ="on";
            ToolTip(app,app.ImperialToolTipLabel,k);
            legend(app.UIAxesImperialRSD,app.ImperialLegendLabels,app.ImperialLegendItems,'location','southeast');
            

        end

        function ImperialMouseClick2(app,~,~)
            app.ImperialToolTipLabel.Visible ="off";
            childObjects = findobj(app.ImperialRSDplotTab, 'UserData', -1);
            delete(childObjects); 
            app.highlightUID = -1;
        end

        function MouseClick2(app,~,~)
            app.ImperialToolTipLabel.Visible ="off";
            app.ComparisonToolTipLabel.Visible ="off";
            app.ForestToolTipLabel.Visible ="off";
            childObjects = findobj(app.TabGroup, 'UserData', -1);
            delete(childObjects); 
            app.highlightUID = -1;
        end

        function ToolTip(app,labelvariable,idx)

           txt0 = sprintf('<b> SampleID:</b> %s: %s',app.PlotFeatureTable.UID{idx},app.PlotFeatureTable.Name{idx});
           txt1 = sprintf('\n<b> qcRSD (95%%CI):</b> %.2f%% (%.2f-%.2f) | <b>sampleRSD (95%%CI):</b> %.2f%% (%.2f-%.2f)', ...
           app.PlotFeatureTable.qcRSD(idx),app.PlotFeatureTable.qcRSDlower95CI(idx),app.PlotFeatureTable.qcRSDupper95CI(idx), ...
           app.PlotFeatureTable.sampleRSD(idx),app.PlotFeatureTable.sampleRSDlower95CI(idx),app.PlotFeatureTable.sampleRSDupper95CI(idx));
           if app.PlotFeatureTable.blankRatio == 0.1
                 txt2 = sprintf('\n<b> dRatio:</b> %.2f%% | <b>blankRatio:</b> < 0.1%%',app.PlotFeatureTable.dRatio(idx));                
           else
                 txt2 = sprintf('\n<b> dRatio:</b> %.2f%% | <b>blankRatio:</b> %.2f%%',app.PlotFeatureTable.dRatio(idx),app.PlotFeatureTable.blankRatio(idx));
           end
           txt3 = sprintf(' | <b>refRSD (95%%CI):</b> %.2f%% (%.2f-%.2f)',app.PlotFeatureTable.refRSD(idx),app.PlotFeatureTable.refRSDlower95CI(idx),app.PlotFeatureTable.refRSDupper95CI(idx));
           txt4 = sprintf(' | <b>qcMissing:</b> %.0f%% | <b>sampleMissing:</b> %.0f%%',app.PlotFeatureTable.qcMissingPerc(idx),app.PlotFeatureTable.sampleMissingPerc(idx));
           txt5 = sprintf(' (filtered on Batch %d)',app.PlotFeatureTable.bestBatch);
          
           if app.PlotFeatureTable.bestBatch ~= -1
               txt0 = [txt0,txt5];
           end
           if any(app.PlotDataTable.Reference)
               labelvariable.Text = [txt0,txt1,txt3,txt2,txt4];
           else
               labelvariable.Text = [txt0,txt1,txt2,txt4];
           end
           
        end

        function plotCompareRSD(app)
            cla(app.UIAxesRSDComparison,'reset');
            grey = [0.7,0.7,0.7];
            %green = [0,0.6,0.6];
            %gold = [218,165,32]/255;
            green = [0, 128, 0]/255;
            purple = [128, 0, 128]/255;
            app.ComparisonToolTipLabel.Visible = "off";
           
            if height(app.PlotFeatureTable) < 100                
                ms = 13;%30;
            elseif height(app.PlotFeatureTable) < 2000
                ms = 9;%20;
            else
                ms = 5;%15;
            end
           
            if ~any(app.PlotDataTable.Reference)
                    app.compareRSDPlotByQC.Value = true;                    
                    app.compareRSDPlotByQC.Visible = "off";                    
            else
                app.compareRSDPlotByQC.Visible = "on";           
            end

            app.CompareFeatureTable = app.PlotFeatureTable;
            
            maxSampleRSD = 200;
            maxQCRSD = 150;
            maxRefRSD = 150;

            app.CompareFeatureTable.sampleRSD(app.CompareFeatureTable.sampleRSD > maxSampleRSD) = maxSampleRSD;
            app.CompareFeatureTable.qcRSD(app.CompareFeatureTable.qcRSD > maxQCRSD) = maxQCRSD;
            app.CompareFeatureTable.refRSD(app.CompareFeatureTable.refRSD > maxRefRSD) = maxRefRSD;

            in = app.PlotFeatureTable.cleanPeaks;

            if any(~in)
                if app.compareRSDPlotByQC.Value
                    scatter(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(~in),app.CompareFeatureTable.qcRSD(~in),ms,grey,'s',"LineWidth",1,'MarkerFaceAlpha',0.7,'ButtonDownFcn',@(src,event)app.ComparisonMouseClick(src,event,~in));  
                else
                    scatter(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(~in),app.CompareFeatureTable.refRSD(~in),ms,grey,'s',"LineWidth",1,'MarkerFaceAlpha',0.7,'ButtonDownFcn',@(src,event)app.ComparisonMouseClick(src,event,~in));  
                end
                hold(app.UIAxesRSDComparison,"on");
            end
            
            if any(in)               
                if app.compareRSDPlotByQC.Value
                    scatter(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(in),app.CompareFeatureTable.qcRSD(in),ms,purple,'filled','MarkerFaceAlpha',0.7,'ButtonDownFcn',@(src,event)app.ComparisonMouseClick(src,event,in));
                else
                    scatter(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(in),app.CompareFeatureTable.refRSD(in),ms,green,'filled','MarkerFaceAlpha',0.7,'ButtonDownFcn',@(src,event)app.ComparisonMouseClick(src,event,in));
                end
                hold(app.UIAxesRSDComparison,"on");
            end
            
            
            xlim(app.UIAxesRSDComparison,[0,max(app.CompareFeatureTable.sampleRSD)]);
            if app.compareRSDPlotByQC.Value              
                ylim(app.UIAxesRSDComparison,[0,max(app.CompareFeatureTable.qcRSD)]);
            else
                ylim(app.UIAxesRSDComparison,[0,max(app.CompareFeatureTable.refRSD)]);
            end
            app.UIAxesRSDComparison.XTick = 0:20:200;
            app.UIAxesRSDComparison.YTick = 0:20:150;
            
            app.UIAxesRSDComparison.XGrid = 'on';            
            app.UIAxesRSDComparison.YGrid = 'on';            
            app.UIAxesRSDComparison.MinorGridLineStyle = '-';
            app.UIAxesRSDComparison.MinorGridAlpha = 0.2;
            

            xlabel(app.UIAxesRSDComparison,'Sample %RSD');
            if app.compareRSDPlotByQC.Value
                ylabel(app.UIAxesRSDComparison,'QC %RSD');
            else
                ylabel(app.UIAxesRSDComparison,'Reference %RSD');
            end

            
            app.UIAxesRSDComparison.Box = 'on';
            app.UIAxesRSDComparison.ButtonDownFcn = createCallbackFcn(app,@(src,event)app.MouseClick2(src,event), true);
        end

        function ComparisonMouseClick(app,source,event,in)        
            k = dsearchn([source.XData;source.YData]',event.IntersectionPoint(:,1:2));
            list = app.CompareFeatureTable.UID(in);
            %list = app.PlotFeatureTable.UID(ismember(app.PlotFeatureTable.sampleRSD,source.XData));
            app.highlightUID = list{k}; 
            %UID = app.PlotFeatureTable.UID{k};
            HighlightAllTabs(app,app.highlightUID);            
        end

        function highlightComparisonValue(app,UID)

            childObjects = findobj(app.RSDScatterplotTab, 'UserData', -1);
            delete(childObjects);              

            k = find(ismember(app.PlotFeatureTable.UID,UID));
                
            gold = [218,165,32]/255;
            red = [220,20,60]/255; 
            red2 = [252, 164, 168]/255;
            cornflowerblue = [100,149,237]/255;          
            grey = [0.8,0.8,0.8];

            if app.PlotFeatureTable.cleanPeaks(k)
                crosscol1 = cornflowerblue;               
                if app.compareRSDPlotByQC.Value
                    crosscol2 = red;
                    app.ComparisonToolTipLabel.BackgroundColor = red2;
                else
                    app.ComparisonToolTipLabel.BackgroundColor = gold;
                    crosscol2 = gold;
                end
            else
               crosscol1 = grey;
               crosscol2 = grey;
               app.ComparisonToolTipLabel.BackgroundColor = grey; 
            end

            
            if app.compareRSDPlotByQC.Value
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(k),app.CompareFeatureTable.qcRSD(k),'o','color',crosscol2,'LineWidth',1,'UserData',-1);
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(k),app.CompareFeatureTable.qcRSDupper95CI(k),'_','color',crosscol2,'LineWidth',3,'UserData',-1);
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(k),app.CompareFeatureTable.qcRSDlower95CI(k),'_','color',crosscol2,'LineWidth',3,'UserData',-1);
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSDlower95CI(k),app.CompareFeatureTable.qcRSD(k),'|','color',crosscol1,'LineWidth',3,'UserData',-1);
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSDupper95CI(k),app.CompareFeatureTable.qcRSD(k),'|','color',crosscol1,'LineWidth',3,'UserData',-1);
                plot(app.UIAxesRSDComparison,[app.CompareFeatureTable.sampleRSDlower95CI(k),app.CompareFeatureTable.sampleRSDupper95CI(k)],[app.CompareFeatureTable.qcRSD(k),app.CompareFeatureTable.qcRSD(k)],'-','color',crosscol1,'LineWidth',1,'UserData',-1);
                plot(app.UIAxesRSDComparison,[app.CompareFeatureTable.sampleRSD(k),app.CompareFeatureTable.sampleRSD(k)],[app.CompareFeatureTable.qcRSDlower95CI(k),app.CompareFeatureTable.qcRSDupper95CI(k)],'-','color',crosscol2,'LineWidth',1,'UserData',-1);
            else
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(k),app.CompareFeatureTable.refRSD(k),'o','color',crosscol2,'LineWidth',1,'UserData',-1);
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(k),app.CompareFeatureTable.refRSDupper95CI(k),'_','color',crosscol2,'LineWidth',3,'UserData',-1);
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSD(k),app.CompareFeatureTable.refRSDlower95CI(k),'_','color',crosscol2,'LineWidth',3,'UserData',-1);
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSDlower95CI(k),app.CompareFeatureTable.refRSD(k),'|','color',crosscol2,'LineWidth',3,'UserData',-1);
                plot(app.UIAxesRSDComparison,app.CompareFeatureTable.sampleRSDupper95CI(k),app.CompareFeatureTable.refRSD(k),'|','color',crosscol2,'LineWidth',3,'UserData',-1);
                plot(app.UIAxesRSDComparison,[app.CompareFeatureTable.sampleRSDlower95CI(k),app.CompareFeatureTable.sampleRSDupper95CI(k)],[app.CompareFeatureTable.refRSD(k),app.CompareFeatureTable.refRSD(k)],'-','color',crosscol1,'LineWidth',1,'UserData',-1);
                plot(app.UIAxesRSDComparison,[app.CompareFeatureTable.sampleRSD(k),app.CompareFeatureTable.sampleRSD(k)],[app.CompareFeatureTable.refRSDlower95CI(k),app.CompareFeatureTable.refRSDupper95CI(k)],'color',crosscol2,'linestyle','-','LineWidth',1,'UserData',-1);
            end
            app.ComparisonToolTipLabel.Visible ="on";
            ToolTip(app,app.ComparisonToolTipLabel,k);
            
            
        end

        function ComparisonMouseClick2(app,~,~)
            app.ComparisonToolTipLabel.Visible ="off";
            childObjects = findobj(app.RSDScatterplotTab, 'UserData', -1);
            delete(childObjects);
            app.highlightUID = -1;
        end

        function HighlightAllTabs(app,UID)
            highlightImperialValue(app,UID);
            highlightForestValue(app,UID);
            highlightComparisonValue(app,UID);
            if ~isempty(app.featureExplorerApp) && isvalid(app.featureExplorerApp)                    
                found = find(ismember(app.FeatureX.UID,UID));                    
                gammaVals = app.GammaVals(found,:);
                epsilonVals = app.EpsilonVals(found,:);
                mpv = app.MPV(:,found);
                mpvREF = app.MPVref(:,found);
                outliers = app.Outliers(:,found);
                outliersREF = app.OutliersREF(:,found);
                numOutliers = app.NumOutliers(found);
                isManual = app.FeatureX.ManualCorrection(found);
                ExternalSetFeature(app.featureExplorerApp,UID,app.baseConfig,gammaVals,epsilonVals,mpv,mpvREF,outliers,outliersREF,numOutliers,isManual);
            else
                if ~isempty(app.SpreadsheetApp) && isvalid(app.SpreadsheetApp)        
                    highlightrow(app.SpreadsheetApp,UID);
                end
            end
        end

        function PlotPCA(app)


            if any(app.PlotDataTable.Reference)
                app.IncludeReferenceSamplesCheckBox.Visible = "on";
            else
                app.IncludeReferenceSamplesCheckBox.Visible = 'off';
            end

            if any(app.PlotDataTable.Blank)
                app.IncludeBlankSamplesCheckBox.Visible = "on";
            else
                app.IncludeBlankSamplesCheckBox.Visible = 'off';
            end

            %try                
                plotPCAXX(app.UIAxesPCA,app.PlotDataTable,app.PCAmodel, ...
                    xaxis = app.XAxisDropDown.Value, ...
                    yaxis = app.YAxisDropDown.Value, ...
                    IncludeQCs= app.IncludeQCSamplesCheckBox.Value, ...
                    IncludeBlanks= app.IncludeBlankSamplesCheckBox.Value, ...
                    IncludeRefs= app.IncludeReferenceSamplesCheckBox.Value, ...
                    label = app.LabelbyDropDown.Value, ...                    
                    plotCIA = app.ConfidenceIntervalsCheckBoxA.Value, ...
                    plotCIB = app.ConfidenceIntervalsCheckBoxB.Value, ...
                    plotDataPoints = app.DataPointsCheckBox.Value, ...
                    cmap = app.cmap);
                if app.LegendCheckBox.Value
                    app.UIAxesPCA.Legend.Visible = true;
                else
                    app.UIAxesPCA.Legend.Visible = false;
                end
                
            % catch ME
            %     uialert(app.UIFigure,ME.message,'Error: PCA Plot','Icon','error');
            %     cla(app.UIAxesPCA);
            %     cla(app.UIAxesPVE);
            %     cla(app.UIAxesPVE2);
            %     return;
            % end
        end

        function calcAndPlotPCA(app)

            if ~app.anyNAN
                impType = 'blank20';
            else
                impType = app.baseConfig.CleanImputationMethod;
            end

            try 
                [app.PCAmodel] = qcrscPCA(app.PlotDataTable,app.PlotFeatureTable, ...
                    RemoveZeros = app.baseConfig.RemoveZeros, ...                    
                    ImputationType = impType, ...
                    LogTransform = app.Log10TransformCheckBox.Value, ...
                    ScaleMethod = app.baseConfig.CleanScaleMethod, ...                
                    batchScale = false, ...
                    k=app.kSpinner.Value);
            catch ME
                uialert(app.UIFigure,{ME.message;'Try reducing the % allowable missing values'},'PCA Error','Icon','error');
                cla(app.UIAxesPCA);
                cla(app.UIAxesPVE);
                cla(app.UIAxesPVE2);
                return;
            end

            pcnum = (1:width(app.PCAmodel.Score))';
            app.XAxisDropDown.Items = ['Order';arrayfun(@(x) ['PC',num2str(x)],pcnum,'UniformOutput',false)];
            app.XAxisDropDown.ItemsData = [0;pcnum];
            app.YAxisDropDown.Items = arrayfun(@(x) ['PC',num2str(x)],pcnum,'UniformOutput',false);
            app.YAxisDropDown.ItemsData = pcnum;  
            
            PlotPCA(app);

            numPCsInBar = 100;
            if length(app.PCAmodel.latent) < numPCsInBar
                numPCsInBar = length(app.PCAmodel.latent);
            end
            
            explainedVals = app.PCAmodel.explained(1:numPCsInBar);
            dRatioVals = app.PCAmodel.DRatio(1:numPCsInBar);
            cumVarVals = app.PCAmodel.cumVar(1:numPCsInBar);
            cumMPQVals = app.PCAmodel.cumMPQ(1:numPCsInBar);
            
            if app.PCStatsSwitch.Value
                thresh = min([75,round(max(dRatioVals)*.1)/.1]);
                temp = dRatioVals;
                temp(temp>thresh) = thresh+4;
                bh = barh(app.UIAxesPVE,-1*temp,'red');
                xlabel(app.UIAxesPVE,'dRatio_{PC}','fontsize',8);
                app.UIAxesPVE.XLim = [-1*(thresh+5),0];
                app.UIAxesPVE.XTick = [-1*thresh,0];
                app.UIAxesPVE.XTickLabel = [thresh,0];

                bh2 = barh(app.UIAxesPVE2,explainedVals,'blue');
                xlabel(app.UIAxesPVE2,'Expl.Var_{PC}','fontsize',8)
                app.UIAxesPVE2.XLim = [0,0.105*ceil(max(explainedVals*10))];
                app.UIAxesPVE2.XTick = [0,0.1*ceil(max(explainedVals*10))];

                bh.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);    
                bh.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Expl.Var.',explainedVals,'%.2f%%');    
                bh.DataTipTemplate.DataTipRows(3) = dataTipTextRow('dRatio',dRatioVals,'%.2f%%');
                 
                bh2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);                  
                bh2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Expl.Var.',explainedVals,'%.2f%%');    
                bh2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('dRatio',dRatioVals,'%.2f%%');


            else
                bh = barh(app.UIAxesPVE,-1*cumMPQVals,'red');
                %xlabel(app.UIAxesPVE,'Cum.MPQ_{PC}','fontsize',8);
                app.UIAxesPVE.XLim = [-0.105*ceil(max(cumMPQVals*10)),0];
                app.UIAxesPVE.XTick = [-0.1*ceil(max(cumMPQVals*10)),0];
                app.UIAxesPVE.XTickLabel = [0.1*ceil(max(cumMPQVals*10)),0];

                bh2 = barh(app.UIAxesPVE2,cumVarVals,'blue');
                xlabel(app.UIAxesPVE2,'Cum.Expl.Var_{PC}','fontsize',8)              
                app.UIAxesPVE2.XLim = [0,100];               
                app.UIAxesPVE2.XTick = [0,100];

                bh.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);    
                bh.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Cum.Expl.Var.',cumVarVals,'%.2f%%');                    
                %bh.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.MPQ_{qc}',cumMPQVals,'%.2f%%');
                 
                bh2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);    
                bh2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Cum.Expl.Var.',cumVarVals,'%.2f%%');                    
                %bh2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.MPQ_{qc}',cumMPQVals,'%.2f%%');

                switch app.baseConfig.CleanScaleMethod
                    case 'Autoscale'
                        xlabel(app.UIAxesPVE,'Cum.sMPQ_{PC}','fontsize',8);
                        bh.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.sMPQ_{qc}',cumMPQVals,'%.2f%%');
                        bh2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.sMPQ_{qc}',cumMPQVals,'%.2f%%');
                    case 'MeanCenter'
                        xlabel(app.UIAxesPVE,'Cum.MPQ_{PC}','fontsize',8);
                        bh.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.MPQ_{qc}',cumMPQVals,'%.2f%%');
                        bh2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.MPQ_{qc}',cumMPQVals,'%.2f%%');
                    otherwise
                        xlabel(app.UIAxesPVE,'Cum.pMPQ_{PC}','fontsize',8);
                        bh.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.pMPQ_{qc}',cumMPQVals,'%.2f%%');
                        bh2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.pMPQ_{qc}',cumMPQVals,'%.2f%%'); 
                end
            end
            
            app.UIAxesPVE.Box = 'on';
            app.UIAxesPVE.YLim = [0,numPCsInBar+1];
            app.UIAxesPVE2.YLim = [0,numPCsInBar+1];
            app.UIAxesPVE.YTick = [];
            app.UIAxesPVE2.YTick = [1,numPCsInBar];
            app.UIAxesPVE2.YTickLabel = [1,numPCsInBar];
            app.UIAxesPVE2.Box = 'on';
            app.UIAxesPVE2.TickDir = 'none';
            
            switch app.baseConfig.CleanScaleMethod
                case 'Autoscale'
                    app.MPQLabel.Text = sprintf('sMPQ: %.1f%%',app.PCAmodel.MPQ);
                    app.MPQ95Label.Text = sprintf('sMPQ<sub>95</sub>: %.1f%%',app.PCAmodel.MPQ95);
                case 'MeanCenter'
                    app.MPQLabel.Text = sprintf('MPQ: %.1f%%',app.PCAmodel.MPQ);
                    app.MPQ95Label.Text = sprintf('MPQ<sub>95</sub>: %.1f%%',app.PCAmodel.MPQ95);
                otherwise
                    app.MPQLabel.Text = sprintf('pMPQ: %.1f%%',app.PCAmodel.MPQ);
                    app.MPQ95Label.Text = sprintf('pMPQ<sub>95</sub>: %.1f%%',app.PCAmodel.MPQ95);
            end
        end        
        

        function plotGammaHistogam(app)
            cla(app.UIAxesQCRSCpdf,'reset');
            ubatch = unique(app.PlotDataTable.Batch);         
            NumOfBatches = numel(ubatch);
            gammaRange = 0:15;
            if length(gammaRange) > 2
                edges1 = gammaRange(1:end-1) - diff(gammaRange) / 2;
                edges2 = gammaRange(2:end) + diff(gammaRange) / 2;
            else
                edges1 = gammaRange(1:end) - diff(gammaRange) / 2;
                edges2 = gammaRange(1:end) + diff(gammaRange) / 2;
            end
            edges = unique([edges1,edges2]);
            
            in = app.PlotFeatureTable.cleanPeaks;


            n0 = nan(NumOfBatches,length(edges)-1);            
            for i = 1:NumOfBatches
                y = app.PlotFeatureTable{:,['gammaB',num2str(ubatch(i))]};
                n0(i,:) = histcounts(y,edges);
            end
            b0 = bar(app.UIAxesQCRSCpdf,gammaRange,n0','FaceColor','w','EdgeColor','k');
            hold(app.UIAxesQCRSCpdf,"on");  

            n = nan(NumOfBatches,length(edges)-1);            
            for i = 1:NumOfBatches
                y = app.PlotFeatureTable{in,['gammaB',num2str(ubatch(i))]};
                n(i,:) = histcounts(y,edges);
            end
            b1 = bar(app.UIAxesQCRSCpdf,gammaRange,n');
            
            for i = 1:NumOfBatches
                bnum = ones(length(gammaRange),1)*ubatch(i);
                b0(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('Batch',bnum);
                b0(i).DataTipTemplate.DataTipRows(2).Label = '#features';
                b1(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('Batch',bnum);
                b1(i).DataTipTemplate.DataTipRows(2).Label = '#features';
            end
            app.UIAxesQCRSCpdf.XTick = 0:15;
            xlabel(app.UIAxesQCRSCpdf,'QCRSC gammaVal range');
            ylabel(app.UIAxesQCRSCpdf,'Number of Features');
            try
                app.UIAxesQCRSCpdf.YLim = [0,1.05*max(n0,[],'all')];
            catch
                app.UIAxesQCRSCpdf.YLim = [0,1];
            end
        end

        function selectBatches(app)

            X = app.DataTable{:,app.FeatureX.UID};
            switch app.baseConfig.OutlierReplacementStrategy
                case 'Ignore'
                case 'Median'
                   X(app.Outliers) = app.MPV(app.Outliers);
                   X(app.OutliersREF) = app.MPVref(app.OutliersREF);
                case 'NaN'
                   X(app.Outliers) = NaN;
                   X(app.OutliersREF) = NaN;
                otherwise
                    ME = MException('QCRSC:UnexpectedQCRSCoutlierAction',"QCRSC OutlierAction '%s' does not exist",app.baseConfig.OutlierReplacementStrategy);
                    throw(ME);
            end
            app.BeforeDataTable = app.DataTable;
            app.BeforeDataTable{:,app.FeatureX.UID} = X;

            if app.SwitchBeforeAfter.Value
                app.PlotDataTable = app.DataX;
                app.PlotFeatureTable = app.FeatureXL;
            else
                app.PlotDataTable = app.BeforeDataTable;
                [~,app.PlotFeatureTable,~,~] = calcStats(app,app.PlotDataTable,app.FeatureX,app.GammaVals,app.NumOutliers);               
            end
            app.UITable_Old = app.UITable.Data;
            app.BatchListToPlot = app.UITable.Data{app.UITable.Data.Include,"Batch"};
            ubatch = unique(app.PlotDataTable.Batch);
            NumOfBatch = numel(ubatch);

            if numel(app.BatchListToPlot) == NumOfBatch
                app.BatchExploredLabel.Text = 'Exploring ALL batches';
            else
                gammaVals = app.GammaVals(:,app.UITable.Data.Include);
                includeSamples = ismember(app.PlotDataTable.Batch,app.BatchListToPlot);
                app.PlotDataTable = app.PlotDataTable(includeSamples,:);
                [~,app.PlotFeatureTable,~,~] = calcStats(app,app.PlotDataTable,app.FeatureX,gammaVals,app.NumOutliers);
                app.BeforeDataTable = app.BeforeDataTable(includeSamples,:);
                if isscalar(app.BatchListToPlot)  
                    app.BatchExploredLabel.Text = ['Exploring Batch ',num2str(app.BatchListToPlot)];
                else
                    app.BatchExploredLabel.Text = ['Exploring a subset of ',num2str(numel(app.BatchListToPlot)),' batches'];
                end
            end
            
        
        end


        function selectcleanPeaks(app)

            if app.FilterBankSwitch.Value == true
                rsdThresh = app.QCRSDKnob.Value;
                sampleThresh = app.SampleRSDKnob.Value;
                dratioThresh = app.DRatioKnob.Value;
                bratioThresh = app.BlankRatioKnob.Value;
                if rsdThresh == 100, rsdThresh = 2000; end
                if dratioThresh == 100, dratioThresh = 2000; end
                if bratioThresh == 100, bratioThresh = 20000; end
            else
                rsdThresh = 2000;
                sampleThresh = 0;
                dratioThresh = 2000;
                bratioThresh = 2000;
            end

            selectBatches(app);

            ubatch = unique(app.PlotDataTable.Batch);
            NumOfBatch = numel(ubatch);
            for i = 1:NumOfBatch
                temp = app.PlotFeatureTable.(['blankRatioB',num2str(ubatch(i))]);
                temp(isnan(temp)) = 0.1;
                temp(temp < 0.1) = 0.1;
                app.PlotFeatureTable.(['blankRatioB',num2str(ubatch(i))]) = temp;
            end
            app.PlotFeatureTable.blankRatio(isnan(app.PlotFeatureTable.blankRatio)) = 0.1;
            app.PlotFeatureTable.blankRatio(app.PlotFeatureTable.blankRatio < 0.1) = 0.1;
            app.PlotFeatureTable.blankRatioMax(isnan(app.PlotFeatureTable.blankRatioMax)) = 0.1;
            app.PlotFeatureTable.blankRatioMax(app.PlotFeatureTable.blankRatioMax < 0.1) = 0.1;
            app.PlotFeatureTable.blankRatioMedian(isnan(app.PlotFeatureTable.blankRatioMedian)) = 0.1;
            app.PlotFeatureTable.blankRatioMedian(app.PlotFeatureTable.blankRatioMedian < 0.1) = 0.1;
            app.PlotFeatureTable.blankRatio(isnan(app.PlotFeatureTable.blankRatio)) = 0.1;
            app.PlotFeatureTable.blankRatio(app.PlotFeatureTable.blankRatio < 0.1) = 0.1;

            app.PlotFeatureTable.bestBatch = ones(height(app.PlotFeatureTable),1)*-1;

            if isscalar(app.BatchListToPlot)
                batchNum = app.BatchListToPlot;              
                app.PlotFeatureTable.qcRSD = app.PlotFeatureTable.(['qcRSDB',num2str(batchNum)]);
                app.PlotFeatureTable.qcRSDlower95CI = app.PlotFeatureTable.(['qcRSDlower95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.qcRSDupper95CI = app.PlotFeatureTable.(['qcRSDupper95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.sampleRSD = app.PlotFeatureTable.(['sampleRSDB',num2str(batchNum)]);
                app.PlotFeatureTable.sampleRSDlower95CI = app.PlotFeatureTable.(['sampleRSDlower95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.sampleRSDupper95CI = app.PlotFeatureTable.(['sampleRSDupper95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.refRSD = app.PlotFeatureTable.(['refRSDB',num2str(batchNum)]);
                app.PlotFeatureTable.refRSDlower95CI = app.PlotFeatureTable.(['refRSDlower95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.refRSDupper95CI = app.PlotFeatureTable.(['refRSDupper95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.dRatio = app.PlotFeatureTable.(['dRatioB',num2str(batchNum)]);
                app.PlotFeatureTable.blankRatio = app.PlotFeatureTable.(['blankRatioB',num2str(batchNum)]);                
                app.PlotDataTable = app.PlotDataTable(app.PlotDataTable.Batch == batchNum,:);
                app.PlotFeatureTable.bestBatch = ones(height(app.PlotFeatureTable),1)*batchNum;
                NumOfBatch = 1;
                ubatch = batchNum;
                app.FilterBankModeDropDown.Value = 'Complete';
            end 
    
            for i = 1:NumOfBatch
                qcRSDi(:,i) = app.PlotFeatureTable.(['qcRSDB',num2str(ubatch(i))]);
                refRSDi(:,i) = app.PlotFeatureTable.(['refRSDB',num2str(ubatch(i))]);
                sampleRSDi(:,i) = app.PlotFeatureTable.(['sampleRSDB',num2str(ubatch(i))]);
                dRatioi(:,i)= app.PlotFeatureTable.(['dRatioB',num2str(ubatch(i))]);
                blankRatioi(:,i) = app.PlotFeatureTable.(['blankRatioB',num2str(ubatch(i))]);
            end
    
            switch app.FilterBankModeDropDown.Value
                case 'Complete'                            
                    qcRSDB = app.PlotFeatureTable.qcRSD;                    
                    sampleRSDB = app.PlotFeatureTable.sampleRSD;
                    dRatioB = app.PlotFeatureTable.dRatio;
                    blankRatioB = app.PlotFeatureTable.blankRatio;
                case 'Median'                   
                    qcRSDB = median(qcRSDi,2,"omitmissing");
                    sampleRSDB = median(sampleRSDi,2,"omitmissing");
                    dRatioB = median(dRatioi,2,"omitmissing");
                    blankRatioB = median(blankRatioi,2,"omitmissing");
                case 'Min'                    
                    qcRSDB = min(qcRSDi,[],2);
                    sampleRSDB = min(sampleRSDi,[],2);
                    dRatioB = min(dRatioi,[],2);
                    blankRatioB = min(blankRatioi,[],2);
                case 'Max'                  
                    qcRSDB = max(qcRSDi,[],2);
                    sampleRSDB = max(sampleRSDi,[],2);
                    dRatioB = max(dRatioi,[],2);
                    blankRatioB = max(blankRatioi,[],2);
                otherwise
                    temp = split(app.FilterBankModeDropDown.Value,'Batch');
                    batchNumFilter = str2double(temp{2});
                    qcRSDB = app.PlotFeatureTable.(['qcRSDB',num2str(batchNumFilter)]);
                    sampleRSDB = app.PlotFeatureTable.(['sampleRSDB',num2str(batchNumFilter)]);
                    dRatioB = app.PlotFeatureTable.(['dRatioB',num2str(batchNumFilter)]);
                    blankRatioB = app.PlotFeatureTable.(['blankRatioB',num2str(batchNumFilter)]);
            end
            

            if ~app.CleanFilterOnQC.Value  
                    qcRSDB = app.PlotFeatureTable.refRSD; %% Note this is intentional filter by refRSD   
                    sampleRSDB = app.PlotFeatureTable.sampleRSD;
                    dRatioB = app.PlotFeatureTable.dRatio;
                    blankRatioB = app.PlotFeatureTable.blankRatio;    
                   % sampleRSDB = median(sampleRSDi,2,"omitmissing");
                   % dRatioB = median(dRatioi,2,"omitmissing");
                   % blankRatioB = median(dRatioi,2,"omitmissing");
            end


            X = app.PlotDataTable{app.PlotDataTable.Sample,app.PlotFeatureTable.UID};

            app.anyNAN = anynan(X);
            if ~app.anyNAN
                app.SampleFilterPercentEditField.Enable = "off";
                app.SampleFilterDropDown.Enable = "off";
                app.MissingValueImputationButtonGroup.Enable = "off";
                app.missingperfeatureLabel.Enable = "off";
            else
                app.SampleFilterPercentEditField.Enable = "on";
                app.SampleFilterDropDown.Enable = "on";
                app.MissingValueImputationButtonGroup.Enable = "on";
                app.missingperfeatureLabel.Enable = "on";
            end

            
            goodFeatures = true(height(app.PlotFeatureTable),1);
            if app.anyNAN
                if isscalar(app.BatchListToPlot)
                    totalPercentMissing = 100 * sum(isnan(X))./height(X);
                    goodFeatures = totalPercentMissing' <= app.SampleFilterPercentEditField.Value;
                else
                    batchSample = app.PlotDataTable.Batch(app.PlotDataTable.Sample);                
                    for i = 1:NumOfBatch
                        Xb = X(batchSample == ubatch(i),:);
                        batchPercentMissing(:,i) = 100 * sum(isnan(Xb))./height(Xb);
                    end
                    batchKeep = batchPercentMissing <= app.SampleFilterPercentEditField.Value;
                    switch app.SampleFilterDropDown.Value
                        case 'EveryBatch'
                            goodFeatures = all(batchKeep,2);
                        case 'AnyBatch'
                            goodFeatures = any(batchKeep,2);
                        otherwise % Complete
                            totalPercentMissing = 100 * sum(isnan(X))./height(X);
                            goodFeatures = totalPercentMissing' <= app.SampleFilterPercentEditField.Value;                           
                    end
                end
            end
            numGoodFeature = sum(goodFeatures);
            app.featuresremainingLabel.Text = sprintf('# features remaining: %u',numGoodFeature);

            
            cleanPeaks = qcRSDB < rsdThresh & sampleRSDB > sampleThresh & dRatioB < dratioThresh & blankRatioB < bratioThresh & goodFeatures;
            

            numcleanPeaks = sum(cleanPeaks);
            app.CleanFeaturesRemainingLabel.Text = sprintf('# clean features: %u',numcleanPeaks);
            app.PlotFeatureTable.cleanPeaks = cleanPeaks;
            app.FeatureX.cleanPeaks = cleanPeaks;

            P1 = app.PlotFeatureTable(goodFeatures,:);
            list = setdiff(app.PlotFeatureTable.UID,P1.UID);
            app.PlotDataTable(:,list) = [];
            app.PlotFeatureTable = P1;
              
        end
              
        function ResetCleanDials(app)

            ResetButtonPushed(app);
            app.BatchExploredLabel.Text = 'Exploring ALL batches';
            app.TabGroup2.SelectedTab = app.FilterTab;
            if isequal(app.FlashTimer.Running, 'on')
                stop(app.FlashTimer); % Stop the flashing
                app.SendButton.BackgroundColor = [0.96,0.96,0.96];
                app.IsTabGroup2Disabled = false;
            end


            app.baseConfig.CleanMissingSampleFilterPercentage = app.importedConfig.CleanMissingSampleFilterPercentage;
            app.baseConfig.CleanFilterBankMode = app.importedConfig.CleanFilterBankMode;
            app.baseConfig.CleanFilterOnQC = app.importedConfig.CleanFilterOnQC;
            app.baseConfig.CleanMissingSampleFilterMode = app.importedConfig.CleanMissingSampleFilterMode;

            app.baseConfig.CleanFilterQCRSD = app.importedConfig.CleanFilterQCRSD;
            app.baseConfig.CleanFilterSampleRSD = app.importedConfig.CleanFilterSampleRSD;
            app.baseConfig.CleanFilterDRatio = app.importedConfig.CleanFilterDRatio;
            app.baseConfig.CleanFilterBlankRatio = app.importedConfig.CleanFilterBlankRatio;
            
            
            try
                setConfigFields(app,Config = app.importedConfig,mode='clean');
            catch
                 mydlg = uiconfirm(app.UIFigure,'Fatal error. Could not set config fields. Better call David.','Confirm Close','Icon','error',"Options",{'OK'});
                waitfor(mydlg);
                UIFigureCloseRequest(app);
            end

            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            
            app.FilterBankSwitch.Value = false;    
            app.CleanFilterOnQC.Value = true; 
            setFilterKnobColors(app);
            
%            app.BatchDropDown.Value = -1;
            app.SwitchBeforeAfter.Value = true;
            
            app.IncludeBlankSamplesCheckBox.Value = false;
            app.IncludeReferenceSamplesCheckBox.Value = false;
            app.IncludeQCSamplesCheckBox.Value = true;
        
            
            app.YAxisDropDown.Items = {'PC 1', 'PC 2'};
            app.YAxisDropDown.ItemsData = [1,2];
            app.XAxisDropDown.Items = {'PC 1', 'PC 2'};
            app.XAxisDropDown.ItemsData = [1,2];
            app.YAxisDropDown.Value = 2;
            app.XAxisDropDown.Value = 1;
            app.ConfidenceIntervalsCheckBoxA.Value = true;
            app.ConfidenceIntervalsCheckBoxB.Value = true;
            app.DataPointsCheckBox.Value = true;               
            app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of SampleType';
            app.ConfidenceIntervalsCheckBoxB.Visible = "off"; 
            app.LegendCheckBox.Value = true;
            app.LabelbyDropDown.Value = 'SampleType';
            app.PCStatsSwitch.Value = true;

            app.QCCheckBox.Value = true;
            app.RefCheckBox.Value = false;
            app.SortbySwitch.Value = 'sample';
     
            app.SmoothBLANKslider.Value = 20;
            app.SmoothDRATIOslider.Value = 20;
            app.SmoothRSDslider.Value = 20;

            app.SwitchDRATIOpdf.Value = 'histogram';
            app.SwitchRSDpdf.Value = 'histogram';
            app.SwitchBlankpdf.Value = 'histogram';

            app.SwitchBlankPlot.Value = 'all';
            app.SwitchRSDPlot.Value = 'all';
            app.SwitchDratioPlot.Value = 'all';
            try
                app.RSDDropDown.Value = 'qcRSD';
            catch
                app.RSDDropDown.Value = 'sampleRSD';
            end

            app.ImperialToolTipLabel.Visible = "off";
            app.ComparisonToolTipLabel.Visible = "off";
            app.ForestToolTipLabel.Visible = "off";

            app.ForestBootNumEditField.Value = 100;
            app.ForestEnabled = true;
            app.ForestDisableButton.Text = 'Disable';
            app.ForestFilteredOnlyCheckBox.Value = false;
            app.ForestQCRefSwitch.Value = true;

            app.AlsosavescaledimputeddataCheckBox.Value = false;

            app.ForestEnabled = true;
            app.highlightUID = -1;
            
        end

         function CleanAndPlot(app)
            
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            switchAllPCAswtiches(app,'on');
            drawnow();
            try
                if app.CleanFilterOnQC.Value
                    if app.noQCflag
                        baseException = MException('CleanExplr:NoQC','For exploration there has to be a at least one QC in every batch!');
                        throw(baseException);
                    end    
                else
                    if app.noREFflag
                        baseException = MException('CleanExplr:NoRef','For exploration, there has to be one or more Reference values per batch!');
                        throw(baseException);
                    end 
                end
                   
                selectcleanPeaks(app);
                if height(app.PlotFeatureTable) < 0.01*height(app.FeatureX)
                    message1 = ['You only have ',num2str(height(app.PlotFeatureTable)),' features remaining. Consider increasing the %missing per feature threshold'];
                    selection = uiconfirm(app.UIFigure,message1,'Low Number of Features','Icon','warning','Options',{'Change Filter','Continue'});
                    waitfor(selection)
                    if strcmp(selection,'Change Filter')
                        ClearPlots(app);
                        set(app.UIFigure, 'Pointer','arrow');
                        switchAllPanelsA(app,'on');
                        switchAllPanelsB(app,'on');
                        drawnow();
                        return;        
                    end
                end
                plotForestRSD(app);
                plotImperialRSD(app);
                plotCompareRSD(app);               
                RSDPlotValueChanged(app)           
                DratioPlotValueChanged(app)
                BlankPlotValueChanged(app)
                plotGammaHistogam(app); 
                if app.highlightUID ~= -1
                    HighlightAllTabs(app,app.highlightUID);
                end
                try
                    calcAndPlotPCA(app);
                catch ME      
                    cla(app.UIAxesPCA);
                    app.YAxisDropDown.Items = {'PC 1', 'PC 2'};
                    app.YAxisDropDown.ItemsData = [1,2];
                    app.XAxisDropDown.Items = {'PC 1', 'PC 2'};
                    app.XAxisDropDown.ItemsData = [1,2];
                    app.YAxisDropDown.Value = 2;
                    app.XAxisDropDown.Value = 1;
                    app.UIAxesPCA.Title.String = 'Principal Component Analysis';
                    ylabel(app.UIAxesPCA,'PC2');
                    xlabel(app.UIAxesPCA,'PC1');
                    app.PCAmodel = [];
                    switchAllPCAswtiches(app,'off');
                    baseException = MException('CleanExplr:PCA',"PCA failure. Possibly due to too few data or imputation method. Consider changing the filter settings.");
                    throw(baseException);
                end

           
            catch ME
                %ClearPlots(app);
                uialert(app.UIFigure,ME.message,'CleanAndPlot Failure','Icon','error');
                %app.CleanExplorePanel.Enable = "off";
                %app.SavePanel.Enable = "on";
                %switchAllPanelsA(app,'on');
                %set(app.UIFigure, 'Pointer','arrow');
                %return;
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
         end

         function RePlotRSD(app)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            try
                plotForestRSD(app);
                plotImperialRSD(app);
                plotCompareRSD(app);
            catch ME
                uialert(app.UIFigure,ME.message,'RePlotRSD Failure','Icon','error');
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
         end

        function ClearPlots(app)
            cla(app.UIAxesRSDpdf);
            cla(app.UIAxesDRATIOpdf);
            cla(app.UIAxesBLANKpdf);
            cla(app.UIAxesQCRSCpdf);
            cla(app.UIAxesPCA);
            cla(app.UIAxesPVE);
            cla(app.UIAxesPVE2);
            cla(app.UIAxesImperialRSD);
            cla(app.UIAxesRSDComparison);

            app.UIAxesPCA.Title.String = 'Principal Component Analysis';
            ylabel(app.UIAxesPCA,'PC2');
            xlabel(app.UIAxesPCA,'PC1');
            app.CleanFeaturesRemainingLabel.Text = '# clean features:';  
            app.featuresremainingLabel.Text = '# features remaining:';
            app.MPQLabel.Text = sprintf('MPQ:');
            app.MPQ95Label.Text = sprintf('MPQ<sub>95</sub>:');
            %app.MPQ95Label.Text = 'MPQ_{95}';
            
            app.QCCorrectionEnginePanel.Title = sprintf('QC Correction Engine:');
            app.PCAmodel = [];
        end

        
        function SaveData(app,Table,TableName,suffix)            
            try
                if strcmp(app.FileType,'xlsx')  
                    d = uiprogressdlg(app.UIFigure,'Title',['Saving ',[TableName,suffix]]);
                    drawnow
                    pause(0.2);                    
                    xlSheetNames = sheetnames(app.ProjectName);
                    sheetname = [TableName,suffix];
                    d.Message = ['Saving: ',sheetname];
                    if ismember(sheetname,xlSheetNames)
                        selection = uiconfirm(app.UIFigure,['Overwrite Sheet ',sheetname,'?'],'Confirm DataTable Action','Icon','warning');
                        if strcmp(selection,'Cancel')
                            return;
                        end
                    end
                    d.Value = 1/2;
                    pause(0.2);
                    savefile = fullfile(app.WorkingDirectory,app.ProjectName);
                    writetable(Table,savefile,'Sheet',sheetname,'WriteMode','overwritesheet');
                    d.Value = 1;
                    pause(0.2);
                    close(d);
                elseif strcmp(app.FileType,'csv')                    
                    temp = split(TableName,'.');
                    filename = [temp{1},suffix,'.csv'];
                    d = uiprogressdlg(app.UIFigure,'Title',['Saving ',filename]);
                    drawnow
                    pause(0.2);
                    d.Message = ['Saving: ',filename];
                    if isfile(filename)
                        selection = uiconfirm(app.UIFigure,['Overwrite ',filename,'?'],'Confirm DataTable Action','Icon','warning');
                        waitfor(selection)
                        if strcmp(selection,'Cancel')
                            return;
                        end
                    end
                    d.Value = 1/2;
                    pause(0.2);
                    savefile = fullfile(app.WorkingDirectory,filename);
                    writetable(Table,savefile);
                    d.Value = 1;
                    pause(0.2);
                    close(d);
                end
            catch baseException
                 uialert(app.UIFigure,baseException.message,'Failed to save Data Table','Icon','error');
            end       
            
            
        end
        

        function switchAllPCAswtiches(app,onoff)
            app.IncludeQCSamplesCheckBox.Enable = onoff;
            app.IncludeReferenceSamplesCheckBox.Enable = onoff;
            app.IncludeBlankSamplesCheckBox.Enable = onoff;
            app.PCStatsSwitch.Enable = onoff;
            app.PopOutButton.Enable = onoff;
            app.LabelbyDropDown.Enable = onoff;
            app.VariationExplainedbyeachPCLabel.Enable = onoff;
            app.DataPointsCheckBox.Enable = onoff;
            app.ConfidenceIntervalsCheckBoxB.Enable = onoff;
            app.ConfidenceIntervalsCheckBoxA.Enable = onoff;
            app.LegendCheckBox.Enable = onoff;
            app.YAxisDropDown.Enable = onoff;
            app.XAxisDropDown.Enable = onoff;
        end

        function switchAllPanelsA(app,onoff)
            app.IncomingDataPanel.Enable = onoff;
            app.QCCorrectionParametersPanel.Enable = onoff;
            app.DefaultConfigurationPanel.Enable = onoff;
            app.FeatureInclusionPreFilterPanel.Enable = onoff;
            app.QCCorrectionEnginePanel.Enable = onoff;
        end

        function switchAllPanelsB(app,onoff)
            app.SavePanel.Enable = onoff;
            app.CleanExplorePanel.Enable = onoff;
        end

        function setFilterKnobColors(app)
            if app.FilterBankSwitch.Value == true  
                if app.CleanFilterOnQC.Value
                    colr = [1,0,0];
                else
                    colr =  [219,171,13]/255;
                end
                app.FilterBankLamp.Color = colr;
                app.SampleRSDKnob.FontColor = colr;
                app.QCRSDKnob.FontColor = colr;
                app.BlankRatioKnob.FontColor = colr;
                app.DRatioKnob.FontColor = colr;
                app.SampleRSDKnobLabel.FontColor = colr;
                app.QCRSDKnobLabel.FontColor = colr;
                app.BlankRatioKnobLabel.FontColor = colr;
                app.DRatioKnobLabel.FontColor = colr;
            else
                app.FilterBankLamp.Color = [0.50,0.50,0.50];
                app.FilterBankSwitch.FontColor = [0,0,0];
                app.PanelKnobs.BackgroundColor = [0.94,0.94,0.94];
                app.SampleRSDKnob.FontColor = [0.65,0.65,0.65];
                app.QCRSDKnob.FontColor = [0.65,0.65,0.65];
                app.BlankRatioKnob.FontColor = [0.65,0.65,0.65];
                app.DRatioKnob.FontColor = [0.65,0.65,0.65];
                app.SampleRSDKnobLabel.FontColor = [0.65,0.65,0.65];
                app.QCRSDKnobLabel.FontColor = [0.65,0.65,0.65];
                app.BlankRatioKnobLabel.FontColor = [0.65,0.65,0.65];
                app.DRatioKnobLabel.FontColor = [0.65,0.65,0.65];
            end
        end

    function checkBetweenBatchValid(app)

        if app.noQCflag
            if ~strcmp(app.baseConfig.WithinBatchCorrectionMode,'Sample')
                app.baseConfig.WithinBatchCorrectionMode = 'Sample';                               
                app.WithinBatchCorrectionModeDropDown.Value = 'Sample';
                uialert(app.UIFigure,'There are no QCs in a least one batch, so switching to within batch "Sample" correction.','Config. correction','Icon','warning');
            end
            if ~strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Sample')
                app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
                app.BetweenBatchCorrectionModeDropDown.Value = 'Sample'; 
                uialert(app.UIFigure,'There are no QCs in a least one batch, so switching to between batch "Sample" correction.','Config. correction','Icon','warning');
            end
        end

        if app.lowQCflag           
            if ~strcmp(app.baseConfig.WithinBatchCorrectionMode,'Median')
                app.baseConfig.WithinBatchCorrectionMode = 'Median';                               
                app.WithinBatchCorrectionModeDropDown.Value = 'Median'; 
                uialert(app.UIFigure,'There are less than 3 QCs in a least one batch, so switching to within batch "Median" correction.','Config. correction','Icon','warning');
            end
        end

        if strcmp(app.baseConfig.WithinBatchCorrectionMode,'Sample')
            if ~strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Sample')
                app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
                app.BetweenBatchCorrectionModeDropDown.Value = 'Sample';
                uialert(app.UIFigure,'If within-batch correction is set to "Sample", then between-batch correction must also be set to "Sample".','Config. correction','Icon','warning');
            end
        end

        if strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Reference')
            if app.noREFflag
                app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
                app.BetweenBatchCorrectionModeDropDown.Value = 'Sample';
                uialert(app.UIFigure,'There are to few Reference samples in a least one batch, so switching to between-batch "Sample" correction.','Config. correction','Icon','warning');
                    
            end
        end

        if ~strcmp(app.baseConfig.BlankRatioMethod,'QC') && app.noQCflag
            app.baseConfig.BlankRatioMethod = 'Median';
            app.CleanBlankRatioMethodDropDown.Value = 'Median';
            uialert(app.UIFigure,'There are to few QCs in a least one batch to calculate a QC based blankRatio, so switching to "Median".','Config. correction','Icon','warning');
        end
    end


    function saveConfig(app)
        temp = split(app.ProjectName,'.');
        filename = [temp{1},'_Config.json'];
        if isfile(filename)
            selection = uiconfirm(app.UIFigure,['Overwrite ',filename,'?'],'Confirm','Icon','warning');
            waitfor(selection)
            if strcmp(selection,'Cancel')
               return;
            end
        end
        configExport = getBaseConfig(app);
        try
            configExport = validateConfig(configExport);
            configExport.WorkingDirectory = replace(configExport.WorkingDirectory,getenv('HOME'),"~");
        catch
            mydlg = uiconfirm(app.UIFigure,'QCMXP config file is corrupted. Better call David.','Confirm','Icon','error',"Options",{'OK'});
            waitfor(mydlg);
            return;
        end

        try
            app.jsonTxt = jsonencode(configExport,'PrettyPrint',true);
            %cfg = jsondecode(app.jsonTxt);
            %jsontxt = jsonencode(cfg,'PrettyPrint',true);
            fid=fopen(fullfile(app.WorkingDirectory,filename),'w');
            %fprintf(fid,jsontxt);
            fprintf(fid,app.jsonTxt);
            fclose(fid);
        catch
            uialert(app.UIFigure,['Failed to save ',filename],'Error','Icon','error');
        end      
    end

    function configExport = getBaseConfig(app)
        configExport.WorkingDirectory = app.WorkingDirectory;
        configExport.FileType = app.FileType;
        configExport.ProjectName = app.ProjectName;
        configExport.DataTable = app.DataName;
        configExport.FeatureTable = app.FeatureName;      
        configExport.MissingQCPreFilterMode = app.PreFilterDropDown.Value;
        configExport.MissingQCPreFilterBatchN = app.NbatchEditField.Value;
        configExport.MissingQCPreFilterPercentage = app.missingQCperFeatureEditField.Value;
        configExport.LOQfilter = app.QCLOQmissingCheckBox.Value;
        configExport.RelativeLOQ = app.LOQalphaEditField.Value;
        configExport.LogTransformedCorrection = app.LogTransformedCorrectionCheckBox.Value;
        configExport.RemoveZeros = app.RemoveZerosCheckBox.Value;
        configExport.OutlierDetectionMethod = app.QCOutlierDetectionMethodDropDown.Value;
        configExport.OutlierDetectionCI = app.QCOutlierDetectionCIEditField.Value;
        configExport.OutlierReplacementStrategy = app.QCOutlierReplacementStrategyDropDown.Value;
        configExport.WithinBatchCorrectionMode = app.WithinBatchCorrectionModeDropDown.Value;
        configExport.BetweenBatchCorrectionMode = app.BetweenBatchCorrectionModeDropDown.Value;
        configExport.QCRSCcvMethod = app.QCRSCCrossValidationMethodDropDown.Value;
        configExport.QCRSCmcReps = app.QCRSCMonteCarlorepetitionsEditField.Value;              
        configExport.BlankRatioMethod = app.BlankRatioMethodDropDown.Value;
        configExport.ParallelProcess = app.ParallelProcessingCheckBox.Value;
        configExport.CorrectedFileSuffix = app.CorrectedSuffixEditField.Value;
        configExport.CleanMissingSampleFilterMode = app.SampleFilterDropDown.Value;
        configExport.CleanMissingSampleFilterPercentage = app.SampleFilterPercentEditField.Value;
        configExport.CleanFilterBankMode = app.FilterBankModeDropDown.Value; 
        configExport.CleanFilterOnQC = app.CleanFilterOnQC.Value; 
        configExport.CleanFilterQCRSD = app.QCRSDKnob.Value; 
        configExport.CleanFilterSampleRSD = app.SampleRSDKnob.Value;
        configExport.CleanFilterDRatio = app.DRatioKnob.Value;
        configExport.CleanFilterBlankRatio = app.BlankRatioKnob.Value;
        configExport.CleanFileSuffix = app.SaveCleanSuffixEditField.Value;
        configExport.CleanLogTransform = app.Log10TransformCheckBox.Value;
        configExport.CleanScaleMethod = app.baseConfig.CleanScaleMethod;
        configExport.CleanImputationMethod = app.baseConfig.CleanImputationMethod;
        configExport.CleanImputationK = app.kSpinner.Value;
    end
        
    function checkStuff(app)
        try
            checkBetweenBatchValid(app);
        catch ME
            uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
        end
        
        if ~ismember(app.baseConfig.WithinBatchCorrectionMode,{'Spline','Spline-C2','Spline-C4','Spline-C6'})                   
            app.QCRSCCrossValidationMethodDropDown.Visible = "off";
            app.QCRSCCVMethodLabel.Enable = "off";
            app.QCRSCMonteCarlorepetitionsEditField.Visible = "off";
            app.QCRSCMCRepsLabel.Enable = "off";
        else
            app.QCRSCCrossValidationMethodDropDown.Visible = "on";
            app.QCRSCCVMethodLabel.Enable = "on";
            if strcmp(app.baseConfig.QCRSCcvMethod,'Leaveout')
                app.QCRSCMonteCarlorepetitionsEditField.Visible = "off";
                app.QCRSCMCRepsLabel.Enable = "off";
            else
                app.QCRSCMonteCarlorepetitionsEditField.Visible = "on";
                app.QCRSCMCRepsLabel.Enable = "on";
            end
        end
        if app.LogTransformedCorrectionCheckBox.Value
            app.RemoveZerosCheckBox.Value = true;
            app.baseConfig.RemoveZeros = app.RemoveZerosCheckBox.Value;
        end

        
        app.baseConfig = validateConfig(app.baseConfig);
        
    end

    function loadTable(app)
        app.WorkingDirectory = app.baseConfig.WorkingDirectory;
        app.ProjectName = app.baseConfig.ProjectName;
        app.DataName = app.baseConfig.DataTable;
        app.FeatureName = app.baseConfig.FeatureTable;
        app.FileType = app.baseConfig.FileType;
        
       cd(app.WorkingDirectory);
       switch app.FileType
           case 'csv'
                [app.DataTable,app.FeatureTable] = readDataCSVapp(app.DataName,app.FeatureName,fighandle=app.UIFigure);
           case 'xlsx'
                [app.DataTable,app.FeatureTable] = readDataXLapp(app.ProjectName,DataSheet=app.DataName,FeatureSheet=app.FeatureName,fighandle=app.UIFigure);                        
           otherwise
                baseException = MException('QCRSC:NoSuchFileType',"FileType must either be 'csv' or 'xlsx'.");
                throw(baseException)
       end
       app.ImportSuccess = true;

    end

        
    function loadTables(app,options)

        arguments
            app
            options.gui = false;
        end

        switchAllPanelsA(app,'off');
        switchAllPanelsB(app,'off');
        app.LaunchFeatureExplorer.Enable = 'off';
        app.LaunchAlgorithmExplorer.Enable = 'off';
        
        if ~isempty(app.SpreadsheetApp) && isvalid(app.SpreadsheetApp)
            delete(app.SpreadsheetApp);
            app.ViewFeatureTableButton.Enable = 'on';
        end

        app.ImportSuccess = false;
        if options.gui
            app.ImportApp = ImportDataFeatureFile(app);
            waitfor(app.ImportApp);
            figure(app.UIFigure);
            app.baseConfig.WorkingDirectory = app.WorkingDirectory;
            app.baseConfig.ProjectName = app.ProjectName;
            app.baseConfig.DataTable = app.DataName;
            app.baseConfig.FeatureTable = app.FeatureName;
            app.baseConfig.FileType = app.FileType;
        else
            try
                loadTable(app);
            catch              
                mydlg = uiconfirm(app.UIFigure,'Failed to autoload Data/Feature tables. Do you want to try loading manually?','Confirm Close','Icon','error','Options',{'Yes','No'});
                waitfor(mydlg);
                if strcmp(mydlg,'Yes')
                    app.WorkingDirectory = '';
                    app.ProjectName = '';
                    app.DataName = '';
                    app.FeatureName = '';
                    app.FileType = '';
                    loadTables(app,gui=true);
                end
            end
        end
        
        app.IncomingDataPanel.Enable = 'on';
        app.LaunchAlgorithmExplorer.Enable = 'on';
        %app.QCCorrectionParametersPanel.Enable = 'on';
        app.DefaultConfigurationPanel.Enable = 'on';
        if app.ImportSuccess               
            % if app.complete
            %     uialert(app.UIFigure,'Resetting Configuration to default','Config Update','Icon','info');
            %     setDefaultConfig(app);
            % end
            delete(app.PopOutPlotApp);
            delete(app.SpreadsheetApp);
            delete(app.configExplorerApp);
            delete(app.featureExplorerApp);
            app.ViewFeatureTableButton.Enable = 'on';
            app.LaunchFeatureExplorer.Enable = 'on'; 
            app.drinkMeButton.Enable = 'on';
            cd(app.WorkingDirectory);
            app.complete = false;
            app.PlotFeatureTable = [];
            app.ForestFeatureTable = [];
            app.ForestStatsTableQC = [];
            app.ForestStatsTableREF = [];
            app.FeatureX = [];
            app.FeatureXS = [];
             app.FeatureXL = [];
            app.DataX = [];
            %ffile = fullfile(app.WorkingDirectory,app.ProjectName);
            app.UIFigure.Name =  ['Working Directory: ',app.WorkingDirectory];
            [~,minNumQCinAnyBatch] = checkNumberInBatch(app.DataTable,'QC');
            app.lowQCflag = minNumQCinAnyBatch < 3;
            app.noQCflag = minNumQCinAnyBatch < 1;
            [~,minNumREFinAnyBatch] = checkNumberInBatch(app.DataTable,'Reference');
            app.noREFflag = minNumREFinAnyBatch < 1;
            ResetData(app);
            ResetCleanDials(app);
            ClearPlots(app);
            preQCRSCclean(app);
            try
                checkBetweenBatchValid(app);
            catch ME
                throw(ME);
            end
        elseif ~isempty(app.DataTable)
            app.WorkingDirectory = app.oldConfig.WorkingDirectory;
            app.ProjectName = app.oldConfig.ProjectName;
            app.DataName = app.oldConfig.DataTable;
            app.FeatureName = app.oldConfig.FeatureTable;
            app.FileType = app.oldConfig.FileType;

            app.LaunchFeatureExplorer.Enable = 'on';                     
            app.FeatureInclusionPreFilterPanel.Enable = 'on';
            app.QCCorrectionEnginePanel.Enable = 'on';              
            app.QCCorrectionParametersPanel.Enable = 'on';
            app.DefaultConfigurationPanel.Enable = 'on';              
            if app.complete
                switchAllPanelsB(app,'on');
            end
            CleanAndPlot(app);
        end
        app.baseConfig.WorkingDirectory = app.WorkingDirectory;
        app.baseConfig.FileType = app.FileType;
        app.baseConfig.ProjectName = app.ProjectName;
        app.baseConfig.DataTable = app.DataName;
        app.baseConfig.FeatureTable = app.FeatureName;  
        end
        
        function  setDefaultConfig(app)
            app.jsonDefault = ['{' newline ...
                '"WorkingDirectory": "",' newline ...
                '"FileType": "",' newline ...
                '"ProjectName": "",' newline ...
                '"DataTable": "",' newline ...
                '"FeatureTable": "",' newline ...
                '"MissingQCPreFilterMode": "EveryBatch",' newline ...
                '"MissingQCPreFilterBatchN": 0,' newline ...
                '"MissingQCPreFilterPercentage": 20,' newline ...         
                '"LOQfilter": true,' newline ...
                '"RelativeLOQ": 1.2,' newline ...
                '"LogTransformedCorrection": true,' newline ...
                '"RemoveZeros": true,' newline ...
                '"OutlierDetectionMethod": "Percentile",' newline ...
                '"OutlierDetectionCI": 0.95,' newline ...
                '"OutlierReplacementStrategy": "NaN",' newline ...
                '"WithinBatchCorrectionMode": "Spline",' newline ...
                '"BetweenBatchCorrectionMode": "QC",' newline ...
                '"QCRSCcvMethod": "3-Fold",' newline ...
                '"QCRSCmcReps": 10,' newline ...
                '"BlankRatioMethod": "QC",' newline ...
                '"ParallelProcess": true,' newline ...
                '"CorrectedFileSuffix": "_QCRSC",' newline ...
                '"CleanMissingSampleFilterMode": "EveryBatch",' newline ...
                '"CleanMissingSampleFilterPercentage": 20,' newline ...
                '"CleanFilterBankMode": "Complete",' newline ...
                '"CleanFilterOnQC": true,' newline ...
                '"CleanFilterQCRSD": 100,' newline ...
                '"CleanFilterSampleRSD": 0,' newline ...
                '"CleanFilterDRatio": 100,' newline ...
                '"CleanFilterBlankRatio": 100,' newline ...
                '"CleanFileSuffix": "_Clean",' newline ...
                '"CleanLogTransform": true,' newline ...
                '"CleanScaleMethod": "Autoscale",' newline ...
                '"CleanImputationMethod": "KNNcolumn",' newline ...
                '"CleanImputationK": 3 ' newline ...
                '}'];
                
            app.configFilename = 'Default';
            app.jsonTxt = app.jsonDefault;
            app.jsonView = strsplit(app.jsonTxt,'\n')';
            app.baseConfig = jsondecode(app.jsonDefault);            
            %app.configFileTextArea.Value = app.jsonView;
            app.DefaultConfigurationPanel.Title = 'Default Configuration';
            
            
            try
                app.baseConfig = validateConfig(app.baseConfig);
                setConfigFields(app);         
            catch
                mydlg = uiconfirm(app.UIFigure,'Default QCMXP config file is corrupted. Better call David.','Confirm Close','Icon','error',"Options",{'OK'});
                waitfor(mydlg);
                UIFigureCloseRequest(app);
                return;
            end
        end

        function toggleButtonColor(app)
            % Check the current color and switch it
            if isequal(app.SendButton.BackgroundColor, app.OriginalColor)
                app.SendButton.BackgroundColor = app.FlashColor;
            else
                app.SendButton.BackgroundColor = app.OriginalColor;
            end
            % Use drawnow to ensure the display refreshes immediately
            drawnow
        end

        function  plotForestwithSlider(app,QCorREF)

            order = app.ForestIdx;
            window_size = app.forestWindowSize;
    
            switch QCorREF
                case 'QC'
                    ForestTable = app.ForestStatsTableQC;
                    sigCol = [0.85,0.1,0.1];
                    tt = 'qcRSD';
                case 'REF'
                    ForestTable = app.ForestStatsTableREF;
                    sigCol = [0.83,0.67,0.21];
                    tt = 'referenceRSD';
            end
    
    
            linkaxes([app.UIAxesForest,app.UIAxesPooledForest],'x');
            PoolTable = ForestTable(1:2,:);
            ForestTable = ForestTable(3:end,:);
            ForestTable = ForestTable(order,:);
            
            xlim_upper = 80;
            xlim_lower = -10;
            hold(app.UIAxesPooledForest,"off");
            hold(app.UIAxesForest,"off");
             
            n_studies = height(ForestTable);
            
            % Line of no effect (usually 0 for differences, 1 for ratios)
            line_of_no_effect = 0; 
            plot(app.UIAxesForest,[line_of_no_effect, line_of_no_effect], [0, n_studies+2], 'k--', 'LineWidth', 1.5);
            hold(app.UIAxesForest,"on");
            
            maxMarkerSize = 400;
            minMarkerSize = 20;
            
            if ismember('cleanPeaks', ForestTable.Properties.VariableNames)
                colCode = ForestTable.cleanPeaks+ForestTable.sig*10;
            else
                temp = ones(height(ForestTable),1);
                colCode = temp+ForestTable.sig*10;   
            end
               
            %green = [46,139,87]/255;
            grey = [47,79,79]/255;

            keys = [0, 1, 10, 11];
            %valuesMFC = {[0,0,0], [0,0.2,1], [0,0,0], sigCol}; % black, blue, sigCol
            valuesMFC = {[0,0,0], grey, [0,0,0], sigCol}; % black, blue, sigCol
            %valuesLC = {[0,0,0,0.1], [0,0.2,1,1], [0,0,0,0.1], [sigCol,1]}; % black, blue, sigCol
            valuesLC = {[0,0,0,0.1], [grey,1], [0,0,0,0.1], [sigCol,1]}; % black, blue, sigCol
            valuesMFA = {0.1, 1, 0.1, 1}; % black, blue, sigCol


            mfcMap = dictionary(keys, valuesMFC);
            lcMap = dictionary(keys, valuesLC);
            mfaMap = dictionary(keys, valuesMFA);
            
            mfc = cell2mat(mfcMap(colCode));
            %lc = cell2mat(lcMap(colCode));
            mfa = cell2mat(mfaMap(colCode));
            
            ylinedata = [2:n_studies+1;2:n_studies+1];
            xlinedata = [ForestTable.lowerCI, ForestTable.upperCI]';
            for i = 1:4
                keep = colCode == keys(i);
                if any(keep)
                    tempx = xlinedata(:,keep);
                    tempy = ylinedata(:,keep);
                    plot(app.UIAxesForest,tempx, tempy, '-', 'Color',valuesLC{i}, 'LineWidth', 1.5);
                    keep = tempx(2,:) > xlim_upper;
                    if any(keep)
                        scatter(app.UIAxesForest,xlim_upper, tempy(1,keep),50,valuesMFC{i},'filled','>','AlphaDataMapping','none','AlphaData',0.2,'MarkerFaceAlpha','flat','MarkerFaceColor','flat');
                    end
                    keep = tempx(1,:) < xlim_lower;
                    if any(keep)
                        scatter(app.UIAxesForest,xlim_lower, tempy(1,keep),50,valuesMFC{i},'filled','<','AlphaDataMapping','none','AlphaData',0.2,'MarkerFaceAlpha','flat','MarkerFaceColor','flat');
                    end
                end
            end
            
            %plot(ax,[ForestTable.lowerCI, ForestTable.upperCI]', temp, '-', 'Color', 'k', 'LineWidth', 1.5);
            ms = minMarkerSize + (ForestTable.cvA * (maxMarkerSize - minMarkerSize)) / 100;
            s = scatter(app.UIAxesForest,ForestTable.meanDeltaCV, 2:n_studies+1, ms, 's','MarkerFaceColor','flat','AlphaDataMapping','none','CData',mfc,'ButtonDownFcn',@(src,event)app.MouseClickForest(src,event,ForestTable));
            alpha(s,mfa);
            
            % Plot formatting ---
            ylim(app.UIAxesForest, [1, 1 + window_size]);
            %ylim(ax,[0, n_studies+2]);
            xlim(app.UIAxesForest,[xlim_lower, xlim_upper]);
            xlabel(app.UIAxesForest,['Delta %RSD (After ',char(8211),' Before)']);
            ylabel(app.UIAxesForest,'');
            yticks(app.UIAxesForest,2:n_studies+1);
            yticklabels(app.UIAxesForest,ForestTable.ShortName(1:end));
            app.UIAxesForest.YAxis.FontSize = 8;
            set(app.UIAxesForest, 'YDir', 'reverse'); % Invert Y-axis so Study 1 is at the top
            grid(app.UIAxesForest,'on');
            box(app.UIAxesForest,'on');
            
            
            % Plot the Pooled Effect Diamond ---
            % Diamond coordinates: [Left tip, Center, Right tip, Center, Bottom tip]
            X_diamond = [PoolTable.lowerCI(1), PoolTable.meanDeltaCV(1), PoolTable.upperCI(1), PoolTable.meanDeltaCV(1), PoolTable.lowerCI(1)];
            X_diamondX = [PoolTable.lowerCI(2), PoolTable.meanDeltaCV(2), PoolTable.upperCI(2), PoolTable.meanDeltaCV(2), PoolTable.lowerCI(2)];
            Y_diamond = [1, 1.5, 1, 0.5, 1];
            %Y_diamond = [n_studies+0.5, n_studies+1, n_studies+0.5, n_studies, n_studies+0.5];
            
            % Draw the diamond
            fill(app.UIAxesPooledForest,X_diamond, Y_diamond, 'k', 'FaceAlpha', 0, 'EdgeColor', 'k');
            hold(app.UIAxesPooledForest,"on");
            plot(app.UIAxesPooledForest,[line_of_no_effect, line_of_no_effect], [0, 2], 'k--', 'LineWidth', 1.5);
            if PoolTable.sig(2)
                fill(app.UIAxesPooledForest,X_diamondX, Y_diamond, sigCol, 'FaceAlpha', 0.5, 'EdgeColor', 'k','ButtonDownFcn',@(src,event)app.MouseClickPooled(src,PoolTable));
            else
                fill(app.UIAxesPooledForest,X_diamondX, Y_diamond, [0,0.2,1], 'FaceAlpha', 0.5, 'EdgeColor', 'k','ButtonDownFcn',@(src,event)app.MouseClickPooled(src,PoolTable));
            end
            
            
            xlim(app.UIAxesPooledForest,[xlim_lower, xlim_upper]);
            app.UIAxesPooledForest.XTick = app.UIAxesForest.XTick;
            app.UIAxesPooledForest.XAxis.TickLength = [0 0];
            ylim(app.UIAxesPooledForest,[0,2]);
            yticks(app.UIAxesPooledForest,1);
            yticklabels(app.UIAxesPooledForest,'Pooled Effect');
            grid(app.UIAxesPooledForest,'on');
            box(app.UIAxesPooledForest,'on');
            app.UIAxesPooledForest.PlotBoxAspectRatio = [20 1 1];
            title(app.UIAxesPooledForest,[tt,' : Unweighted Forest Plot']);
            
            app.UIAxesPooledForest.InnerPosition(1) = app.UIAxesForest.InnerPosition(1);
            app.UIAxesPooledForest.InnerPosition(3) = app.UIAxesForest.InnerPosition(3);
            app.UIAxesForest.ButtonDownFcn = @(src,event)app.MouseClick2(src);
            app.UIAxesPooledForest.ButtonDownFcn = @(src,event)app.MouseClick2(src);
            
            
            if window_size <= n_studies
                min_y = 1;
                max_y = n_studies;
                slider_max = max_y - window_size + 1; 
                sliderstep = [(window_size+1)/n_studies, (window_size+1)/n_studies];
                
                %fc = slider_max/(window_size);

                if app.highlightUID == -1
                    highlightLoc = slider_max;
                else
                    positionUID = find(ismember(ForestTable.UID,app.highlightUID));                
                    %highlightLoc = ((slider_max-1) * (find(ismember(ForestTable.UID,app.highlightUID)) - min_y)/(max_y-min_y))+1;
                    highlightLoc = round(((1 -slider_max) * (positionUID - min_y)/(max_y-min_y))+slider_max);
                    start_y = slider_max - highlightLoc + 1;
                    ylim(app.UIAxesForest, [start_y, start_y + window_size+1]);
                end
                
                a = uicontrol('Parent', app.ForestPlotTab, ...
                        'Style', 'slider', ...
                        'Units', 'pixels', ...
                        'Position', [15,120,25,460], ...
                        'Min', min_y, ...
                        'Max', slider_max, ...
                        'Value', highlightLoc, ...  
                        'SliderStep', sliderstep, ...
                        'Callback', @(src, event)app.scroll_callbackForest(src,window_size));
            end
            %'SliderStep', [1, window_size], ...   
            hold(app.UIAxesForest,"off");
            hold(app.UIAxesPooledForest,"off");
        end
        
        function scroll_callbackForest(app,slider_handle, window_size)
            % childObjects = findobj(slider_handle.Parent.Parent, 'UserData', -1);
            % delete(childObjects);
            % app.ForestToolTipLabel.Visible ="off";
            start_y = get(slider_handle, 'Max') - get(slider_handle, 'Value') + 1;
            %ylim(target_axes, [start_y, start_y + window_size]);
            ylim(app.UIAxesForest, [start_y, start_y + window_size+1]);
            % if ischar(app.highlightUID)
            %     highlightForestValue(app,app.highlightUID);
            % end
        end
        
        function MouseClickForest(app,~,event,statsData)                    
               idx = int32(round(event.IntersectionPoint(2)-1));
               app.highlightUID = statsData.UID{idx};
               HighlightAllTabs(app,app.highlightUID);
               
        end

        function highlightForestValue(app,UID)

               childObjects = findobj(app.ForestPlotTab, 'UserData', -1);
               delete(childObjects);

               if app.ForestQCRefSwitch.Value
                   statsData = app.ForestStatsTableQC(3:end,:);
                   label = 'qcRSD';
                   sigCol = [252, 164, 168]/255;
               else
                   statsData = app.ForestStatsTableREF(3:end,:);
                   label = 'refRSD';
                   sigCol = [237, 214, 138]/255; 
               end
               statsData = statsData(app.ForestIdx,:);

               idx = find(ismember(statsData.UID,UID)); 

               if app.ForestQCRefSwitch.Value
                   plotForestwithSlider(app,'QC'); 
               else
                   plotForestwithSlider(app,'REF');
               end

               %idx = source.YData-1;
               hold(app.UIAxesForest,"on");
               %scatter(app.UIAxesForest,statsData.meanDeltaCV(idx), idx+1, source.SizeData(idx) + 80, 'k', 'o', 'UserData',-1,'LineWidth',1.5);
               scatter(app.UIAxesForest,statsData.meanDeltaCV(idx), idx+1, 420, 'k', 'o', 'UserData',-1,'LineWidth',1.5);
               hold(app.UIAxesForest,"off");
               txt0 = sprintf('<b> SampleID:</b> %s: %s',statsData.UID{idx},statsData.Name{idx});
               txt1 = sprintf('\n<b> qcRSD (95%%CI):</b> %.2f%% (%.2f-%.2f) | <b>sampleRSD (95%%CI):</b> %.2f%% (%.2f-%.2f)', ...
               statsData.qcRSD(idx),statsData.qcRSDlower95CI(idx),statsData.qcRSDupper95CI(idx), ...
               statsData.sampleRSD(idx),statsData.sampleRSDlower95CI(idx),statsData.sampleRSDupper95CI(idx));
               if statsData.blankRatio == 0.1
                     txt2 = sprintf('\n<b> dRatio:</b> %.2f%% | <b>blankRatio:</b> < 0.1%%',statsData.dRatio(idx));                
               else
                     txt2 = sprintf('\n<b> dRatio:</b> %.2f%% | <b>blankRatio:</b> %.2f%%',statsData.dRatio(idx),statsData.blankRatio(idx));
               end
               txt3 = sprintf(' | <b>refRSD (95%%CI):</b> %.2f%% (%.2f-%.2f)',statsData.refRSD(idx),statsData.refRSDlower95CI(idx),statsData.refRSDupper95CI(idx));
               txt4 = sprintf(' | <b>qcMissing:</b> %.0f%% | <b>sampleMissing:</b> %.0f%%',statsData.qcMissingPerc(idx),statsData.sampleMissingPerc(idx));
               txt5 = sprintf('\n<b> %c%s (95%%CI):</b> %.2f%% (%.2f-%.2f)',916,label,statsData.meanDeltaCV(idx),statsData.lowerCI(idx),statsData.upperCI(idx));
               
               if any(statsData.refRSD)
                   app.ForestToolTipLabel.Text = [txt0,txt1,txt3,txt2,txt4,txt5];
               else
                   app.ForestToolTipLabel.Text = [txt0,txt1,txt2,txt4,txt5];
               end
               if statsData.cleanPeaks(idx)
                   if statsData.sig(idx)
                       app.ForestToolTipLabel.BackgroundColor = sigCol;
                   else
                       app.ForestToolTipLabel.BackgroundColor = [0.7,0.7,0.7];%[172,225,175]/255;%[30,144,255]/255;%[0.68,0.79,0.84];
                   end
               else
                   app.ForestToolTipLabel.BackgroundColor = [0.9,0.9,0.9];
               end
        
        
               app.ForestToolTipLabel.Visible = true;

        end
        
        function MouseClickForest2(app)
                childObjects = findobj(app.ForestPlotTab, 'UserData', -1);
                delete(childObjects);
                app.ForestToolTipLabel.Visible ="off";
                app.highlightUID = -1;
        end
        
        function MouseClickPooled(app,~,statsData)
           
               childObjects = findobj(app.ForestPlotTab, 'UserData', -1);
               delete(childObjects);
        
               hold(app.UIAxesPooledForest,"on");
               scatter(app.UIAxesPooledForest,statsData.meanDeltaCV(2), 1, 200, 'k', 'o', 'UserData',-1,'LineWidth',1.5);
               hold(app.UIAxesPooledForest,"off");
               txt0 = sprintf('<b> Pooled Effect </b>');
               txt5 = sprintf('\n<b> %cRSD (95%%CI):</b> %.2f%% (%.2f-%.2f)',916,statsData.meanDeltaCV(2),statsData.lowerCI(2),statsData.upperCI(2));
                   
               app.ForestToolTipLabel.Text = [txt0,txt5];
        
               % switch source.FaceColor(2)
               %     case 0.1
               %         app.ForestToolTipLabel.BackgroundColor = [252, 164, 168]/255; %red
               %     case 0.67
               %         app.ForestToolTipLabel.BackgroundColor = [237, 214, 138]/255; %yellow
               %     otherwise
               %         app.ForestToolTipLabel.BackgroundColor = [124, 139, 245]/255; %blue
               % end
               
               app.ForestToolTipLabel.Visible = true;
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)

            
            if ismac
                changeFont(app.UIFigure,'Avenir');
            elseif ispc
                changeFont(app.UIFigure,'MS Sans Serif');
            end
                 
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            left = screenWidth*0.1;
            bottom = screenHeight*0.05;
            width = screenWidth*0.8;
            height = screenHeight*0.9;            
            maxheight = screenHeight*0.95 - bottom;            
            %app.UIFigure.Position = [left bottom width height]; 
            app.UIFigure.Position = [left bottom 1313 907];
            if maxheight < 892
                uialert(app.UIFigure,'This app is designed to be run on a computer with a screen resolution of at least 1920x1080 pixels (100% scaling). The app with try its best to resize, but you will have to use the scroll bars to navigate all the features. Alternatively, change the resolution/scaling of your screen in Systems Settings.','Low Screen Resolution Warning','Icon','Warning');
                app.UIFigure.Position = [left bottom width height];
                app.UIFigure.Scrollable = 'on';
            end
            
            
            drawnow;
            
            app.UIFigure.Name = 'QCMXP';
            app.UIFigure.Icon= 'icon_512x512@2x.png';
            
            app.ViewFeatureTableButton.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.AbortButton.Enable = 'off';
            app.SavePanel.Enable = 'off';
            app.LaunchFeatureExplorer.Enable = 'off';
            app.drinkMeButton.Enable = 'off';
            
            app.FeatureInclusionPreFilterPanel.Enable = 'off';
            app.QCCorrectionParametersPanel.Enable = 'off';
            %app.DefaultConfigurationPanel.Enable = 'off';
            app.QCCorrectionEnginePanel.Enable = 'off';
            app.CleanExplorePanel.Enable = 'off';
            
            app.RunningLamp.Enable = 'off';
            app.CompleteLamp.Enable = 'off';
            app.ImportSuccess = true;
            
            app.complete = false;
                                    
            app.CleanFeaturesRemainingLabel.Text = '# clean features:'; 
            app.featuresremainingLabel.Text = '# features remaining:';
            app.MPQLabel.Text = 'MPQ:';                            
            app.MPQ95Label.Text = 'MPQ<sub>95</sub>:';
            %app.MPQ95Label.Text = 'MPQ_{95}';

            app.UIAxesPCA.Title.String = 'Principal Component Analysis';
            app.UIAxesPCA.Title.FontSize = 12;
            app.UIAxesPCA.Title.Color = 'r';
            app.QCRSCgTab.Title = ['QCRSC ',char(947)'];
            

            app.FileType = ''; 
            app.ProjectName = '';
            app.FilterBankSwitch.Value = false;
            %app.SwitchBeforeAfter.Visible = "on";
            

            % Colormaps: {'colorblind7','tolBright7','colorblind12','okabe&ito8','quoka', 'lines', 'colorcube', 'prism', 'moog', 'random'};                       
            app.cmapName = 'colorblind12';
            app.cmap = mapRcolors(app.cmapName);

            setDefaultConfig(app);   
            app.oldConfig = app.baseConfig;
            app.importedConfig = app.baseConfig;
            app.configImported = false;

            if ~isempty(app.DataTable)
                ResetData(app);
                ResetCleanDials(app);
                preQCRSCclean(app);
                CleanAndPlot(app);
            end
            app.PCAmodel = [];

            app.OriginalColor = app.SendButton.BackgroundColor; 
            app.FlashColor = [0.96,0.96,0.96]; % flash color (a shade of red)
            app.FlashTimer = timer('Period', 0.5, ...
            'ExecutionMode', 'fixedRate', ...
            'TimerFcn', @(~, ~) toggleButtonColor(app), ...
            'StopFcn', @(~, ~) set(app.SendButton, 'BackgroundColor', app.OriginalColor));

            app.CurrentTab2 = app.TabGroup2.SelectedTab;

            app.ForestEnabled = true;
            app.highlightUID = -1;


            % if ~isdeployed && isunix && ~ismac  
            if ~isdeployed
            % 
            %     mydlg = uiconfirm(app.UIFigure,'QC:MXP has detected that it has launched in the Matlab environment. When using Matlab-Online, performance wll be EXTREMELY SLOW. As such, very small data set to demonstrate the capabilities of the software will now be preloaded. Before processing any of your own data I suggest that you download the OSX or Windows standalone version and explore some of the larger test data sets provided on the GitHub repository. N.B. QC:MXP runs approximately 50xfaster as a standalone app.','Matlab Online: Demo Mode','Icon','warning',"Options",{'OK'});
            %     waitfor(mydlg);
            % 
               try
                    cd('../testdata')
                    file = 'QuickDemoMetabolomicsData_Config.json';
                    app.jsonTxt = fileread(file);
                    app.baseConfig = jsondecode(app.jsonTxt);
                    app.baseConfig.WorkingDirectory = pwd;
                    app.baseConfig = validateConfig(app.baseConfig);
                    app.jsonDefault = app.jsonTxt;
                    app.jsonView = strsplit(app.jsonTxt,'\n')';
                    app.configFilename = file;
                    loadTables(app);
                    app.importedConfig = app.baseConfig;
                    app.configImported = true;
                    app.DefaultConfigurationPanel.Title = app.configFilename;
                    setConfigFields(app); 
                catch
                    cd('~');
                end
            end
            

        end

        % Button pushed function: LOADDATAButton
        function LOADDATAButtonPushed(app, event)
            app.oldConfig = app.baseConfig;
            loadTables(app,gui=true);            
        end

        % Button pushed function: ViewFeatureTableButton
        function ViewFeatureTableButtonPushed(app, event)
            app.ViewFeatureTableButton.Enable = 'off';
            app.SpreadsheetApp = TidyFeatureTableView(app, app.FeatureTable);
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            app.oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView; 

            % if ismac
            %     scrsz = get(0,'ScreenSize');
            %     f = figure('Position', [scrsz(3)*0.45 scrsz(4)*0.6 0 0]);
            %     [file, path] = uigetfile('.json');               
            %     delete(f);
            %     pause(1);
            %     figure(app.UIFigure);
            % else       
              
                [file, path] = uigetfile('.json');             
                pause(1);
                figure(app.UIFigure);
            %end

               
            if ~isequal(file,0)
                app.jsonTxt = fileread(fullfile(path,file));
                app.baseConfig = jsondecode(app.jsonTxt);
                try
                    app.baseConfig = validateConfig(app.baseConfig);
                    app.jsonDefault = app.jsonTxt;
                    app.jsonView = strsplit(app.jsonTxt,'\n')';
                    app.configFilename = file;
                    
                    if ~isempty(app.baseConfig.ProjectName)
                        res = uiconfirm(app.UIFigure,'Do you want to also load the project data tables?','Confirm Action','Options',{'Yes','No'});
                        waitfor(res);
                        if strcmp(res,'No')
                            app.ImportSuccess = false;
                            app.baseConfig.WorkingDirectory = '';
                            app.baseConfig.FileType = '';
                            app.baseConfig.ProjectName = '';
                            app.baseConfig.DataTable = '';
                            app.baseConfig.FeatureTable = '';
                        else
                            loadTables(app);
                        end                      
                        setConfigFields(app); 
                        if app.ImportSuccess
                            dummy = uiconfirm(app.UIFigure,'QCMXP configuration and data tables sucessfully loaded','Config Update','Icon','success');
                            waitfor(dummy);
                        else
                            dummy = uiconfirm(app.UIFigure,'QCMXP configuration partially loaded. The data tables were not loaded','Config Update','Icon','warning');
                            waitfor(dummy);
                        end
                    else
                        setConfigFields(app);
                        dummy = uiconfirm(app.UIFigure,'QCMXP configuration sucessfully loaded','Config Update','Icon','success');
                        waitfor(dummy);
                    end
                    app.importedConfig = app.baseConfig;
                    app.configImported = true;
                    
                catch ME
                    uialert(app.UIFigure,{ME.message;char('Failed to load config file. Resetting to last used configuration.')},'Error: Config Error','Icon','error');
                    app.jsonTxt = oldJSON;
                    app.jsonView = oldJSONview;
                    app.baseConfig = app.oldConfig;
                    app.importedConfig = app.baseConfig;         
                    setConfigFields(app);
                end  
            end
            app.DefaultConfigurationPanel.Title = app.configFilename; 
            
            try
                checkBetweenBatchValid(app);
            catch ME
                uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
            end
            if app.complete
                switchAllPanelsB(app,'on');
                uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for the changes to have an effect.','Warning','Icon','warning');
            end
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)

            saveConfig = app.baseConfig;
            msg = "Do you want to save as a 'Project' or a 'Generic' Configuration file?";
            title = "Choose Config File Type";
            selection = uiconfirm(app.UIFigure,msg,title, ...
                "Options",["Project","Generic","Cancel"], ...
                "DefaultOption",2,"CancelOption",3);
            waitfor(selection)
            temp = split(app.baseConfig.ProjectName,'.');
            switch selection
                case 'Project'                    
                    tempFilename = [temp{1},'_Config.json'];
                case 'Generic'
                    saveConfig.WorkingDirectory = '';
                    saveConfig.FileType = '';
                    saveConfig.ProjectName = '';
                    saveConfig.DataTable = '';
                    saveConfig.FeatureTable = '';
                    if ismember(app.configFilename,{'Default',[temp{1},'_Config.json']}) 
                        tempFilename = 'userConfig.json';
                    else
                        tempFilename = app.configFilename;
                    end
                otherwise
                    return;
            end

            try
                saveConfig = validateConfig(saveConfig);
                saveConfig.WorkingDirectory = replace(saveConfig.WorkingDirectory,getenv('HOME'),"~");
            catch
                mydlg = uiconfirm(app.UIFigure,'QCMXP config file is corrupted. Better call David.','Confirm','Icon','error',"Options",{'OK'});
                waitfor(mydlg);
            end

            % if ismac
            %     scrsz = get(0,'ScreenSize');
            %     f = figure('Position', [scrsz(3)*0.45 scrsz(4)*0.6 0 0]);
            %     [file,path]=uiputfile('.json','Save QCMXP config file',tempFilename);
            %     delete(f);
            %     figure(app.UIFigure);
            % else       
                [file,path]=uiputfile('.json','Save QCMXP config file',tempFilename);
                figure(app.UIFigure);
            %end

            app.jsonTxt = jsonencode(saveConfig,'PrettyPrint',true);
          
            if ~isequal(file,0)
                try
                    fid=fopen(fullfile(path,file),'w');
                    fprintf(fid,app.jsonTxt);
                    fclose(fid);
                    app.configFilename = file;
                catch
                    uialert(app.UIFigure,'Failed to save config file','Error','Icon','error');
                end
            end
            
            app.DefaultConfigurationPanel.Title = app.configFilename;
            
        end

        % Button pushed function: LaunchFeatureExplorer
        function LaunchFeatureExplorerPushed(app, event)
                try
                     app.featureExplorerApp = QCRSCfeatureExplorer(app,app.highlightUID);
                     if app.complete
                         if app.highlightUID == -1 
                             UID = app.FeatureX.UID{1};
                         else
                             UID = app.highlightUID;
                         end
                         found = find(ismember(app.FeatureX.UID,UID));                       
                         gammaVals = app.GammaVals(found,:);
                         epsilonVals = app.EpsilonVals(found,:);
                         mpv = app.MPV(:,found);
                         mpvREF = app.MPVref(:,found);
                         outliers = app.Outliers(:,found);
                         outliersREF = app.OutliersREF(:,found);
                         numOutliers = app.NumOutliers(found);
                         isManual = app.FeatureX.ManualCorrection(found);
                         ExternalSetFeature(app.featureExplorerApp,UID,app.baseConfig,gammaVals,epsilonVals,mpv,mpvREF,outliers,outliersREF,numOutliers,isManual);
                     end                  
                 catch ME
                   uialert(app.UIFigure,ME.message,'batchExplorerApp Error','Icon','warning');
                 end            
        end

        % Button pushed function: LaunchAlgorithmExplorer
        function LaunchQCcorrectionExplorerPushed(app, event)
                try    
                   app.configExplorerApp = QCRSCconfigExplorer(app,app.highlightUID);
                catch ME
                %     uialert(app.UIFigure,ME.message,'QCcorrectionExplorerApp Error','Icon','warning');
                end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.PopOutPlotApp)
            delete(app.ImportApp)
            delete(app.configExplorerApp)
            delete(app.featureExplorerApp)
            delete(app.SpreadsheetApp)
            delete(app.BatchSpreadsheetApp)
            delete(app.CheatSheetApp)
            delete(app)           
        end

        % Value changed function: LOQalphaEditField, NbatchEditField, 
        % ...and 3 other components
        function FeatureInclusionPreFilterPanelValueChanged(app, event)
            app.baseConfig.MissingQCPreFilterMode = app.PreFilterDropDown.Value;
            app.baseConfig.MissingQCPreFilterPercentage = app.missingQCperFeatureEditField.Value;           
            app.baseConfig.LOQfilter = app.QCLOQmissingCheckBox.Value;
            app.baseConfig.RelativeLOQ = app.LOQalphaEditField.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            if strcmp(app.PreFilterDropDown.Value,'Disable')
                app.drinkMeButton.Visible = "off";
                app.missingQCperFeatureEditField.Visible = "off";               
                app.LOQalphaEditField.Visible = "off";
                app.xmaxBlankLabel.Visible = "off";
                app.QCLOQmissingCheckBox.Visible = "off";
                app.aLabel.Visible = "off";
                app.QCsmissingperfeatureLabel.Visible = "off";
            else
                app.drinkMeButton.Visible = "on";
                app.missingQCperFeatureEditField.Visible = "on";
                app.LOQalphaEditField.Visible = "on";
                app.xmaxBlankLabel.Visible = "on";
                app.QCLOQmissingCheckBox.Visible = "on";
                app.aLabel.Visible = "on";
                app.QCsmissingperfeatureLabel.Visible = "on";
            end
            if strcmp(app.PreFilterDropDown.Value,'NBatch')
                app.NbatchEditField.Visible = "on";
                app.QCsmissingperfeatureLabel_2.Visible = "on";
                app.baseConfig.MissingQCPreFilterBatchN = app.NbatchEditField.Value;
            else
                app.NbatchEditField.Visible = "off";
                app.QCsmissingperfeatureLabel_2.Visible = "off";
            end

            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();            
            preQCRSCclean(app);           
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            drawnow();            
            if app.complete
                switchAllPanelsB(app,'on');
                uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an effect.','Warning','Icon','warning');
            end
        end

        % Button pushed function: RunButton
        function StartButtonPushed(app, event)
            set(app.UIFigure, 'Pointer','watch');
            app.complete = false;
            app.featuresCorrectedGauge.Value = 0;
            app.CompleteLamp.Enable = 'off';
            ResetCleanDials(app);
            ClearPlots(app); 
            app.ParallelProcessingCheckBox.Enable = 'off';
            app.IncomingDataPanel.Enable = 'off';
            app.QCCorrectionParametersPanel.Enable = 'off';
            app.FeatureInclusionPreFilterPanel.Enable = 'off';
            app.DefaultConfigurationPanel.Enable = 'off';
            app.CleanExplorePanel.Enable = 'off';
            app.SavePanel.Enable = 'off';
            app.RunButton.Enable = 'off';
            app.BypassButton.Enable = 'off';       
            app.PauseButton.Enable = 'on';
            app.AbortButton.Enable = 'on';
            drawnow();
            try
                checkBetweenBatchValid(app);
                preQCRSCclean(app);
                optimiseAndCorrectTable(app);
            catch ME
                uialert(app.UIFigure,ME.message,'QC Correction Engine Failure','Icon','error');           
                app.RunButton.Enable = 'on';
                app.BypassButton.Enable = 'on';
                app.PauseButton.Enable = 'off';
                app.AbortButton.Enable = 'off';
                app.ParallelProcessingCheckBox.Enable = 'on';
                switchAllPanelsA(app,'on');
                set(app.UIFigure, 'Pointer','arrow');
                return;
            end
            app.ParallelProcessingCheckBox.Enable = 'on';
            app.RunButton.Enable = 'on';
            app.BypassButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
            app.AbortButton.Enable = 'off';
            app.IncomingDataPanel.Enable = 'on';
            app.QCCorrectionParametersPanel.Enable = 'on';
            app.FeatureInclusionPreFilterPanel.Enable = 'on';
            app.DefaultConfigurationPanel.Enable = 'on';
            app.SavePanel.Enable = 'on';
            app.CleanExplorePanel.Enable = 'on';
            if app.aborted
                app.CleanExplorePanel.Enable = 'off';
                app.SavePanel.Enable = 'off';               
                app.aborted = false;
                set(app.UIFigure, 'Pointer','arrow');
                return;
            end
            app.bypass = false;       
            CleanAndPlot(app);            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.paused = not(app.paused);
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.aborted = true;         
            res = uiconfirm(app.UIFigure,'Are you sure you want to prematurely end this run?','Confirm Action','Options',{'Yes','No'});
            if strcmp(res,'No')
                app.aborted = false;
            end                    
        end

        % Button pushed function: BypassButton
        function BypassButtonPushed(app, event)
            app.bypass = true;            
            res = uiconfirm(app.UIFigure,{'Are you sure you want to bypass QCRSC?';'Before/After will show the same data'},'Confirm Action','Options',{'Yes','No'});
            if strcmp(res,'No')
                app.bypass = false;
                return;
            end
            StartButtonPushed(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            suffix = app.CorrectedSuffixEditField.Value;
            TempFeature = app.FeatureX;
            TempFeature.cleanPeaks = []; 
            if any(isnan(TempFeature.refRSD))
                TempFeature.refRSD = [];
            end
            SaveData(app,app.DataX,app.DataName,suffix);
            SaveData(app,TempFeature,app.FeatureName,suffix);
            SaveData(app,app.FeatureXS,app.FeatureName,[suffix,'_Stats']);
            saveConfig(app);
            app.QCRSCsaved = true;
        end

        % Button pushed function: SaveCleanButton
        function SaveCleanButtonPushed(app, event)
            FeatureClean = app.FeatureX(app.FeatureX.cleanPeaks,:);
            DataClean = app.DataX;
            suffix = app.SaveCleanSuffixEditField.Value;
            list = setdiff(app.FeatureX.UID,FeatureClean.UID);
            DataClean(:,list) = [];    
            FeatureClean.cleanPeaks = [];

            if ~all(app.UITable.Data.Include)
                res = uiconfirm(app.UIFigure,{'Do you wish to save only the selected subset of batches?'},'Confirm Action','Options',{'Yes','No'});
                if strcmp(res,'Yes')
                    includeSamples = ismember(DataClean.Batch,app.BatchListToPlot);
                    DataClean = DataClean(includeSamples,:);             
                end
            end


            SaveData(app,DataClean,app.DataName,suffix);
            SaveData(app,FeatureClean,app.FeatureName,suffix);
            
            if app.AlsosavescaledimputeddataCheckBox.Value == true
                FeatureClean.cleanPeaks = ones(height(FeatureClean),1);
                DataImputed = preprocessDataForExport(DataClean,FeatureClean, ...
                        RemoveZeros = app.baseConfig.RemoveZeros, ...                    
                        ImputationType = app.baseConfig.CleanImputationMethod, ...
                        LogTransform = app.Log10TransformCheckBox.Value, ...
                        ScaleMethod = app.baseConfig.CleanScaleMethod, ...
                        batchScale = false, ...
                        k=app.kSpinner.Value);                
                temp = [suffix,'Imputed'];
                FeatureClean.cleanPeaks = [];           
                SaveData(app,DataImputed,app.DataName,temp);
            end 
            saveConfig(app);  
        end

        % Value changed function: BlankRatioKnob, DRatioKnob, QCRSDKnob, 
        % ...and 1 other component
        function KnobValueChanged(app, event)
            app.baseConfig.CleanFilterQCRSD = app.QCRSDKnob.Value;
            app.baseConfig.CleanFilterSampleRSD = app.SampleRSDKnob.Value;          
            app.baseConfig.CleanFilterDRatio = app.DRatioKnob.Value;
            app.baseConfig.CleanFilterBlankRatio = app.BlankRatioKnob.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            if app.FilterBankSwitch.Value == true
                CleanAndPlot(app);
            end
        end

        % Value changed function: PCStatsSwitch
        function RedoPCA(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            switchAllPCAswtiches(app,'on');
            drawnow();            
           try
                calcAndPlotPCA(app);
           catch ME      
                cla(app.UIAxesPCA);
                app.YAxisDropDown.Items = {'PC 1', 'PC 2'};
                app.YAxisDropDown.ItemsData = [1,2];
                app.XAxisDropDown.Items = {'PC 1', 'PC 2'};
                app.XAxisDropDown.ItemsData = [1,2];
                app.YAxisDropDown.Value = 2;
                app.XAxisDropDown.Value = 1;
                app.UIAxesPCA.Title.String = 'Principal Component Analysis';
                ylabel(app.UIAxesPCA,'PC2');
                xlabel(app.UIAxesPCA,'PC1');         
                app.PCAmodel = [];
                switchAllPCAswtiches(app,'off');
                uialert(app.UIFigure,"PCA failure. Possibly due to too few data or imputation method. consider changing the filter settings.",'PCA failure','Icon','error');
           end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
        end

        % Value changed function: SortbySwitch
        function ImperialPlotValueChanged(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            plotImperialRSD(app); 
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            if ischar(app.highlightUID)
                highlightImperialValue(app,app.highlightUID);
            end
            drawnow();
        end

        % Value changed function: RSDDropDown, SmoothRSDslider, 
        % ...and 2 other components
        function RSDPlotValueChanged(app, event)
            
            red = [220,20,60]/255;            
            cornflowerblue = [100,149,237]/255;
            gold = [212,175,55]/255;
            
            
            if strcmp(app.SwitchRSDpdf.Value,'histogram')
                app.SmoothRSDslider.Visible = 'off';
                app.SmoothSlider_2Label.Visible = 'off';
            else
                app.SmoothRSDslider.Visible = 'on';
                app.SmoothSlider_2Label.Visible = 'on';
            end

            switch app.RSDDropDown.Value
                case 'qcRSD'
                    baseColor = red;
                case 'refRSD'
                    baseColor = gold;
                case 'sampleRSD'
                    baseColor = cornflowerblue;
            end

           
            bvec = unique(app.PlotDataTable.Batch);
            QCRSCplotdistribution(app.UIAxesRSDpdf,app.PlotFeatureTable,bvec,label=app.RSDDropDown.Value,plotType=app.SwitchRSDpdf.Value,smooth=app.SmoothRSDslider.Value,split=app.SwitchRSDPlot.Value,cmap=app.cmap,baseColor=baseColor);
        end

        % Value changed function: SmoothBLANKslider, SwitchBlankPlot, 
        % ...and 1 other component
        function BlankPlotValueChanged(app, event)
            app.CleanBlankRatioMethodDropDown.Value = app.baseConfig.BlankRatioMethod;
            if strcmp(app.SwitchBlankpdf.Value,'histogram')
                app.SmoothBLANKslider.Visible = 'off'; 
                app.SmoothSlider_2Label_3.Visible = 'off';
            else
                app.SmoothBLANKslider.Visible = 'on';
                app.SmoothSlider_2Label_3.Visible = 'on';
            end
            bvec = unique(app.PlotDataTable.Batch);
            QCRSCplotdistribution(app.UIAxesBLANKpdf,app.PlotFeatureTable,bvec,label='blankRatio',plotType=app.SwitchBlankpdf.Value,smooth=app.SmoothBLANKslider.Value,split=app.SwitchBlankPlot.Value,cmap=app.cmap);
            
        end

        % Value changed function: SmoothDRATIOslider, SwitchDRATIOpdf, 
        % ...and 1 other component
        function DratioPlotValueChanged(app, event)
            if strcmp(app.SwitchDRATIOpdf.Value,'histogram')
                app.SmoothDRATIOslider.Visible = 'off';  
                app.SmoothSlider_2Label_2.Visible = 'off';
            else
                app.SmoothDRATIOslider.Visible = 'on';
                app.SmoothSlider_2Label_2.Visible = 'on';
            end
            bvec = unique(app.PlotDataTable.Batch);
            QCRSCplotdistribution(app.UIAxesDRATIOpdf,app.PlotFeatureTable,bvec,label='dRatio',plotType=app.SwitchDRATIOpdf.Value,smooth=app.SmoothDRATIOslider.Value,split=app.SwitchDratioPlot.Value,cmap=app.cmap);
            
        end

        % Value changed function: CleanBlankRatioMethodDropDown
        function CleanBlankRatioMethodDropDownValueChanged(app, event)
            app.baseConfig.BlankRatioMethod = app.CleanBlankRatioMethodDropDown.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            app.BlankRatioMethodDropDown.Value = app.baseConfig.BlankRatioMethod;
            calculateStats(app);
            CleanAndPlot(app);

            if app.QCRSCsaved
                TempFeature = app.FeatureX;
                TempFeature.cleanPeaks = [];
                suffix = app.CorrectedSuffixEditField.Value;            
                SaveData(app,TempFeature,app.FeatureName,suffix); 
                SaveData(app,app.FeatureXS,app.FeatureName,[suffix,'_Stats']);
                saveConfig(app);
            end
        end

        % Value changed function: drinkMeButton
        function drinkMeButtonValueChanged(app, event)
            if app.drinkMeButton.Value
                set(app.UIFigure, 'Pointer','watch');
                switchAllPanelsA(app,'off');
                switchAllPanelsB(app,'off');
                drawnow();            
                drinkMe(app);
                preQCRSCclean(app);           
                set(app.UIFigure, 'Pointer','arrow');
                switchAllPanelsA(app,'on');
                drawnow();    
                if app.complete
                    switchAllPanelsB(app,'on');
                    uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an effect.','Warning','Icon','warning');
                end
            else
                uialert(app.UIFigure,"Sorry, You cannot reverse 'drinking the shrinking potion'! Please reload the data",'Warning','Icon','warning');
                app.drinkMeButton.Value = true;
            end
        end

        % Value changed function: SwitchBeforeAfter
        function SwitchBeforeAfterValueChanged(app, event)
            app.FilterBankSwitch.Value = false;
            setFilterKnobColors(app);     
            CleanAndPlot(app); 
            LabelbyDropDownValueChanged(app);
        end

        % Button pushed function: PopOutButton
        function PopOutButtonPushed(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            try
                app.PopOutPlotApp = ScatterView(app.PlotDataTable,app.PlotFeatureTable, ...
                                    RemoveZeros = app.baseConfig.RemoveZeros, ...
                                    IncludeQCs= app.IncludeQCSamplesCheckBox.Value, ...
                                    IncludeRefs = app.IncludeReferenceSamplesCheckBox.Value, ...
                                    IncludeBlanks = app.IncludeBlankSamplesCheckBox.Value, ...
                                    ImputationType = app.baseConfig.CleanImputationMethod, ...
                                    ScaleMethod = app.baseConfig.CleanScaleMethod, ...
                                    BatchScale = false, ...
                                    LogTransform = app.Log10TransformCheckBox.Value, ...
                                    k = app.kSpinner.Value, ...
                                    xaxis = app.XAxisDropDown.Value, ...
                                    yaxis = app.YAxisDropDown.Value, ...                    
                                    label = app.LabelbyDropDown.Value, ...
                                    plotCIA = app.ConfidenceIntervalsCheckBoxA.Value, ...
                                    plotCIB = app.ConfidenceIntervalsCheckBoxB.Value, ...
                                    plotDataPoints = app.DataPointsCheckBox.Value, ...
                                    cmapName = app.cmapName);


            catch ME
               uialert(app.UIFigure,ME.message,'Warning: ScatterView App Error','Icon','warning');
               delete(app.PopOutPlotApp);
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
        end

        % Value changed function: DataPointsCheckBox
        function DataPointsCheckBoxValueChanged(app, event)
            value1 = app.ConfidenceIntervalsCheckBoxB.Value;
            value2 = app.ConfidenceIntervalsCheckBoxA.Value;
            value3 = app.DataPointsCheckBox.Value;
            if ~any([value1,value2,value3])
                app.DataPointsCheckBox.Value = true;
            end
                PlotPCA(app);
        end

        % Value changed function: ConfidenceIntervalsCheckBoxB
        function ConfidenceIntervalsCheckBoxBValueChanged(app, event)
            value1 = app.ConfidenceIntervalsCheckBoxB.Value;
            value2 = app.ConfidenceIntervalsCheckBoxA.Value;
            value3 = app.DataPointsCheckBox.Value;
            if ~any([value1,value2,value3])
                app.ConfidenceIntervalsCheckBoxB.Value = true;
            end
            PlotPCA(app);
        end

        % Value changed function: ConfidenceIntervalsCheckBoxA
        function ConfidenceIntervalsCheckBoxAValueChanged(app, event)
            value1 = app.ConfidenceIntervalsCheckBoxB.Value;
            value2 = app.ConfidenceIntervalsCheckBoxA.Value;
            value3 = app.DataPointsCheckBox.Value;
            if ~any([value1,value2,value3])
                app.ConfidenceIntervalsCheckBoxA.Value = true;
            end
            PlotPCA(app);
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            if app.LegendCheckBox.Value
                app.UIAxesPCA.Legend.Visible = true;
            else
                app.UIAxesPCA.Legend.Visible = false;
            end   
        end

        % Value changed function: FilterBankSwitch
        function FilterButtonPushed(app, event)
            setFilterKnobColors(app);
            if app.QCRSDKnob.Value ~= 100 || ...
                app.SampleRSDKnob.Value ~= 0 || ...
                app.DRatioKnob.Value ~= 100 || ...
                app.BlankRatioKnob.Value ~= 100
                    CleanAndPlot(app);
            end
            
        end

        % Value changed function: SampleFilterDropDown
        function CleanMissingSampleFilterValueChange(app, event)
            app.baseConfig.CleanMissingSampleFilterMode = app.SampleFilterDropDown.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            CleanAndPlot(app);
        end

        % Value changed function: IncludeBlankSamplesCheckBox
        function IncludeBlankSamplesCheckBoxValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: IncludeReferenceSamplesCheckBox
        function IncludeReferenceSamplesCheckBoxValueChanged(app, event)
             PlotPCA(app)     
        end

        % Value changed function: IncludeQCSamplesCheckBox
        function IncludeQCSamplesCheckBoxValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            if app.XAxisDropDown.Value == 0
                app.YAxisDropDown.Value = 1;           
            elseif app.XAxisDropDown.Value == app.YAxisDropDown.Value
                try
                    app.YAxisDropDown.Value = app.XAxisDropDown.Value + 1;
                catch
                    app.YAxisDropDown.Value = app.XAxisDropDown.Value - 1;
                end
            end
            PlotPCA(app)
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: LabelbyDropDown
        function LabelbyDropDownValueChanged(app, event)
            switch app.LabelbyDropDown.Value
                case 'BatchQC'
                    app.ConfidenceIntervalsCheckBoxA.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Value = true;
                    app.DataPointsCheckBox.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Visible = "on";
                    app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of Batch QC';
                    app.ConfidenceIntervalsCheckBoxB.Text = '95% CI of Batch Sample';
                    app.IncludeQCSamplesCheckBox.Value = true;
                    app.IncludeQCSamplesCheckBox.Enable = false;
                    app.IncludeReferenceSamplesCheckBox.Enable = false;
                    app.IncludeBlankSamplesCheckBox.Enable = false;
                case 'SampleType'
                    app.ConfidenceIntervalsCheckBoxA.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Value = false;
                    app.DataPointsCheckBox.Value = true;               
                    app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of SampleType';
                    app.ConfidenceIntervalsCheckBoxB.Visible = "off";                                     
                    app.IncludeQCSamplesCheckBox.Value = true;
                    app.IncludeQCSamplesCheckBox.Enable = true;
                    app.IncludeReferenceSamplesCheckBox.Value = false;
                    app.IncludeReferenceSamplesCheckBox.Enable = true;
                    app.IncludeBlankSamplesCheckBox.Value = false;
                    app.IncludeBlankSamplesCheckBox.Enable = true;
                otherwise
                    app.ConfidenceIntervalsCheckBoxA.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Value = true;
                    app.DataPointsCheckBox.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Visible = "on";
                    app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of Group Mean';
                    app.ConfidenceIntervalsCheckBoxB.Text = '95% CI of Group Sample';
                    app.IncludeQCSamplesCheckBox.Value = false;
                    app.IncludeQCSamplesCheckBox.Enable = true;
                    app.IncludeReferenceSamplesCheckBox.Value = false;
                    app.IncludeReferenceSamplesCheckBox.Enable = true;
                    app.IncludeBlankSamplesCheckBox.Value = false;
                    app.IncludeBlankSamplesCheckBox.Enable = true;            
            end
            PlotPCA(app)
        end

        % Selection changed function: MissingValueImputationButtonGroup
        function MissingValueImputationButtonGroupSelectionChanged(app, event)
            selectedButton = app.MissingValueImputationButtonGroup.SelectedObject;
            switch selectedButton.Text
                case app.KNNcolumnButton.Text
                    app.baseConfig.CleanImputationMethod = 'KNNcolumn';
                case app.KNNrowButton.Text
                    app.baseConfig.CleanImputationMethod = 'KNNrow';
                case app.blank20minButton.Text
                    app.baseConfig.CleanImputationMethod = 'blank20';
            end
            app.baseConfig = validateConfig(app.baseConfig);
            RedoPCA(app);
        end

        % Value changed function: Log10TransformCheckBox
        function Log10TransformCheckBoxValueChanged(app, event)
            app.baseConfig.CleanLogTransform = app.Log10TransformCheckBox.Value;
            RedoPCA(app);
        end

        % Value changed function: FilterBankModeDropDown
        function FilterBankModeDropDownValueChanged(app, event)
            app.baseConfig.CleanFilterBankMode = app.FilterBankModeDropDown.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            if app.FilterBankSwitch.Value == true
                CleanAndPlot(app);  
            end
        end

        % Value changed function: QCCheckBox
        function QCCheckBoxValueChanged(app, event)
            if app.QCCheckBox.Value || app.RefCheckBox.Value
                ImperialPlotValueChanged(app);
            else
                app.QCCheckBox.Value = true;
                ImperialPlotValueChanged(app);
            end
            if ischar(app.highlightUID)
                highlightImperialValue(app,app.highlightUID);
            end
        end

        % Value changed function: RefCheckBox
        function RefCheckBoxValueChanged(app, event)
            if app.QCCheckBox.Value || app.RefCheckBox.Value
                ImperialPlotValueChanged(app);
            else
                app.RefCheckBox.Value = true;
                ImperialPlotValueChanged(app);
            end
            if ischar(app.highlightUID)
                highlightImperialValue(app,app.highlightUID);
            end
        end

        % Value changed function: SampleFilterPercentEditField
        function SampleFilterPercentEditFieldValueChanged(app, event)
            app.baseConfig.CleanMissingSampleFilterPercentage = app.SampleFilterPercentEditField.Value;
            if app.SampleFilterPercentEditField.Value > 25
                app.blank20minButton.Value = true;
                app.baseConfig.CleanImputationMethod = 'blank20';
            end
            app.baseConfig = validateConfig(app.baseConfig);
            CleanAndPlot(app);
        end

        % Value changed function: kSpinner
        function kSpinnerValueChanged(app, event)
            app.baseConfig.CleanImputationK = app.kSpinner.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            RedoPCA(app);
        end

        % Selection changed function: ScalingButtonGroup
        function ScalingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScalingButtonGroup.SelectedObject;
            switch selectedButton.Text
                case app.AutoscaleButton.Text
                    app.baseConfig.CleanScaleMethod = 'Autoscale';
                case app.ParetoscaleButton.Text
                    app.baseConfig.CleanScaleMethod = 'Paretoscale';
                case app.MeanCenterOnlyButton.Text
                    app.baseConfig.CleanScaleMethod = 'MeanCenter';
            end
            app.baseConfig = validateConfig(app.baseConfig);
            RedoPCA(app);
        end

        % Value changed function: ParallelProcessingCheckBox
        function ParallelProcessingCheckBoxValueChanged(app, event)
            app.baseConfig.ParallelProcess = app.ParallelProcessingCheckBox.Value;
        end

        % Value changed function: BetweenBatchCorrectionModeDropDown, 
        % ...and 9 other components
        function CorrectionConfigValueChanged(app, event)
            app.baseConfig.LogTransformedCorrection = app.LogTransformedCorrectionCheckBox.Value;
            app.baseConfig.RemoveZeros = app.RemoveZerosCheckBox.Value;
            app.baseConfig.OutlierDetectionMethod = app.QCOutlierDetectionMethodDropDown.Value;
            app.baseConfig.OutlierDetectionCI = app.QCOutlierDetectionCIEditField.Value;
            app.baseConfig.OutlierReplacementStrategy = app.QCOutlierReplacementStrategyDropDown.Value;
            app.baseConfig.WithinBatchCorrectionMode = app.WithinBatchCorrectionModeDropDown.Value;
            app.baseConfig.BetweenBatchCorrectionMode = app.BetweenBatchCorrectionModeDropDown.Value;            
            app.baseConfig.QCRSCcvMethod = app.QCRSCCrossValidationMethodDropDown.Value;
            app.baseConfig.QCRSCmcReps = app.QCRSCMonteCarlorepetitionsEditField.Value;
            app.baseConfig.BlankRatioMethod = app.BlankRatioMethodDropDown.Value;

            checkStuff(app);
            if app.complete
                switchAllPanelsB(app,'on');
                uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for any changes to have an effect.','Warning','Icon','warning');
            end
            
        end

        % Value changed function: CorrectedSuffixEditField
        function CorrectedSuffixEditFieldValueChanged(app, event)
            app.baseConfig.CorrectedFileSuffix = app.CorrectedSuffixEditField.Value;
            app.baseConfig = validateConfig(app.baseConfig);
        end

        % Value changed function: SaveCleanSuffixEditField
        function SaveCleanSuffixEditFieldValueChanged(app, event)
            app.baseConfig.CleanFileSuffix = app.SaveCleanSuffixEditField.Value;
            app.baseConfig = validateConfig(app.baseConfig);
        end

        % Button pushed function: ResetConfigButton
        function ResetConfigButtonPushed(app, event)
            if app.configImported
                res = uiconfirm(app.UIFigure,'Do you want to reset to the last imported configuration or the default configuration?','Reset Configuration','Options',{'Imported','Default','Cancel'});
                waitfor(res);
                switch res
                    case 'Imported'
                        app.baseConfig = app.importedConfig;
                        setConfigFields(app);
                    case 'Default'
                        setDefaultConfig(app); 
                end     
            else
                res = uiconfirm(app.UIFigure,'Are you sure you want to reset to the default configuration?','Reset Configuration','Options',{'Yes','Cancel'});
                waitfor(res);
                if strcmp(res,'Yes')
                    setDefaultConfig(app); 
                end
            end
        end

        % Button pushed function: CHEATSHEETButton
        function CHEATSHEETButtonPushed(app, event)
             try    
                app.CheatSheetApp = Cheatsheet();
             catch ME
                uialert(app.UIFigure,ME.message,'Cheatsheet would not launch','Icon','error');
             end   
        end

        % Value changed function: compareRSDPlotByQC
        function CompareRSDplotValueChanged(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            plotCompareRSD(app); 
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            if ischar(app.highlightUID)
                highlightComparisonValue(app,app.highlightUID);
            end
            drawnow();
        end

        % Value changed function: CleanFilterOnQC
        function CleanFilterOnQCValueChanged(app, event)
            if app.CleanFilterOnQC.Value
                app.QCRSDKnobLabel.Text = 'QC-RSD';
                app.FilterBankModeDropDown.Enable = "on";
                app.FilterBankModeDropDown.Value = 'Complete';
               
            else
                app.QCRSDKnobLabel.Text = 'REF-RSD';
                app.FilterBankModeDropDown.Value = 'Complete';
                app.FilterBankModeDropDown.Enable = "off";
            end
            app.baseConfig.CleanFilterBankMode = app.FilterBankModeDropDown.Value;
            app.baseConfig.CleanFilterOnQC = app.CleanFilterOnQC.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            if app.FilterBankSwitch.Value == true
                setFilterKnobColors(app);
                CleanAndPlot(app); 
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            %app.UITable.BackgroundColor = [0.5 0.5 0.5;0.54 0.54 0.54];

            if isequal(app.UITable.Data,app.UITable_Old) | all(app.UITable.Data.Include)
                app.RevertButton.Enable = "off";
            else
                app.RevertButton.Enable = "on";
            end


            if ~any(app.UITable.Data.Include)
                app.SendButton.Enable = "off";
                %app.ViewBatchTableButton.Enable = "off";
                if isequal(app.FlashTimer.Running, 'on')
                stop(app.FlashTimer);
                end
            else
                app.SendButton.Enable = "on";
                %app.ViewBatchTableButton.Enable = "on";
            end

            if all(app.UITable.Data.Include)              
                app.ResetButton.Enable = "off";
            else
                app.ResetButton.Enable = "on";
            end

            app.UITable.ForegroundColor = [0.9 0.2 0.2];
            if ~isequal(app.FlashTimer.Running, 'on')
                start(app.FlashTimer);
            end
            app.IsTabGroup2Disabled = true;
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            stop(app.FlashTimer); % Stop the flashing
            app.SendButton.BackgroundColor = [0.96,0.96,0.96];
            app.IsTabGroup2Disabled = false;
            app.SendButton.Enable = "off";
            app.RevertButton.Enable = "off";
            CleanAndPlot(app);
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            %selectedTab = app.TabGroup2.SelectedTab;
            if app.IsTabGroup2Disabled
                app.TabGroup2.SelectedTab = app.BatchSelectTab;               
            end
        end

        % Button pushed function: InvertButton
        function InvertButtonPushed(app, event)
            app.UITable.Data.Include = ~app.UITable.Data.Include;           
            UITableCellEdit(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UITable.Data.Include(:) = true;    
            UITableCellEdit(app);
        end

        % Button pushed function: ViewBatchTableButton
        function ViewBatchTableButtonPushed(app, event)
            app.ViewBatchTableButton.Enable = 'off';
            app.BatchSpreadsheetApp = BatchStatsTableView(app, app.BatchSummaryStats);
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            app.UITable.Data = app.UITable_Old;
            UITableCellEdit(app);
        end

        % Value changed function: ForestSortByDropDown, ForestUpDownButton
        function ForestValueChanged(app, event)
            set(app.UIFigure, 'Pointer','watch');
            app.CleanExplorePanel.Enable = 'off';
            drawnow();
            childObjects = findobj(app.ForestPlotTab.Children, 'UserData', -1);
            delete(childObjects);
            app.ForestToolTipLabel.Visible ="off";
            
            if app.ForestUpDownButton.Value
                app.ForestUpDownButton.Icon = 'down-arrow.png';
                %app.PlotFeatureTable = sortrows(app.PlotFeatureTable,app.ForestSortByDropDown.Value,'descend');
                [~,idx] = sortrows(app.ForestFeatureTableX,app.ForestSortByDropDown.Value,'descend');
            else
                app.ForestUpDownButton.Icon = 'up-arrow.png';
                %app.PlotFeatureTable = sortrows(app.PlotFeatureTable,app.ForestSortByDropDown.Value,'ascend');
                [~,idx] = sortrows(app.ForestFeatureTableX,app.ForestSortByDropDown.Value,'ascend');
            end
            app.ForestIdx = idx;
            ForestChooseQCorREF(app)
            set(app.UIFigure, 'Pointer','arrow');
            app.CleanExplorePanel.Enable = 'on';
            if ischar(app.highlightUID)
                highlightForestValue(app,app.highlightUID);
            end
            drawnow();
        end

        % Value changed function: ForestLabelByDropDown, ForestQCRefSwitch
        function ForestChooseQCorREF(app, event)
            set(app.UIFigure, 'Pointer','watch');
            app.CleanExplorePanel.Enable = 'off';
            drawnow();
            app.ForestToolTipLabel.Visible = "off";
            maxLength = 30;
            Label = app.ForestLabelByDropDown.Value;  
            if app.ForestQCRefSwitch.Value
                if iscell(app.ForestStatsTableQC.(Label))
                    app.ForestStatsTableQC.ShortName = cellfun(@(x) pad(x(1:min(length(x), maxLength)),maxLength,"left"), app.ForestStatsTableQC.(Label), 'UniformOutput', false);
                    app.ForestStatsTableQC.ShortName = strrep(app.ForestStatsTableQC.ShortName,'_',' ');
                else
                    temp = arrayfun(@num2str, app.ForestStatsTableQC.(Label), 'UniformOutput', false);
                    app.ForestStatsTableQC.ShortName = cellfun(@(x) pad(x(1:min(length(x), maxLength)),maxLength,"left"), temp, 'UniformOutput', false);
                    app.ForestStatsTableQC.ShortName = strrep(app.ForestStatsTableQC.ShortName,'_',' ');
                end

                plotForestwithSlider(app,'QC'); 
                app.ForestToolTipLabel.BackgroundColor = [252, 164, 168]/255;
            else
                if iscell(app.ForestStatsTableQC.(Label))
                    app.ForestStatsTableREF.ShortName = cellfun(@(x) pad(x(1:min(length(x), maxLength)),maxLength,"left"), app.ForestStatsTableREF.(Label), 'UniformOutput', false);
                    app.ForestStatsTableREF.ShortName = strrep(app.ForestStatsTableREF.ShortName,'_',' ');
                else
                    temp = arrayfun(@num2str, app.ForestStatsTableQC.(Label), 'UniformOutput', false);
                    app.ForestStatsTableREF.ShortName = cellfun(@(x) pad(x(1:min(length(x), maxLength)),maxLength,"left"), temp, 'UniformOutput', false);
                    app.ForestStatsTableREF.ShortName = strrep(app.ForestStatsTableREF.ShortName,'_',' ');
                end

                plotForestwithSlider(app,'REF'); 
                app.ForestToolTipLabel.BackgroundColor = [237, 214, 138]/255;              
            end   
            set(app.UIFigure, 'Pointer','arrow');
            app.CleanExplorePanel.Enable = 'on';
            if ischar(app.highlightUID)
                highlightForestValue(app,app.highlightUID);
            end
            drawnow();
            
        end

        % Value changed function: ForestFilteredOnlyCheckBox
        function ForestFilteredOnlyCheckBoxValueChanged(app, event)
            set(app.UIFigure, 'Pointer','watch');
            app.CleanExplorePanel.Enable = 'off';
            drawnow();
            app.ForestToolTipLabel.Visible = "off";
            plotForestRSD(app);
            set(app.UIFigure, 'Pointer','arrow');
            app.CleanExplorePanel.Enable = 'on';
            drawnow();
            if ischar(app.highlightUID) && ismember(app.highlightUID,app.ForestFeatureTableX.UID)
                highlightForestValue(app,app.highlightUID);
            end
        end

        % Button pushed function: ForestSaveButton
        function ForestSaveButtonPushed(app, event)
            
            Temp = app.ForestStatsTableQC(:,{'UID','Name','meanDeltaCV','lowerCI','upperCI','sig'});
            Temp.Properties.VariableNames = {'UID','Name','meanDeltaCV_QC','lowerCI_QC','upperCI_QC','sig_QC'};
            if any(app.PlotDataTable.Reference)
                Temp2 = app.ForestStatsTableREF(:,{'meanDeltaCV','lowerCI','upperCI','sig'});
                Temp2.Properties.VariableNames = {'meanDeltaCV_REF','lowerCI_REF','upperCI_REF','sig_REF'};
                Temp = [Temp,Temp2];
            end
            Temp(1,:) = [];
            Temp.UID{1} = 'PE';
            Temp.Name{1} = 'PooledEffect';
            SaveData(app,Temp,app.FeatureName,'_ForestStats');
        end

        % Button pushed function: ForestDisableButton
        function ForestDisableButtonPushed(app, event)
            set(app.UIFigure, 'Pointer','watch');
            app.CleanExplorePanel.Enable = 'off';
            drawnow();
            if app.ForestEnabled
                app.ForestEnabled = false;
                app.ForestDisableButton.Text = 'Enable';
                tf = false;
                app.ForestToolTipLabel.Visible = false;
            else
                app.ForestEnabled = true;
                app.ForestDisableButton.Text = 'Disable';
                tf = true;
                plotForestRSD(app);
            end
            %app.UIAxesForest.Visible = tf;
            set(app.UIAxesForest.Children, 'Visible', tf);
            %app.UIAxesPooledForest.Visible = tf;
            set(app.UIAxesPooledForest.Children, 'Visible', tf);            
            app.ForestLabelByDropDown.Enable = tf;
            app.ForestSaveButton.Enable = tf;
            app.ForestFilteredOnlyCheckBox.Enable = tf;
            app.ForestBootNumEditField.Enable = tf;
            app.ForestUpDownButton.Enable = tf;
            app.ForestSortByDropDown.Enable = tf;
            app.ForestQCRefSwitch.Enable = tf;
            app.SortByDropDownLabel.Enable = tf;
            app.BootNumLabel.Enable = tf;
            app.LabelBy.Enable = tf;      
            set(app.UIFigure, 'Pointer','arrow');
            app.CleanExplorePanel.Enable = 'on';
            drawnow();
        end

        % Value changed function: ForestBootNumEditField
        function ForestBootNumEditFieldValueChanged(app, event)
            set(app.UIFigure, 'Pointer','watch');
            app.CleanExplorePanel.Enable = 'off';
            drawnow();
            plotForestRSD(app);
            set(app.UIFigure, 'Pointer','arrow');
            app.CleanExplorePanel.Enable = 'on';
            drawnow();
            if ischar(app.highlightUID)
                highlightForestValue(app,app.highlightUID);
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'res', 'icon_512x512@2x.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'res', 'Logo.png');
            app.CleanExplorePanel = uipanel(app.UIFigure);
            app.CleanExplorePanel.Title = 'Clean & Explore:';
            app.SaveCleanPanel = uipanel(app.CleanExplorePanel);
            app.SaveCleanSuffixEditField = uieditfield(app.SaveCleanPanel, 'text');
            app.SaveCleanSuffixEditField.ValueChangedFcn = createCallbackFcn(app, @SaveCleanSuffixEditFieldValueChanged, true);
            app.SaveCleanSuffixEditField.Tooltip = {'_Clean'};
            app.SaveCleanSuffixEditField.Value = '_Clean';
            app.SaveCleanButton = uibutton(app.SaveCleanPanel, 'push');
            app.SaveCleanButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCleanButtonPushed, true);
            app.SaveCleanButton.Text = 'Save';
            app.suffixLabel_2 = uilabel(app.SaveCleanPanel);
            app.suffixLabel_2.Text = 'suffix: ';
            app.AlsosavescaledimputeddataCheckBox = uicheckbox(app.SaveCleanPanel);
            app.AlsosavescaledimputeddataCheckBox.Text = 'Also save scaled & imputed data';
            app.TabGroup = uitabgroup(app.CleanExplorePanel);
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.UIAxesPCA = uiaxes(app.PCATab);
            xlabel(app.UIAxesPCA, 'PC 1')
            ylabel(app.UIAxesPCA, 'PC 2')
            zlabel(app.UIAxesPCA, 'Z')
            app.UIAxesPVE = uiaxes(app.PCATab);
            app.UIAxesPVE.XTick = [];
            app.UIAxesPVE.YTick = [];
            app.UIAxesPVE2 = uiaxes(app.PCATab);
            app.UIAxesPVE2.XTick = [];
            app.UIAxesPVE2.YTick = [];
            app.XAxisDropDownLabel = uilabel(app.PCATab);
            app.XAxisDropDownLabel.Text = 'X Axis:';
            app.XAxisDropDown = uidropdown(app.PCATab);
            app.XAxisDropDown.Items = {'PC 1', 'PC 2', 'PC 3', 'PC 4', 'PC 5', 'PC 6', 'PC 7', 'PC 8', 'PC 9', 'PC 10'};
            app.XAxisDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 1;
            app.YAxisDropDownLabel = uilabel(app.PCATab);
            app.YAxisDropDownLabel.Text = 'Y Axis:';
            app.YAxisDropDown = uidropdown(app.PCATab);
            app.YAxisDropDown.Items = {'PC 1', 'PC 2', 'PC 3', 'PC 4', 'PC 5', 'PC 6', 'PC 7', 'PC 8', 'PC 9', 'PC 10'};
            app.YAxisDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 2;
            app.LegendCheckBox = uicheckbox(app.PCATab);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.ConfidenceIntervalsCheckBoxA = uicheckbox(app.PCATab);
            app.ConfidenceIntervalsCheckBoxA.ValueChangedFcn = createCallbackFcn(app, @ConfidenceIntervalsCheckBoxAValueChanged, true);
            app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of Group Mean';
            app.ConfidenceIntervalsCheckBoxB = uicheckbox(app.PCATab);
            app.ConfidenceIntervalsCheckBoxB.ValueChangedFcn = createCallbackFcn(app, @ConfidenceIntervalsCheckBoxBValueChanged, true);
            app.ConfidenceIntervalsCheckBoxB.Text = '95% CI of Group Pop.';
            app.DataPointsCheckBox = uicheckbox(app.PCATab);
            app.DataPointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataPointsCheckBoxValueChanged, true);
            app.DataPointsCheckBox.Text = 'Data Points';
            app.VariationExplainedbyeachPCLabel = uilabel(app.PCATab);
            app.VariationExplainedbyeachPCLabel.Text = 'Variation Explained by each PC';
            app.LabelbyDropDownLabel = uilabel(app.PCATab);
            app.LabelbyDropDownLabel.Text = 'Label by:';
            app.LabelbyDropDown = uidropdown(app.PCATab);
            app.LabelbyDropDown.Items = {'SampleType'};
            app.LabelbyDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelbyDropDownValueChanged, true);
            app.LabelbyDropDown.Value = 'SampleType';
            app.PopOutButton = uibutton(app.PCATab, 'push');
            app.PopOutButton.ButtonPushedFcn = createCallbackFcn(app, @PopOutButtonPushed, true);
            app.PopOutButton.Icon = fullfile(pathToMLAPP, 'res', 'PlotPopOut.png');
            app.PopOutButton.Text = '';
            app.PCStatsSwitch = uiswitch(app.PCATab, 'slider');
            app.PCStatsSwitch.Items = {'Component', 'Cumulative'};
            app.PCStatsSwitch.ItemsData = [true false];
            app.PCStatsSwitch.ValueChangedFcn = createCallbackFcn(app, @RedoPCA, true);
            app.PCStatsSwitch.Value = true;
            app.IncludeBlankSamplesCheckBox = uicheckbox(app.PCATab);
            app.IncludeBlankSamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeBlankSamplesCheckBoxValueChanged, true);
            app.IncludeBlankSamplesCheckBox.Text = 'Project in Blank samples';
            app.IncludeReferenceSamplesCheckBox = uicheckbox(app.PCATab);
            app.IncludeReferenceSamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeReferenceSamplesCheckBoxValueChanged, true);
            app.IncludeReferenceSamplesCheckBox.Text = 'Project in Reference samples';
            app.IncludeQCSamplesCheckBox = uicheckbox(app.PCATab);
            app.IncludeQCSamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeQCSamplesCheckBoxValueChanged, true);
            app.IncludeQCSamplesCheckBox.Text = 'Project in QC samples';
            app.IncludeQCSamplesCheckBox.Value = true;
            app.MissingValueImputationButtonGroup = uibuttongroup(app.PCATab);
            app.MissingValueImputationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MissingValueImputationButtonGroupSelectionChanged, true);
            app.MissingValueImputationButtonGroup.Title = 'Missing Value Imputation:';
            app.KNNcolumnButton = uiradiobutton(app.MissingValueImputationButtonGroup);
            app.KNNcolumnButton.Text = 'KNN column';
            app.KNNcolumnButton.Value = true;
            app.KNNrowButton = uiradiobutton(app.MissingValueImputationButtonGroup);
            app.KNNrowButton.Text = 'KNN row';
            app.blank20minButton = uiradiobutton(app.MissingValueImputationButtonGroup);
            app.blank20minButton.Text = 'blank/20%min';
            app.kSpinnerLabel = uilabel(app.MissingValueImputationButtonGroup);
            app.kSpinnerLabel.Text = 'k =';
            app.kSpinner = uispinner(app.MissingValueImputationButtonGroup);
            app.kSpinner.Limits = [1 20];
            app.kSpinner.ValueChangedFcn = createCallbackFcn(app, @kSpinnerValueChanged, true);
            app.kSpinner.Value = 3;
            app.pcaPreprocessingPanel = uipanel(app.PCATab);
            app.pcaPreprocessingPanel.Title = 'Preprocessing:';
            app.Log10TransformCheckBox = uicheckbox(app.pcaPreprocessingPanel);
            app.Log10TransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @Log10TransformCheckBoxValueChanged, true);
            app.Log10TransformCheckBox.Text = 'Log10 Transform';
            app.Log10TransformCheckBox.Value = true;
            app.ScalingButtonGroup = uibuttongroup(app.PCATab);
            app.ScalingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScalingButtonGroupSelectionChanged, true);
            app.MeanCenterOnlyButton = uiradiobutton(app.ScalingButtonGroup);
            app.MeanCenterOnlyButton.Text = 'MeanCenter only';
            app.AutoscaleButton = uiradiobutton(app.ScalingButtonGroup);
            app.AutoscaleButton.Text = 'Autoscale';
            app.AutoscaleButton.Value = true;
            app.ParetoscaleButton = uiradiobutton(app.ScalingButtonGroup);
            app.ParetoscaleButton.Enable = 'off';
            app.ParetoscaleButton.Visible = 'off';
            app.ParetoscaleButton.Text = 'Paretoscale';
            app.ForestPlotTab = uitab(app.TabGroup);
            app.ForestPlotTab.Title = 'Forest Plot';
            app.UIAxesForest = uiaxes(app.ForestPlotTab);
            xlabel(app.UIAxesForest, 'X')
            ylabel(app.UIAxesForest, 'Y')
            zlabel(app.UIAxesForest, 'Z')
            app.UIAxesPooledForest = uiaxes(app.ForestPlotTab);
            app.UIAxesPooledForest.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxesPooledForest.XTickLabel = '';
            app.ForestToolTipLabel = uilabel(app.ForestPlotTab);
            app.ForestToolTipLabel.Visible = 'off';
            app.ForestToolTipLabel.Interpreter = 'html';
            app.ForestToolTipLabel.Text = '';
            app.ForestQCRefSwitch = uiswitch(app.ForestPlotTab, 'slider');
            app.ForestQCRefSwitch.Items = {'qcRSD', 'refRSD'};
            app.ForestQCRefSwitch.ItemsData = [true false];
            app.ForestQCRefSwitch.ValueChangedFcn = createCallbackFcn(app, @ForestChooseQCorREF, true);
            app.ForestQCRefSwitch.Value = true;
            app.SortByDropDownLabel = uilabel(app.ForestPlotTab);
            app.SortByDropDownLabel.Text = 'SortBy:';
            app.ForestSortByDropDown = uidropdown(app.ForestPlotTab);
            app.ForestSortByDropDown.ValueChangedFcn = createCallbackFcn(app, @ForestValueChanged, true);
            app.ForestUpDownButton = uibutton(app.ForestPlotTab, 'state');
            app.ForestUpDownButton.ValueChangedFcn = createCallbackFcn(app, @ForestValueChanged, true);
            app.ForestUpDownButton.Icon = fullfile(pathToMLAPP, 'res', 'up-arrow.png');
            app.ForestUpDownButton.Text = '';
            app.BootNumLabel = uilabel(app.ForestPlotTab);
            app.BootNumLabel.Text = 'BootNum:';
            app.ForestBootNumEditField = uieditfield(app.ForestPlotTab, 'numeric');
            app.ForestBootNumEditField.Limits = [10 1000];
            app.ForestBootNumEditField.RoundFractionalValues = 'on';
            app.ForestBootNumEditField.ValueChangedFcn = createCallbackFcn(app, @ForestBootNumEditFieldValueChanged, true);
            app.ForestBootNumEditField.Value = 100;
            app.ForestFilteredOnlyCheckBox = uicheckbox(app.ForestPlotTab);
            app.ForestFilteredOnlyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForestFilteredOnlyCheckBoxValueChanged, true);
            app.ForestFilteredOnlyCheckBox.Text = 'Filtered Only';
            app.ForestSaveButton = uibutton(app.ForestPlotTab, 'push');
            app.ForestSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ForestSaveButtonPushed, true);
            app.ForestSaveButton.Text = 'Save';
            app.LabelBy = uilabel(app.ForestPlotTab);
            app.LabelBy.Text = 'LabelBy:';
            app.ForestLabelByDropDown = uidropdown(app.ForestPlotTab);
            app.ForestLabelByDropDown.ValueChangedFcn = createCallbackFcn(app, @ForestChooseQCorREF, true);
            app.ForestDisableButton = uibutton(app.ForestPlotTab, 'push');
            app.ForestDisableButton.ButtonPushedFcn = createCallbackFcn(app, @ForestDisableButtonPushed, true);
            app.ForestDisableButton.Text = 'Disable';
            app.ImperialRSDplotTab = uitab(app.TabGroup);
            app.ImperialRSDplotTab.Title = 'Imperial RSD plot';
            app.UIAxesImperialRSD = uiaxes(app.ImperialRSDplotTab);
            xlabel(app.UIAxesImperialRSD, 'X')
            ylabel(app.UIAxesImperialRSD, 'Y')
            zlabel(app.UIAxesImperialRSD, 'Z')
            app.SortbySwitch = uiswitch(app.ImperialRSDplotTab, 'slider');
            app.SortbySwitch.Items = {'sampleRSD', 'qcRSD'};
            app.SortbySwitch.ItemsData = {'sample', 'qc'};
            app.SortbySwitch.ValueChangedFcn = createCallbackFcn(app, @ImperialPlotValueChanged, true);
            app.SortbySwitch.Value = 'sample';
            app.RefCheckBox = uicheckbox(app.ImperialRSDplotTab);
            app.RefCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefCheckBoxValueChanged, true);
            app.RefCheckBox.Text = 'Plot Reference RSD';
            app.SortbySwitchLabel = uilabel(app.ImperialRSDplotTab);
            app.SortbySwitchLabel.Text = 'Sort by';
            app.QCCheckBox = uicheckbox(app.ImperialRSDplotTab);
            app.QCCheckBox.ValueChangedFcn = createCallbackFcn(app, @QCCheckBoxValueChanged, true);
            app.QCCheckBox.Text = 'Plot QC RSD';
            app.QCCheckBox.Value = true;
            app.ImperialToolTipLabel = uilabel(app.ImperialRSDplotTab);
            app.ImperialToolTipLabel.Visible = 'off';
            app.ImperialToolTipLabel.Interpreter = 'html';
            app.ImperialToolTipLabel.Text = '';
            app.RSDScatterplotTab = uitab(app.TabGroup);
            app.RSDScatterplotTab.Title = 'RSD Scatter plot';
            app.UIAxesRSDComparison = uiaxes(app.RSDScatterplotTab);
            xlabel(app.UIAxesRSDComparison, 'X')
            ylabel(app.UIAxesRSDComparison, 'Y')
            zlabel(app.UIAxesRSDComparison, 'Z')
            app.ComparisonToolTipLabel = uilabel(app.RSDScatterplotTab);
            app.ComparisonToolTipLabel.Visible = 'off';
            app.ComparisonToolTipLabel.Interpreter = 'html';
            app.ComparisonToolTipLabel.Text = '';
            app.compareRSDPlotByQC = uiswitch(app.RSDScatterplotTab, 'slider');
            app.compareRSDPlotByQC.Items = {'qcRSD', 'referenceRSD'};
            app.compareRSDPlotByQC.ItemsData = [true false];
            app.compareRSDPlotByQC.ValueChangedFcn = createCallbackFcn(app, @CompareRSDplotValueChanged, true);
            app.compareRSDPlotByQC.Value = true;
            app.RSDTab = uitab(app.TabGroup);
            app.RSDTab.Title = 'RSD';
            app.UIAxesRSDpdf = uiaxes(app.RSDTab);
            xlabel(app.UIAxesRSDpdf, 'X')
            ylabel(app.UIAxesRSDpdf, 'Y')
            zlabel(app.UIAxesRSDpdf, 'Z')
            app.SwitchRSDPlot = uiswitch(app.RSDTab, 'slider');
            app.SwitchRSDPlot.Items = {'Complete Data Set', 'Batch Breakdown'};
            app.SwitchRSDPlot.ItemsData = {'all', 'batch'};
            app.SwitchRSDPlot.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.SwitchRSDPlot.Value = 'all';
            app.SwitchRSDpdf = uiswitch(app.RSDTab, 'slider');
            app.SwitchRSDpdf.Items = {'Histogram', 'p.d.f.'};
            app.SwitchRSDpdf.ItemsData = {'histogram', 'pdf'};
            app.SwitchRSDpdf.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.SwitchRSDpdf.Value = 'histogram';
            app.SmoothSlider_2Label = uilabel(app.RSDTab);
            app.SmoothSlider_2Label.Visible = 'off';
            app.SmoothSlider_2Label.Text = 'Smooth:';
            app.SmoothRSDslider = uislider(app.RSDTab);
            app.SmoothRSDslider.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.SmoothRSDslider.Visible = 'off';
            app.SmoothRSDslider.Value = 20;
            app.RSDDropDown = uidropdown(app.RSDTab);
            app.RSDDropDown.Items = {'qcRSD', 'refRSD', 'sampleRSD'};
            app.RSDDropDown.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.RSDDropDown.Value = 'qcRSD';
            app.dRatioTab = uitab(app.TabGroup);
            app.dRatioTab.Title = 'dRatio';
            app.UIAxesDRATIOpdf = uiaxes(app.dRatioTab);
            xlabel(app.UIAxesDRATIOpdf, 'X')
            ylabel(app.UIAxesDRATIOpdf, 'Y')
            zlabel(app.UIAxesDRATIOpdf, 'Z')
            app.SwitchDratioPlot = uiswitch(app.dRatioTab, 'slider');
            app.SwitchDratioPlot.Items = {'Complete Data Set', 'Batch Breakdown'};
            app.SwitchDratioPlot.ItemsData = {'all', 'batch'};
            app.SwitchDratioPlot.ValueChangedFcn = createCallbackFcn(app, @DratioPlotValueChanged, true);
            app.SwitchDratioPlot.Value = 'all';
            app.SwitchDRATIOpdf = uiswitch(app.dRatioTab, 'slider');
            app.SwitchDRATIOpdf.Items = {'Histogram', 'p.d.f.'};
            app.SwitchDRATIOpdf.ItemsData = {'histogram', 'pdf'};
            app.SwitchDRATIOpdf.ValueChangedFcn = createCallbackFcn(app, @DratioPlotValueChanged, true);
            app.SwitchDRATIOpdf.Value = 'histogram';
            app.SmoothSlider_2Label_2 = uilabel(app.dRatioTab);
            app.SmoothSlider_2Label_2.Visible = 'off';
            app.SmoothSlider_2Label_2.Text = 'Smooth:';
            app.SmoothDRATIOslider = uislider(app.dRatioTab);
            app.SmoothDRATIOslider.ValueChangedFcn = createCallbackFcn(app, @DratioPlotValueChanged, true);
            app.SmoothDRATIOslider.Visible = 'off';
            app.SmoothDRATIOslider.Value = 20;
            app.blankRatioTab = uitab(app.TabGroup);
            app.blankRatioTab.Title = 'blankRatio';
            app.UIAxesBLANKpdf = uiaxes(app.blankRatioTab);
            xlabel(app.UIAxesBLANKpdf, 'X')
            ylabel(app.UIAxesBLANKpdf, 'Y')
            zlabel(app.UIAxesBLANKpdf, 'Z')
            app.SwitchBlankPlot = uiswitch(app.blankRatioTab, 'slider');
            app.SwitchBlankPlot.Items = {'Complete Data Set', 'Batch Breakdown'};
            app.SwitchBlankPlot.ItemsData = {'all', 'batch'};
            app.SwitchBlankPlot.ValueChangedFcn = createCallbackFcn(app, @BlankPlotValueChanged, true);
            app.SwitchBlankPlot.Value = 'all';
            app.SwitchBlankpdf = uiswitch(app.blankRatioTab, 'slider');
            app.SwitchBlankpdf.Items = {'Histogram', 'p.d.f.'};
            app.SwitchBlankpdf.ItemsData = {'histogram', 'pdf'};
            app.SwitchBlankpdf.ValueChangedFcn = createCallbackFcn(app, @BlankPlotValueChanged, true);
            app.SwitchBlankpdf.Value = 'histogram';
            app.SmoothSlider_2Label_3 = uilabel(app.blankRatioTab);
            app.SmoothSlider_2Label_3.Visible = 'off';
            app.SmoothSlider_2Label_3.Text = 'Smooth:';
            app.SmoothBLANKslider = uislider(app.blankRatioTab);
            app.SmoothBLANKslider.ValueChangedFcn = createCallbackFcn(app, @BlankPlotValueChanged, true);
            app.SmoothBLANKslider.Visible = 'off';
            app.SmoothBLANKslider.Value = 20;
            app.BlankRatioMethodDropDownLabel = uilabel(app.blankRatioTab);
            app.BlankRatioMethodDropDownLabel.Text = 'Blank Ratio Method';
            app.CleanBlankRatioMethodDropDown = uidropdown(app.blankRatioTab);
            app.CleanBlankRatioMethodDropDown.Items = {'QC', 'Median', 'Percentile'};
            app.CleanBlankRatioMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CleanBlankRatioMethodDropDownValueChanged, true);
            app.CleanBlankRatioMethodDropDown.Value = 'QC';
            app.QCRSCgTab = uitab(app.TabGroup);
            app.QCRSCgTab.Title = 'QCRSC g';
            app.UIAxesQCRSCpdf = uiaxes(app.QCRSCgTab);
            xlabel(app.UIAxesQCRSCpdf, 'X')
            ylabel(app.UIAxesQCRSCpdf, 'Y')
            zlabel(app.UIAxesQCRSCpdf, 'Z')
            app.CleanFeaturesRemainingLabel = uilabel(app.CleanExplorePanel);
            app.CleanFeaturesRemainingLabel.Text = '#Clean Features:';
            app.MPQ95Label = uilabel(app.CleanExplorePanel);
            app.MPQ95Label.Interpreter = 'html';
            app.MPQ95Label.Text = 'XXX';
            app.MPQLabel = uilabel(app.CleanExplorePanel);
            app.MPQLabel.Text = 'XXX';
            app.TabGroup2 = uitabgroup(app.CleanExplorePanel);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.FilterTab = uitab(app.TabGroup2);
            app.FilterTab.Title = 'Filter';
            app.PanelKnobs = uipanel(app.FilterTab);
            app.QCRSDKnobLabel = uilabel(app.PanelKnobs);
            app.QCRSDKnobLabel.Text = 'QC-RSD';
            app.QCRSDKnob = uiknob(app.PanelKnobs, 'discrete');
            app.QCRSDKnob.Items = {'5', '10', '15', '20', '25', '30', '35', '40', '50', '75', 'Off'};
            app.QCRSDKnob.ItemsData = [5 10 15 20 25 30 35 40 50 75 100];
            app.QCRSDKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.QCRSDKnob.Value = 5;
            app.DRatioKnobLabel = uilabel(app.PanelKnobs);
            app.DRatioKnobLabel.Text = 'D-Ratio';
            app.DRatioKnob = uiknob(app.PanelKnobs, 'discrete');
            app.DRatioKnob.Items = {'2', '5', '10', '20', '25', '30', '40', '50', '60', '75', 'Off'};
            app.DRatioKnob.ItemsData = [2 5 10 20 25 30 40 50 60 75 100];
            app.DRatioKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.DRatioKnob.Value = 2;
            app.BlankRatioKnob = uiknob(app.PanelKnobs, 'discrete');
            app.BlankRatioKnob.Items = {'0.1', '1', '5', '10', '20', '30', '40', '50', '60', '70', 'Off'};
            app.BlankRatioKnob.ItemsData = [0.11 1 5 10 20 30 40 50 60 70 100];
            app.BlankRatioKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.BlankRatioKnob.Value = 0.11;
            app.BlankRatioKnobLabel = uilabel(app.PanelKnobs);
            app.BlankRatioKnobLabel.Text = 'Blank Ratio';
            app.SampleRSDKnobLabel = uilabel(app.PanelKnobs);
            app.SampleRSDKnobLabel.Text = 'Sample-RSD';
            app.SampleRSDKnob = uiknob(app.PanelKnobs, 'discrete');
            app.SampleRSDKnob.Items = {'50', '40', '35', '30', '25', '20', '15', '10', '5', '2 ', 'Off'};
            app.SampleRSDKnob.ItemsData = [50 40 35 30 25 20 15 10 5 2 0];
            app.SampleRSDKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.SampleRSDKnob.Value = 50;
            app.FilterBankSwitchLabel = uilabel(app.PanelKnobs);
            app.FilterBankSwitchLabel.Text = 'Filter Bank';
            app.FilterBankSwitch = uiswitch(app.PanelKnobs, 'rocker');
            app.FilterBankSwitch.ItemsData = [false true];
            app.FilterBankSwitch.ValueChangedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterBankSwitch.Value = false;
            app.FilterBankLamp = uilamp(app.PanelKnobs);
            app.FilterBankModeDropDown = uidropdown(app.PanelKnobs);
            app.FilterBankModeDropDown.Items = {'Complete', 'Median', 'Min', 'Max'};
            app.FilterBankModeDropDown.ItemsData = {'Complete', 'Median', 'Min', 'Max'};
            app.FilterBankModeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterBankModeDropDownValueChanged, true);
            app.FilterBankModeDropDown.Value = 'Complete';
            app.FilterTypeLabel = uilabel(app.PanelKnobs);
            app.FilterTypeLabel.Text = 'Mode:';
            app.CleanFilterOnQC = uiswitch(app.PanelKnobs, 'slider');
            app.CleanFilterOnQC.Items = {'QC', 'Reference'};
            app.CleanFilterOnQC.ItemsData = [true false];
            app.CleanFilterOnQC.ValueChangedFcn = createCallbackFcn(app, @CleanFilterOnQCValueChanged, true);
            app.CleanFilterOnQC.Value = true;
            app.MissingSamplesFilterPanel = uipanel(app.FilterTab);
            app.MissingSamplesFilterPanel.Title = 'Missing Samples Filter:';
            app.missingperfeatureLabel = uilabel(app.MissingSamplesFilterPanel);
            app.missingperfeatureLabel.Text = '%missing per feature <=';
            app.SampleFilterPercentEditField = uieditfield(app.MissingSamplesFilterPanel, 'numeric');
            app.SampleFilterPercentEditField.Limits = [0 100];
            app.SampleFilterPercentEditField.ValueChangedFcn = createCallbackFcn(app, @SampleFilterPercentEditFieldValueChanged, true);
            app.SampleFilterPercentEditField.Value = 100;
            app.featuresremainingLabel = uilabel(app.MissingSamplesFilterPanel);
            app.featuresremainingLabel.Text = '# features remaining:';
            app.SampleFilterDropDown = uidropdown(app.MissingSamplesFilterPanel);
            app.SampleFilterDropDown.Items = {'Complete', 'Every Batch', 'Any Batch'};
            app.SampleFilterDropDown.ItemsData = {'Complete', 'EveryBatch', 'AnyBatch'};
            app.SampleFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @CleanMissingSampleFilterValueChange, true);
            app.SampleFilterDropDown.Value = 'EveryBatch';
            app.BatchSelectTab = uitab(app.TabGroup2);
            app.BatchSelectTab.Title = 'Batch Select';
            app.RevertButton = uibutton(app.BatchSelectTab, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Enable = 'off';
            app.RevertButton.Text = 'Revert';
            app.InvertButton = uibutton(app.BatchSelectTab, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @InvertButtonPushed, true);
            app.InvertButton.Text = 'Invert';
            app.ResetButton = uibutton(app.BatchSelectTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.SendButton = uibutton(app.BatchSelectTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.ViewBatchTableButton = uibutton(app.BatchSelectTab, 'push');
            app.ViewBatchTableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewBatchTableButtonPushed, true);
            app.ViewBatchTableButton.Icon = fullfile(pathToMLAPP, 'res', 'StatsPopOutx.png');
            app.ViewBatchTableButton.Text = '';
            app.UITable = uitable(app.BatchSelectTab);
            app.UITable.ColumnName = {'Batch'; 'Include'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.SwitchBeforeAfter = uiswitch(app.CleanExplorePanel, 'slider');
            app.SwitchBeforeAfter.Items = {'Before', 'After'};
            app.SwitchBeforeAfter.ItemsData = [false true];
            app.SwitchBeforeAfter.ValueChangedFcn = createCallbackFcn(app, @SwitchBeforeAfterValueChanged, true);
            app.SwitchBeforeAfter.Value = true;
            app.BatchExploredLabel = uilabel(app.CleanExplorePanel);
            app.BatchExploredLabel.Text = 'Exploring ALL batches';
            app.QCCorrectionEnginePanel = uipanel(app.UIFigure);
            app.QCCorrectionEnginePanel.Title = 'QC Correction Engine:';
            app.SavePanel = uipanel(app.QCCorrectionEnginePanel);
            app.SaveButton = uibutton(app.SavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.suffixLabel = uilabel(app.SavePanel);
            app.suffixLabel.Text = 'suffix:';
            app.CorrectedSuffixEditField = uieditfield(app.SavePanel, 'text');
            app.CorrectedSuffixEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectedSuffixEditFieldValueChanged, true);
            app.CorrectedSuffixEditField.Tooltip = {'_QCRSC'};
            app.CorrectedSuffixEditField.Value = '_QCRSC';
            app.peakscorrectedLabel = uilabel(app.QCCorrectionEnginePanel);
            app.peakscorrectedLabel.Text = '% features corrected';
            app.RunningLampLabel = uilabel(app.QCCorrectionEnginePanel);
            app.RunningLampLabel.Text = 'Running';
            app.CompleteLampLabel = uilabel(app.QCCorrectionEnginePanel);
            app.CompleteLampLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.QCCorrectionEnginePanel);
            app.RunningLamp = uilamp(app.QCCorrectionEnginePanel);
            app.featuresCorrectedGauge = uigauge(app.QCCorrectionEnginePanel, 'circular');
            app.BypassButton = uibutton(app.QCCorrectionEnginePanel, 'push');
            app.BypassButton.ButtonPushedFcn = createCallbackFcn(app, @BypassButtonPushed, true);
            app.BypassButton.Icon = 'bypass2.png';
            app.BypassButton.Tooltip = {''};
            app.BypassButton.Text = '';
            app.AbortButton = uibutton(app.QCCorrectionEnginePanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Icon = 'eject2.png';
            app.AbortButton.Tooltip = {''};
            app.AbortButton.Text = '';
            app.PauseButton = uibutton(app.QCCorrectionEnginePanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'pause2.png';
            app.PauseButton.Tooltip = {''};
            app.PauseButton.Text = '';
            app.RunButton = uibutton(app.QCCorrectionEnginePanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.RunButton.Icon = 'play2.png';
            app.RunButton.Tooltip = {''};
            app.RunButton.Text = '';
            app.ParallelProcessingCheckBox = uicheckbox(app.QCCorrectionEnginePanel);
            app.ParallelProcessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParallelProcessingCheckBoxValueChanged, true);
            app.ParallelProcessingCheckBox.Text = 'Parallel Processing';
            app.ParallelProcessingCheckBox.Value = true;
            app.LaunchFeatureExplorer = uibutton(app.UIFigure, 'push');
            app.LaunchFeatureExplorer.ButtonPushedFcn = createCallbackFcn(app, @LaunchFeatureExplorerPushed, true);
            app.LaunchFeatureExplorer.Icon = fullfile(pathToMLAPP, 'res', 'BatchExplorer2.png');
            app.LaunchFeatureExplorer.Text = '';
            app.QCCorrectionParametersPanel = uipanel(app.UIFigure);
            app.QCCorrectionParametersPanel.Title = 'QC Correction Parameters:';
            app.OutlierMethodLabel = uilabel(app.QCCorrectionParametersPanel);
            app.OutlierMethodLabel.Text = 'QC Outlier Detection Method:';
            app.OutlierCILabel = uilabel(app.QCCorrectionParametersPanel);
            app.OutlierCILabel.Text = 'QC Outlier Detection %CI:';
            app.OutlierPostHocLabel = uilabel(app.QCCorrectionParametersPanel);
            app.OutlierPostHocLabel.Text = 'QC Outlier Replacement Strategy:';
            app.IntraBatchModeLabel = uilabel(app.QCCorrectionParametersPanel);
            app.IntraBatchModeLabel.Text = 'Within-Batch Correction Mode:';
            app.BetweenBatchMethodLabel = uilabel(app.QCCorrectionParametersPanel);
            app.BetweenBatchMethodLabel.Text = 'Between-Batch Correction Mode:';
            app.QCRSCCVMethodLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCCVMethodLabel.Text = 'QCRSC Cross Validation Method:';
            app.QCRSCMCRepsLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCMCRepsLabel.Text = 'QCRSC Monte Carlo repetitions:';
            app.QCRSCMonteCarlorepetitionsEditField = uieditfield(app.QCCorrectionParametersPanel, 'numeric');
            app.QCRSCMonteCarlorepetitionsEditField.Limits = [1 Inf];
            app.QCRSCMonteCarlorepetitionsEditField.RoundFractionalValues = 'on';
            app.QCRSCMonteCarlorepetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCRSCMonteCarlorepetitionsEditField.Value = 10;
            app.QCRSCCrossValidationMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCRSCCrossValidationMethodDropDown.Items = {'3-Fold', '5-Fold', '7-Fold', 'Leaveout'};
            app.QCRSCCrossValidationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCRSCCrossValidationMethodDropDown.Value = '3-Fold';
            app.BetweenBatchCorrectionModeDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.BetweenBatchCorrectionModeDropDown.Items = {'QC', 'Reference', 'Sample'};
            app.BetweenBatchCorrectionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.BetweenBatchCorrectionModeDropDown.Value = 'QC';
            app.WithinBatchCorrectionModeDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.WithinBatchCorrectionModeDropDown.Items = {'Sample', 'Median', 'Linear', 'Spline', 'Spline-C2', 'Spline-C4', 'Spline-C6'};
            app.WithinBatchCorrectionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.WithinBatchCorrectionModeDropDown.Value = 'Spline';
            app.QCOutlierReplacementStrategyDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCOutlierReplacementStrategyDropDown.Items = {'Median', 'Ignore', 'NaN'};
            app.QCOutlierReplacementStrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCOutlierReplacementStrategyDropDown.Value = 'Median';
            app.QCOutlierDetectionCIEditField = uieditfield(app.QCCorrectionParametersPanel, 'numeric');
            app.QCOutlierDetectionCIEditField.Limits = [0.9 0.999];
            app.QCOutlierDetectionCIEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCOutlierDetectionCIEditField.Value = 0.95;
            app.QCOutlierDetectionMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCOutlierDetectionMethodDropDown.Items = {'None', 'Percentile', 'Linear', 'Quadratic', 'Cubic'};
            app.QCOutlierDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCOutlierDetectionMethodDropDown.Value = 'None';
            app.RemoveZerosCheckBox = uicheckbox(app.QCCorrectionParametersPanel);
            app.RemoveZerosCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.RemoveZerosCheckBox.Text = 'Remove Zeros';
            app.RemoveZerosCheckBox.Value = true;
            app.LogTransformedCorrectionCheckBox = uicheckbox(app.QCCorrectionParametersPanel);
            app.LogTransformedCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.LogTransformedCorrectionCheckBox.Text = 'Log Transformed Correction';
            app.LogTransformedCorrectionCheckBox.Value = true;
            app.BlankRatioMethodDropDown_2Label = uilabel(app.QCCorrectionParametersPanel);
            app.BlankRatioMethodDropDown_2Label.Text = 'Blank Ratio Method:';
            app.BlankRatioMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.BlankRatioMethodDropDown.Items = {'QC', 'Median', 'Percentile'};
            app.BlankRatioMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.BlankRatioMethodDropDown.Value = 'QC';
            app.LaunchAlgorithmExplorer = uibutton(app.UIFigure, 'push');
            app.LaunchAlgorithmExplorer.ButtonPushedFcn = createCallbackFcn(app, @LaunchQCcorrectionExplorerPushed, true);
            app.LaunchAlgorithmExplorer.Icon = fullfile(pathToMLAPP, 'res', 'QCRSCexplorerX.png');
            app.LaunchAlgorithmExplorer.Text = '';
            app.FeatureInclusionPreFilterPanel = uipanel(app.UIFigure);
            app.FeatureInclusionPreFilterPanel.Title = 'Feature Inclusion PreFilter:';
            app.UIAxesPreprocessing = uiaxes(app.FeatureInclusionPreFilterPanel);
            xlabel(app.UIAxesPreprocessing, '% QC missing (per batch)')
            zlabel(app.UIAxesPreprocessing, 'Z')
            app.featuresremainingLabel_2 = uilabel(app.FeatureInclusionPreFilterPanel);
            app.featuresremainingLabel_2.Text = '# features remaining: ';
            app.QCLOQmissingCheckBox = uicheckbox(app.FeatureInclusionPreFilterPanel);
            app.QCLOQmissingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FeatureInclusionPreFilterPanelValueChanged, true);
            app.QCLOQmissingCheckBox.Text = 'QC < LOQ* = missing';
            app.QCLOQmissingCheckBox.Value = true;
            app.xmaxBlankLabel = uilabel(app.FeatureInclusionPreFilterPanel);
            app.xmaxBlankLabel.Text = 'x max(Blank)';
            app.aLabel = uilabel(app.FeatureInclusionPreFilterPanel);
            app.aLabel.Text = 'LOQ* =';
            app.LOQalphaEditField = uieditfield(app.FeatureInclusionPreFilterPanel, 'numeric');
            app.LOQalphaEditField.Limits = [1 Inf];
            app.LOQalphaEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureInclusionPreFilterPanelValueChanged, true);
            app.LOQalphaEditField.Value = 1.2;
            app.PreFilterDropDown = uidropdown(app.FeatureInclusionPreFilterPanel);
            app.PreFilterDropDown.Items = {'Complete', 'Every Batch', 'Any Batch', 'N Batch', 'Disable'};
            app.PreFilterDropDown.ItemsData = {'Complete', 'EveryBatch', 'AnyBatch', 'NBatch', 'Disable'};
            app.PreFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureInclusionPreFilterPanelValueChanged, true);
            app.PreFilterDropDown.Value = 'EveryBatch';
            app.QCsmissingperfeatureLabel = uilabel(app.FeatureInclusionPreFilterPanel);
            app.QCsmissingperfeatureLabel.Text = '%QCs missing per feature <=';
            app.missingQCperFeatureEditField = uieditfield(app.FeatureInclusionPreFilterPanel, 'numeric');
            app.missingQCperFeatureEditField.Limits = [0 100];
            app.missingQCperFeatureEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureInclusionPreFilterPanelValueChanged, true);
            app.missingQCperFeatureEditField.Value = 20;
            app.drinkMeButton = uibutton(app.FeatureInclusionPreFilterPanel, 'state');
            app.drinkMeButton.ValueChangedFcn = createCallbackFcn(app, @drinkMeButtonValueChanged, true);
            app.drinkMeButton.Icon = 'drinkme.png';
            app.drinkMeButton.Text = '';
            app.QCsmissingperfeatureLabel_2 = uilabel(app.FeatureInclusionPreFilterPanel);
            app.QCsmissingperfeatureLabel_2.Visible = 'off';
            app.QCsmissingperfeatureLabel_2.Text = 'N >=';
            app.NbatchEditField = uieditfield(app.FeatureInclusionPreFilterPanel, 'numeric');
            app.NbatchEditField.Limits = [-1 Inf];
            app.NbatchEditField.RoundFractionalValues = 'on';
            app.NbatchEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureInclusionPreFilterPanelValueChanged, true);
            app.NbatchEditField.Visible = 'off';
            app.DefaultConfigurationPanel = uipanel(app.UIFigure);
            app.DefaultConfigurationPanel.Title = 'Default Configuration';
            app.LoadConfigButton = uibutton(app.DefaultConfigurationPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Tooltip = {''};
            app.LoadConfigButton.Text = 'Load Config';
            app.SaveConfigButton = uibutton(app.DefaultConfigurationPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Tooltip = {''};
            app.SaveConfigButton.Text = 'Save Config';
            app.ResetConfigButton = uibutton(app.DefaultConfigurationPanel, 'push');
            app.ResetConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ResetConfigButtonPushed, true);
            app.ResetConfigButton.Tooltip = {''};
            app.ResetConfigButton.Text = 'Reset Config';
            app.IncomingDataPanel = uipanel(app.UIFigure);
            app.IncomingDataPanel.Title = 'Incoming Data:';
            app.NumberofSamplesLabel = uilabel(app.IncomingDataPanel);
            app.NumberofSamplesLabel.Interpreter = 'html';
            app.NumberofSamplesLabel.Text = 'Number of Samples:';
            app.NumberofFeaturesLabel = uilabel(app.IncomingDataPanel);
            app.NumberofFeaturesLabel.Interpreter = 'html';
            app.NumberofFeaturesLabel.Text = 'Number of Features:';
            app.ViewFeatureTableButton = uibutton(app.IncomingDataPanel, 'push');
            app.ViewFeatureTableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFeatureTableButtonPushed, true);
            app.ViewFeatureTableButton.Text = 'View Feature Table';
            app.LOADDATAButton = uibutton(app.IncomingDataPanel, 'push');
            app.LOADDATAButton.ButtonPushedFcn = createCallbackFcn(app, @LOADDATAButtonPushed, true);
            app.LOADDATAButton.Text = 'Load Data';
            app.NumberofBatchesLabel = uilabel(app.IncomingDataPanel);
            app.NumberofBatchesLabel.Interpreter = 'html';
            app.NumberofBatchesLabel.Text = 'Number of Batches:';
            app.NumberofQCLabel = uilabel(app.IncomingDataPanel);
            app.NumberofQCLabel.Interpreter = 'html';
            app.NumberofQCLabel.Text = 'Number of QC:';
            app.NumberofReferenceLabel = uilabel(app.IncomingDataPanel);
            app.NumberofReferenceLabel.Interpreter = 'html';
            app.NumberofReferenceLabel.Text = 'Number of Reference:';
            app.NumberofBlanksLabel = uilabel(app.IncomingDataPanel);
            app.NumberofBlanksLabel.Interpreter = 'html';
            app.NumberofBlanksLabel.Text = 'Number of Blanks:';
            app.FeatureTableLabel = uilabel(app.IncomingDataPanel);
            app.FeatureTableLabel.Interpreter = 'html';
            app.FeatureTableLabel.Text = 'Feature Table:';
            app.DataTableLabel = uilabel(app.IncomingDataPanel);
            app.DataTableLabel.Interpreter = 'html';
            app.DataTableLabel.Text = 'DataTable:';
            app.ProjectNameLabel = uilabel(app.IncomingDataPanel);
            app.ProjectNameLabel.Interpreter = 'html';
            app.ProjectNameLabel.Text = 'ProjectName:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'textx10.png';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'http://www.qcmxp.org';
            app.Hyperlink.Text = 'QCMXP.org';
            app.Panel = uipanel(app.UIFigure);
            app.CHEATSHEETButton = uibutton(app.UIFigure, 'push');
            app.CHEATSHEETButton.ButtonPushedFcn = createCallbackFcn(app, @CHEATSHEETButtonPushed, true);
            app.CHEATSHEETButton.Text = 'CHEATSHEET';
            app.QualityControlMultivariateExplorerVersion40Label = uilabel(app.UIFigure);
            app.QualityControlMultivariateExplorerVersion40Label.Text = 'Quality Control Multivariate Explorer Version 4.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCmXP(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end