classdef QCmXP3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        Hyperlink                       matlab.ui.control.Hyperlink
        Image                           matlab.ui.control.Image
        VersionLabel                    matlab.ui.control.Label
        Logo                            matlab.ui.control.Image
        IncomingDataPanel               matlab.ui.container.Panel
        ProjectNameLabel                matlab.ui.control.Label
        DataTableLabel                  matlab.ui.control.Label
        FeatureTableLabel               matlab.ui.control.Label
        NumberofBlanksLabel             matlab.ui.control.Label
        NumberofReferenceLabel          matlab.ui.control.Label
        NumberofQCLabel                 matlab.ui.control.Label
        NumberofBatchesLabel            matlab.ui.control.Label
        LOADDATAButton                  matlab.ui.control.Button
        ViewFeatureTableButton          matlab.ui.control.Button
        NumberofFeaturesLabel           matlab.ui.control.Label
        NumberofSamplesLabel            matlab.ui.control.Label
        QCMXPConfigurationPanel         matlab.ui.container.Panel
        ResetConfigButton               matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        FeatureInclusionPreFilterPanel  matlab.ui.container.Panel
        drinkMeButton                   matlab.ui.control.StateButton
        missingQCperFeatureEditField    matlab.ui.control.NumericEditField
        QCsmissingperfeatureLabel       matlab.ui.control.Label
        PreFilterDropDown               matlab.ui.control.DropDown
        LOQalphaEditField               matlab.ui.control.NumericEditField
        aLabel                          matlab.ui.control.Label
        xmaxBlankLabel                  matlab.ui.control.Label
        QCLOQmissingCheckBox            matlab.ui.control.CheckBox
        FeaturesremainingLabel          matlab.ui.control.Label
        UIAxesPreprocessing             matlab.ui.control.UIAxes
        LaunchAlgorithmExplorer         matlab.ui.control.Button
        QCCorrectionParametersPanel     matlab.ui.container.Panel
        BlankRatioMethodDropDown        matlab.ui.control.DropDown
        BlankRatioMethodDropDown_2Label  matlab.ui.control.Label
        LogTransformedCorrectionCheckBox  matlab.ui.control.CheckBox
        RemoveZerosCheckBox             matlab.ui.control.CheckBox
        QCOutlierDetectionMethodDropDown  matlab.ui.control.DropDown
        QCOutlierDetectionCIEditField   matlab.ui.control.NumericEditField
        QCOutlierReplacementStrategyDropDown  matlab.ui.control.DropDown
        WithinBatchCorrectionModeDropDown  matlab.ui.control.DropDown
        BetweenBatchCorrectionModeDropDown  matlab.ui.control.DropDown
        QCRSCgammarangeEditField        matlab.ui.control.EditField
        QCRSCCrossValidationMethodDropDown  matlab.ui.control.DropDown
        QCRSCMonteCarlorepetitionsEditField  matlab.ui.control.NumericEditField
        QCRSCMCRepsLabel                matlab.ui.control.Label
        QCRSCCVMethodLabel              matlab.ui.control.Label
        QCRSCgammarangeLabel            matlab.ui.control.Label
        BetweenBatchMethodLabel         matlab.ui.control.Label
        IntraBatchModeLabel             matlab.ui.control.Label
        OutlierPostHocLabel             matlab.ui.control.Label
        OutlierCILabel                  matlab.ui.control.Label
        OutlierMethodLabel              matlab.ui.control.Label
        LaunchFeatureExplorer           matlab.ui.control.Button
        QCCorrectionEnginePanel         matlab.ui.container.Panel
        ParallelProcessingCheckBox      matlab.ui.control.CheckBox
        RunButton                       matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        AbortButton                     matlab.ui.control.Button
        BypassButton                    matlab.ui.control.Button
        featuresCorrectedGauge          matlab.ui.control.Gauge
        RunningLamp                     matlab.ui.control.Lamp
        CompleteLamp                    matlab.ui.control.Lamp
        CompleteLampLabel               matlab.ui.control.Label
        RunningLampLabel                matlab.ui.control.Label
        peakscorrectedLabel             matlab.ui.control.Label
        SavePanel                       matlab.ui.container.Panel
        CorrectedSuffixEditField        matlab.ui.control.EditField
        suffixLabel                     matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        CleanExplorePanel               matlab.ui.container.Panel
        BatchDropDown                   matlab.ui.control.DropDown
        BatchLabel                      matlab.ui.control.Label
        MissingSamplesFilterPanel       matlab.ui.container.Panel
        SampleFilterDropDown            matlab.ui.control.DropDown
        featuresremainingLabel          matlab.ui.control.Label
        SampleFilterPercentEditField    matlab.ui.control.NumericEditField
        missingperfeatureLabel          matlab.ui.control.Label
        SwitchBeforeAfter               matlab.ui.control.Switch
        PanelKnobs                      matlab.ui.container.Panel
        FilterTypeLabel                 matlab.ui.control.Label
        FilterBankModeDropDown          matlab.ui.control.DropDown
        FilterBankLamp                  matlab.ui.control.Lamp
        FilterBankSwitch                matlab.ui.control.RockerSwitch
        FilterBankLabel                 matlab.ui.control.Label
        QCRSDKnob                       matlab.ui.control.DiscreteKnob
        SampleRSDKnob                   matlab.ui.control.DiscreteKnob
        SampleRSDKnobLabel              matlab.ui.control.Label
        BlankRatioKnobLabel             matlab.ui.control.Label
        BlankRatioKnob                  matlab.ui.control.DiscreteKnob
        DRatioKnob                      matlab.ui.control.DiscreteKnob
        DRatioKnobLabel                 matlab.ui.control.Label
        QCRSDKnobLabel                  matlab.ui.control.Label
        ResetFilterButton               matlab.ui.control.Button
        MPQLabel                        matlab.ui.control.Label
        MPQ95Label                      matlab.ui.control.Label
        CleanFeaturesRemainingLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PCATab                          matlab.ui.container.Tab
        ScalingButtonGroup              matlab.ui.container.ButtonGroup
        ParetoscaleButton               matlab.ui.control.RadioButton
        AutoscaleButton                 matlab.ui.control.RadioButton
        MeanCenterOnlyButton            matlab.ui.control.RadioButton
        pcaPreprocessingPanel           matlab.ui.container.Panel
        Log10TransformCheckBox          matlab.ui.control.CheckBox
        MissingValueImputationButtonGroup  matlab.ui.container.ButtonGroup
        kSpinner                        matlab.ui.control.Spinner
        kSpinnerLabel                   matlab.ui.control.Label
        blank20minButton                matlab.ui.control.RadioButton
        KNNrowButton                    matlab.ui.control.RadioButton
        KNNcolumnButton                 matlab.ui.control.RadioButton
        IncludeQCSamplesCheckBox        matlab.ui.control.CheckBox
        IncludeReferenceSamplesCheckBox  matlab.ui.control.CheckBox
        IncludeBlankSamplesCheckBox     matlab.ui.control.CheckBox
        PCStatsSwitch                   matlab.ui.control.Switch
        PopOutButton                    matlab.ui.control.Button
        LabelbyDropDown                 matlab.ui.control.DropDown
        LabelbyDropDownLabel            matlab.ui.control.Label
        VariationExplainedbyeachPCLabel  matlab.ui.control.Label
        DataPointsCheckBox              matlab.ui.control.CheckBox
        ConfidenceIntervalsCheckBoxB    matlab.ui.control.CheckBox
        ConfidenceIntervalsCheckBoxA    matlab.ui.control.CheckBox
        LegendCheckBox                  matlab.ui.control.CheckBox
        YAxisDropDown                   matlab.ui.control.DropDown
        YAxisDropDownLabel              matlab.ui.control.Label
        XAxisDropDown                   matlab.ui.control.DropDown
        XAxisDropDownLabel              matlab.ui.control.Label
        UIAxesPVE2                      matlab.ui.control.UIAxes
        UIAxesPVE                       matlab.ui.control.UIAxes
        UIAxesPCA                       matlab.ui.control.UIAxes
        ImperialRSDplotTab              matlab.ui.container.Tab
        ImperialToolTipLabel            matlab.ui.control.Label
        QCCheckBox                      matlab.ui.control.CheckBox
        SortbySwitchLabel               matlab.ui.control.Label
        RefCheckBox                     matlab.ui.control.CheckBox
        SortbySwitch                    matlab.ui.control.Switch
        UIAxesImperialRSD               matlab.ui.control.UIAxes
        RSDScatterplotTab               matlab.ui.container.Tab
        ComparisonToolTipLabel          matlab.ui.control.Label
        UIAxesRSDComparison             matlab.ui.control.UIAxes
        qcRSDTab                        matlab.ui.container.Tab
        SmoothRSDslider                 matlab.ui.control.Slider
        SmoothSlider_2Label             matlab.ui.control.Label
        SwitchRSDpdf                    matlab.ui.control.Switch
        SwitchRSDPlot                   matlab.ui.control.Switch
        UIAxesRSDpdf                    matlab.ui.control.UIAxes
        dRatioTab                       matlab.ui.container.Tab
        SmoothDRATIOslider              matlab.ui.control.Slider
        SmoothSlider_2Label_2           matlab.ui.control.Label
        SwitchDRATIOpdf                 matlab.ui.control.Switch
        SwitchDratioPlot                matlab.ui.control.Switch
        UIAxesDRATIOpdf                 matlab.ui.control.UIAxes
        blankRatioTab                   matlab.ui.container.Tab
        CleanBlankRatioMethodDropDown   matlab.ui.control.DropDown
        BlankRatioMethodDropDownLabel   matlab.ui.control.Label
        SmoothBLANKslider               matlab.ui.control.Slider
        SmoothSlider_2Label_3           matlab.ui.control.Label
        SwitchBlankpdf                  matlab.ui.control.Switch
        SwitchBlankPlot                 matlab.ui.control.Switch
        UIAxesBLANKpdf                  matlab.ui.control.UIAxes
        QCRSCgTab                       matlab.ui.container.Tab
        UIAxesQCRSCpdf                  matlab.ui.control.UIAxes
        SaveCleanPanel                  matlab.ui.container.Panel
        AlsosavescaledimputeddataCheckBox  matlab.ui.control.CheckBox
        suffixLabel_2                   matlab.ui.control.Label
        SaveCleanButton                 matlab.ui.control.Button
        SaveCleanSuffixEditField        matlab.ui.control.EditField
    end

    
    properties (Access = public)

        % Setup and import
        WorkingDirectory   % 
        SpreadsheetApp  % popup app to view (edit) Data & Feature tables
        PopOutPlotApp   % popout scatter plot for PCA stats
        ImportApp       % popup app to import Feature/Data tables 
        configExplorerApp
	    featureExplorerApp
        jsonTxt         % current json config.
        jsonDefault     % default json config for use during reset. 
        jsonView        % formatted json file for printing to screen.
        configFilename  % config filename


        DataTable       % Data Table - Public so it can be edited by app1
        FeatureTable       % Feature Table - primarliy to access P.UID
        ProjectName     % Excel Filename or 'CSV Project Name'
        DataName        % Excel Sheet or CSV name
        FeatureName        % Excel Sheet or CSV name
        FileType        % csv or xlsx
        ImportSuccess
        baseConfig      % Data Structure containing all the operable configuration options
        importedConfig
        preProcKeep     % Features to keep after preQCRSC clean 
        DataX           % Corrected Data Table
        FeatureX           % Corrected Feature Table + Stats
        GammaVals
        Outliers
        NumOutliers
        FeatureXBefore     % Uncorrected Feature Table + Batch Stats
        FeatureXAfter      % % Corrected Feature Table + Batch Stats
        

        paused          % is QCRSC auto paused?
        aborted         % is QCRSC auto aborted?
        complete        % is QCRSC complete?
        bypass          % is QCRSC bypassed?
        
        PlotFeatureTable   % Table for Plotting (remade every dial change)
        PlotDataTable   % Table for Plotting (remade every dial change)
        cmap            % colormap for plots
        cmapName
        

        scatterX        % X data for popout scatterplot (2 cols)
        scatterY        % y axis data for popout scatterplot (1 col)

        PCAmodel
        anyNAN
        noQCflag

        ImputationMethod
        ScaleMethod
        QCRSCcvMethod
        OutlierDetectionMethod

        ifig
        ifigindex
        ImperialLegendItems
        ImperialLegendLabels

        ifig2
        ifigindex2

    end
    
    properties (Access = private)
  
    end

    methods (Access = public) 

        function setConfigFields(app,options)
            arguments
                app
                options.configStruct = app.baseConfig;
                options.mode = 'all';
            end
            configStruct = options.configStruct;

            % if strcmp(app.FileType,'csv')
            %     app.ProjectNameLabel.Text = 'CSV';
            %     app.DataTableLabel.Text = ['DataTable: ',configStruct.DataTable];
            %     app.DataTableLabel.Tooltip = configStruct.DataTable;
            %     app.FeatureTableLabel.Text = ['FeatureTable: ',configStruct.FeatureTable];
            %     app.FeatureTableLabel.Tooltip = configStruct.FeatureTable;
            % else
            %     app.ProjectNameLabel.Text = ['Filename: ',app.ProjectName];
            %     app.DataTableLabel.Text = ['DataTable: ',configStruct.DataTable];
            %     app.DataTableLabel.Tooltip = configStruct.DataTable;
            %     app.FeatureTableLabel.Text = ['FeatureTable: ',configStruct.FeatureTable];
            %     app.FeatureTableLabel.Tooltip = [configStruct.FeatureTable,'.csv'];
            % end

            if ismember(options.mode,{'all','qcrsc'})                

                app.missingQCperFeatureEditField.Value = configStruct.MissingQCPreFilterPercentage;
                app.PreFilterDropDown.Value = configStruct.MissingQCPreFilterMode;
                app.QCLOQmissingCheckBox.Value = configStruct.LOQfilter;
                app.LOQalphaEditField.Value = configStruct.RelativeLOQ;
    
                app.LogTransformedCorrectionCheckBox.Value = configStruct.LogTransformedCorrection;
                app.RemoveZerosCheckBox.Value = configStruct.RemoveZeros;
                app.QCOutlierDetectionMethodDropDown.Value = configStruct.OutlierDetectionMethod;
                app.QCOutlierDetectionCIEditField.Value = configStruct.OutlierDetectionCI;
                app.QCOutlierReplacementStrategyDropDown.Value = configStruct.OutlierReplacementStrategy;
                app.WithinBatchCorrectionModeDropDown.Value = configStruct.WithinBatchCorrectionMode;
                app.BetweenBatchCorrectionModeDropDown.Value = configStruct.BetweenBatchCorrectionMode;
                app.QCRSCgammarangeEditField.Value = configStruct.QCRSCgammaRange;
                app.QCRSCCrossValidationMethodDropDown.Value = configStruct.QCRSCcvMethod;
                app.QCRSCMonteCarlorepetitionsEditField.Value = configStruct.QCRSCmcReps;
                
    
                app.ParallelProcessingCheckBox.Value = configStruct.ParallelProcess;

                app.BlankRatioMethodDropDown.Value = configStruct.BlankRatioMethod;
                app.CleanBlankRatioMethodDropDown.Value = configStruct.BlankRatioMethod;
            end

            if ismember(options.mode,{'all','clean'})

                app.CorrectedSuffixEditField.Value = configStruct.CorrectedFileSuffix;
                app.CorrectedSuffixEditField.Tooltip = {configStruct.CorrectedFileSuffix};
                app.SampleFilterDropDown.Value = configStruct.CleanMissingSampleFilterMode;
                app.SampleFilterPercentEditField.Value = configStruct.CleanMissingSampleFilterPercentage;
                app.FilterBankModeDropDown.Value = configStruct.CleanFilterBankMode;
    
                app.QCRSDKnob.Value = configStruct.CleanFilterQCRSD;
                app.SampleRSDKnob.Value = configStruct.CleanFilterSampleRSD;
                app.DRatioKnob.Value = configStruct.CleanFilterDRatio;
                app.BlankRatioKnob.Value = configStruct.CleanFilterBlankRatio;
                
    
                app.SaveCleanSuffixEditField.Value = configStruct.CleanFileSuffix;
                app.SaveCleanSuffixEditField.Tooltip = {configStruct.CleanFileSuffix};
                app.Log10TransformCheckBox.Value = configStruct.CleanLogTransform;
    
                switch configStruct.CleanScaleMethod
                    case 'Autoscale'
                        app.AutoscaleButton.Value = true;
                    case 'Paretoscale'
                        app.ParetoscaleButton.Value = true;
                    case 'None'
                        app.MeanCenterOnlyButton.Value = true;
                end
    
                switch configStruct.CleanImputationMethod
                    case 'KNNcolumn'
                        app.KNNcolumnButton.Value = true;
                    case 'KNNrow'
                        app.KNNrowButton.Value = true;
                    case 'blank20'
                        app.blank20minButton.Value = true;
                end
     
                app.kSpinner.Value = configStruct.CleanImputationK;
                      
            end
        end


    end
    
    
    methods (Access = private)  
        
        function ResetData(app)
                app.ViewFeatureTableButton.Enable = 'on';
                app.FeatureInclusionPreFilterPanel.Enable = 'on';  
                app.QCCorrectionParametersPanel.Enable = 'on';
                app.QCMXPConfigurationPanel.Enable = 'on';
                app.LaunchFeatureExplorer.Enable = 'on';
               
                app.baseConfig.WorkingDirectory = app.WorkingDirectory;
                app.baseConfig.FileType = app.FileType;
                app.baseConfig.ProjectName = app.ProjectName;
                app.baseConfig.DataTable = app.DataName;
                app.baseConfig.FeatureTable = app.FeatureName;
                if strcmp(app.FileType,'csv')
                    app.ProjectNameLabel.Text = ['<b>CSV ProjectName:</b> ',app.ProjectName];
                    app.DataTableLabel.Text = ['<b>DataTable:</b> ',app.baseConfig.DataTable];
                    app.DataTableLabel.Tooltip = app.baseConfig.DataTable;
                    app.FeatureTableLabel.Text = ['<b>FeatureTable:</b> ',app.baseConfig.FeatureTable];
                    app.FeatureTableLabel.Tooltip = app.baseConfig.FeatureTable;
                else
                    app.ProjectNameLabel.Text = ['<b>XLSX ProjectName:</b> ',app.ProjectName];
                    app.DataTableLabel.Text = ['<b>DataTable:</b> ',app.baseConfig.DataTable];
                    app.DataTableLabel.Tooltip = app.baseConfig.DataTable;
                    app.FeatureTableLabel.Text = ['<b>FeatureTable:</b> ',app.baseConfig.FeatureTable];
                    app.FeatureTableLabel.Tooltip = app.baseConfig.FeatureTable;
                end
                
                app.NumberofFeaturesLabel.Text = ['<b>Number of Features:</b> ',num2str(height(app.FeatureTable))];
                app.NumberofBatchesLabel.Text = ['<b>Number of Batches:</b> ',num2str(numel(unique(app.DataTable.Batch)))];
                app.NumberofQCLabel.Text = ['<b>Number of QC:</b> ',num2str(sum(app.DataTable.QC))];
                app.NumberofReferenceLabel.Text = ['<b>Number of Reference:</b> ',num2str(num2str(sum(app.DataTable.Reference)))];
                app.NumberofBlanksLabel.Text = ['<b>Number of Blank:</b> ',num2str(num2str(sum(app.DataTable.Blank)))];
                app.NumberofSamplesLabel.Text = ['<b>Number of Samples:</b> ',num2str(sum(ismember(app.DataTable.SampleType,'Sample')))];                
                
                app.QCCorrectionEnginePanel.Enable = 'on';
                app.RunButton.Enable = 'on';
                app.BypassButton.Enable = 'on';
                app.PauseButton.Enable = 'off';
                app.AbortButton.Enable = 'off';
                app.featuresCorrectedGauge.Value = 0; 
                app.paused = false;
                app.aborted = false;
                app.bypass = false;
                app.RunningLamp.Enable = 'off';
                app.CompleteLamp.Enable = 'off';
                
                batch = unique(app.DataTable.Batch);
                Batch = arrayfun(@(x) ['Batch',num2str(x)],batch,'UniformOutput',false);
                app.BatchDropDown.Items = ['All';Batch];
                app.BatchDropDown.ItemsData = [-1;batch];
       

                list = app.DataTable.Properties.VariableNames;
                listx = {};
                h = height(app.DataTable);
                for i = 1:length(list)
                    temp = length(unique(app.DataTable.(list{i})));
                    if temp ~= h && temp > 1, listx = [listx;list{i}]; end
                end
                rm = ismember(listx,{'QC','Sample','Blank','Reference'});
                listx(rm) = [];
                rm = ismember(listx,app.FeatureTable.UID);
                listx(rm) = [];

                app.LabelbyDropDown.Items = ['BatchQC';listx];
                app.LabelbyDropDown.Value = 'SampleType';
                app.drinkMeButton.Value = false;

                if sum(app.DataTable.Blank) == 0
                    app.QCLOQmissingCheckBox.Value = false;
                    app.QCLOQmissingCheckBox.Enable = 'off';
                else
                    app.QCLOQmissingCheckBox.Enable = 'on';
                end
        end

        
        function drinkMe(app)
                set(app.UIFigure, 'Pointer','watch');
                switchAllPanelsA(app,'off');
                switchAllPanelsB(app,'off');
                drawnow();
                if sum(app.DataTable.Blank==1) == 0
                    uialert(app.UIFigure,'There are no Blank samples in the data set','Warning','Icon','warning');
                    app.drinkMeButton.Value = false;
                    set(app.UIFigure, 'Pointer','arrow');
                    switchAllPanelsA(app,'on');
                    if app.complete
                        switchAllPanelsB(app,'on');
                    end
                    return
                end

                ubatch = unique(app.DataTable.Batch);         
                NumOfBatches = numel(ubatch);
                Xall = app.DataTable{:,app.FeatureTable.UID};
                for i = 1:NumOfBatches
                    found = app.DataTable.Blank~=1 & app.DataTable.Batch == ubatch(i);
                    foundBlank = app.DataTable.Blank==1 & app.DataTable.Batch == ubatch(i);
                    XU = Xall(found,:);
                    BU = Xall(foundBlank,:);
                    XU = LowerThanBlankAsNaN(XU,BU,app.baseConfig.RelativeLOQ);
                    Xall(found,:) = XU;
                end
                app.DataTable{:,app.FeatureTable.UID} = Xall;
                nanAll = isnan(Xall(app.DataTable.Sample==1,:));
                remove = sum(nanAll) == height(nanAll);
                removeUIDs = app.FeatureTable.UID(remove);
                app.FeatureTable(remove,:) = [];
                app.DataTable(:,removeUIDs) = [];
                app.NumberofFeaturesLabel.Text = ['Number of Features: ',num2str(height(app.FeatureTable))];
     
                set(app.UIFigure, 'Pointer','arrow');
                switchAllPanelsA(app,'on');
                if app.complete
                    switchAllPanelsB(app,'on');
                    uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an effect','Warning','Icon','warning');
                end
                drawnow();
        end


        function preQCRSCclean(app)
            cla(app.UIAxesPreprocessing,'reset');

            if app.noQCflag
                keep = true(height(app.FeatureTable),1);
                app.preProcKeep = keep;
                found = sum(keep);
                app.FeaturesremainingLabel.Text = ['#Features remaining: ',num2str(found)];
                return;
            end
           
            DataQC = app.DataTable(app.DataTable.QC==1,:);
            Xqc = DataQC{:,app.FeatureTable.UID};          
            if app.baseConfig.RemoveZeros
                Xqc(Xqc<=0) = nan;                
            end
            if app.QCLOQmissingCheckBox.Value
                DataBlank = app.DataTable(app.DataTable.Blank==1,:);
                Xblank = DataBlank{:,app.FeatureTable.UID};
                if app.baseConfig.RemoveZeros                
                    Xblank(Xblank<=0) = nan;
                end
            end
            ubatch = unique(DataQC.Batch);         
            NumOfBatches = numel(ubatch);
            pmissing = zeros(height(app.FeatureTable),NumOfBatches);
            numMissing = zeros(height(app.FeatureTable),NumOfBatches);
            for i = 1:NumOfBatches
                XU = Xqc(DataQC.Batch == ubatch(i),:);
                if app.QCLOQmissingCheckBox.Value
                    BU = Xblank(DataBlank.Batch == ubatch(i),:);
                    XU = LowerThanBlankAsNaN(XU,BU,app.baseConfig.RelativeLOQ);
                end
                pmissing(:,i) = (100*sum(isnan(XU))./height(XU))';
                numMissing(:,i) = sum(isnan(XU));
            end

            total_pmissing = 100*sum(numMissing,2)./height(DataQC);
            
            if all(pmissing == 0)
                text(app.UIAxesPreprocessing,0.5,0.5,'No missing QCs',"HorizontalAlignment","center");                
            else
                [n(1,:),edges] = histcounts(pmissing(:,1));
                if length(edges) > 11
                    clear n;
                    [n(1,:),edges] = histcounts(pmissing(:,1),10);
                end
                if NumOfBatches > 1
                    for i = 2:NumOfBatches
                        [n(i,:)] = histcounts(pmissing(:,i),edges);
                    end
                end
                center = edges(1:end-1) + diff(edges) / 2;
                pp = bar(app.UIAxesPreprocessing,center,n);
                app.UIAxesPreprocessing.XTick = edges;
                app.UIAxesPreprocessing.XTickLabel = round(edges);
                app.UIAxesPreprocessing.XLim = [min(edges),max(edges)];
                
                tmp = string(round(10*edges)/10);
                temp = cellfun(@(x,y) strcat('(',x,'>x>',y,')'),tmp(1:end-1),tmp(2:end),'UniformOutput',false);   
                
                for i = 1:NumOfBatches            
                    found = edges(1:end-1) >= app.missingQCperFeatureEditField.Value;
                    pp(i).FaceColor = 'flat';
                    pp(i).CData(found,:) = repmat([1,1,1],sum(found),1);

                    blabel = ones(length(center),1)*ubatch(i);
                    pp(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('% missing',temp);
                    pp(i).DataTipTemplate.DataTipRows(2).Label = '#Features';
                    pp(i).DataTipTemplate.DataTipRows(3) = dataTipTextRow('Batch#',blabel);
                end

            end

            temp = pmissing < app.missingQCperFeatureEditField.Value;

            switch app.PreFilterDropDown.Value
                case 'EveryBatch'
                    keep = all(temp,2);
                    %keep = sum(pmissing <= app.missingQCperFeatureEditField.Value,2) == width(pmissing);
                case 'AnyBatch'
                    keep = any(temp,2);
                otherwise % Complete
                    keep = total_pmissing <= app.missingQCperFeatureEditField.Value;
            end

            % 
            % if app.BatchwiseCheckBox.Value
            %     keep = sum(pmissing <= app.missingQCperFeatureEditField.Value,2) == width(pmissing);
            % 
            % else
            %     %keep = sum(pmissing <= app.missingQCperFeatureEditField.Value,2) > 0;
            %     keep = total_pmissing <= app.missingQCperFeatureEditField.Value;
            % end
            
            found = sum(keep);
            app.FeaturesremainingLabel.Text = ['#Features remaining: ',num2str(found)];
            
            app.preProcKeep = keep;

            xlabel(app.UIAxesPreprocessing, '% QC missing (per batch)');

        end
              
        function optimiseAndCorrectTable(app)
            app.QCCorrectionEnginePanel.Title = sprintf('QC Correction Engine:');            
            app.FeatureX = app.FeatureTable(app.preProcKeep,:);
            app.DataX = app.DataTable;
            list = setdiff(app.FeatureTable.UID,app.FeatureX.UID);
            app.DataX(:,list) = [];

            if ~app.bypass
                      
                X = app.DataX{:,app.FeatureX.UID};
                t = app.DataX.Order;
                Batch = app.DataX.Batch;
                isQC = logical(app.DataX.QC);
                isSample = logical(app.DataX.Sample);
                isREF = logical(app.DataX.Reference);
                isBlank = logical(app.DataX.Blank);
                
                ubatch = unique(Batch);
                NumOfBatches = numel(ubatch);
                NumOfFeatures = width(X);
                NumOfObjects = height(X);
                
                if app.baseConfig.RemoveZeros
                    X(X <= 0) = NaN;
                end
                
                if app.baseConfig.LogTransformedCorrection
                    X(X == 0) = NaN;
                    X = log10(X);
                end
                
                Z = nan(size(X));
                gammaVals = nan(NumOfFeatures,NumOfBatches);
                outliers = zeros(NumOfObjects,NumOfFeatures);               
                numOutliers = nan(NumOfFeatures,1);
                
                set(app.UIFigure, 'Pointer','watch')
                drawnow();
    
                if app.baseConfig.ParallelProcess
                    app.PauseButton.Enable = 'off';
                    app.AbortButton.Enable = 'off';
                    if isempty(gcp('nocreate'))
                        d = uiprogressdlg(app.UIFigure,'Title','Parallel Processor Admin.');
                        d.Message = "Starting parallel pool (parpool) using the 'local' profile ...";
                        d.Value = 0.5;
                        [~,pp] = evalc('gcp');
                        d.Message = sprintf('Connected to the parallel pool (number of workers: %u).',pp.NumWorkers);                       
                        d.Value = 1;
                        pause(2);
                        close(d);
                    else
                        pp = gcp('nocreate');
                    end
                                    
                    
                    config = app.baseConfig;  
                    
                    
                    try
                        tnum = pp.NumWorkers*10;
                        parfor i =1:tnum
                            OptimiseAndCorrectFeatureX(config,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);
                        end
                        tic;
                        parfor i =1:tnum
                            OptimiseAndCorrectFeatureX(config,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);
                        end
                        elapsedTime = toc;
                        expectedTime = elapsedTime*NumOfFeatures/tnum;                    
                    catch
                        expectedTime = -1;
                    end
                    if expectedTime == -1
                        RunningTimeText = 'Estimated running time is unknown';                                    
                    elseif expectedTime < 60
                        seconds = round(expectedTime);
                        RunningTimeText = sprintf('Estimated running time is %d seconds',seconds);                    
                    elseif expectedTime < 3600
                        mins = round(expectedTime/60);
                        RunningTimeText = sprintf('Estimated running time is %d minutes',mins);      
                    else
                        hrs = floor(expectedTime/3600);
                        mins = round(rem(expectedTime,3600)/60);
                        RunningTimeText = sprintf('Estimated running time is %d hours, %d mins',hrs,mins);
                    end
                    app.QCCorrectionEnginePanel.Title = ['QC Correction Parallel Engine: ',RunningTimeText];
                    

                    tic;
                    F(1:NumOfFeatures) = parallel.FevalFuture;
                    for i = 1:NumOfFeatures
                        F(i) = parfeval(@OptimiseAndCorrectFeatureX,4,config,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);                    
                    end
                    
                    set(app.UIFigure, 'Pointer','arrow');
                    app.RunningLamp.Enable = 'on';
                    app.CompleteLamp.Enable = 'off';
                    app.AbortButton.Enable = 'on';                
                    NumCompleted = 0;
                    drawnow();
    
                    while NumCompleted < NumOfFeatures
                        NumCompleted = sum({F.State}=="finished");
                        percDone = 100*NumCompleted/NumOfFeatures;
                        app.featuresCorrectedGauge.Value = percDone;
                        app.RunningLamp.Enable = 'off';
                        pause(0.1);
                        app.RunningLamp.Enable = 'on';
                        if app.aborted
                            app.AbortButton.Enable = 'off';
                            set(app.UIFigure, 'Pointer','watch')
                            drawnow();
                            cancel(F);
                            app.featuresCorrectedGauge.Value = 0;
                            delete(gcp('nocreate'));                
                            app.RunningLamp.Enable = 'off';
                            app.CompleteLamp.Enable = 'off';
                            app.QCCorrectionEnginePanel.Title = 'QC Correction Engine: ';
                            set(app.UIFigure, 'Pointer','arrow');
                            return
                        end
                        pause(1);
                    end
    
                    for i = 1:NumOfFeatures
                        Z(:,i) = F(i).OutputArguments{1};
                        gammaVals(i,:) = F(i).OutputArguments{3};
                        outliers(:,i) = ismember(t,F(i).OutputArguments{4});
                        numOutliers(i,:) = length(F(i).OutputArguments{4});                        
                    end

                    elapsedTime = toc;               
                    if elapsedTime < 60
                        seconds = round(elapsedTime);
                        TimeText = sprintf('Completed in %d seconds',seconds);                     
                    elseif elapsedTime < 3600
                        mins = floor(elapsedTime/60);
                        seconds = round(rem(elapsedTime,60));
                        TimeText = sprintf('Completed in %d minutes, %d seconds',mins,seconds);      
                    else
                        hrs = floor(elapsedTime/3600);
                        mins = round(rem(elapsedTime,3600)/60);
                        TimeText = sprintf('Completed in %d hours, %d minutes',hrs,mins);
                    end
                    app.QCCorrectionEnginePanel.Title = ['QC Correction Parallel Engine: ',TimeText];

                    d = uiprogressdlg(app.UIFigure,'Title','Parallel Processor Admin.');
                    d.Message = "Stopping parallel processor ...";
                    d.Value = 0.5;
                    message = evalc("delete(gcp('nocreate'))");                  
                    d.Message = message;
                    d.Value = 1;
                    pause(2);
                    close(d);
         
                else

                    
                    try
                        tic;
                        for i =1:10
                            OptimiseAndCorrectFeatureX(app.baseConfig,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);
                        end
                        elapsedTime = toc;
                        expectedTime = elapsedTime*NumOfFeatures/10;
                    catch
                        expectedTime = -1;
                    end
    
                    if expectedTime == -1
                        RunningTimeText = 'Estimated execution time is unknown';                    
                    elseif expectedTime < 60
                        seconds = round(expectedTime);
                        RunningTimeText = sprintf('Estimated execution time is %d seconds',seconds);                    
                    elseif expectedTime < 3600
                        mins = round(expectedTime/60);
                        RunningTimeText = sprintf('Estimated execution time is %d minutes',mins);      
                    else
                        hrs = floor(expectedTime/3600);
                        mins = round(rem(expectedTime,3600)/60);
                        RunningTimeText = sprintf('Estimated execution time is %d hours, %d mins',hrs,mins);
                    end
                    app.QCCorrectionEnginePanel.Title = ['QC Correction Engine: ',RunningTimeText];


                    set(app.UIFigure, 'Pointer','arrow')
                    app.RunningLamp.Enable = 'on';
                    app.CompleteLamp.Enable = 'off';
                    
                    lampflag = true;
                
                    tic;    
                    for i = 1:NumOfFeatures
                        lampflag = not(lampflag);
                        if lampflag
                            app.RunningLamp.Enable = 'on';
                        else
                            app.RunningLamp.Enable = 'off';
                        end
                        while app.paused
                            app.RunningLamp.Enable = 'off';
                            pause(0.5);
                            app.PauseButton.BackgroundColor = [0.80,0.80,0.80];
                            pause(0.5); 
                            app.PauseButton.BackgroundColor = [1.00,1.00,0.07];                    
                        end
                         if app.aborted
                            app.AbortButton.Enable = 'off';
                            app.featuresCorrectedGauge.Value = 0;
                            app.RunningLamp.Enable = 'off';
                            app.CompleteLamp.Enable = 'off';
                            app.QCCorrectionEnginePanel.Title = 'QC Correction Engine: ';
                            return
                         end                       
                        [Z(:,i),~,gammaVals(i,:),oo] = OptimiseAndCorrectFeatureX(app.baseConfig,t,X(:,i),Batch,isQC,isSample,isREF,isBlank);
                        outliers(:,i) = ismember(t,oo);
                        numOutliers(i,:) = length(oo);
                        percDone = 100*i/NumOfFeatures;
                        app.featuresCorrectedGauge.Value = percDone;
                                                        
                    end
                    elapsedTime = toc;               
                    if elapsedTime < 60
                        seconds = round(elapsedTime);
                        TimeText = sprintf('Completed in %d seconds',seconds);                    
                    elseif elapsedTime < 3600
                        mins = floor(elapsedTime/60);
                        seconds = round(rem(elapsedTime,60));
                        TimeText = sprintf('Completed in %d minutes, %d seconds',mins,seconds);      
                    else
                        hrs = floor(elapsedTime/3600);
                        mins = round(rem(elapsedTime,3600)/60);
                        TimeText = sprintf('Completed in %d hours, %d minutes',hrs,mins);
                    end
                    app.QCCorrectionEnginePanel.Title = ['QC Correction Engine: ',TimeText];
                end
            else
                Z = app.DataX{:,app.FeatureX.UID};
                if app.baseConfig.LogTransformedCorrection
                    Z = log10(Z);
                end
                Batch = app.DataX.Batch;
                ubatch = unique(Batch);
                NumOfBatches = numel(ubatch);
                NumOfFeatures = width(Z);
                NumOfObjects = height(Z);
                gammaVals = nan(NumOfFeatures,NumOfBatches);               
                outliers = zeros(NumOfObjects,NumOfFeatures);
                numOutliers = nan(NumOfFeatures,1);
            end
            app.GammaVals = gammaVals;          
            app.Outliers = outliers;
            app.NumOutliers = numOutliers;
            if app.baseConfig.LogTransformedCorrection
                Z = power(10,Z);
            end            
            app.DataX{:,app.FeatureX.UID} = Z;
            app.CompleteLamp.Enable = 'on';
            app.RunningLamp.Enable = 'off';
            calculateStats(app);     
        end

        function calculateStats(app)

            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();

            Batch = app.DataX.Batch;
            ubatch = unique(Batch);
            NumOfBatches = numel(ubatch);
            NumOfFeatures = height(app.FeatureX);

            X = app.DataTable{:,app.FeatureX.UID};
            X(logical(app.Outliers)) = NaN;
            DataXX = app.DataTable;
            DataXX{:,app.FeatureX.UID} = X;

            gammaVars = cellfun(@(x) strcat('gammaB',x),string(ubatch'),'UniformOutput',false);            
            gammaTable = array2table([app.GammaVals,app.NumOutliers],'variablenames',[gammaVars,'NumOfQCoutliers']);
            gammaTable0 = array2table(nan(size(app.GammaVals,1),size(app.GammaVals,2)+1),'variablenames',[gammaVars,'NumOfQCoutliers']);

            
            BeforeQCRSDVals = nan(NumOfFeatures,NumOfBatches);
            BeforeQCRSDlowVals = nan(NumOfFeatures,NumOfBatches);
            BeforeQCRSDuppVals = nan(NumOfFeatures,NumOfBatches);
            BeforeSampleRSDVals = nan(NumOfFeatures,NumOfBatches);
            BeforeSampleRSDlowVals = nan(NumOfFeatures,NumOfBatches);
            BeforeSampleRSDuppVals = nan(NumOfFeatures,NumOfBatches);
            BeforeRefRSDVals = nan(NumOfFeatures,NumOfBatches);
            BeforeRefRSDlowVals = nan(NumOfFeatures,NumOfBatches);
            BeforeRefRSDuppVals = nan(NumOfFeatures,NumOfBatches);
            BeforeblankRatioVals = nan(NumOfFeatures,NumOfBatches);
            BeforeDRatioVals = nan(NumOfFeatures,NumOfBatches);
                     
            AfterQCRSDVals = nan(NumOfFeatures,NumOfBatches);
            AfterQCRSDlowVals = nan(NumOfFeatures,NumOfBatches);
            AfterQCRSDuppVals = nan(NumOfFeatures,NumOfBatches);
            AfterSampleRSDVals = nan(NumOfFeatures,NumOfBatches);
            AfterSampleRSDlowVals = nan(NumOfFeatures,NumOfBatches);
            AfterSampleRSDuppVals = nan(NumOfFeatures,NumOfBatches);
            AfterRefRSDVals = nan(NumOfFeatures,NumOfBatches);
            AfterRefRSDlowVals = nan(NumOfFeatures,NumOfBatches);
            AfterRefRSDuppVals = nan(NumOfFeatures,NumOfBatches);
            AfterblankRatioVals = nan(NumOfFeatures,NumOfBatches);
            AfterDRatioVals = nan(NumOfFeatures,NumOfBatches);

            for i = 1:NumOfBatches
                BatchStatsBefore(i).Tbl = BatchCalcStatsTable(DataXX,app.FeatureX,ubatch(i),app.baseConfig);
                BeforeQCRSDVals(:,i) = BatchStatsBefore(i).Tbl.qcRSD;
                BeforeQCRSDlowVals(:,i) = BatchStatsBefore(i).Tbl.qcRSDlower95CI; 
                BeforeQCRSDuppVals(:,i) = BatchStatsBefore(i).Tbl.qcRSDupper95CI; 
                BeforeSampleRSDVals(:,i) = BatchStatsBefore(i).Tbl.sampleRSD;
                BeforeSampleRSDlowVals(:,i) = BatchStatsBefore(i).Tbl.sampleRSDlower95CI;
                BeforeSampleRSDuppVals(:,i) = BatchStatsBefore(i).Tbl.sampleRSDupper95CI;
                BeforeRefRSDVals(:,i) = BatchStatsBefore(i).Tbl.refRSD;
                BeforeRefRSDlowVals(:,i) = BatchStatsBefore(i).Tbl.refRSDlower95CI;
                BeforeRefRSDuppVals(:,i) = BatchStatsBefore(i).Tbl.refRSDupper95CI;
                BeforeDRatioVals(:,i) = BatchStatsBefore(i).Tbl.dRatio;
                BeforeblankRatioVals(:,i) = BatchStatsBefore(i).Tbl.blankRatio;
            end            

            for i = 1:NumOfBatches
                BatchStatsAfter(i).Tbl = BatchCalcStatsTable(app.DataX,app.FeatureX,ubatch(i),app.baseConfig);
                AfterQCRSDVals(:,i) = BatchStatsAfter(i).Tbl.qcRSD;
                AfterQCRSDlowVals(:,i) = BatchStatsAfter(i).Tbl.qcRSDlower95CI; 
                AfterQCRSDuppVals(:,i) = BatchStatsAfter(i).Tbl.qcRSDupper95CI; 
                AfterSampleRSDVals(:,i) = BatchStatsAfter(i).Tbl.sampleRSD;
                AfterSampleRSDlowVals(:,i) = BatchStatsAfter(i).Tbl.sampleRSDlower95CI;
                AfterSampleRSDuppVals(:,i) = BatchStatsAfter(i).Tbl.sampleRSDupper95CI;
                AfterRefRSDVals(:,i) = BatchStatsAfter(i).Tbl.refRSD;
                AfterRefRSDlowVals(:,i) = BatchStatsAfter(i).Tbl.refRSDlower95CI;
                AfterRefRSDuppVals(:,i) = BatchStatsAfter(i).Tbl.refRSDupper95CI;
                AfterDRatioVals(:,i) = BatchStatsAfter(i).Tbl.dRatio;
                AfterblankRatioVals(:,i) = BatchStatsAfter(i).Tbl.blankRatio;
            end 

            statsBefore = BatchCalcStatsTable(DataXX,app.FeatureX,-1,app.baseConfig);
            statsAfter = BatchCalcStatsTable(app.DataX,app.FeatureX,-1,app.baseConfig);

            % QCRSD tables

            qcRSDVars = cellfun(@(x) strcat('qcRSDB',x),string(ubatch'),'UniformOutput',false);
            qcRSDTableBefore = array2table(BeforeQCRSDVals,'variablenames',qcRSDVars);
            qcRSDVars = cellfun(@(x) strcat('qcRSDupper95CIB',x),string(ubatch'),'UniformOutput',false);
            tempUpper = array2table(BeforeQCRSDuppVals,'variablenames',qcRSDVars);
            qcRSDVars = cellfun(@(x) strcat('qcRSDlower95CIB',x),string(ubatch'),'UniformOutput',false);
            tempLower = array2table(BeforeQCRSDlowVals,'variablenames',qcRSDVars);
            qcRSDTableBefore = [qcRSDTableBefore,tempLower,tempUpper];
            qcRSDTableBefore.qcRSD = statsBefore.qcRSD;
            qcRSDTableBefore.qcRSDlower95CI = statsBefore.qcRSDlower95CI;
            qcRSDTableBefore.qcRSDupper95CI = statsBefore.qcRSDupper95CI;

            qcRSDVars = cellfun(@(x) strcat('qcRSDB',x),string(ubatch'),'UniformOutput',false);
            qcRSDTable = array2table(AfterQCRSDVals,'variablenames',qcRSDVars);
            qcRSDVars = cellfun(@(x) strcat('qcRSDupper95CIB',x),string(ubatch'),'UniformOutput',false);
            tempUpper = array2table(AfterQCRSDuppVals,'variablenames',qcRSDVars);
            qcRSDVars = cellfun(@(x) strcat('qcRSDlower95CIB',x),string(ubatch'),'UniformOutput',false);
            tempLower = array2table(AfterQCRSDlowVals,'variablenames',qcRSDVars);
            qcRSDTable = [qcRSDTable,tempLower,tempUpper];
            qcRSDTable.qcRSD = statsAfter.qcRSD;
            qcRSDTable.qcRSDlower95CI = statsAfter.qcRSDlower95CI;
            qcRSDTable.qcRSDupper95CI = statsAfter.qcRSDupper95CI;

            qcRSDVars = cellfun(@(x) strcat('qcRSDB',x),string(ubatch'),'UniformOutput',false);
            qcRSDTableX = array2table(AfterQCRSDVals,'variablenames',qcRSDVars);
            qcRSDTableX.qcRSD = statsAfter.qcRSD;
            qcRSDTableX.qcRSDlower95CI = statsAfter.qcRSDlower95CI;
            qcRSDTableX.qcRSDupper95CI = statsAfter.qcRSDupper95CI;

            % sampleRSD tables

            sampleRSDVars = cellfun(@(x) strcat('sampleRSDB',x),string(ubatch'),'UniformOutput',false);
            sampleRSDTableBefore = array2table(BeforeSampleRSDVals,'variablenames',sampleRSDVars);
            sampleRSDVars = cellfun(@(x) strcat('sampleRSDupper95CIB',x),string(ubatch'),'UniformOutput',false);
            tempUpper = array2table(BeforeSampleRSDuppVals,'variablenames',sampleRSDVars);
            sampleRSDVars = cellfun(@(x) strcat('sampleRSDlower95CIB',x),string(ubatch'),'UniformOutput',false);
            tempLower = array2table(BeforeSampleRSDlowVals,'variablenames',sampleRSDVars);
            sampleRSDTableBefore = [sampleRSDTableBefore,tempLower,tempUpper];
            sampleRSDTableBefore.sampleRSD = statsBefore.sampleRSD;
            sampleRSDTableBefore.sampleRSDlower95CI = statsBefore.sampleRSDlower95CI;
            sampleRSDTableBefore.sampleRSDupper95CI = statsBefore.sampleRSDupper95CI;

            sampleRSDVars = cellfun(@(x) strcat('sampleRSDB',x),string(ubatch'),'UniformOutput',false);
            sampleRSDTable = array2table(AfterSampleRSDVals,'variablenames',sampleRSDVars);
            sampleRSDVars = cellfun(@(x) strcat('sampleRSDupper95CIB',x),string(ubatch'),'UniformOutput',false);
            tempUpper = array2table(AfterSampleRSDuppVals,'variablenames',sampleRSDVars);
            sampleRSDVars = cellfun(@(x) strcat('sampleRSDlower95CIB',x),string(ubatch'),'UniformOutput',false);
            tempLower = array2table(AfterSampleRSDlowVals,'variablenames',sampleRSDVars);
            sampleRSDTable = [sampleRSDTable,tempLower,tempUpper];
            sampleRSDTable.sampleRSD = statsAfter.sampleRSD;
            sampleRSDTable.sampleRSDlower95CI = statsAfter.sampleRSDlower95CI;
            sampleRSDTable.sampleRSDupper95CI = statsAfter.sampleRSDupper95CI;

            sampleRSDVars = cellfun(@(x) strcat('sampleRSDB',x),string(ubatch'),'UniformOutput',false);
            sampleRSDTableX = array2table(AfterSampleRSDVals,'variablenames',sampleRSDVars);
            sampleRSDTableX.sampleRSD = statsAfter.sampleRSD;
            sampleRSDTableX.sampleRSDlower95CI = statsAfter.sampleRSDlower95CI;
            sampleRSDTableX.sampleRSDupper95CI = statsAfter.sampleRSDupper95CI;

            % refRSD tables

            refRSDVars = cellfun(@(x) strcat('refRSDB',x),string(ubatch'),'UniformOutput',false);
            refRSDTableBefore = array2table(BeforeRefRSDVals,'variablenames',refRSDVars);
            refRSDVars = cellfun(@(x) strcat('refRSDupper95CIB',x),string(ubatch'),'UniformOutput',false);
            tempUpper = array2table(BeforeRefRSDuppVals,'variablenames',refRSDVars);
            refRSDVars = cellfun(@(x) strcat('refRSDlower95CIB',x),string(ubatch'),'UniformOutput',false);
            tempLower = array2table(BeforeRefRSDlowVals,'variablenames',refRSDVars);
            refRSDTableBefore = [refRSDTableBefore,tempLower,tempUpper];
            refRSDTableBefore.refRSD = statsBefore.refRSD;
            refRSDTableBefore.refRSDlower95CI = statsBefore.refRSDlower95CI;
            refRSDTableBefore.refRSDupper95CI = statsBefore.refRSDupper95CI;

            refRSDVars = cellfun(@(x) strcat('refRSDB',x),string(ubatch'),'UniformOutput',false);
            refRSDTable = array2table(AfterRefRSDVals,'variablenames',refRSDVars);
            refRSDVars = cellfun(@(x) strcat('refRSDupper95CIB',x),string(ubatch'),'UniformOutput',false);
            tempUpper = array2table(AfterRefRSDuppVals,'variablenames',refRSDVars);
            refRSDVars = cellfun(@(x) strcat('refRSDlower95CIB',x),string(ubatch'),'UniformOutput',false);
            tempLower = array2table(AfterRefRSDlowVals,'variablenames',refRSDVars);
            refRSDTable = [refRSDTable,tempLower,tempUpper];
            refRSDTable.refRSD = statsAfter.refRSD;
            refRSDTable.refRSDlower95CI = statsAfter.refRSDlower95CI;
            refRSDTable.refRSDupper95CI = statsAfter.refRSDupper95CI;

            refRSDVars = cellfun(@(x) strcat('refRSDB',x),string(ubatch'),'UniformOutput',false);
            refRSDTableX = array2table(AfterRefRSDVals,'variablenames',refRSDVars);
            refRSDTableX.refRSD = statsAfter.refRSD;
            refRSDTableX.refRSDlower95CI = statsAfter.refRSDlower95CI;
            refRSDTableX.refRSDupper95CI = statsAfter.refRSDupper95CI;


            % Dratio tables

            dRatioVars = cellfun(@(x) strcat('dRatioB',x),string(ubatch'),'UniformOutput',false);
            dRatioTableBefore = array2table(BeforeDRatioVals,'variablenames',dRatioVars);            
            dRatioTableBefore.dRatio = statsBefore.dRatio;

            dRatioTable = array2table(AfterDRatioVals,'variablenames',dRatioVars);
            dRatioTable.dRatio = statsAfter.dRatio;

            dRatioTableX = array2table(AfterDRatioVals,'variablenames',dRatioVars);
            dRatioTableX.dRatio = statsAfter.dRatio;

            % blankRatio tables
            % force "complete" blankRatio to be the mean of the batch blankRatio

            blankRatioVars = cellfun(@(x) strcat('blankRatioB',x),string(ubatch'),'UniformOutput',false);
            blankRatioTableBefore = array2table(BeforeblankRatioVals,'variablenames',blankRatioVars);
            blankRatioTableBefore.blankRatioMax = max(BeforeblankRatioVals,[],2);            
            blankRatioTableBefore.blankRatioMean = mean(BeforeblankRatioVals,2,"omitmissing");
            blankRatioTableBefore.blankRatioMedian = median(BeforeblankRatioVals,2,"omitmissing");
            %blankRatioTableBefore.blankRatio = statsBefore.blankRatio;
            blankRatioTableBefore.blankRatio = max(BeforeblankRatioVals,[],2);

            blankRatioTable = array2table(AfterblankRatioVals,'variablenames',blankRatioVars);
            blankRatioTable.blankRatioMax =  max(AfterblankRatioVals,[],2);           
            blankRatioTable.blankRatioMean =  mean(AfterblankRatioVals,2,"omitmissing");
            blankRatioTable.blankRatioMedian =  median(AfterblankRatioVals,2,"omitmissing");
            %blankRatioTable.blankRatio = statsAfter.blankRatio;
            blankRatioTable.blankRatio = max(AfterblankRatioVals,[],2);

            blankRatioTableX = array2table(AfterblankRatioVals,'variablenames',blankRatioVars);
            blankRatioTableX.blankRatio = max(AfterblankRatioVals,[],2);

            
            app.FeatureX(:,ismember(app.FeatureX.Properties.VariableNames,gammaTable.Properties.VariableNames)) = [];
            app.FeatureX(:,ismember(app.FeatureX.Properties.VariableNames,qcRSDTable.Properties.VariableNames)) = [];
            app.FeatureX(:,ismember(app.FeatureX.Properties.VariableNames,sampleRSDTable.Properties.VariableNames)) = [];
            app.FeatureX(:,ismember(app.FeatureX.Properties.VariableNames,refRSDTable.Properties.VariableNames)) = [];
            app.FeatureX(:,ismember(app.FeatureX.Properties.VariableNames,dRatioTable.Properties.VariableNames)) = [];
            app.FeatureX(:,ismember(app.FeatureX.Properties.VariableNames,blankRatioTable.Properties.VariableNames)) = [];
                        
         
            app.FeatureXBefore = [app.FeatureX,gammaTable0,qcRSDTableBefore,sampleRSDTableBefore,refRSDTableBefore,dRatioTableBefore,blankRatioTableBefore];
            app.FeatureXAfter = [app.FeatureX,gammaTable,qcRSDTable,sampleRSDTable,refRSDTable,dRatioTable,blankRatioTable];
            app.FeatureX = [app.FeatureX,gammaTable,qcRSDTableX,sampleRSDTableX,refRSDTableX,dRatioTableX,blankRatioTableX];
            
            set(app.UIFigure, 'Pointer','arrow');
            app.complete = true;
        
            app.RunButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
            app.AbortButton.Enable = 'off';
            app.BypassButton.Enable = 'on';
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();          
        end
             
        function plotImperialRSD(app)

            red = [220,20,60]/255;
            grey = [0.7,0.7,0.7];
            cornflowerblue = [100,149,237]/255;
            gold = [212,175,55]/255;
            
            cla(app.UIAxesImperialRSD,'reset');
            app.ImperialToolTipLabel.Visible = "off";
            
            bin = (1:height(app.PlotFeatureTable))';
            if ismac
                ms = 9;
            else
                ms = 7;
            end
            

            if any(app.PlotDataTable.Reference)
                app.RefCheckBox.Visible = "on";
                app.QCCheckBox.Visible = 'on';
            else
                app.RefCheckBox.Visible = 'off';
                app.QCCheckBox.Visible = 'off';
            end


            if strcmp(app.SortbySwitch.Value,'sample')
                [ss,index] = sortrows(app.PlotFeatureTable.sampleRSD);
                qq = app.PlotFeatureTable.qcRSD(index);
            else
                [qq,index] = sortrows(app.PlotFeatureTable.qcRSD);
                ss = app.PlotFeatureTable.sampleRSD(index);
            end

            qql = app.PlotFeatureTable.qcRSDlower95CI(index);
            qqu = app.PlotFeatureTable.qcRSDupper95CI(index);      
            ssl = app.PlotFeatureTable.sampleRSDlower95CI(index);
            ssu = app.PlotFeatureTable.sampleRSDupper95CI(index);
            rr = app.PlotFeatureTable.refRSD(index);
            rrl = app.PlotFeatureTable.refRSDlower95CI(index);
            rru = app.PlotFeatureTable.refRSDupper95CI(index);

            dd = app.PlotFeatureTable.dRatio(index);          
            bb = app.PlotFeatureTable.blankRatio(index);
            id = app.PlotFeatureTable.UID(index);
           
            in = app.PlotFeatureTable.cleanPeaks(index);
            bestbatch = app.PlotFeatureTable.bestBatch(index);
            
            ll = [];
            lt = {};
            
           
            if any(~in)

                outdata.id = id(~in);
                outdata.dd = dd(~in);
                outdata.bb = bb(~in);
                outdata.qq = qq(~in);
                outdata.qql = qql(~in);
                outdata.qqu = qqu(~in);
                outdata.ss = ss(~in);
                outdata.ssl = ssl(~in);
                outdata.ssu = ssu(~in);
                outdata.rr = rr(~in);
                outdata.rrl = rrl(~in);
                outdata.rru = rru(~in);
                outdata.bin = bin(~in);
                outdata.bestbatch = bestbatch(~in);
                               
                h2outA = semilogx(app.UIAxesImperialRSD,outdata.ss,outdata.bin,'.','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event,outdata));
                h2outA.MarkerSize = ms;
                h2outA.MarkerFaceColor = grey;
                h2outA.MarkerEdgeColor = grey;
                
                hold(app.UIAxesImperialRSD,"on");

                if app.QCCheckBox.Value
                    h2outB = semilogx(app.UIAxesImperialRSD,outdata.qq,outdata.bin,'.','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event,outdata));
                    h2outB.MarkerSize = ms;
                    h2outB.MarkerFaceColor = grey;
                    h2outB.MarkerEdgeColor = grey;
                end
                
                if app.RefCheckBox.Value   
                    h2outC = semilogx(app.UIAxesImperialRSD,outdata.rr,outdata.bin,'.','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event,outdata));                    
                    h2outC.MarkerSize = ms;
                    h2outC.MarkerFaceColor = grey;
                    h2outC.MarkerEdgeColor = grey;
                end
                
            end 
            
            if any(in)
                
                indata.id = id(in);
                indata.dd = dd(in);
                indata.bb = bb(in);
                indata.qq = qq(in);
                indata.qql = qql(in);
                indata.qqu = qqu(in);
                indata.ss = ss(in);
                indata.ssl = ssl(in);
                indata.ssu = ssu(in);
                indata.rr = rr(in);
                indata.rrl = rrl(in);
                indata.rru = rru(in);
                indata.bin = bin(in);
                indata.bestbatch = bestbatch(in);
                                
                h2inA = semilogx(app.UIAxesImperialRSD,indata.ss,indata.bin,'.','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event,indata));
                h2inA.MarkerSize = ms;
                h2inA.MarkerFaceColor = cornflowerblue;
                h2inA.MarkerEdgeColor = cornflowerblue;
                
                hold(app.UIAxesImperialRSD,"on");
                ll = [ll,h2inA];
                lt = [lt,{'Sample'}];

                if app.QCCheckBox.Value
                    h2inB = semilogx(app.UIAxesImperialRSD,indata.qq,indata.bin,'.','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event,indata));
                    h2inB.MarkerSize = ms;
                    h2inB.MarkerFaceColor = red;
                    h2inB.MarkerEdgeColor = red;
                
                    ll = [ll,h2inB];
                    lt = [lt,{'QC'}];
                end
                
                if app.RefCheckBox.Value  
                    h2inC = semilogx(app.UIAxesImperialRSD,indata.rr,indata.bin,'.','ButtonDownFcn',@(src,event)app.ImperialMouseClick(src,event,indata));                    
                    h2inC.MarkerSize = ms;
                    h2inC.MarkerFaceColor = gold;
                    h2inC.MarkerEdgeColor = gold;
                    

                    ll = [ll,h2inC];
                    lt = [lt,{'Reference'}];
                end
                
                
                % -------------------- Plot Loess Curves --------------------------------
                
                if strcmp(app.SortbySwitch.Value,'sample')
                    if app.QCCheckBox.Value
                        found = ~isnan(qq) & in;
                        y = smooth(log10(qq(found)),0.8,'rloess');              
                        y = power(10,y);                    
                        fx = semilogx(app.UIAxesImperialRSD,y,bin(found),'k--','LineWidth',2);
                        ll = [ll,fx];
                        lt = [lt,{'Loess QC trend'}];
                    end
                else
                    found = ~isnan(ss) & in;
                    y = smooth(log10(ss(found)),0.8,'rloess');  
                    y = power(10,y);
                    fx = semilogx(app.UIAxesImperialRSD,y,bin(found),'k--','LineWidth',2);
                    ll = [ll,fx];
                    lt = [lt,{'Loess Sample trend'}];
                end
                              
                if app.RefCheckBox.Value        
                    found = ~(rr==0) & ~isnan(rr) & in;
                    y = smooth(log10(rr(found)),0.8,'rloess');  
                    y = power(10,y);
                    fx2 = semilogx(app.UIAxesImperialRSD,y,bin(found),'k-.','LineWidth',2);
                    ll = [ll,fx2];
                    lt = [lt,{'Loess Reference trend'}];
                end

            end
            xlabel(app.UIAxesImperialRSD,'%RSD');
            ylabel(app.UIAxesImperialRSD,'Cumulative Number of Features');
            legend(app.UIAxesImperialRSD,ll,lt,'location','southeast');
            app.ImperialLegendLabels = ll;
            app.ImperialLegendItems = lt;
            
            app.UIAxesImperialRSD.Box = 'on';
            ylim(app.UIAxesImperialRSD,[0,1.05*max(bin)])
            

            app.UIAxesImperialRSD.XGrid = 'on';            
            app.UIAxesImperialRSD.YGrid = 'on';            
            app.UIAxesImperialRSD.MinorGridLineStyle = '-';
            app.UIAxesImperialRSD.MinorGridAlpha = 0.2;
            xlim(app.UIAxesImperialRSD,[0.9*min([1;qq;ss]),1.1*max([qq;ss])])                
            app.UIAxesImperialRSD.XTickLabel = humaniseLogTickLabel(app.UIAxesImperialRSD.XTickLabel);


            app.UIAxesImperialRSD.ButtonDownFcn = createCallbackFcn(app,@(src,event)app.ImperialMouseClick2(src,event), true);
        end

        function ImperialMouseClick(app,source,event,indata)
                gold = [218,165,32]/255;
                if ~isempty(app.ifig)
                    for i=1:length(app.ifig)          
                        delete(app.ifig(i));                 
                    end
                end
                k = dsearchn([source.XData;source.YData]',event.IntersectionPoint(:,1:2));
                if k == app.ifigindex, for i=1:length(app.ifig), delete(app.ifig(i)); end; end

                fh(1) = semilogx(app.UIAxesImperialRSD,indata.ss(k),indata.bin(k),'bo','LineWidth',1);
                fh(2) = semilogx(app.UIAxesImperialRSD,indata.ssu(k),indata.bin(k),'b|','LineWidth',3);
                fh(3) = semilogx(app.UIAxesImperialRSD,indata.ssl(k),indata.bin(k),'b|','LineWidth',3);
                fh(4) = semilogx(app.UIAxesImperialRSD,[indata.ssl(k),indata.ssu(k)],[indata.bin(k),indata.bin(k)],'b-','LineWidth',1);
                if app.QCCheckBox.Value
                    fh(end+1) = semilogx(app.UIAxesImperialRSD,indata.qq(k),indata.bin(k),'ro','LineWidth',1);
                    fh(end+1) = semilogx(app.UIAxesImperialRSD,indata.qqu(k),indata.bin(k),'r|','LineWidth',3);
                    fh(end+1) = semilogx(app.UIAxesImperialRSD,indata.qql(k),indata.bin(k),'r|','LineWidth',3);
                    fh(end+1) = semilogx(app.UIAxesImperialRSD,[indata.qql(k),indata.qqu(k)],[indata.bin(k),indata.bin(k)],'r-','LineWidth',1);
                end
                if app.RefCheckBox.Value
                    fh(end+1) = semilogx(app.UIAxesImperialRSD,indata.rr(k),indata.bin(k),'o','Color',gold,'LineWidth',1);
                    fh(end+1) = semilogx(app.UIAxesImperialRSD,indata.rru(k),indata.bin(k),'|','Color',gold,'LineWidth',3);
                    fh(end+1) = semilogx(app.UIAxesImperialRSD,indata.rrl(k),indata.bin(k),'|','Color',gold,'LineWidth',3);
                    fh(end+1) = semilogx(app.UIAxesImperialRSD,[indata.rrl(k),indata.rru(k)],[indata.bin(k),indata.bin(k)],'-','Color',gold,'LineWidth',1); 
                end
                app.ifig = fh;
                app.ifigindex = k;
                statsData.id =indata.id(k);
                statsData.ss =indata.ss(k);
                statsData.ssl =indata.ssl(k);
                statsData.ssu =indata.ssu(k);
                statsData.qq =indata.qq(k);
                statsData.qql =indata.qql(k);
                statsData.qqu =indata.qqu(k);
                statsData.rr =indata.rr(k);
                statsData.rrl =indata.rrl(k);
                statsData.rru =indata.rru(k);
                statsData.dd =indata.dd(k);
                statsData.bb =indata.bb(k);
                statsData.bestbatch =indata.bestbatch(k);

                app.ImperialToolTipLabel.Visible ="on";
                ToolTip(app,app.ImperialToolTipLabel,statsData);
                legend(app.UIAxesImperialRSD,app.ImperialLegendLabels,app.ImperialLegendItems,'location','southeast');
        end

        function ImperialMouseClick2(app,~,~)
            app.ImperialToolTipLabel.Visible ="off";
            if ~isempty(app.ifig)
                for i=1:length(app.ifig)          
                    delete(app.ifig(i));                 
                end
            end
        end

        function ToolTip(app,labelvariable,statsData)
           txt0 = sprintf(' SampleID : %s',statsData.id{:});
           txt1 = sprintf('\n %%qcRSD (95%%CI) : %.2f (%.2f-%.2f) | %%sampleRSD (95%%CI) : %.2f (%.2f-%.2f)', ...
               statsData.qq,statsData.qql,statsData.qqu, ...
               statsData.ss,statsData.ssl,statsData.ssu);
           txt2 = sprintf('\n %%dRatio : %.2f | %%blankRatio : %.2f',statsData.dd,statsData.bb);
           txt3 = sprintf(' | %%refRSD (95%%CI) : %.2f (%.2f-%.2f)',statsData.rr,statsData.rrl,statsData.rru);
           txt4 = sprintf(' (filtered on Batch %d)',statsData.bestbatch);
           if statsData.bestbatch ~= -1
               txt0 = [txt0,txt4];
           end
           if any(app.PlotDataTable.Reference)
               labelvariable.Text = [txt0,txt1,txt3,txt2];
           else
               labelvariable.Text = [txt0,txt1,txt2];
           end
           
        end

        function plotCompareRSD(app)
            cla(app.UIAxesRSDComparison,'reset');
            grey = [0.7,0.7,0.7];
            green = [0,0.6,0.6];
            if ismac
                if height(app.PlotFeatureTable) < 100                
                    ms = 16;%30;
                elseif height(app.PlotFeatureTable) < 2000
                    ms = 12;%20;
                else
                    ms = 8;%15;
                end
            else
                if height(app.PlotFeatureTable) < 100                
                    ms = 13;%30;
                elseif height(app.PlotFeatureTable) < 2000
                    ms = 9;%20;
                else
                    ms = 5;%15;
                end
            end
            
            qq = app.PlotFeatureTable.qcRSD;
            qql = app.PlotFeatureTable.qcRSDlower95CI;
            qqu = app.PlotFeatureTable.qcRSDupper95CI;   
            ss = app.PlotFeatureTable.sampleRSD;
            ssl = app.PlotFeatureTable.sampleRSDlower95CI;
            ssu = app.PlotFeatureTable.sampleRSDupper95CI;
            rr = app.PlotFeatureTable.refRSD;
            rrl = app.PlotFeatureTable.refRSDlower95CI;
            rru = app.PlotFeatureTable.refRSDupper95CI;

            dd = app.PlotFeatureTable.dRatio;          
            bb = app.PlotFeatureTable.blankRatio;
            id = app.PlotFeatureTable.UID;
            bestbatch = app.PlotFeatureTable.bestBatch;

            in = app.PlotFeatureTable.cleanPeaks;

            maxSampleRSD = 200;
            maxQCRSD = 150;

            ss(ss > maxSampleRSD) = maxSampleRSD;
            qq(qq > maxQCRSD) = maxQCRSD;

            % maxSampleRSD = max(ss);
            % if maxSampleRSD > 200
            %     maxSampleRSD = 200;
            % end
            % maxQCRSD = max(qq);
            % if maxQCRSD > 150
            %     maxQCRSD = 150;
            % end
            

            if any(~in)

                outdata.id = id(~in);
                outdata.dd = dd(~in);
                outdata.bb = bb(~in);
                outdata.qq = qq(~in);
                outdata.qql = qql(~in);
                outdata.qqu = qqu(~in);
                outdata.ss = ss(~in);
                outdata.ssl = ssl(~in);
                outdata.ssu = ssu(~in);
                outdata.rr = rr(~in);
                outdata.rrl = rrl(~in);
                outdata.rru = rru(~in);               
                outdata.bestbatch = bestbatch(~in);  

                scatter(app.UIAxesRSDComparison,outdata.ss,outdata.qq,ms,grey,'filled','MarkerFaceAlpha',0.7,'ButtonDownFcn',@(src,event)app.ComparisonMouseClick(src,event,outdata));     
                hold(app.UIAxesRSDComparison,"on");
            end
            
            if any(in)

                indata.id = id(in);
                indata.dd = dd(in);
                indata.bb = bb(in);
                indata.qq = qq(in);
                indata.qql = qql(in);
                indata.qqu = qqu(in);
                indata.ss = ss(in);
                indata.ssl = ssl(in);
                indata.ssu = ssu(in);
                indata.rr = rr(in);
                indata.rrl = rrl(in);
                indata.rru = rru(in);  
                indata.bestbatch = bestbatch(in); 
                
                scatter(app.UIAxesRSDComparison,indata.ss,indata.qq,ms,green,'filled','MarkerFaceAlpha',0.7,'ButtonDownFcn',@(src,event)app.ComparisonMouseClick(src,event,indata));
                hold(app.UIAxesRSDComparison,"on");
            end
            
            
            xlim(app.UIAxesRSDComparison,[0,max(ss)]);
            ylim(app.UIAxesRSDComparison,[0,max(qq)]);
            app.UIAxesRSDComparison.XTick = 0:20:200;
            app.UIAxesRSDComparison.YTick = 0:20:150;
            
            app.UIAxesRSDComparison.XGrid = 'on';            
            app.UIAxesRSDComparison.YGrid = 'on';            
            app.UIAxesRSDComparison.MinorGridLineStyle = '-';
            app.UIAxesRSDComparison.MinorGridAlpha = 0.2;
            

            xlabel(app.UIAxesRSDComparison,'Sample %RSD');
            ylabel(app.UIAxesRSDComparison,'QC %RSD');
            
            app.UIAxesRSDComparison.Box = 'on';
            app.UIAxesRSDComparison.ButtonDownFcn = createCallbackFcn(app,@(src,event)app.ComparisonMouseClick2(src,event), true);
        end

        function ComparisonMouseClick(app,source,event,indata)
                if ~isempty(app.ifig2)
                    for i=1:length(app.ifig2)          
                        delete(app.ifig2(i));                 
                    end
                end
                k = dsearchn([source.XData;source.YData]',event.IntersectionPoint(:,1:2));
                if k == app.ifigindex2, for i=1:length(app.ifig2), delete(app.ifig2(i)); end; end

                fh(1) = plot(app.UIAxesRSDComparison,indata.ss(k),indata.qq(k),'ko','LineWidth',1);
                fh(2) = plot(app.UIAxesRSDComparison,indata.ss(k),indata.qqu(k),'k_','LineWidth',3);
                fh(4) = plot(app.UIAxesRSDComparison,indata.ss(k),indata.qql(k),'k_','LineWidth',3);
                fh(5) = plot(app.UIAxesRSDComparison,indata.ssl(k),indata.qq(k),'k|','LineWidth',3);
                fh(6) = plot(app.UIAxesRSDComparison,indata.ssu(k),indata.qq(k),'k|','LineWidth',3);
                fh(7) = plot(app.UIAxesRSDComparison,[indata.ssl(k),indata.ssu(k)],[indata.qq(k),indata.qq(k)],'b-','LineWidth',1);
                fh(8) = plot(app.UIAxesRSDComparison,[indata.ss(k),indata.ss(k)],[indata.qql(k),indata.qqu(k)],'b-','LineWidth',1);
                
                statsData.id =indata.id(k);
                statsData.ss =indata.ss(k);
                statsData.ssl =indata.ssl(k);
                statsData.ssu =indata.ssu(k);
                statsData.qq =indata.qq(k);
                statsData.qql =indata.qql(k);
                statsData.qqu =indata.qqu(k);
                statsData.rr =indata.rr(k);
                statsData.rrl =indata.rrl(k);
                statsData.rru =indata.rru(k);
                statsData.dd =indata.dd(k);
                statsData.bb =indata.bb(k);
                statsData.bestbatch = indata.bestbatch(k);

                app.ComparisonToolTipLabel.Visible ="on";
                ToolTip(app,app.ComparisonToolTipLabel,statsData);
                app.ifig2 = fh;
                app.ifigindex2 = k;             
        end

        function ComparisonMouseClick2(app,~,~)
            app.ComparisonToolTipLabel.Visible ="off";
            if ~isempty(app.ifig2)
                for i=1:length(app.ifig2)          
                    delete(app.ifig2(i));                 
                end
            end
        end

        function PlotPCA(app)
            try
                plotPCAXX(app.UIAxesPCA,app.PlotDataTable,app.PCAmodel, ...
                    xaxis = app.XAxisDropDown.Value, ...
                    yaxis = app.YAxisDropDown.Value, ...
                    IncludeQCs= app.IncludeQCSamplesCheckBox.Value, ...
                    IncludeBlanks= app.IncludeBlankSamplesCheckBox.Value, ...
                    IncludeRefs= app.IncludeReferenceSamplesCheckBox.Value, ...
                    label = app.LabelbyDropDown.Value, ...
                    plotCIA = app.ConfidenceIntervalsCheckBoxA.Value, ...
                    plotCIB = app.ConfidenceIntervalsCheckBoxB.Value, ...
                    plotDataPoints = app.DataPointsCheckBox.Value, ...
                    cmap = app.cmap);
                    if app.LegendCheckBox.Value
                        app.UIAxesPCA.Legend.Visible = true;
                    else
                        app.UIAxesPCA.Legend.Visible = false;
                    end 
            catch ME
                uialert(app.UIFigure,ME.message,'Error: PCA Plot','Icon','error');
                cla(app.UIAxesPCA);
                cla(app.UIAxesPVE);
                cla(app.UIAxesPVE2);
                return;
            end
        end

        function calcAndPlotPCA(app)

            if ~app.anyNAN
                impType = 'blank20';
            else
                impType = app.baseConfig.CleanImputationMethod;
            end

            try 
                [app.PCAmodel] = qcrscPCA(app.PlotDataTable,app.PlotFeatureTable, ...
                    RemoveZeros = app.baseConfig.RemoveZeros, ...                    
                    ImputationType = impType, ...
                    LogTransform = app.Log10TransformCheckBox.Value, ...
                    ScaleMethod = app.baseConfig.CleanScaleMethod, ...                
                    batchScale = false, ...
                    k=app.kSpinner.Value);
            catch ME
                uialert(app.UIFigure,{ME.message;'Try reducing the % allowable missing values'},'PCA Error','Icon','error');
                cla(app.UIAxesPCA);
                cla(app.UIAxesPVE);
                cla(app.UIAxesPVE2);
                return;
            end

            pcnum = (1:width(app.PCAmodel.Score))';
            app.XAxisDropDown.Items = ['Order';arrayfun(@(x) ['PC',num2str(x)],pcnum,'UniformOutput',false)];
            app.XAxisDropDown.ItemsData = [0;pcnum];
            app.YAxisDropDown.Items = arrayfun(@(x) ['PC',num2str(x)],pcnum,'UniformOutput',false);
            app.YAxisDropDown.ItemsData = pcnum;  
            
            PlotPCA(app);

            numPCsInBar = 100;
            if length(app.PCAmodel.latent) < numPCsInBar
                numPCsInBar = length(app.PCAmodel.latent);
            end
            
            explainedVals = app.PCAmodel.explained(1:numPCsInBar);
            dRatioVals = app.PCAmodel.DRatio(1:numPCsInBar);
            cumVarVals = app.PCAmodel.cumVar(1:numPCsInBar);
            cumMPQVals = app.PCAmodel.cumMPQ(1:numPCsInBar);
            
            if app.PCStatsSwitch.Value
                thresh = min([75,round(max(dRatioVals)*.1)/.1]);
                temp = dRatioVals;
                temp(temp>thresh) = thresh+4;
                bh = barh(app.UIAxesPVE,-1*temp,'red');
                xlabel(app.UIAxesPVE,'dRatio_{PC}','fontsize',10);
                app.UIAxesPVE.XLim = [-1*(thresh+5),0];
                app.UIAxesPVE.XTick = [-1*thresh,0];
                app.UIAxesPVE.XTickLabel = [thresh,0];

                bh2 = barh(app.UIAxesPVE2,explainedVals,'blue');
                xlabel(app.UIAxesPVE2,'Expl.Var_{PC}','fontsize',10)
                app.UIAxesPVE2.XLim = [0,0.105*ceil(max(explainedVals*10))];
                app.UIAxesPVE2.XTick = [0,0.1*ceil(max(explainedVals*10))];

                bh.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);    
                bh.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Expl.Var.',explainedVals,'%.2f%%');    
                bh.DataTipTemplate.DataTipRows(3) = dataTipTextRow('dRatio',dRatioVals,'%.2f%%');
                 
                bh2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);                  
                bh2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Expl.Var.',explainedVals,'%.2f%%');    
                bh2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('dRatio',dRatioVals,'%.2f%%');


            else
                bh = barh(app.UIAxesPVE,-1*cumMPQVals,'red');
                xlabel(app.UIAxesPVE,'Cum.MPQ_{PC}','fontsize',10);
                app.UIAxesPVE.XLim = [-0.105*ceil(max(cumMPQVals*10)),0];
                app.UIAxesPVE.XTick = [-0.1*ceil(max(cumMPQVals*10)),0];
                app.UIAxesPVE.XTickLabel = [0.1*ceil(max(cumMPQVals*10)),0];

                bh2 = barh(app.UIAxesPVE2,cumVarVals,'blue');
                xlabel(app.UIAxesPVE2,'Cum.Expl.Var_{PC}','fontsize',10)              
                app.UIAxesPVE2.XLim = [0,100];               
                app.UIAxesPVE2.XTick = [0,100];

                bh.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);    
                bh.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Cum.Expl.Var.',cumVarVals,'%.2f%%');                    
                bh.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.MPQ_{qc}',cumMPQVals,'%.2f%%');
                 
                bh2.DataTipTemplate.DataTipRows(1) = dataTipTextRow('PC',1:numPCsInBar);    
                bh2.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Cum.Expl.Var.',cumVarVals,'%.2f%%');                    
                bh2.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Cum.MPQ_{qc}',cumMPQVals,'%.2f%%');                
            end
            
            app.UIAxesPVE.Box = 'on';
            app.UIAxesPVE.YLim = [0,numPCsInBar+1];
            app.UIAxesPVE2.YLim = [0,numPCsInBar+1];
            app.UIAxesPVE.YTick = [];
            app.UIAxesPVE2.YTick = [1,numPCsInBar];
            app.UIAxesPVE2.YTickLabel = [1,numPCsInBar];
            app.UIAxesPVE2.Box = 'on';
            app.UIAxesPVE2.TickDir = 'none';
            
            app.MPQLabel.Text = sprintf('MPQ: %.1f%%',app.PCAmodel.MPQ);
            app.MPQ95Label.Text = sprintf('MPQ<sub>95</sub>: %.1f%%',app.PCAmodel.MPQ95);
            %app.MPQ95Label.Text = 'MPQ_{95}';
        end        
        
        function plotGammaHistogam(app)
            cla(app.UIAxesQCRSCpdf,'reset');
            ubatch = unique(app.PlotDataTable.Batch);         
            NumOfBatches = numel(ubatch);
            gammaRange = str2num(app.baseConfig.QCRSCgammaRange);
            if length(gammaRange) > 2
                edges1 = gammaRange(1:end-1) - diff(gammaRange) / 2;
                edges2 = gammaRange(2:end) + diff(gammaRange) / 2;
            else
                edges1 = gammaRange(1:end) - diff(gammaRange) / 2;
                edges2 = gammaRange(1:end) + diff(gammaRange) / 2;
            end
            edges = unique([edges1,edges2]);
            
            in = app.PlotFeatureTable.cleanPeaks;


            n0 = nan(NumOfBatches,length(edges)-1);            
            for i = 1:NumOfBatches
                y = app.PlotFeatureTable{:,['gammaB',num2str(ubatch(i))]};
                n0(i,:) = histcounts(y,edges);
            end
            b0 = bar(app.UIAxesQCRSCpdf,gammaRange,n0','FaceColor','w','EdgeColor','k');
            hold(app.UIAxesQCRSCpdf,"on");  

            n = nan(NumOfBatches,length(edges)-1);            
            for i = 1:NumOfBatches
                y = app.PlotFeatureTable{in,['gammaB',num2str(ubatch(i))]};
                n(i,:) = histcounts(y,edges);
            end
            b1 = bar(app.UIAxesQCRSCpdf,gammaRange,n');
            
            for i = 1:NumOfBatches
                bnum = ones(length(gammaRange),1)*ubatch(i);
                b0(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('Batch',bnum);
                b0(i).DataTipTemplate.DataTipRows(2).Label = '#features';
                b1(i).DataTipTemplate.DataTipRows(1) = dataTipTextRow('Batch',bnum);
                b1(i).DataTipTemplate.DataTipRows(2).Label = '#features';
            end
            xlabel(app.UIAxesQCRSCpdf,'QCRSC gammaVal range');
            ylabel(app.UIAxesQCRSCpdf,'Number of Features');
            try
                app.UIAxesQCRSCpdf.YLim = [0,1.05*max(n0,[],'all')];
            catch
                app.UIAxesQCRSCpdf.YLim = [0,1];
            end
        end


        function selectcleanPeaks(app)
            if app.FilterBankSwitch.Value == true
                rsdThresh = app.QCRSDKnob.Value;
                sampleThresh = app.SampleRSDKnob.Value;
                dratioThresh = app.DRatioKnob.Value;
                bratioThresh = app.BlankRatioKnob.Value;
                if rsdThresh == 100, rsdThresh = 2000; end
                if dratioThresh == 100, dratioThresh = 2000; end
                if bratioThresh == 100, bratioThresh = 20000; end
            else
                rsdThresh = 2000;
                sampleThresh = 0;
                dratioThresh = 2000;
                bratioThresh = 2000;
            end


            batchNum = app.BatchDropDown.Value;

            if app.SwitchBeforeAfter.Value
                app.PlotFeatureTable = app.FeatureXAfter;
                app.PlotDataTable = app.DataX;
            else
                app.PlotFeatureTable = app.FeatureXBefore;
                app.PlotDataTable = app.DataTable;
                list = setdiff(app.FeatureTable.UID,app.PlotFeatureTable.UID);
                app.PlotDataTable(:,list) = [];
            end
                  
            ubatch = unique(app.PlotDataTable.Batch);
            NumOfBatch = numel(ubatch);
            for i = 1:NumOfBatch
                temp = app.PlotFeatureTable.(['blankRatioB',num2str(ubatch(i))]);
                temp(isnan(temp)) = 0.1;
                temp(temp < 0.1) = 0.1;
                app.PlotFeatureTable.(['blankRatioB',num2str(ubatch(i))]) = temp;
            end
            app.PlotFeatureTable.blankRatio(isnan(app.PlotFeatureTable.blankRatio)) = 0.1;
            app.PlotFeatureTable.blankRatio(app.PlotFeatureTable.blankRatio < 0.1) = 0.1;
            app.PlotFeatureTable.blankRatioMax(isnan(app.PlotFeatureTable.blankRatioMax)) = 0.1;
            app.PlotFeatureTable.blankRatioMax(app.PlotFeatureTable.blankRatioMax < 0.1) = 0.1;
            app.PlotFeatureTable.blankRatioMedian(isnan(app.PlotFeatureTable.blankRatioMedian)) = 0.1;
            app.PlotFeatureTable.blankRatioMedian(app.PlotFeatureTable.blankRatioMedian < 0.1) = 0.1;
            app.PlotFeatureTable.blankRatio(isnan(app.PlotFeatureTable.blankRatio)) = 0.1;
            app.PlotFeatureTable.blankRatio(app.PlotFeatureTable.blankRatio < 0.1) = 0.1;


            if batchNum > 0
                app.PlotFeatureTable.qcRSD = app.PlotFeatureTable.(['qcRSDB',num2str(batchNum)]);
                app.PlotFeatureTable.qcRSDlower95CI = app.PlotFeatureTable.(['qcRSDlower95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.qcRSDupper95CI = app.PlotFeatureTable.(['qcRSDupper95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.sampleRSD = app.PlotFeatureTable.(['sampleRSDB',num2str(batchNum)]);
                app.PlotFeatureTable.sampleRSDlower95CI = app.PlotFeatureTable.(['sampleRSDlower95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.sampleRSDupper95CI = app.PlotFeatureTable.(['sampleRSDupper95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.refRSD = app.PlotFeatureTable.(['refRSDB',num2str(batchNum)]);
                app.PlotFeatureTable.refRSDlower95CI = app.PlotFeatureTable.(['refRSDlower95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.refRSDupper95CI = app.PlotFeatureTable.(['refRSDupper95CIB',num2str(batchNum)]);
                app.PlotFeatureTable.dRatio = app.PlotFeatureTable.(['dRatioB',num2str(batchNum)]);
                app.PlotFeatureTable.blankRatio = app.PlotFeatureTable.(['blankRatioB',num2str(batchNum)]);                
                app.PlotDataTable = app.PlotDataTable(app.PlotDataTable.Batch == batchNum,:);
                app.PlotFeatureTable.bestBatch = ones(height(app.PlotFeatureTable),1)*batchNum;
            else
                if ~strcmp(app.FilterBankModeDropDown.Value,'Complete')
                    switch app.FilterBankModeDropDown.Value
                        case 'Max qcRSD'
                            for i = 1:NumOfBatch
                                val(:,i) = app.PlotFeatureTable.(['qcRSDB',num2str(ubatch(i))]);
                            end
                            [~,idx] = max(val,[],2);
                            bestBatch = ubatch(idx);                                                                        
                        case 'Max dRatio'
                            for i = 1:NumOfBatch
                                val(:,i) = app.PlotFeatureTable.(['dRatioB',num2str(ubatch(i))]);
                            end
                            [~,idx] = max(val,[],2);
                            bestBatch = ubatch(idx);
                        case 'Min qcRSD'
                            for i = 1:NumOfBatch
                                val(:,i) = app.PlotFeatureTable.(['qcRSDB',num2str(ubatch(i))]);
                            end
                            [~,idx] = min(val,[],2);
                            bestBatch = ubatch(idx);                                                                        
                        case 'Min dRatio'
                            for i = 1:NumOfBatch
                                val(:,i) = app.PlotFeatureTable.(['dRatioB',num2str(ubatch(i))]);
                            end
                            [~,idx] = min(val,[],2);
                            bestBatch = ubatch(idx);
                        case 'Median qcRSD'
                            for i = 1:NumOfBatch
                                val(:,i) = app.PlotFeatureTable.(['qcRSDB',num2str(ubatch(i))]);
                            end
                            mm = median(val,2,"omitmissing");
                            for i = 1:height(app.PlotFeatureTable)
                                idx(i) = dsearchn(val(i,:)',mm(i));
                            end
                            bestBatch = ubatch(idx);
                        case 'Median dRatio'
                            for i = 1:NumOfBatch
                                val(:,i) = app.PlotFeatureTable.(['dRatioB',num2str(ubatch(i))]);
                            end
                            mm = median(val,2,"omitmissing");
                            for i = 1:height(app.PlotFeatureTable)
                                idx(i) = dsearchn(val(i,:)',mm(i));
                            end
                            bestBatch = ubatch(idx);   
                        otherwise
                            baseException = MException('QCRSC:BatchWiseDropDown','unknown option');
                            throw(baseException)
                    end 
                    for i = 1:height(app.PlotFeatureTable)
                        app.PlotFeatureTable.bestBatch(i) = bestBatch(i);
                        app.PlotFeatureTable.qcRSD(i) = app.PlotFeatureTable.(['qcRSDB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.qcRSDlower95CI(i) = app.PlotFeatureTable.(['qcRSDlower95CIB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.qcRSDupper95CI(i) = app.PlotFeatureTable.(['qcRSDupper95CIB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.sampleRSD(i) = app.PlotFeatureTable.(['sampleRSDB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.sampleRSDlower95CI(i) = app.PlotFeatureTable.(['sampleRSDlower95CIB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.sampleRSDupper95CI(i) = app.PlotFeatureTable.(['sampleRSDupper95CIB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.refRSD(i) = app.PlotFeatureTable.(['refRSDB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.refRSDlower95CI(i) = app.PlotFeatureTable.(['refRSDlower95CIB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.refRSDupper95CI(i) = app.PlotFeatureTable.(['refRSDupper95CIB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.dRatio(i) = app.PlotFeatureTable.(['dRatioB',num2str(bestBatch(i))])(i);
                        app.PlotFeatureTable.blankRatio(i) = app.PlotFeatureTable.(['blankRatioB',num2str(bestBatch(i))])(i);
                    end   
                else
                    app.PlotFeatureTable.bestBatch = ones(height(app.PlotFeatureTable),1)*-1;
                end
            end      
            
            qcRSDB = app.PlotFeatureTable.qcRSD;
            sampleRSDB = app.PlotFeatureTable.sampleRSD;
            dRatioB = app.PlotFeatureTable.dRatio;
            blankRatioB = app.PlotFeatureTable.blankRatio;
            

            X = app.PlotDataTable{app.PlotDataTable.Sample,app.PlotFeatureTable.UID};

            app.anyNAN = anynan(X);
            if ~app.anyNAN
                app.SampleFilterPercentEditField.Enable = "off";
                app.SampleFilterDropDown.Enable = "off";
                app.MissingValueImputationButtonGroup.Enable = "off";
                app.missingperfeatureLabel.Enable = "off";
            else
                app.SampleFilterPercentEditField.Enable = "on";
                app.SampleFilterDropDown.Enable = "on";
                app.MissingValueImputationButtonGroup.Enable = "on";
                app.missingperfeatureLabel.Enable = "on";
            end

            
            goodFeatures = true(height(app.PlotFeatureTable),1);
            if app.anyNAN
                if batchNum > 0
                    totalPercentMissing = 100 * sum(isnan(X))./height(X);
                    goodFeatures = totalPercentMissing' <= app.SampleFilterPercentEditField.Value;
                else
                    batchSample = app.PlotDataTable.Batch(app.PlotDataTable.Sample);                
                    for i = 1:NumOfBatch
                        Xb = X(batchSample == ubatch(i),:);
                        batchPercentMissing(:,i) = 100 * sum(isnan(Xb))./height(Xb);
                    end
                    batchKeep = batchPercentMissing <= app.SampleFilterPercentEditField.Value;
                    switch app.SampleFilterDropDown.Value
                        case 'EveryBatch'
                            goodFeatures = all(batchKeep,2);
                        case 'AnyBatch'
                            goodFeatures = any(batchKeep,2);
                        otherwise % Complete
                            totalPercentMissing = 100 * sum(isnan(X))./height(X);
                            goodFeatures = totalPercentMissing' <= app.SampleFilterPercentEditField.Value;                           
                    end
                end
            end
            numGoodFeature = sum(goodFeatures);
            app.featuresremainingLabel.Text = sprintf('#Features remaining: %u',numGoodFeature);

            
            cleanPeaks = qcRSDB < rsdThresh & sampleRSDB > sampleThresh & dRatioB < dratioThresh & blankRatioB < bratioThresh & goodFeatures;
            

            numcleanPeaks = sum(cleanPeaks);
            app.CleanFeaturesRemainingLabel.Text = sprintf('# Clean Features: %u',numcleanPeaks);
            app.PlotFeatureTable.cleanPeaks = cleanPeaks;
            app.FeatureX.cleanPeaks = cleanPeaks;

            P1 = app.PlotFeatureTable(goodFeatures,:);
            list = setdiff(app.PlotFeatureTable.UID,P1.UID);
            app.PlotDataTable(:,list) = [];
            app.PlotFeatureTable = P1;
              
        end
              
        function ResetCleanDials(app)

            %resetConfigFieldsRight(app);

            setConfigFields(app,mode='clean');

            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            
            app.FilterBankSwitch.Value = false;          
            setFilterKnobColors(app);
            
            app.BatchDropDown.Value = -1;
            app.SwitchBeforeAfter.Value = true;
            
            app.IncludeBlankSamplesCheckBox.Value = false;
            app.IncludeReferenceSamplesCheckBox.Value = false;
            app.IncludeQCSamplesCheckBox.Value = true;
        
            
            app.YAxisDropDown.Items = {'PC 1', 'PC 2'};
            app.YAxisDropDown.ItemsData = [1,2];
            app.XAxisDropDown.Items = {'PC 1', 'PC 2'};
            app.XAxisDropDown.ItemsData = [1,2];
            app.YAxisDropDown.Value = 2;
            app.XAxisDropDown.Value = 1;
            app.ConfidenceIntervalsCheckBoxA.Value = true;
            app.ConfidenceIntervalsCheckBoxB.Value = true;
            app.DataPointsCheckBox.Value = true;               
            app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of SampleType';
            app.ConfidenceIntervalsCheckBoxB.Visible = "off"; 
            app.LegendCheckBox.Value = true;
            app.LabelbyDropDown.Value = 'SampleType';
            app.PCStatsSwitch.Value = true;

            app.QCCheckBox.Value = true;
            app.RefCheckBox.Value = false;
            app.SortbySwitch.Value = 'sample';
     
            app.SmoothBLANKslider.Value = 20;
            app.SmoothDRATIOslider.Value = 20;
            app.SmoothRSDslider.Value = 20;

            app.SwitchDRATIOpdf.Value = 'histogram';
            app.SwitchRSDpdf.Value = 'histogram';
            app.SwitchBlankpdf.Value = 'histogram';

            app.SwitchBlankPlot.Value = 'all';
            app.SwitchRSDPlot.Value = 'all';
            app.SwitchDratioPlot.Value = 'all';

            app.ImperialToolTipLabel.Visible = "off";
            app.ComparisonToolTipLabel.Visible = "off";

            app.AlsosavescaledimputeddataCheckBox.Value = false;

            if ~isempty(app.PlotFeatureTable)
                    %calculateStats(app);
                    CleanAndPlot(app);
            end
            
        end

         function CleanAndPlot(app)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            switchAllPCAswtiches(app,'on');
            drawnow();
            try
                if app.noQCflag
                    baseException = MException('CleanExplr:NoQC','There has to be 3 or more QCs per batch for exploration!');
                    throw(baseException);
                end                
                selectcleanPeaks(app);
                if height(app.PlotFeatureTable) < 0.1*height(app.FeatureXAfter)
                    message1 = ['You only have ',num2str(height(app.PlotFeatureTable)),' features remaining. Consider increasing the %missing per feature threshold'];
                    selection = uiconfirm(app.UIFigure,message1,'Low Number of Features','Icon','warning','Options',{'Change Filter','Continue'});
                    if strcmp(selection,'Change Filter')
                        ClearPlots(app);
                        set(app.UIFigure, 'Pointer','arrow');
                        switchAllPanelsA(app,'on');
                        switchAllPanelsB(app,'on');
                        drawnow();
                        return;        
                    end
                end
                plotImperialRSD(app);
                plotCompareRSD(app);               
                RSDPlotValueChanged(app)           
                DratioPlotValueChanged(app)
                BlankPlotValueChanged(app)
                plotGammaHistogam(app); 
                try
                    calcAndPlotPCA(app);
                catch ME      
                    cla(app.UIAxesPCA);
                    app.YAxisDropDown.Items = {'PC 1', 'PC 2'};
                    app.YAxisDropDown.ItemsData = [1,2];
                    app.XAxisDropDown.Items = {'PC 1', 'PC 2'};
                    app.XAxisDropDown.ItemsData = [1,2];
                    app.YAxisDropDown.Value = 2;
                    app.XAxisDropDown.Value = 1;
                    app.UIAxesPCA.Title.String = 'Principal Component Analysis';
                    ylabel(app.UIAxesPCA,'PC2');
                    xlabel(app.UIAxesPCA,'PC1');
                    app.PCAmodel = [];
                    switchAllPCAswtiches(app,'off');
                    baseException = MException('CleanExplr:PCA',"PCA failure. Possibly due to too few data or imputation method. Consider changing the filter settings.");
                    throw(baseException);
                end
            catch ME
                %ClearPlots(app);
                uialert(app.UIFigure,ME.message,'CleanAndPlot Failure','Icon','error');
                %app.CleanExplorePanel.Enable = "off";
                %app.SavePanel.Enable = "on";
                %switchAllPanelsA(app,'on');
                %set(app.UIFigure, 'Pointer','arrow');
                %return;
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
         end

         % function RePlotAll(app)
         % 
         %    set(app.UIFigure, 'Pointer','watch');
         %    switchAllPanelsA(app,'off');
         %    switchAllPanelsB(app,'off');
         %    drawnow();
         %    try     
         %        plotImperialRSD(app);
         %        plotCompareRSD(app);
         %        calcAndPlotPCA(app);        
         %        RSDPlotValueChanged(app)           
         %        DratioPlotValueChanged(app)
         %        BlankPlotValueChanged(app)
         %        plotGammaHistogam(app);
         %    catch ME
         %        ClearPlots(app);
         %        uialert(app.UIFigure,ME.message,'RePlotAll Failure','Icon','error');
         %    end
         %    set(app.UIFigure, 'Pointer','arrow');
         %    switchAllPanelsA(app,'on');
         %    switchAllPanelsB(app,'on');
         %    drawnow();
         % end

         function RePlotRSD(app)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            try
                plotImperialRSD(app);
                plotCompareRSD(app);
            catch ME
                uialert(app.UIFigure,ME.message,'RePlotRSD Failure','Icon','error');
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
         end

        function ClearPlots(app)
            cla(app.UIAxesRSDpdf);
            cla(app.UIAxesDRATIOpdf);
            cla(app.UIAxesBLANKpdf);
            cla(app.UIAxesQCRSCpdf);
            cla(app.UIAxesPCA);
            cla(app.UIAxesPVE);
            cla(app.UIAxesPVE2);
            cla(app.UIAxesImperialRSD);
            cla(app.UIAxesRSDComparison);

            app.UIAxesPCA.Title.String = 'Principal Component Analysis';
            ylabel(app.UIAxesPCA,'PC2');
            xlabel(app.UIAxesPCA,'PC1');
            app.CleanFeaturesRemainingLabel.Text = '# Clean Features:';          
            app.MPQLabel.Text = sprintf('MPQ:');
            app.MPQ95Label.Text = sprintf('MPQ<sub>95</sub>:');
            %app.MPQ95Label.Text = 'MPQ_{95}';
            
            app.QCCorrectionEnginePanel.Title = sprintf('QC Correction Engine:');
            app.PCAmodel = [];
        end

        
        function SaveData(app,Table,sheetname)
            
            %try
                if strcmp(app.FileType,'xlsx')  
                    
                    d = uiprogressdlg(app.UIFigure,'Title',['Saving ',sheetname]);
                    drawnow
                    pause(0.2);
                    ExcelName = [app.ProjectName,'.',app.FileType];
                    xlSheetNames = sheetnames(ExcelName);
                    %sheetname = [app.baseConfig.DataTable,suffix];
                    d.Message = ['Saving: ',sheetname];
                    if ismember(sheetname,xlSheetNames)
                        selection = uiconfirm(app.UIFigure,['Overwrite Sheet ',sheetname,'?'],'Confirm DataTable Action','Icon','warning');
                        if strcmp(selection,'Cancel')
                            return;
                            % baseException = MException('QCRSC:ExportError',['Cannot save to Sheet:',sheetname]);
                            % throw(baseException)
                        end
                    end
                    d.Value = 1/2;
                    pause(0.2);
                    writetable(Table,ExcelName,'Sheet',sheetname,'WriteMode','overwritesheet');
                    d.Value = 1;
                    pause(0.2);
                    close(d);
                elseif strcmp(app.FileType,'csv')                    
                    
                    d = uiprogressdlg(app.UIFigure,'Title',['Saving ',sheetname]);
                    drawnow
                    pause(0.2);
                    filename = [sheetname,'.csv'];
                    d.Message = ['Saving: ',filename];
                    if isfile(filename)
                        selection = uiconfirm(app.UIFigure,['Overwrite ',filename,'?'],'Confirm DataTable Action','Icon','warning');
                        if strcmp(selection,'Cancel')
                            return;
                            % baseException = MException('QCRSC:ExportError',['Cannot save to ',filename]);
                            % throw(baseException)
                        end
                    end
                    d.Value = 1/2;
                    pause(0.2);
                    writetable(Table,filename);
                    d.Value = 1;
                    pause(0.2);
                    close(d);
                end
            % catch baseException
            %      uialert(app.UIFigure,baseException.message,'Failed to save Data Table','Icon','error');
            % end       
            
            
        end
        

        function switchAllPCAswtiches(app,onoff)
            app.IncludeQCSamplesCheckBox.Enable = onoff;
            app.IncludeReferenceSamplesCheckBox.Enable = onoff;
            app.IncludeBlankSamplesCheckBox.Enable = onoff;
            app.PCStatsSwitch.Enable = onoff;
            app.PopOutButton.Enable = onoff;
            app.LabelbyDropDown.Enable = onoff;
            app.VariationExplainedbyeachPCLabel.Enable = onoff;
            app.DataPointsCheckBox.Enable = onoff;
            app.ConfidenceIntervalsCheckBoxB.Enable = onoff;
            app.ConfidenceIntervalsCheckBoxA.Enable = onoff;
            app.LegendCheckBox.Enable = onoff;
            app.YAxisDropDown.Enable = onoff;
            app.XAxisDropDown.Enable = onoff;
        end

        function switchAllPanelsA(app,onoff)
            app.IncomingDataPanel.Enable = onoff;
            app.QCCorrectionParametersPanel.Enable = onoff;
            app.QCMXPConfigurationPanel.Enable = onoff;
            app.FeatureInclusionPreFilterPanel.Enable = onoff;
            app.QCCorrectionEnginePanel.Enable = onoff;
        end

        function switchAllPanelsB(app,onoff)
            app.SavePanel.Enable = onoff;
            app.CleanExplorePanel.Enable = onoff;
        end

        function setFilterKnobColors(app)
            if app.FilterBankSwitch.Value == true
                colr = [1,0,0];
                app.FilterBankLamp.Color = colr;
                app.SampleRSDKnob.FontColor = colr;
                app.QCRSDKnob.FontColor = colr;
                app.BlankRatioKnob.FontColor = colr;
                app.DRatioKnob.FontColor = colr;
                app.SampleRSDKnobLabel.FontColor = colr;
                app.QCRSDKnobLabel.FontColor = colr;
                app.BlankRatioKnobLabel.FontColor = colr;
                app.DRatioKnobLabel.FontColor = colr;
                % app.FilterBankSwitch.FontColor = [1.00,1.00,1.00];
                % app.FilterBankLabel.FontColor = [1.00,1.00,1.00];
                % app.PanelKnobs.BackgroundColor = [0.98,0.22,0.22];
                % app.SampleRSDKnob.FontColor = [1.00,1.00,1.00];
                % app.QCRSDKnob.FontColor = [1.00,1.00,1.00];
                % app.BlankRatioKnob.FontColor = [1.00,1.00,1.00];
                % app.DRatioKnob.FontColor = [1.00,1.00,1.00];
                % app.SampleRSDKnobLabel.FontColor = [1.00,1.00,1.00];
                % app.QCRSDKnobLabel.FontColor = [1.00,1.00,1.00];
                % app.BlankRatioKnobLabel.FontColor = [1.00,1.00,1.00];
                % app.DRatioKnobLabel.FontColor = [1.00,1.00,1.00];
            else
                app.FilterBankLamp.Color = [0.50,0.50,0.50];
                app.FilterBankSwitch.FontColor = [0,0,0];
                app.FilterBankLabel.FontColor = [0,0,0];
                app.PanelKnobs.BackgroundColor = [0.94,0.94,0.94];
                app.SampleRSDKnob.FontColor = [0.65,0.65,0.65];
                app.QCRSDKnob.FontColor = [0.65,0.65,0.65];
                app.BlankRatioKnob.FontColor = [0.65,0.65,0.65];
                app.DRatioKnob.FontColor = [0.65,0.65,0.65];
                app.SampleRSDKnobLabel.FontColor = [0.65,0.65,0.65];
                app.QCRSDKnobLabel.FontColor = [0.65,0.65,0.65];
                app.BlankRatioKnobLabel.FontColor = [0.65,0.65,0.65];
                app.DRatioKnobLabel.FontColor = [0.65,0.65,0.65];
            end
        end

    function checkBetweenBatchValid(app)
            if app.noQCflag 
                 app.baseConfig.WithinBatchCorrectionMode = 'Sample';
                app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
                app.baseConfig.BlankRatioMethod = 'Median';
                app.WithinBatchCorrectionModeDropDown.Value = 'Sample';
                app.BetweenBatchCorrectionModeDropDown.Value = 'Sample';
                app.CleanBlankRatioMethodDropDown.Value = 'Median';
                baseException = MException('QCRSC:WrongBetweenBatchCorrectionMode','There are <3 QCs per batch so switching to "Sample" correction. WithinBatchCorrectionMode, BetweenBatchCorrectionMode & BlankRatioMethod changed.');
                throw(baseException)
            end

            if strcmp(app.baseConfig.WithinBatchCorrectionMode,'Sample')
                if ~strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Sample')
                    app.baseConfig.BetweenBatchCorrectionMode = 'Sample';
                    app.baseConfig.BlankRatioMethod = 'Median';
                    app.BetweenBatchCorrectionModeDropDown.Value = 'Sample';
                    app.CleanBlankRatioMethodDropDown.Value = 'Median';
                    baseException = MException('QCRSC:WrongBetweenBatchCorrectionMode','When using Sample-based within-batch correction the between-batch correction must be also be set to "Sample" (and blankRatio set to "Median"). BetweenBatchCorrectionMode & BlankRatioMethod changed.');
                    throw(baseException)
                end
            end
            % if strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Sample')
            %     if ~strcmp(app.baseConfig.WithinBatchCorrectionMode,'Sample')
            %         app.baseConfig.BetweenBatchCorrectionMode = 'QC';
            %         app.BetweenBatchCorrectionModeDropDown.Value = 'QC';
            %         baseException = MException('QCRSC:WrongBetweenBatchCorrectionMode','When when using QC intra-batch correction the inter-batch correction cannot be set to "Sample". BetweenBatchCorrectionMode changed to "QC".');
            %         throw(baseException)
            %     end
            % end
            if strcmp(app.baseConfig.BetweenBatchCorrectionMode,'Reference')
                ubatch = unique(app.DataTable.Batch);         
                NumOfBatches = numel(ubatch);
                for i = 1:NumOfBatches
                    batchRef = app.DataTable.Reference(app.DataTable.Batch == ubatch(i));
                    batchRefsum(i) = sum(batchRef);
                end
                if any(batchRefsum == 0)
                    app.baseConfig.BetweenBatchCorrectionMode = 'QC';
                    app.BetweenBatchCorrectionModeDropDown.Value = 'QC';
                    baseException = MException('QCRSC:WrongBetweenBatchCorrectionMode','There must be at least one Reference sample per batch for Reference-based between-batch correction. BetweenBatchCorrectionMode changed to "QC".');
                    throw(baseException)
                % else
                %     app.BatchwiseSwitch.Value = true;
                %     app.BatchwiseSwitch.Enable = "off";
                %     app.FilterBankModeDropDown.Enable = "on";
                end
            end
    end

    % function mouseClickSample(app,source,event)
    %     a = 1;
    % end

    function saveConfig(app)

        % if strcmp(app.FileType,'csv')
        %     filename = app.ProjectName
        % 
        % app.ProjectName
        
        if isfile(filename)
            selection = uiconfirm(app.UIFigure,[filename,' will be overwritten'],'Confirm DataTable Action','Icon','warning');
            if strcmp(selection,'Cancel')
               return;
            end
        end
        configExport = getBaseConfig(app);
        try
            configExport = validateConfig(configExport);
        catch
            mydlg = uiconfirm(app.UIFigure,'QCRSC config is corrupted. Better call David.','Confirm Close','Icon','error');
            waitfor(mydlg);
            UIFigureCloseRequest(app);
            return;
        end

        try
            app.jsonTxt = jsonencode(configExport,'PrettyPrint',true);
            cfg = jsondecode(app.jsonTxt);
            %jsontxt = jsonencode(cfg,'PrettyPrint',true);
            fid=fopen(fullfile(path,file),'w');
            %fprintf(fid,jsontxt);
            fprintf(fid,app.jsonTxt);
            fclose(fid);
        catch
            uialert(app.UIFigure,['Failed to save ',filename'],'Error','Icon','error');
        end      
    end

    function configExport = getBaseConfig(app)
        configExport.WorkingDirectory = app.WorkingDirectory;
        configExport.FileType = app.FileType;
        configExport.ProjectName = app.ProjectName;
        configExport.DataTable = app.DataName;
        configExport.FeatureTable = app.FeatureName;      
        configExport.MissingQCPreFilterMode = app.PreFilterDropDown.Value;
        configExport.MissingQCPreFilterPercentage = app.missingQCperFeatureEditField.Value;
        configExport.LOQfilter = app.QCLOQmissingCheckBox.Value;
        configExport.RelativeLOQ = app.LOQalphaEditField.Value;
        configExport.LogTransformedCorrection = app.LogTransformedCorrectionCheckBox.Value;
        configExport.RemoveZeros = app.RemoveZerosCheckBox.Value;
        configExport.OutlierDetectionMethod = app.QCOutlierDetectionMethodDropDown.Value;
        configExport.OutlierDetectionCI = app.QCOutlierDetectionCIEditField.Value;
        configExport.OutlierReplacementStrategy = app.QCOutlierReplacementStrategyDropDown.Value;
        configExport.WithinBatchCorrectionMode = app.WithinBatchCorrectionModeDropDown.Value;
        configExport.BetweenBatchCorrectionMode = app.BetweenBatchCorrectionModeDropDown.Value;
        configExport.QCRSCgammaRange = app.QCRSCgammarangeEditField.Value;
        configExport.QCRSCcvMethod = app.QCRSCCrossValidationMethodDropDown.Value;
        configExport.QCRSCmcReps = app.QCRSCMonteCarlorepetitionsEditField.Value;              
        configExport.BlankRatioMethod = app.BlankRatioMethodDropDown.Value;
        configExport.ParallelProcess = app.ParallelProcessingCheckBox.Value;
        configExport.CorrectedFileSuffix = app.CorrectedSuffixEditField.Value;
        configExport.CleanMissingSampleFilterMode = app.SampleFilterDropDown.Value;
        configExport.CleanMissingSampleFilterPercentage = app.SampleFilterPercentEditField.Value;
        configExport.CleanFilterBankMode = app.FilterBankModeDropDown.Value;        
        configExport.CleanFilterQCRSD = app.QCRSDKnob.Value; 
        configExport.CleanFilterSampleRSD = app.SampleRSDKnob.Value;
        configExport.CleanFilterDRatio = app.DRatioKnob.Value;
        configExport.CleanFilterBlankRatio = app.BlankRatioKnob.Value;
        configExport.CleanFileSuffix = app.SaveCleanSuffixEditField.Value;
        configExport.CleanLogTransform = app.Log10TransformCheckBox.Value;
        configExport.CleanScaleMethod = app.baseConfig.CleanScaleMethod;
        configExport.CleanImputationMethod = app.baseConfig.CleanImputationMethod;
        configExport.CleanImputationK = app.kSpinner.Value;
    end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
         
            app.UIFigure.Name = 'QCMXP';
            app.UIFigure.Icon= 'icon_512x512@2x.png';
            
            app.ViewFeatureTableButton.Enable = 'off';
            app.PauseButton.Enable = 'off';
            app.AbortButton.Enable = 'off';
            app.SavePanel.Enable = 'off';
            app.LaunchFeatureExplorer.Enable = 'off';
            app.drinkMeButton.Enable = 'off';
            
            app.FeatureInclusionPreFilterPanel.Enable = 'off';
            app.QCCorrectionParametersPanel.Enable = 'off';
            app.QCMXPConfigurationPanel.Enable = 'off';
            app.QCCorrectionEnginePanel.Enable = 'off';
            app.CleanExplorePanel.Enable = 'off';
            
            app.RunningLamp.Enable = 'off';
            app.CompleteLamp.Enable = 'off';
            app.ImportSuccess = true;
            
            app.complete = false;
                                    
            app.MPQLabel.Text = 'MPQ:';                            
            app.MPQ95Label.Text = 'MPQ<sub>95</sub>:';
            %app.MPQ95Label.Text = 'MPQ_{95}';

            app.UIAxesPCA.Title.String = 'Principal Component Analysis';
            app.UIAxesPCA.Title.FontSize = 12;
            app.UIAxesPCA.Title.Color = 'r';
            app.QCRSCgTab.Title = ['QCRSC ',char(947)'];

            app.FileType = ''; 
            app.ProjectName = '';
            app.FilterBankSwitch.Value = false;


            % Colormaps: {'colorblind7','tolBright7','colorblind12','okabe&ito8','quoka', 'lines', 'colorcube', 'prism', 'moog', 'random'};                       
            app.cmapName = 'colorblind12';
            app.cmap = mapRcolors(app.cmapName);

            app.ifigindex = -1;
      

            switch nargin
                case 1
                    
                case 2                   
                    [app.DataTable,app.FeatureTable] = readDataXLapp(varargin{1},fighandle=app.UIFigure);
                    app.ProjectName = varargin{1};
                    app.FeatureInclusionPreFilterPanel.Enable = 'on';
                    app.QCCorrectionParametersPanel.Enable = 'on';
                    app.QCMXPConfigurationPanel.Enable = 'on';
                    app.QCCorrectionEnginePanel.Enable = 'on';
                    app.LaunchFeatureExplorer.Enable = 'on';
                    app.drinkMeButton.Enable = 'on';
                    app.baseConfig.DataTable = 'Data';
                    app.baseConfig.FeatureTable = 'Feature';
                    app.FileType = 'xlsx';
                case 3
                    app.DataTable = varargin{1};
                    app.FeatureTable = varargin{2};
                    app.ProjectName = 'Matlab Mode';  
                    app.baseConfig.DataTable = '';
                    app.baseConfig.FeatureTable = '';                 
                    app.FeatureInclusionPreFilterPanel.Enable = 'on';
                    app.QCCorrectionParametersPanel.Enable = 'on';
                    app.QCMXPConfigurationPanel.Enable = 'on';
                    app.QCCorrectionEnginePanel.Enable = 'on';
                    app.LaunchFeatureExplorer.Enable = 'on';
                    app.drinkMeButton.Enable = 'on';
                    app.FileType = 'matlab';
                    [app.DataTable,app.FeatureTable] = validatingDataFeatureTables(app.DataTable,app.FeatureTable,fighandle = app.UIFigure);
                otherwise
                    uialert(app.UIFigure,'Too many input arguments','Error','Icon','error');
            end

            app.jsonDefault = ['{' newline ...
                '"WorkingDirectory": "",' newline ...
                '"FileType": "",' newline ...
                '"ProjectName": "",' newline ...
                '"DataTable": "",' newline ...
                '"FeatureTable": "",' newline ...
                '"MissingQCPreFilterMode": "EveryBatch",' newline ...
                '"MissingQCPreFilterPercentage": 20,' newline ...         
                '"LOQfilter": true,' newline ...
                '"RelativeLOQ": 1.2,' newline ...
                '"LogTransformedCorrection": true,' newline ...
                '"RemoveZeros": true,' newline ...
                '"OutlierDetectionMethod": "Linear",' newline ...
                '"OutlierDetectionCI": 0.95,' newline ...
                '"OutlierReplacementStrategy": "NaN",' newline ...
                '"WithinBatchCorrectionMode": "Spline",' newline ...
                '"BetweenBatchCorrectionMode": "QC",' newline ...
                '"QCRSCgammaRange": "0.5:0.5:4",' newline ...
                '"QCRSCcvMethod": "5-Fold",' newline ...
                '"QCRSCmcReps": 10,' newline ...
                '"BlankRatioMethod": "QC",' newline ...
                '"ParallelProcess": true,' newline ...
                '"CorrectedFileSuffix": "_QCRSC",' newline ...
                '"CleanMissingSampleFilterMode": "EveryBatch",' newline ...
                '"CleanMissingSampleFilterPercentage": 25,' newline ...
                '"CleanFilterBankMode": "Complete",' newline ...
                '"CleanFilterQCRSD": 100,' newline ...
                '"CleanFilterSampleRSD": 0,' newline ...
                '"CleanFilterDRatio": 100,' newline ...
                '"CleanFilterBlankRatio": 100,' newline ...
                '"CleanFileSuffix": "_Clean",' newline ...
                '"CleanLogTransform": true,' newline ...
                '"CleanScaleMethod": "Autoscale",' newline ...
                '"CleanImputationMethod": "KNNcolumn",' newline ...
                '"CleanImputationK": 3 ' newline ...
                '}'];
                
            app.configFilename = 'Default';
            app.jsonTxt = app.jsonDefault;
            app.jsonView = strsplit(app.jsonTxt,'\n')';
            app.baseConfig = jsondecode(app.jsonDefault);            
            %app.configFileTextArea.Value = app.jsonView;
            app.QCMXPConfigurationPanel.Title = 'QC:MXP Configuration: Default Settings';
            
            try
                app.baseConfig = validateConfig(app.baseConfig);
                setConfigFields(app);
            catch
                mydlg = uiconfirm(app.UIFigure,'Default QCRSC config is corrupted. Better call David.','Confirm Close','Icon','error');
                waitfor(mydlg);
                UIFigureCloseRequest(app);
                return;
            end

            if ~isempty(app.DataTable)
                ResetData(app);
                ResetCleanDials(app);
                preQCRSCclean(app);
            end
            app.PCAmodel = [];
            app.importedConfig = app.baseConfig;

        end

        % Button pushed function: LOADDATAButton
        function LOADDATAButtonPushed(app, event)
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            app.LaunchFeatureExplorer.Enable = 'off';
            app.LaunchAlgorithmExplorer.Enable = 'off';
            
            if ~isempty(app.SpreadsheetApp)
                delete(app.SpreadsheetApp);
                app.ViewFeatureTableButton.Enable = 'on';
            end

            app.ImportSuccess = false;
            app.ImportApp = ImportDataFeatureFile(app);
            waitfor(app.ImportApp);
            figure(app.UIFigure);
            app.IncomingDataPanel.Enable = 'on';
            app.LaunchAlgorithmExplorer.Enable = 'on';
            app.QCCorrectionParametersPanel.Enable = 'on';
            app.QCMXPConfigurationPanel.Enable = 'on';
            if app.ImportSuccess
                delete(app.PopOutPlotApp);
                delete(app.SpreadsheetApp);
                delete(app.configExplorerApp);
                delete(app.featureExplorerApp);
                app.ViewFeatureTableButton.Enable = 'on';
                app.LaunchFeatureExplorer.Enable = 'on'; 
                app.drinkMeButton.Enable = 'on';
                cd(app.WorkingDirectory);
                app.complete = false;
                app.PlotFeatureTable = [];
                app.FeatureX = [];
                app.DataX = [];
                %ffile = fullfile(app.WorkingDirectory,app.ProjectName);
                app.UIFigure.Name =  ['Working Directory: ',app.WorkingDirectory];
                maxNumQCperBatch = checkNumberInBatch(app.DataTable,'QC');
                app.noQCflag = maxNumQCperBatch < 3;
                ResetData(app);
                ResetCleanDials(app);
                ClearPlots(app);
                preQCRSCclean(app);
                try
                    checkBetweenBatchValid(app);
                catch ME
                    uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
                end
            elseif ~isempty(app.DataTable)
                app.LaunchFeatureExplorer.Enable = 'on';                     
                app.FeatureInclusionPreFilterPanel.Enable = 'on';
                app.QCCorrectionEnginePanel.Enable = 'on';              
                app.QCCorrectionParametersPanel.Enable = 'on';
                app.QCMXPConfigurationPanel.Enable = 'on';              
                if app.complete
                    switchAllPanelsB(app,'on');
                end            
            end
        end

        % Button pushed function: ViewFeatureTableButton
        function ViewFeatureTableButtonPushed(app, event)
            app.ViewFeatureTableButton.Enable = 'off';
            %app.LOADDATAButton.Enable = 'off';     
            %app.SpreadsheetApp = TidyDataView(app, app.DataTable, app.FeatureTable);
            app.SpreadsheetApp = TidyFeatureTableView(app, app.FeatureTable);
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            oldConfig = app.baseConfig;
            oldJSON = app.jsonTxt;
            oldJSONview = app.jsonView; 

            if ismac
                scrsz = get(0,'ScreenSize');
                f = figure('Position', [scrsz(3)*0.45 scrsz(4)*0.6 0 0]);
                [file, path] = uigetfile('.json');               
                delete(f);
                pause(1);
                figure(app.UIFigure);
            else       
                %app.UIFigure.Visible = 'off';
                [file, path] = uigetfile('.json');
                %app.UIFigure.Visible = 'on';
                pause(1);
                figure(app.UIFigure);
            end


            if ~isequal(file,0)
                app.jsonTxt = fileread(fullfile(path,file));
                app.baseConfig = jsondecode(app.jsonTxt);
                try
                    app.baseConfig = validateConfig(app.baseConfig);
                    app.jsonDefault = app.jsonTxt;
                    app.jsonView = strsplit(app.jsonTxt,'\n')';
                    app.configFilename = file;
                    app.importedConfig = app.baseConfig;
                    pause(1);
                    setConfigFields(app);               
                    uialert(app.UIFigure,'QCMXP configuration sucessfully loaded','Config Update','Icon','success');
                catch ME
                    uialert(app.UIFigure,{ME.message;char('Failed to import config file.')},'Error: Config Error','Icon','error');
                    app.jsonTxt = oldJSON;
                    app.jsonView = oldJSONview;
                    app.baseConfig = oldConfig;
                end  
            else
                uialert(app.UIFigure,'Failed to import config file','Config Error','Icon','error');
            end
            app.QCMXPConfigurationPanel.Title = ['QC:MXP Configuration: ',app.configFilename]; 
            try
                checkBetweenBatchValid(app);
            catch ME
                uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
            end
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            if strcmp(app.configFilename,'Default')
                tempFilename = 'userConfig.json';
            else
                tempFilename = app.configFilename;
            end

            if ismac
                scrsz = get(0,'ScreenSize');
                f = figure('Position', [scrsz(3)*0.45 scrsz(4)*0.6 0 0]);
                [file,path]=uiputfile('.json','Save QCRSC config file',tempFilename);
                delete(f);
                figure(app.UIFigure);
            else       
                [file,path]=uiputfile('.json','Save QCRSC config file',tempFilename);
                figure(app.UIFigure);
            end

            app.jsonTxt = jsonencode(app.baseConfig,'PrettyPrint',true);
          
            if ~isequal(file,0)
                try
                    %cfg = jsondecode(app.jsonTxt);
                    %jsontxt = jsonencode(cfg,'PrettyPrint',true);
                    fid=fopen(fullfile(path,file),'w');
                    %fprintf(fid,jsontxt);
                    fprintf(fid,app.jsonTxt);
                    fclose(fid);
                    app.configFilename = file;
                catch
                    uialert(app.UIFigure,'Failed to save config file','Error','Icon','error');
                end
            end
            
            app.QCMXPConfigurationPanel.Title = ['QC:MXP Configuration: ',app.configFilename];
        end

        % Button pushed function: LaunchFeatureExplorer
        function LaunchFeatureExplorerPushed(app, event)
                 try    
                   app.featureExplorerApp = QCRSCfeatureExplorer(app);
                 catch ME
                     uialert(app.UIFigure,ME.message,'Warning: featureExplorerApp Error','Icon','warning');
                 end            
        end

        % Button pushed function: LaunchAlgorithmExplorer
        function LaunchQCcorrectionExplorerPushed(app, event)
                try    
                   app.configExplorerApp = QCRSCconfigExplorer(app);
                catch ME
                    uialert(app.UIFigure,ME.message,'Warning: configExplorerApp Error','Icon','warning');
                end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.PopOutPlotApp)
            delete(app.ImportApp)
            delete(app.configExplorerApp)
            delete(app.featureExplorerApp)
            delete(app.SpreadsheetApp)
            delete(app)           
        end

        % Value changed function: LOQalphaEditField, PreFilterDropDown, 
        % ...and 2 other components
        function FeatureInclusionPreFilterPanelValueChanged(app, event)
            app.baseConfig.MissingQCPreFilterMode = app.PreFilterDropDown.Value;
            app.baseConfig.MissingQCPreFilterPercentage = app.missingQCperFeatureEditField.Value;
            app.baseConfig.LOQfilter = app.QCLOQmissingCheckBox.Value;
            app.baseConfig.RelativeLOQ = app.LOQalphaEditField.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();            
            preQCRSCclean(app);           
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            drawnow();            
            if app.complete
                switchAllPanelsB(app,'on');
                uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an effect','Warning','Icon','warning');
            end
        end

        % Button pushed function: RunButton
        function StartButtonPushed(app, event)
            set(app.UIFigure, 'Pointer','watch');
            app.complete = false;
            app.featuresCorrectedGauge.Value = 0;
            app.CompleteLamp.Enable = 'off';
            ResetCleanDials(app);
            ClearPlots(app);    
            app.IncomingDataPanel.Enable = 'off';
            app.QCCorrectionParametersPanel.Enable = 'off';
            app.FeatureInclusionPreFilterPanel.Enable = 'off';
            app.QCMXPConfigurationPanel.Enable = 'off';
            app.CleanExplorePanel.Enable = 'off';
            app.SavePanel.Enable = 'off';
            app.RunButton.Enable = 'off';
            app.BypassButton.Enable = 'off';       
            app.PauseButton.Enable = 'on';
            app.AbortButton.Enable = 'on';
            drawnow();
            try
                checkBetweenBatchValid(app);
                preQCRSCclean(app);
                optimiseAndCorrectTable(app);
            catch ME
                uialert(app.UIFigure,ME.message,'QC Correction Engine Failure','Icon','error');           
                app.RunButton.Enable = 'on';
                app.BypassButton.Enable = 'on';
                app.PauseButton.Enable = 'off';
                app.AbortButton.Enable = 'off';
                switchAllPanelsA(app,'on');
                set(app.UIFigure, 'Pointer','arrow');
                return;
            end
            app.RunButton.Enable = 'on';
            app.BypassButton.Enable = 'on';
            app.PauseButton.Enable = 'off';
            app.AbortButton.Enable = 'off';
            app.IncomingDataPanel.Enable = 'on';
            app.QCCorrectionParametersPanel.Enable = 'on';
            app.FeatureInclusionPreFilterPanel.Enable = 'on';
            app.QCMXPConfigurationPanel.Enable = 'on';
            app.SavePanel.Enable = 'on';
            app.CleanExplorePanel.Enable = 'on';
            if app.aborted
                app.CleanExplorePanel.Enable = 'off';
                app.SavePanel.Enable = 'off';               
                app.aborted = false;
                set(app.UIFigure, 'Pointer','arrow');
                return;
            end
            app.bypass = false;       
            CleanAndPlot(app);            
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.paused = not(app.paused);
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.aborted = true;         
            res = uiconfirm(app.UIFigure,'Are you sure you want to prematurely end this run?','Confirm Action','Options',{'Yes','No'});
            if strcmp(res,'No')
                app.aborted = false;
            end                    
        end

        % Button pushed function: BypassButton
        function BypassButtonPushed(app, event)
            app.bypass = true;            
            res = uiconfirm(app.UIFigure,{'Are you sure you want to bypass QCRSC?';'Before/After will show the same data'},'Confirm Action','Options',{'Yes','No'});
            if strcmp(res,'No')
                app.bypass = false;
                return;
            end
            StartButtonPushed(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            suffix = app.CorrectedSuffixEditField.Value;
            TempFeature = app.FeatureX;
            TempFeature.cleanPeaks = [];         
            SaveData(app,app.DataX,[app.DataName,suffix]);
            SaveData(app,TempFeature,[app.FeatureName,suffix]);
            saveConfig(app,[app.ProjectName,'.json']);
        end

        % Button pushed function: SaveCleanButton
        function SaveCleanButtonPushed(app, event)
            FeatureClean = app.FeatureX(app.FeatureX.cleanPeaks,:);
            DataClean = app.DataX;
            suffix = app.SaveCleanSuffixEditField.Value;
            list = setdiff(app.FeatureX.UID,FeatureClean.UID);
            DataClean(:,list) = [];    
            FeatureClean.cleanPeaks = [];

            SaveData(app,app.DataClean,[app.DataName,suffix]);
            SaveData(app,FeatureClean,[app.FeatureName,suffix]);
            
            %saveConfig(app,[sheetname,'.json']);
           % SaveData(app,DataClean,FeatureClean,suffix);
            if app.AlsosavescaledimputeddataCheckBox.Value == true
                FeatureClean.cleanPeaks = ones(height(FeatureClean),1);
                DataImputed = preprocessDataForExport(DataClean,FeatureClean, ...
                        RemoveZeros = app.baseConfig.RemoveZeros, ...                    
                        ImputationType = app.baseConfig.CleanImputationMethod, ...
                        LogTransform = app.Log10TransformCheckBox.Value, ...
                        Autoscale = app.AutoscaleCheckBox.Value, ...
                        Paretoscale = app.ParetoscaleCheckBox.Value, ...
                        batchScale = app.batchwiseScaleCheckBox.Value, ...
                        k = app.kEditField.Value);
                %temp = [suffix,'Imputed'];
                FeatureClean.cleanPeaks = [];           
                SaveData(app,DataImputed,[app.DataName,suffix,temp]);
            end 
            saveConfig(app,[app.ProjectName,'.json']);  
        end

        % Button pushed function: ResetFilterButton
        function ResetFilterButtonPushed(app, event)
            ResetCleanDials(app);
            CleanAndPlot(app);
        end

        % Value changed function: BlankRatioKnob, DRatioKnob, QCRSDKnob, 
        % ...and 1 other component
        function KnobValueChanged(app, event)
            app.baseConfig.CleanFilterQCRSD = app.QCRSDKnob.Value;
            app.baseConfig.CleanFilterSampleRSD = app.SampleRSDKnob.Value;          
            app.baseConfig.CleanFilterDRatio = app.DRatioKnob.Value;
            app.baseConfig.CleanFilterBlankRatio = app.BlankRatioKnob.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            if app.FilterBankSwitch.Value == true
                CleanAndPlot(app);
            end
        end

        % Value changed function: PCStatsSwitch
        function RedoPCA(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            switchAllPCAswtiches(app,'on');
            drawnow();            
           try
                calcAndPlotPCA(app);
           catch ME      
                cla(app.UIAxesPCA);
                app.YAxisDropDown.Items = {'PC 1', 'PC 2'};
                app.YAxisDropDown.ItemsData = [1,2];
                app.XAxisDropDown.Items = {'PC 1', 'PC 2'};
                app.XAxisDropDown.ItemsData = [1,2];
                app.YAxisDropDown.Value = 2;
                app.XAxisDropDown.Value = 1;
                app.UIAxesPCA.Title.String = 'Principal Component Analysis';
                ylabel(app.UIAxesPCA,'PC2');
                xlabel(app.UIAxesPCA,'PC1');         
                app.PCAmodel = [];
                switchAllPCAswtiches(app,'off');
                uialert(app.UIFigure,"PCA failure. Possibly due to too few data or imputation method. consider changing the filter settings.",'PCA failure','Icon','error');
           end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
        end

        % Value changed function: SortbySwitch
        function ImperialPlotValueChanged(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            plotImperialRSD(app); 
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
        end

        % Value changed function: SmoothRSDslider, SwitchRSDPlot, 
        % ...and 1 other component
        function RSDPlotValueChanged(app, event)
            
            if strcmp(app.SwitchRSDpdf.Value,'histogram')
                app.SmoothRSDslider.Visible = 'off';
                app.SmoothSlider_2Label.Visible = 'off';
            else
                app.SmoothRSDslider.Visible = 'on';
                app.SmoothSlider_2Label.Visible = 'on';
            end
            
            bvec = unique(app.PlotDataTable.Batch);
            QCRSCplotdistribution(app.UIAxesRSDpdf,app.PlotFeatureTable,bvec,label='qcRSD',plotType=app.SwitchRSDpdf.Value,smooth=app.SmoothRSDslider.Value,split=app.SwitchRSDPlot.Value,cmap=app.cmap);
        end

        % Value changed function: SmoothBLANKslider, SwitchBlankPlot, 
        % ...and 1 other component
        function BlankPlotValueChanged(app, event)
            if strcmp(app.SwitchBlankpdf.Value,'histogram')
                app.SmoothBLANKslider.Visible = 'off'; 
                app.SmoothSlider_2Label_3.Visible = 'off';
            else
                app.SmoothBLANKslider.Visible = 'on';
                app.SmoothSlider_2Label_3.Visible = 'on';
            end
            bvec = unique(app.PlotDataTable.Batch);
            QCRSCplotdistribution(app.UIAxesBLANKpdf,app.PlotFeatureTable,bvec,label='blankRatio',plotType=app.SwitchBlankpdf.Value,smooth=app.SmoothBLANKslider.Value,split=app.SwitchBlankPlot.Value,cmap=app.cmap);
            
        end

        % Value changed function: SmoothDRATIOslider, SwitchDRATIOpdf, 
        % ...and 1 other component
        function DratioPlotValueChanged(app, event)
            if strcmp(app.SwitchDRATIOpdf.Value,'histogram')
                app.SmoothDRATIOslider.Visible = 'off';  
                app.SmoothSlider_2Label_2.Visible = 'off';
            else
                app.SmoothDRATIOslider.Visible = 'on';
                app.SmoothSlider_2Label_2.Visible = 'on';
            end
            bvec = unique(app.PlotDataTable.Batch);
            QCRSCplotdistribution(app.UIAxesDRATIOpdf,app.PlotFeatureTable,bvec,label='dRatio',plotType=app.SwitchDRATIOpdf.Value,smooth=app.SmoothDRATIOslider.Value,split=app.SwitchDratioPlot.Value,cmap=app.cmap);
            
        end

        % Value changed function: CleanBlankRatioMethodDropDown
        function CleanBlankRatioMethodDropDownValueChanged(app, event)
            app.baseConfig.BlankRatioMethod = app.CleanBlankRatioMethodDropDown.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            setConfigFields(app,mode='qcrsc');
            calculateStats(app);  
            CleanAndPlot(app);

            TempFeature = app.FeatureX;
            TempFeature.cleanPeaks = [];
            suffix = app.CorrectedSuffixEditField.Value;
            sheetname = [app.baseConfig.FeatureTable,suffix];
            SaveData(app,TempFeature,sheetname);        
        end

        % Value changed function: drinkMeButton
        function drinkMeButtonValueChanged(app, event)
            if app.drinkMeButton.Value
                set(app.UIFigure, 'Pointer','watch');
                switchAllPanelsA(app,'off');
                switchAllPanelsB(app,'off');
                drawnow();            
                drinkMe(app);
                preQCRSCclean(app);           
                set(app.UIFigure, 'Pointer','arrow');
                switchAllPanelsA(app,'on');
                drawnow();    
                if app.complete
                    switchAllPanelsB(app,'on');
                    uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an effect','Warning','Icon','warning');
                end
            else
                uialert(app.UIFigure,"Sorry, You cannot reverse 'drinking the shrinking potion'! Please reload the data",'Warning','Icon','warning');
                app.drinkMeButton.Value = true;
            end
        end

        % Value changed function: SwitchBeforeAfter
        function SwitchBeforeAfterValueChanged(app, event)
            app.FilterBankSwitch.Value = false;
            setFilterKnobColors(app);
            CleanAndPlot(app);            
        end

        % Button pushed function: PopOutButton
        function PopOutButtonPushed(app, event)
            set(app.UIFigure, 'Pointer','watch');
            switchAllPanelsA(app,'off');
            switchAllPanelsB(app,'off');
            drawnow();
            try
                app.PopOutPlotApp = ScatterView(app.PlotDataTable,app.PlotFeatureTable, ...
                                    RemoveZeros = app.baseConfig.RemoveZeros, ...
                                    IncludeQCs= app.IncludeQCSamplesCheckBox.Value, ...
                                    IncludeRefs = app.IncludeReferenceSamplesCheckBox.Value, ...
                                    IncludeBlanks = app.IncludeBlankSamplesCheckBox.Value, ...
                                    ImputationType = app.baseConfig.CleanImputationMethod, ...
                                    ScaleMethod = app.baseConfig.CleanScaleMethod, ...
                                    BatchScale = false, ...
                                    LogTransform = app.Log10TransformCheckBox.Value, ...
                                    k = app.kSpinner.Value, ...
                                    xaxis = app.XAxisDropDown.Value, ...
                                    yaxis = app.YAxisDropDown.Value, ...                    
                                    label = app.LabelbyDropDown.Value, ...
                                    plotCIA = app.ConfidenceIntervalsCheckBoxA.Value, ...
                                    plotCIB = app.ConfidenceIntervalsCheckBoxB.Value, ...
                                    plotDataPoints = app.DataPointsCheckBox.Value, ...
                                    cmapName = app.cmapName);


            catch ME
               uialert(app.UIFigure,ME.message,'Warning: ScatterView App Error','Icon','warning');
               delete(app.PopOutPlotApp);
            end
            set(app.UIFigure, 'Pointer','arrow');
            switchAllPanelsA(app,'on');
            switchAllPanelsB(app,'on');
            drawnow();
        end

        % Value changed function: DataPointsCheckBox
        function DataPointsCheckBoxValueChanged(app, event)
            value1 = app.ConfidenceIntervalsCheckBoxB.Value;
            value2 = app.ConfidenceIntervalsCheckBoxA.Value;
            value3 = app.DataPointsCheckBox.Value;
            if ~any([value1,value2,value3])
                app.DataPointsCheckBox.Value = true;
            end
                PlotPCA(app);
        end

        % Value changed function: ConfidenceIntervalsCheckBoxB
        function ConfidenceIntervalsCheckBoxBValueChanged(app, event)
            value1 = app.ConfidenceIntervalsCheckBoxB.Value;
            value2 = app.ConfidenceIntervalsCheckBoxA.Value;
            value3 = app.DataPointsCheckBox.Value;
            if ~any([value1,value2,value3])
                app.ConfidenceIntervalsCheckBoxB.Value = true;
            end
            PlotPCA(app);
        end

        % Value changed function: ConfidenceIntervalsCheckBoxA
        function ConfidenceIntervalsCheckBoxAValueChanged(app, event)
            value1 = app.ConfidenceIntervalsCheckBoxB.Value;
            value2 = app.ConfidenceIntervalsCheckBoxA.Value;
            value3 = app.DataPointsCheckBox.Value;
            if ~any([value1,value2,value3])
                app.ConfidenceIntervalsCheckBoxA.Value = true;
            end
            PlotPCA(app);
        end

        % Value changed function: LegendCheckBox
        function LegendCheckBoxValueChanged(app, event)
            if app.LegendCheckBox.Value
                app.UIAxesPCA.Legend.Visible = true;
            else
                app.UIAxesPCA.Legend.Visible = false;
            end   
        end

        % Value changed function: FilterBankSwitch
        function FilterButtonPushed(app, event)
            setFilterKnobColors(app);
            if app.QCRSDKnob.Value ~= 100 || ...
                app.SampleRSDKnob.Value ~= 0 || ...
                app.DRatioKnob.Value ~= 100 || ...
                app.BlankRatioKnob.Value ~= 100
                    CleanAndPlot(app);
            end
            
        end

        % Value changed function: SampleFilterDropDown
        function CleanMissingSampleFilterValueChange(app, event)
            app.baseConfig.CleanMissingSampleFilterMode = app.SampleFilterDropDown.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            CleanAndPlot(app);
        end

        % Value changed function: IncludeBlankSamplesCheckBox
        function IncludeBlankSamplesCheckBoxValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: IncludeReferenceSamplesCheckBox
        function IncludeReferenceSamplesCheckBoxValueChanged(app, event)
             PlotPCA(app)     
        end

        % Value changed function: IncludeQCSamplesCheckBox
        function IncludeQCSamplesCheckBoxValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            PlotPCA(app)
        end

        % Value changed function: LabelbyDropDown
        function LabelbyDropDownValueChanged(app, event)
            switch app.LabelbyDropDown.Value
                case 'BatchQC'
                    app.ConfidenceIntervalsCheckBoxA.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Value = true;
                    app.DataPointsCheckBox.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Visible = "on";
                    app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of Batch QC';
                    app.ConfidenceIntervalsCheckBoxB.Text = '95% CI of Batch Sample';
                    app.IncludeQCSamplesCheckBox.Value = true;
                    app.IncludeQCSamplesCheckBox.Enable = false;
                    app.IncludeReferenceSamplesCheckBox.Enable = false;
                    app.IncludeBlankSamplesCheckBox.Enable = false;
                case 'SampleType'
                    app.ConfidenceIntervalsCheckBoxA.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Value = false;
                    app.DataPointsCheckBox.Value = true;               
                    app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of SampleType';
                    app.ConfidenceIntervalsCheckBoxB.Visible = "off";                                     
                    app.IncludeQCSamplesCheckBox.Value = true;
                    app.IncludeQCSamplesCheckBox.Value = true;
                    app.IncludeReferenceSamplesCheckBox.Value = false;
                    app.IncludeBlankSamplesCheckBox.Value = false;
                    app.IncludeQCSamplesCheckBox.Enable = true;
                    app.IncludeReferenceSamplesCheckBox.Enable = true;
                    app.IncludeBlankSamplesCheckBox.Enable = true;
                otherwise
                    app.ConfidenceIntervalsCheckBoxA.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Value = true;
                    app.DataPointsCheckBox.Value = true;
                    app.ConfidenceIntervalsCheckBoxB.Visible = "on";
                    app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of Group Mean';
                    app.ConfidenceIntervalsCheckBoxB.Text = '95% CI of Group Sample';
                    app.IncludeQCSamplesCheckBox.Value = false;
                    app.IncludeReferenceSamplesCheckBox.Value = false;
                    app.IncludeBlankSamplesCheckBox.Value = false;
                    app.IncludeQCSamplesCheckBox.Enable = true;
                    app.IncludeReferenceSamplesCheckBox.Enable = true;
                    app.IncludeBlankSamplesCheckBox.Enable = true;
            end
            PlotPCA(app)
        end

        % Selection changed function: MissingValueImputationButtonGroup
        function MissingValueImputationButtonGroupSelectionChanged(app, event)
            selectedButton = app.MissingValueImputationButtonGroup.SelectedObject;
            switch selectedButton.Text
                case app.KNNcolumnButton.Text
                    app.baseConfig.CleanImputationMethod = 'KNNcolumn';
                case app.KNNrowButton.Text
                    app.baseConfig.CleanImputationMethod = 'KNNrow';
                case app.blank20minButton.Text
                    app.baseConfig.CleanImputationMethod = 'blank20';
            end
            app.baseConfig = validateConfig(app.baseConfig);
            RedoPCA(app);
        end

        % Value changed function: Log10TransformCheckBox
        function Log10TransformCheckBoxValueChanged(app, event)
            app.baseConfig.CleanLogTransform = app.Log10TransformCheckBox.Value;
            RedoPCA(app);
        end

        % Value changed function: FilterBankModeDropDown
        function FilterBankModeDropDownValueChanged(app, event)
            value = app.FilterBankModeDropDown.Value;
            ubatch = unique(app.PlotDataTable.Batch);
            NumOfBatch = numel(ubatch);
            switch value
                case 'Median qcRSD'
                    if NumOfBatch < 3
                        app.FilterBankModeDropDown.Value = 'Max qcRSD';
                    end
                case 'Median dRatio'
                    if NumOfBatch < 3
                        app.FilterBankModeDropDown.Value = 'Max dRatio';
                    end
            end
            app.baseConfig.CleanFilterBankMode = app.FilterBankModeDropDown.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            CleanAndPlot(app);            
        end

        % Value changed function: QCCheckBox
        function QCCheckBoxValueChanged(app, event)
            if app.QCCheckBox.Value || app.RefCheckBox.Value
                ImperialPlotValueChanged(app);
            else
                app.QCCheckBox.Value = true;
                ImperialPlotValueChanged(app);
            end          
        end

        % Value changed function: RefCheckBox
        function RefCheckBoxValueChanged(app, event)
            if app.QCCheckBox.Value || app.RefCheckBox.Value
                ImperialPlotValueChanged(app);
            else
                app.RefCheckBox.Value = true;
                ImperialPlotValueChanged(app);
            end           
        end

        % Value changed function: SampleFilterPercentEditField
        function SampleFilterPercentEditFieldValueChanged(app, event)
            app.baseConfig.CleanMissingSampleFilterPercentage = app.SampleFilterPercentEditField.Value;
            if app.SampleFilterPercentEditField.Value > 25
                app.blank20minButton.Value = true;
                app.baseConfig.CleanImputationMethod = 'blank20';
            end
            app.baseConfig = validateConfig(app.baseConfig);
            CleanAndPlot(app);
        end

        % Value changed function: kSpinner
        function kSpinnerValueChanged(app, event)
            app.baseConfig.CleanImputationK = app.kSpinner.Value;
            app.baseConfig = validateConfig(app.baseConfig);
            RedoPCA(app);
        end

        % Selection changed function: ScalingButtonGroup
        function ScalingButtonGroupSelectionChanged(app, event)
            selectedButton = app.ScalingButtonGroup.SelectedObject;
            switch selectedButton.Text
                case app.AutoscaleButton.Text
                    app.baseConfig.CleanScaleMethod = 'Autoscale';
                case app.ParetoscaleButton.Text
                    app.baseConfig.CleanScaleMethod = 'Paretoscale';
                case app.MeanCenterOnlyButton.Text
                    app.baseConfig.CleanScaleMethod = 'MeanCenter';
            end
            app.baseConfig = validateConfig(app.baseConfig);
            RedoPCA(app);
        end

        % Value changed function: ParallelProcessingCheckBox
        function ParallelProcessingCheckBoxValueChanged(app, event)
            app.baseConfig.ParallelProcess = app.ParallelProcessingCheckBox.Value;
        end

        % Value changed function: BetweenBatchCorrectionModeDropDown, 
        % ...and 10 other components
        function CorrectionConfigValueChanged(app, event)
            app.baseConfig.LogTransformedCorrection = app.LogTransformedCorrectionCheckBox.Value;
            app.baseConfig.RemoveZeros = app.RemoveZerosCheckBox.Value;
            app.baseConfig.OutlierDetectionMethod = app.QCOutlierDetectionMethodDropDown.Value;
            app.baseConfig.OutlierDetectionCI = app.QCOutlierDetectionCIEditField.Value;
            app.baseConfig.OutlierReplacementStrategy = app.QCOutlierReplacementStrategyDropDown.Value;
            app.baseConfig.WithinBatchCorrectionMode = app.WithinBatchCorrectionModeDropDown.Value;
            app.baseConfig.BetweenBatchCorrectionMode = app.BetweenBatchCorrectionModeDropDown.Value;
            app.baseConfig.QCRSCgammaRange = app.QCRSCgammarangeEditField.Value;
            app.baseConfig.QCRSCcvMethod = app.QCRSCCrossValidationMethodDropDown.Value;
            app.baseConfig.QCRSCmcReps = app.QCRSCMonteCarlorepetitionsEditField.Value;

            try
                checkBetweenBatchValid(app);
            catch ME
                uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
            end
            
            if ~strcmp(app.baseConfig.WithinBatchCorrectionMode,'Spline')
                app.QCRSCgammarangeEditField.Visible = "off";
                app.QCRSCgammarangeLabel.Visible = "off";
                app.QCRSCCrossValidationMethodDropDown.Visible = "off";
                app.QCRSCCVMethodLabel.Visible = "off";
                app.QCRSCMonteCarlorepetitionsEditField.Visible = "off";
                app.QCRSCMCRepsLabel.Visible = "off";
            else
                app.QCRSCgammarangeEditField.Visible = "on";
                app.QCRSCgammarangeLabel.Visible = "on";
                app.QCRSCCrossValidationMethodDropDown.Visible = "on";
                app.QCRSCCVMethodLabel.Visible = "on";
                app.QCRSCMonteCarlorepetitionsEditField.Visible = "on";
                app.QCRSCMCRepsLabel.Visible = "on";
            end
            if app.LogTransformedCorrectionCheckBox.Value
                app.RemoveZerosCheckBox.Value = true;
                app.baseConfig.RemoveZeros = app.RemoveZerosCheckBox.Value;
            end
            app.baseConfig = validateConfig(app.baseConfig);

            try
                checkBetweenBatchValid(app);
                if app.complete
                    switchAllPanelsB(app,'on');
                    uialert(app.UIFigure,'QCRSC must be rerun (or bypassed) for this change to have an effect','Warning','Icon','warning');
                end
            catch ME
                uialert(app.UIFigure,ME.message,'Error: Config Error','Icon','error');
            end
            
        end

        % Value changed function: CorrectedSuffixEditField
        function CorrectedSuffixEditFieldValueChanged(app, event)
            app.baseConfig.CorrectedFileSuffix = app.CorrectedSuffixEditField.Value;
            app.baseConfig = validateConfig(app.baseConfig);
        end

        % Value changed function: SaveCleanSuffixEditField
        function SaveCleanSuffixEditFieldValueChanged(app, event)
            app.baseConfig.CleanFileSuffix = app.SaveCleanSuffixEditField.Value;
            app.baseConfig = validateConfig(app.baseConfig);
        end

        % Value changed function: BatchDropDown
        function BatchDropDownValueChanged(app, event)
            CleanAndPlot(app);
        end

        % Button pushed function: ResetConfigButton
        function ResetConfigButtonPushed(app, event)
            res = uiconfirm(app.UIFigure,['Are you sure you want to reset the configuration to ',app.configFilename,' settings?'],'Confirm Action','Options',{'Yes','No'});
            if strcmp(res,'Yes')
                app.baseConfig = app.importedConfig;
                app.baseConfig = validateConfig(app.baseConfig);
                setConfigFields(app);
                app.QCMXPConfigurationPanel.Title = ['QC:MXP Configuration: ',app.configFilename];
            end  
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icon_512x512@2x.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CleanExplorePanel = uipanel(app.UIFigure);
            app.CleanExplorePanel.Title = 'Clean & Explore:';
            app.SaveCleanPanel = uipanel(app.CleanExplorePanel);
            app.SaveCleanSuffixEditField = uieditfield(app.SaveCleanPanel, 'text');
            app.SaveCleanSuffixEditField.ValueChangedFcn = createCallbackFcn(app, @SaveCleanSuffixEditFieldValueChanged, true);
            app.SaveCleanSuffixEditField.Tooltip = {'_Clean'};
            app.SaveCleanSuffixEditField.Value = '_Clean';
            app.SaveCleanButton = uibutton(app.SaveCleanPanel, 'push');
            app.SaveCleanButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCleanButtonPushed, true);
            app.SaveCleanButton.Text = 'Save';
            app.suffixLabel_2 = uilabel(app.SaveCleanPanel);
            app.suffixLabel_2.Text = 'suffix: ';
            app.AlsosavescaledimputeddataCheckBox = uicheckbox(app.SaveCleanPanel);
            app.AlsosavescaledimputeddataCheckBox.Text = 'Also save scaled & imputed data';
            app.TabGroup = uitabgroup(app.CleanExplorePanel);
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.UIAxesPCA = uiaxes(app.PCATab);
            xlabel(app.UIAxesPCA, 'PC 1')
            ylabel(app.UIAxesPCA, 'PC 2')
            zlabel(app.UIAxesPCA, 'Z')
            app.UIAxesPVE = uiaxes(app.PCATab);
            app.UIAxesPVE.XTick = [];
            app.UIAxesPVE.YTick = [];
            app.UIAxesPVE2 = uiaxes(app.PCATab);
            app.UIAxesPVE2.XTick = [];
            app.UIAxesPVE2.YTick = [];
            app.XAxisDropDownLabel = uilabel(app.PCATab);
            app.XAxisDropDownLabel.Text = 'X Axis:';
            app.XAxisDropDown = uidropdown(app.PCATab);
            app.XAxisDropDown.Items = {'PC 1', 'PC 2', 'PC 3', 'PC 4', 'PC 5', 'PC 6', 'PC 7', 'PC 8', 'PC 9', 'PC 10'};
            app.XAxisDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 1;
            app.YAxisDropDownLabel = uilabel(app.PCATab);
            app.YAxisDropDownLabel.Text = 'Y Axis:';
            app.YAxisDropDown = uidropdown(app.PCATab);
            app.YAxisDropDown.Items = {'PC 1', 'PC 2', 'PC 3', 'PC 4', 'PC 5', 'PC 6', 'PC 7', 'PC 8', 'PC 9', 'PC 10'};
            app.YAxisDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10];
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 2;
            app.LegendCheckBox = uicheckbox(app.PCATab);
            app.LegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendCheckBoxValueChanged, true);
            app.LegendCheckBox.Text = 'Legend';
            app.ConfidenceIntervalsCheckBoxA = uicheckbox(app.PCATab);
            app.ConfidenceIntervalsCheckBoxA.ValueChangedFcn = createCallbackFcn(app, @ConfidenceIntervalsCheckBoxAValueChanged, true);
            app.ConfidenceIntervalsCheckBoxA.Text = '95% CI of Group Mean';
            app.ConfidenceIntervalsCheckBoxB = uicheckbox(app.PCATab);
            app.ConfidenceIntervalsCheckBoxB.ValueChangedFcn = createCallbackFcn(app, @ConfidenceIntervalsCheckBoxBValueChanged, true);
            app.ConfidenceIntervalsCheckBoxB.Text = '95% CI of Group Pop.';
            app.DataPointsCheckBox = uicheckbox(app.PCATab);
            app.DataPointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataPointsCheckBoxValueChanged, true);
            app.DataPointsCheckBox.Text = 'Data Points';
            app.VariationExplainedbyeachPCLabel = uilabel(app.PCATab);
            app.VariationExplainedbyeachPCLabel.Text = 'Variation Explained by each PC';
            app.LabelbyDropDownLabel = uilabel(app.PCATab);
            app.LabelbyDropDownLabel.Text = 'Label by:';
            app.LabelbyDropDown = uidropdown(app.PCATab);
            app.LabelbyDropDown.Items = {'SampleType'};
            app.LabelbyDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelbyDropDownValueChanged, true);
            app.LabelbyDropDown.Value = 'SampleType';
            app.PopOutButton = uibutton(app.PCATab, 'push');
            app.PopOutButton.ButtonPushedFcn = createCallbackFcn(app, @PopOutButtonPushed, true);
            app.PopOutButton.Icon = 'fullscreen.png';
            app.PopOutButton.Text = '';
            app.PCStatsSwitch = uiswitch(app.PCATab, 'slider');
            app.PCStatsSwitch.Items = {'Component', 'Cumulative'};
            app.PCStatsSwitch.ItemsData = [true false];
            app.PCStatsSwitch.ValueChangedFcn = createCallbackFcn(app, @RedoPCA, true);
            app.PCStatsSwitch.Value = true;
            app.IncludeBlankSamplesCheckBox = uicheckbox(app.PCATab);
            app.IncludeBlankSamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeBlankSamplesCheckBoxValueChanged, true);
            app.IncludeBlankSamplesCheckBox.Text = 'Project in Blank samples';
            app.IncludeReferenceSamplesCheckBox = uicheckbox(app.PCATab);
            app.IncludeReferenceSamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeReferenceSamplesCheckBoxValueChanged, true);
            app.IncludeReferenceSamplesCheckBox.Text = 'Project in Reference samples';
            app.IncludeQCSamplesCheckBox = uicheckbox(app.PCATab);
            app.IncludeQCSamplesCheckBox.ValueChangedFcn = createCallbackFcn(app, @IncludeQCSamplesCheckBoxValueChanged, true);
            app.IncludeQCSamplesCheckBox.Text = 'Project in QC samples';
            app.IncludeQCSamplesCheckBox.Value = true;
            app.MissingValueImputationButtonGroup = uibuttongroup(app.PCATab);
            app.MissingValueImputationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MissingValueImputationButtonGroupSelectionChanged, true);
            app.MissingValueImputationButtonGroup.Title = 'Missing Value Imputation:';
            app.KNNcolumnButton = uiradiobutton(app.MissingValueImputationButtonGroup);
            app.KNNcolumnButton.Text = 'KNN column';
            app.KNNcolumnButton.Value = true;
            app.KNNrowButton = uiradiobutton(app.MissingValueImputationButtonGroup);
            app.KNNrowButton.Text = 'KNN row';
            app.blank20minButton = uiradiobutton(app.MissingValueImputationButtonGroup);
            app.blank20minButton.Text = 'blank/20%min';
            app.kSpinnerLabel = uilabel(app.MissingValueImputationButtonGroup);
            app.kSpinnerLabel.Text = 'k =';
            app.kSpinner = uispinner(app.MissingValueImputationButtonGroup);
            app.kSpinner.Limits = [1 20];
            app.kSpinner.ValueChangedFcn = createCallbackFcn(app, @kSpinnerValueChanged, true);
            app.kSpinner.Value = 3;
            app.pcaPreprocessingPanel = uipanel(app.PCATab);
            app.pcaPreprocessingPanel.Title = 'Preprocessing:';
            app.Log10TransformCheckBox = uicheckbox(app.pcaPreprocessingPanel);
            app.Log10TransformCheckBox.ValueChangedFcn = createCallbackFcn(app, @Log10TransformCheckBoxValueChanged, true);
            app.Log10TransformCheckBox.Text = 'Log10 Transform';
            app.Log10TransformCheckBox.Value = true;
            app.ScalingButtonGroup = uibuttongroup(app.PCATab);
            app.ScalingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScalingButtonGroupSelectionChanged, true);
            app.MeanCenterOnlyButton = uiradiobutton(app.ScalingButtonGroup);
            app.MeanCenterOnlyButton.Text = 'MeanCenter only';
            app.AutoscaleButton = uiradiobutton(app.ScalingButtonGroup);
            app.AutoscaleButton.Text = 'Autoscale';
            app.AutoscaleButton.Value = true;
            app.ParetoscaleButton = uiradiobutton(app.ScalingButtonGroup);
            app.ParetoscaleButton.Text = 'Paretoscale';
            app.ImperialRSDplotTab = uitab(app.TabGroup);
            app.ImperialRSDplotTab.Title = 'Imperial RSD plot';
            app.UIAxesImperialRSD = uiaxes(app.ImperialRSDplotTab);
            xlabel(app.UIAxesImperialRSD, 'X')
            ylabel(app.UIAxesImperialRSD, 'Y')
            zlabel(app.UIAxesImperialRSD, 'Z')
            app.SortbySwitch = uiswitch(app.ImperialRSDplotTab, 'slider');
            app.SortbySwitch.Items = {'sampleRSD', 'qcRSD'};
            app.SortbySwitch.ItemsData = {'sample', 'qc'};
            app.SortbySwitch.ValueChangedFcn = createCallbackFcn(app, @ImperialPlotValueChanged, true);
            app.SortbySwitch.Value = 'sample';
            app.RefCheckBox = uicheckbox(app.ImperialRSDplotTab);
            app.RefCheckBox.ValueChangedFcn = createCallbackFcn(app, @RefCheckBoxValueChanged, true);
            app.RefCheckBox.Text = 'Plot Reference RSD';
            app.SortbySwitchLabel = uilabel(app.ImperialRSDplotTab);
            app.SortbySwitchLabel.Text = 'Sort by';
            app.QCCheckBox = uicheckbox(app.ImperialRSDplotTab);
            app.QCCheckBox.ValueChangedFcn = createCallbackFcn(app, @QCCheckBoxValueChanged, true);
            app.QCCheckBox.Text = 'Plot QC RSD';
            app.QCCheckBox.Value = true;
            app.ImperialToolTipLabel = uilabel(app.ImperialRSDplotTab);
            app.ImperialToolTipLabel.Visible = 'off';
            app.RSDScatterplotTab = uitab(app.TabGroup);
            app.RSDScatterplotTab.Title = 'RSD Scatter plot';
            app.UIAxesRSDComparison = uiaxes(app.RSDScatterplotTab);
            xlabel(app.UIAxesRSDComparison, 'X')
            ylabel(app.UIAxesRSDComparison, 'Y')
            zlabel(app.UIAxesRSDComparison, 'Z')
            app.ComparisonToolTipLabel = uilabel(app.RSDScatterplotTab);
            app.ComparisonToolTipLabel.Visible = 'off';
            app.qcRSDTab = uitab(app.TabGroup);
            app.qcRSDTab.Title = 'qcRSD';
            app.UIAxesRSDpdf = uiaxes(app.qcRSDTab);
            xlabel(app.UIAxesRSDpdf, 'X')
            ylabel(app.UIAxesRSDpdf, 'Y')
            zlabel(app.UIAxesRSDpdf, 'Z')
            app.SwitchRSDPlot = uiswitch(app.qcRSDTab, 'slider');
            app.SwitchRSDPlot.Items = {'Complete Data Set', 'Batch Breakdown'};
            app.SwitchRSDPlot.ItemsData = {'all', 'batch'};
            app.SwitchRSDPlot.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.SwitchRSDPlot.Value = 'all';
            app.SwitchRSDpdf = uiswitch(app.qcRSDTab, 'slider');
            app.SwitchRSDpdf.Items = {'Histogram', 'p.d.f.'};
            app.SwitchRSDpdf.ItemsData = {'histogram', 'pdf'};
            app.SwitchRSDpdf.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.SwitchRSDpdf.Value = 'histogram';
            app.SmoothSlider_2Label = uilabel(app.qcRSDTab);
            app.SmoothSlider_2Label.Visible = 'off';
            app.SmoothSlider_2Label.Text = 'Smooth:';
            app.SmoothRSDslider = uislider(app.qcRSDTab);
            app.SmoothRSDslider.ValueChangedFcn = createCallbackFcn(app, @RSDPlotValueChanged, true);
            app.SmoothRSDslider.Visible = 'off';
            app.SmoothRSDslider.Value = 20;
            app.dRatioTab = uitab(app.TabGroup);
            app.dRatioTab.Title = 'dRatio';
            app.UIAxesDRATIOpdf = uiaxes(app.dRatioTab);
            xlabel(app.UIAxesDRATIOpdf, 'X')
            ylabel(app.UIAxesDRATIOpdf, 'Y')
            zlabel(app.UIAxesDRATIOpdf, 'Z')
            app.SwitchDratioPlot = uiswitch(app.dRatioTab, 'slider');
            app.SwitchDratioPlot.Items = {'Complete Data Set', 'Batch Breakdown'};
            app.SwitchDratioPlot.ItemsData = {'all', 'batch'};
            app.SwitchDratioPlot.ValueChangedFcn = createCallbackFcn(app, @DratioPlotValueChanged, true);
            app.SwitchDratioPlot.Value = 'all';
            app.SwitchDRATIOpdf = uiswitch(app.dRatioTab, 'slider');
            app.SwitchDRATIOpdf.Items = {'Histogram', 'p.d.f.'};
            app.SwitchDRATIOpdf.ItemsData = {'histogram', 'pdf'};
            app.SwitchDRATIOpdf.ValueChangedFcn = createCallbackFcn(app, @DratioPlotValueChanged, true);
            app.SwitchDRATIOpdf.Value = 'histogram';
            app.SmoothSlider_2Label_2 = uilabel(app.dRatioTab);
            app.SmoothSlider_2Label_2.Visible = 'off';
            app.SmoothSlider_2Label_2.Text = 'Smooth:';
            app.SmoothDRATIOslider = uislider(app.dRatioTab);
            app.SmoothDRATIOslider.ValueChangedFcn = createCallbackFcn(app, @DratioPlotValueChanged, true);
            app.SmoothDRATIOslider.Visible = 'off';
            app.SmoothDRATIOslider.Value = 20;
            app.blankRatioTab = uitab(app.TabGroup);
            app.blankRatioTab.Title = 'blankRatio';
            app.UIAxesBLANKpdf = uiaxes(app.blankRatioTab);
            xlabel(app.UIAxesBLANKpdf, 'X')
            ylabel(app.UIAxesBLANKpdf, 'Y')
            zlabel(app.UIAxesBLANKpdf, 'Z')
            app.SwitchBlankPlot = uiswitch(app.blankRatioTab, 'slider');
            app.SwitchBlankPlot.Items = {'Complete Data Set', 'Batch Breakdown'};
            app.SwitchBlankPlot.ItemsData = {'all', 'batch'};
            app.SwitchBlankPlot.ValueChangedFcn = createCallbackFcn(app, @BlankPlotValueChanged, true);
            app.SwitchBlankPlot.Value = 'all';
            app.SwitchBlankpdf = uiswitch(app.blankRatioTab, 'slider');
            app.SwitchBlankpdf.Items = {'Histogram', 'p.d.f.'};
            app.SwitchBlankpdf.ItemsData = {'histogram', 'pdf'};
            app.SwitchBlankpdf.ValueChangedFcn = createCallbackFcn(app, @BlankPlotValueChanged, true);
            app.SwitchBlankpdf.Value = 'histogram';
            app.SmoothSlider_2Label_3 = uilabel(app.blankRatioTab);
            app.SmoothSlider_2Label_3.Visible = 'off';
            app.SmoothSlider_2Label_3.Text = 'Smooth:';
            app.SmoothBLANKslider = uislider(app.blankRatioTab);
            app.SmoothBLANKslider.ValueChangedFcn = createCallbackFcn(app, @BlankPlotValueChanged, true);
            app.SmoothBLANKslider.Visible = 'off';
            app.SmoothBLANKslider.Value = 20;
            app.BlankRatioMethodDropDownLabel = uilabel(app.blankRatioTab);
            app.BlankRatioMethodDropDownLabel.Text = 'Blank Ratio Method';
            app.CleanBlankRatioMethodDropDown = uidropdown(app.blankRatioTab);
            app.CleanBlankRatioMethodDropDown.Items = {'QC', 'Median', 'Percentile'};
            app.CleanBlankRatioMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CleanBlankRatioMethodDropDownValueChanged, true);
            app.CleanBlankRatioMethodDropDown.Value = 'QC';
            app.QCRSCgTab = uitab(app.TabGroup);
            app.QCRSCgTab.Title = 'QCRSC g';
            app.UIAxesQCRSCpdf = uiaxes(app.QCRSCgTab);
            xlabel(app.UIAxesQCRSCpdf, 'X')
            ylabel(app.UIAxesQCRSCpdf, 'Y')
            zlabel(app.UIAxesQCRSCpdf, 'Z')
            app.CleanFeaturesRemainingLabel = uilabel(app.CleanExplorePanel);
            app.CleanFeaturesRemainingLabel.Text = '#Clean Features:';
            app.MPQ95Label = uilabel(app.CleanExplorePanel);
            app.MPQ95Label.Interpreter = 'html';
            app.MPQ95Label.Text = 'XXX';
            app.MPQLabel = uilabel(app.CleanExplorePanel);
            app.MPQLabel.Text = 'XXX';
            app.ResetFilterButton = uibutton(app.CleanExplorePanel, 'push');
            app.ResetFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFilterButtonPushed, true);
            app.ResetFilterButton.Icon = 'resetx.png';
            app.ResetFilterButton.Tooltip = {''};
            app.ResetFilterButton.Text = '';
            app.PanelKnobs = uipanel(app.CleanExplorePanel);
            app.QCRSDKnobLabel = uilabel(app.PanelKnobs);
            app.QCRSDKnobLabel.Text = 'QC-RSD';
            app.DRatioKnobLabel = uilabel(app.PanelKnobs);
            app.DRatioKnobLabel.Text = 'D-Ratio';
            app.DRatioKnob = uiknob(app.PanelKnobs, 'discrete');
            app.DRatioKnob.Items = {'2', '5', '10', '20', '25', '30', '40', '50', '60', '75', 'Off'};
            app.DRatioKnob.ItemsData = [2 5 10 20 25 30 40 50 60 75 100];
            app.DRatioKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.DRatioKnob.Value = 100;
            app.BlankRatioKnob = uiknob(app.PanelKnobs, 'discrete');
            app.BlankRatioKnob.Items = {'0.1', '1', '5', '10', '20', '30', '40', '50', '60', '70', 'Off'};
            app.BlankRatioKnob.ItemsData = [0.11 1 5 10 20 30 40 50 60 70 100];
            app.BlankRatioKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.BlankRatioKnob.Value = 100;
            app.BlankRatioKnobLabel = uilabel(app.PanelKnobs);
            app.BlankRatioKnobLabel.Text = 'Blank Ratio';
            app.SampleRSDKnobLabel = uilabel(app.PanelKnobs);
            app.SampleRSDKnobLabel.Text = 'Sample-RSD';
            app.SampleRSDKnob = uiknob(app.PanelKnobs, 'discrete');
            app.SampleRSDKnob.Items = {'50', '40', '35', '30', '25', '20', '15', '10', '5', '2 ', 'Off'};
            app.SampleRSDKnob.ItemsData = [50 40 35 30 25 20 15 10 5 2 0];
            app.SampleRSDKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.SampleRSDKnob.Value = 0;
            app.QCRSDKnob = uiknob(app.PanelKnobs, 'discrete');
            app.QCRSDKnob.Items = {'5', '10', '15', '20', '25', '30', '35', '40', '50', '75', 'Off'};
            app.QCRSDKnob.ItemsData = [5 10 15 20 25 30 35 40 50 75 100];
            app.QCRSDKnob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.QCRSDKnob.Value = 100;
            app.FilterBankLabel = uilabel(app.PanelKnobs);
            app.FilterBankLabel.Text = 'Filter Bank';
            app.FilterBankSwitch = uiswitch(app.PanelKnobs, 'rocker');
            app.FilterBankSwitch.ItemsData = [false true];
            app.FilterBankSwitch.ValueChangedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterBankSwitch.Value = false;
            app.FilterBankLamp = uilamp(app.PanelKnobs);
            app.FilterBankModeDropDown = uidropdown(app.PanelKnobs);
            app.FilterBankModeDropDown.Items = {'Complete', 'Max qcRSD', 'Max dRatio', 'Median qcRSD', 'Median dRatio', 'Min qcRSD', 'Min dRatio'};
            app.FilterBankModeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterBankModeDropDownValueChanged, true);
            app.FilterBankModeDropDown.Value = 'Complete';
            app.FilterTypeLabel = uilabel(app.PanelKnobs);
            app.FilterTypeLabel.Text = 'Mode:';
            app.SwitchBeforeAfter = uiswitch(app.CleanExplorePanel, 'slider');
            app.SwitchBeforeAfter.Items = {'Before', 'After'};
            app.SwitchBeforeAfter.ItemsData = [false true];
            app.SwitchBeforeAfter.ValueChangedFcn = createCallbackFcn(app, @SwitchBeforeAfterValueChanged, true);
            app.SwitchBeforeAfter.Value = true;
            app.MissingSamplesFilterPanel = uipanel(app.CleanExplorePanel);
            app.MissingSamplesFilterPanel.Title = 'Missing Samples Filter:';
            app.missingperfeatureLabel = uilabel(app.MissingSamplesFilterPanel);
            app.missingperfeatureLabel.Text = '%missing per feature <=';
            app.SampleFilterPercentEditField = uieditfield(app.MissingSamplesFilterPanel, 'numeric');
            app.SampleFilterPercentEditField.Limits = [0 100];
            app.SampleFilterPercentEditField.ValueChangedFcn = createCallbackFcn(app, @SampleFilterPercentEditFieldValueChanged, true);
            app.SampleFilterPercentEditField.Value = 100;
            app.featuresremainingLabel = uilabel(app.MissingSamplesFilterPanel);
            app.featuresremainingLabel.Text = '#features remaining:';
            app.SampleFilterDropDown = uidropdown(app.MissingSamplesFilterPanel);
            app.SampleFilterDropDown.Items = {'Complete', 'Every Batch', 'Any Batch'};
            app.SampleFilterDropDown.ItemsData = {'Complete', 'EveryBatch', 'AnyBatch'};
            app.SampleFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @CleanMissingSampleFilterValueChange, true);
            app.SampleFilterDropDown.Value = 'EveryBatch';
            app.BatchLabel = uilabel(app.CleanExplorePanel);
            app.BatchLabel.Text = 'Batch:';
            app.BatchDropDown = uidropdown(app.CleanExplorePanel);
            app.BatchDropDown.ValueChangedFcn = createCallbackFcn(app, @BatchDropDownValueChanged, true);
            app.QCCorrectionEnginePanel = uipanel(app.UIFigure);
            app.QCCorrectionEnginePanel.Title = 'QC Correction Engine:';
            app.SavePanel = uipanel(app.QCCorrectionEnginePanel);
            app.SaveButton = uibutton(app.SavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.suffixLabel = uilabel(app.SavePanel);
            app.suffixLabel.Text = 'suffix:';
            app.CorrectedSuffixEditField = uieditfield(app.SavePanel, 'text');
            app.CorrectedSuffixEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectedSuffixEditFieldValueChanged, true);
            app.CorrectedSuffixEditField.Tooltip = {'_QCRSC'};
            app.CorrectedSuffixEditField.Value = '_QCRSC';
            app.peakscorrectedLabel = uilabel(app.QCCorrectionEnginePanel);
            app.peakscorrectedLabel.Text = '% features corrected';
            app.RunningLampLabel = uilabel(app.QCCorrectionEnginePanel);
            app.RunningLampLabel.Text = 'Running';
            app.CompleteLampLabel = uilabel(app.QCCorrectionEnginePanel);
            app.CompleteLampLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.QCCorrectionEnginePanel);
            app.RunningLamp = uilamp(app.QCCorrectionEnginePanel);
            app.featuresCorrectedGauge = uigauge(app.QCCorrectionEnginePanel, 'circular');
            app.BypassButton = uibutton(app.QCCorrectionEnginePanel, 'push');
            app.BypassButton.ButtonPushedFcn = createCallbackFcn(app, @BypassButtonPushed, true);
            app.BypassButton.Icon = 'bypass2.png';
            app.BypassButton.Tooltip = {''};
            app.BypassButton.Text = '';
            app.AbortButton = uibutton(app.QCCorrectionEnginePanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Icon = 'eject2.png';
            app.AbortButton.Tooltip = {''};
            app.AbortButton.Text = '';
            app.PauseButton = uibutton(app.QCCorrectionEnginePanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'pause2.png';
            app.PauseButton.Tooltip = {''};
            app.PauseButton.Text = '';
            app.RunButton = uibutton(app.QCCorrectionEnginePanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.RunButton.Icon = 'play2.png';
            app.RunButton.Tooltip = {''};
            app.RunButton.Text = '';
            app.ParallelProcessingCheckBox = uicheckbox(app.QCCorrectionEnginePanel);
            app.ParallelProcessingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParallelProcessingCheckBoxValueChanged, true);
            app.ParallelProcessingCheckBox.Text = 'Parallel Processing';
            app.ParallelProcessingCheckBox.Value = true;
            app.LaunchFeatureExplorer = uibutton(app.UIFigure, 'push');
            app.LaunchFeatureExplorer.ButtonPushedFcn = createCallbackFcn(app, @LaunchFeatureExplorerPushed, true);
            app.LaunchFeatureExplorer.Icon = fullfile(pathToMLAPP, 'res', 'QCFEx_icon.png');
            app.LaunchFeatureExplorer.Text = '';
            app.QCCorrectionParametersPanel = uipanel(app.UIFigure);
            app.QCCorrectionParametersPanel.Title = 'QC Correction Parameters:';
            app.OutlierMethodLabel = uilabel(app.QCCorrectionParametersPanel);
            app.OutlierMethodLabel.Text = 'QC Outlier Detection Method:';
            app.OutlierCILabel = uilabel(app.QCCorrectionParametersPanel);
            app.OutlierCILabel.Text = 'QC Outlier Detection %CI:';
            app.OutlierPostHocLabel = uilabel(app.QCCorrectionParametersPanel);
            app.OutlierPostHocLabel.Text = 'QC Outlier Replacement Strategy:';
            app.IntraBatchModeLabel = uilabel(app.QCCorrectionParametersPanel);
            app.IntraBatchModeLabel.Text = 'Within-Batch Correction Mode:';
            app.BetweenBatchMethodLabel = uilabel(app.QCCorrectionParametersPanel);
            app.BetweenBatchMethodLabel.Text = 'Between-Batch Correction Mode:';
            app.QCRSCgammarangeLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCgammarangeLabel.Text = 'QCRSC gamma range:';
            app.QCRSCCVMethodLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCCVMethodLabel.Text = 'QCRSC Cross Validation Method:';
            app.QCRSCMCRepsLabel = uilabel(app.QCCorrectionParametersPanel);
            app.QCRSCMCRepsLabel.Text = 'QCRSC Monte Carlo repetitions:';
            app.QCRSCMonteCarlorepetitionsEditField = uieditfield(app.QCCorrectionParametersPanel, 'numeric');
            app.QCRSCMonteCarlorepetitionsEditField.Limits = [1 Inf];
            app.QCRSCMonteCarlorepetitionsEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCRSCMonteCarlorepetitionsEditField.Value = 10;
            app.QCRSCCrossValidationMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCRSCCrossValidationMethodDropDown.Items = {'3-Fold', '5-Fold', '7-Fold', 'Leaveout'};
            app.QCRSCCrossValidationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCRSCCrossValidationMethodDropDown.Value = '3-Fold';
            app.QCRSCgammarangeEditField = uieditfield(app.QCCorrectionParametersPanel, 'text');
            app.QCRSCgammarangeEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCRSCgammarangeEditField.Value = '0.5:0.5:4';
            app.BetweenBatchCorrectionModeDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.BetweenBatchCorrectionModeDropDown.Items = {'QC', 'Reference', 'Sample'};
            app.BetweenBatchCorrectionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.BetweenBatchCorrectionModeDropDown.Value = 'QC';
            app.WithinBatchCorrectionModeDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.WithinBatchCorrectionModeDropDown.Items = {'Median', 'Linear', 'Spline', 'Sample'};
            app.WithinBatchCorrectionModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.WithinBatchCorrectionModeDropDown.Value = 'Spline';
            app.QCOutlierReplacementStrategyDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCOutlierReplacementStrategyDropDown.Items = {'Median', 'Ignore', 'NaN'};
            app.QCOutlierReplacementStrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCOutlierReplacementStrategyDropDown.Value = 'Median';
            app.QCOutlierDetectionCIEditField = uieditfield(app.QCCorrectionParametersPanel, 'numeric');
            app.QCOutlierDetectionCIEditField.Limits = [0.9 0.999];
            app.QCOutlierDetectionCIEditField.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCOutlierDetectionCIEditField.Value = 0.95;
            app.QCOutlierDetectionMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.QCOutlierDetectionMethodDropDown.Items = {'None', 'Percentile', 'Linear', 'Quadratic', 'Cubic'};
            app.QCOutlierDetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.QCOutlierDetectionMethodDropDown.Value = 'None';
            app.RemoveZerosCheckBox = uicheckbox(app.QCCorrectionParametersPanel);
            app.RemoveZerosCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.RemoveZerosCheckBox.Text = 'Remove Zeros';
            app.RemoveZerosCheckBox.Value = true;
            app.LogTransformedCorrectionCheckBox = uicheckbox(app.QCCorrectionParametersPanel);
            app.LogTransformedCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.LogTransformedCorrectionCheckBox.Text = 'Log Transformed Correction';
            app.LogTransformedCorrectionCheckBox.Value = true;
            app.BlankRatioMethodDropDown_2Label = uilabel(app.QCCorrectionParametersPanel);
            app.BlankRatioMethodDropDown_2Label.Text = 'Blank Ratio Method:';
            app.BlankRatioMethodDropDown = uidropdown(app.QCCorrectionParametersPanel);
            app.BlankRatioMethodDropDown.Items = {'QC', 'Median', 'Percentile'};
            app.BlankRatioMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @CorrectionConfigValueChanged, true);
            app.BlankRatioMethodDropDown.Value = 'QC';
            app.LaunchAlgorithmExplorer = uibutton(app.UIFigure, 'push');
            app.LaunchAlgorithmExplorer.ButtonPushedFcn = createCallbackFcn(app, @LaunchQCcorrectionExplorerPushed, true);
            app.LaunchAlgorithmExplorer.Icon = fullfile(pathToMLAPP, 'res', 'QCCEx_icon.png');
            app.LaunchAlgorithmExplorer.Text = '';
            app.FeatureInclusionPreFilterPanel = uipanel(app.UIFigure);
            app.FeatureInclusionPreFilterPanel.Title = 'Feature Inclusion PreFilter:';
            app.UIAxesPreprocessing = uiaxes(app.FeatureInclusionPreFilterPanel);
            xlabel(app.UIAxesPreprocessing, '% QC missing (per batch)')
            zlabel(app.UIAxesPreprocessing, 'Z')
            app.FeaturesremainingLabel = uilabel(app.FeatureInclusionPreFilterPanel);
            app.FeaturesremainingLabel.Text = '#Features remaining: ';
            app.QCLOQmissingCheckBox = uicheckbox(app.FeatureInclusionPreFilterPanel);
            app.QCLOQmissingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FeatureInclusionPreFilterPanelValueChanged, true);
            app.QCLOQmissingCheckBox.Text = 'QC < LOQ* = missing';
            app.QCLOQmissingCheckBox.Value = true;
            app.xmaxBlankLabel = uilabel(app.FeatureInclusionPreFilterPanel);
            app.xmaxBlankLabel.Text = 'x max(Blank)';
            app.aLabel = uilabel(app.FeatureInclusionPreFilterPanel);
            app.aLabel.Text = 'LOQ* =';
            app.LOQalphaEditField = uieditfield(app.FeatureInclusionPreFilterPanel, 'numeric');
            app.LOQalphaEditField.Limits = [1 Inf];
            app.LOQalphaEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureInclusionPreFilterPanelValueChanged, true);
            app.LOQalphaEditField.Value = 1.2;
            app.PreFilterDropDown = uidropdown(app.FeatureInclusionPreFilterPanel);
            app.PreFilterDropDown.Items = {'Complete', 'Every Batch', 'Any Batch'};
            app.PreFilterDropDown.ItemsData = {'Complete', 'EveryBatch', 'AnyBatch'};
            app.PreFilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureInclusionPreFilterPanelValueChanged, true);
            app.PreFilterDropDown.Value = 'EveryBatch';
            app.QCsmissingperfeatureLabel = uilabel(app.FeatureInclusionPreFilterPanel);
            app.QCsmissingperfeatureLabel.Text = '%QCs missing per feature <=';
            app.missingQCperFeatureEditField = uieditfield(app.FeatureInclusionPreFilterPanel, 'numeric');
            app.missingQCperFeatureEditField.Limits = [0 100];
            app.missingQCperFeatureEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureInclusionPreFilterPanelValueChanged, true);
            app.missingQCperFeatureEditField.Value = 20;
            app.drinkMeButton = uibutton(app.FeatureInclusionPreFilterPanel, 'state');
            app.drinkMeButton.ValueChangedFcn = createCallbackFcn(app, @drinkMeButtonValueChanged, true);
            app.drinkMeButton.Icon = 'drinkme.png';
            app.drinkMeButton.Text = '';
            app.QCMXPConfigurationPanel = uipanel(app.UIFigure);
            app.QCMXPConfigurationPanel.Title = 'QC:MXP Configuration:';
            app.LoadConfigButton = uibutton(app.QCMXPConfigurationPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.SaveConfigButton = uibutton(app.QCMXPConfigurationPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.ResetConfigButton = uibutton(app.QCMXPConfigurationPanel, 'push');
            app.ResetConfigButton.ButtonPushedFcn = createCallbackFcn(app, @ResetConfigButtonPushed, true);
            app.ResetConfigButton.Text = 'Reset Config';
            app.IncomingDataPanel = uipanel(app.UIFigure);
            app.IncomingDataPanel.Title = 'Incoming Data:';
            app.NumberofSamplesLabel = uilabel(app.IncomingDataPanel);
            app.NumberofSamplesLabel.Interpreter = 'html';
            app.NumberofSamplesLabel.Text = 'Number of Samples:';
            app.NumberofFeaturesLabel = uilabel(app.IncomingDataPanel);
            app.NumberofFeaturesLabel.Interpreter = 'html';
            app.NumberofFeaturesLabel.Text = 'Number of Features:';
            app.ViewFeatureTableButton = uibutton(app.IncomingDataPanel, 'push');
            app.ViewFeatureTableButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFeatureTableButtonPushed, true);
            app.ViewFeatureTableButton.Text = 'View Feature Table';
            app.LOADDATAButton = uibutton(app.IncomingDataPanel, 'push');
            app.LOADDATAButton.ButtonPushedFcn = createCallbackFcn(app, @LOADDATAButtonPushed, true);
            app.LOADDATAButton.Text = 'Load Data';
            app.NumberofBatchesLabel = uilabel(app.IncomingDataPanel);
            app.NumberofBatchesLabel.Interpreter = 'html';
            app.NumberofBatchesLabel.Text = 'Number of Batches:';
            app.NumberofQCLabel = uilabel(app.IncomingDataPanel);
            app.NumberofQCLabel.Interpreter = 'html';
            app.NumberofQCLabel.Text = 'Number of QC:';
            app.NumberofReferenceLabel = uilabel(app.IncomingDataPanel);
            app.NumberofReferenceLabel.Interpreter = 'html';
            app.NumberofReferenceLabel.Text = 'Number of Reference:';
            app.NumberofBlanksLabel = uilabel(app.IncomingDataPanel);
            app.NumberofBlanksLabel.Interpreter = 'html';
            app.NumberofBlanksLabel.Text = 'Number of Blanks:';
            app.FeatureTableLabel = uilabel(app.IncomingDataPanel);
            app.FeatureTableLabel.Interpreter = 'html';
            app.FeatureTableLabel.Text = 'Feature Table:';
            app.DataTableLabel = uilabel(app.IncomingDataPanel);
            app.DataTableLabel.Interpreter = 'html';
            app.DataTableLabel.Text = 'DataTable:';
            app.ProjectNameLabel = uilabel(app.IncomingDataPanel);
            app.ProjectNameLabel.Interpreter = 'html';
            app.ProjectNameLabel.Text = 'ProjectName:';
            app.Logo = uiimage(app.UIFigure);
            app.Logo.ImageSource = fullfile(pathToMLAPP, 'res', 'QCMXPz.png');
            app.VersionLabel = uilabel(app.UIFigure);
            app.VersionLabel.Text = 'version: 2.0';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'textx10.png';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'http://www.qcmxp.org';
            app.Hyperlink.Text = 'QCMXP.org';
            app.Panel = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCmXP3(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end