classdef ScatterView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VisibilityPanel                matlab.ui.container.Panel
        WarningLabel                   matlab.ui.control.Label
        Tree                           matlab.ui.container.CheckBoxTree
        DataPointsNode                 matlab.ui.container.TreeNode
        GroupMeanNode                  matlab.ui.container.TreeNode
        CImeanNode                     matlab.ui.container.TreeNode
        CIpopNode                      matlab.ui.container.TreeNode
        ExportPanel                    matlab.ui.container.Panel
        ExportDataButton               matlab.ui.control.Button
        BlackWhiteButton               matlab.ui.control.Button
        NameEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        dpiDropDown                    matlab.ui.control.DropDown
        dpiDropDownLabel               matlab.ui.control.Label
        ExportFigureButton             matlab.ui.control.Button
        OptionsPanel                   matlab.ui.container.Panel
        ColormapDropDown               matlab.ui.control.DropDown
        ColormapDropDownLabel          matlab.ui.control.Label
        TitleFontSizeEditField         matlab.ui.control.NumericEditField
        AxisFontSizeEditField          matlab.ui.control.NumericEditField
        MarkerSizeEditField            matlab.ui.control.NumericEditField
        MarkerLineWidthEditField       matlab.ui.control.NumericEditField
        MarkerLineWidthEditFieldLabel  matlab.ui.control.Label
        CILineWidthEditField           matlab.ui.control.NumericEditField
        LegendonCheckBox               matlab.ui.control.CheckBox
        CILineWidthEditFieldLabel      matlab.ui.control.Label
        AxisFontSizeEditFieldLabel     matlab.ui.control.Label
        MarkerSizeEditFieldLabel       matlab.ui.control.Label
        FontSizeLabel                  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        CallingApp   % Main app object
        filename % Description
        bw % Description
        Node % Description
        handleScatter % Description
        handleCImean % Description
        handleCIpop % Description
        handlePopMean % Description
        Data % Description
        Feature % Description
        options % Description
        cmap 
        RESULT
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)

        function PCAandPlot(app)
            try 
                [RES] = qcrscPCA(app.Data,app.Feature, ...
                    RemoveZeros = app.options.RemoveZeros, ...
                    ImputationType = app.options.ImputationType, ...
                    LogTransform = app.options.LogTransform, ...
                    ScaleMethod = app.options.ScaleMethod, ...
                    batchScale = app.options.BatchScale, ...
                    k = app.options.k);
            
                    plotPCAXX(app.UIAxes,app.Data,RES, ...
                    xaxis = app.options.xaxis, ...
                    yaxis = app.options.yaxis, ...
                    IncludeQCs= app.options.IncludeQCs, ...
                    IncludeBlanks= app.options.IncludeBlanks, ...
                    IncludeRefs = app.options.IncludeRefs, ...
                    label = app.options.label, ...
                    plotDataPoints = app.options.plotDataPoints, ...
                    plotCIA = app.options.plotCIA, ...
                    plotCIB = app.options.plotCIA, ...
                    cmap = app.cmap);
            catch ME
            uialert(app.UIFigure,ME.message,'Error: PCA Error','Icon','error');
            return;
            end
            app.RESULT = RES;
            app.handleScatter = findobj(app.UIAxes,'Type','Scatter');
            temp = findobj(app.UIAxes,'Type','Line');
            app.handlePopMean = findobj(temp,'Marker','x');
            app.handleCIpop = findobj(temp,'LineStyle','--');
            app.handleCImean = findobj(temp,'LineStyle',':');
            redraw(app);
        end
        
        function redraw(app)
            value = app.AxisFontSizeEditField.Value;
            app.UIAxes.FontSize = value;
            app.UIAxes.XLabel.FontSize = value;
            app.UIAxes.YLabel.FontSize = value;
            app.UIAxes.Legend.FontSize = value;
            app.UIAxes.Title.FontSize = app.TitleFontSizeEditField.Value;
              
            for i = 1:length(app.handleScatter)
                app.handleScatter(i).SizeData = app.MarkerSizeEditField.Value;
                app.handleScatter(i).LineWidth = app.MarkerLineWidthEditField.Value;
                if app.bw
                     app.handleScatter(i).MarkerEdgeColor = 'k';
                     app.handleScatter(i).MarkerFaceColor = 'none';
                end
            end

            for i = 1:length(app.handlePopMean)
                app.handlePopMean(i).LineWidth = app.CILineWidthEditField.Value;
                if app.bw
                     app.handlePopMean(i).Color = 'k';                   
                end
            end 
            
            for i = 1:length(app.handleCImean)
                app.handleCImean(i).LineWidth = app.CILineWidthEditField.Value;
                if app.bw
                     app.handleCImean(i).Color = 'k';                   
                end
            end    

            for i = 1:length(app.handleCIpop)
                app.handleCIpop(i).LineWidth = app.CILineWidthEditField.Value;
                if app.bw
                     app.handleCIpop(i).Color = 'k';                   
                end
            end    
           
            if app.LegendonCheckBox.Value
                app.UIAxes.Legend.Visible = true;
            else
                app.UIAxes.Legend.Visible = false;
            end

            drawnow();
            
        end
        
        function createTree(app)
            app.Node = [];
            for i = 1:length(app.handleScatter)
                app.Node = uitreenode(app.DataPointsNode);
                app.Node.Text = [app.handleScatter(i).UserData{1},':',app.handleScatter(i).UserData{2}];
                app.Node.NodeData = app.handleScatter(i);
            end
            
            for i = 1:length(app.handlePopMean)
                app.Node = uitreenode(app.GroupMeanNode);
                app.Node.Text = [app.handlePopMean(i).UserData{1},':',app.handlePopMean(i).UserData{2}];
                app.Node.NodeData = app.handlePopMean(i);
            end

            for i = 1:length(app.handleCImean)
                app.Node = uitreenode(app.CImeanNode);
                app.Node.Text = [app.handleCImean(i).UserData{1},':',app.handleCImean(i).UserData{2}];
                app.Node.NodeData = app.handleCImean(i);
            end 

            for i = 1:length(app.handleCIpop)
                app.Node = uitreenode(app.CIpopNode);
                app.Node.Text = [app.handleCIpop(i).UserData{1},':',app.handleCIpop(i).UserData{2}];
                app.Node.NodeData = app.handleCIpop(i);
            end 

            %expand(app.CImeanNode);
            app.Tree.CheckedNodes = app.Tree.Children;
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data, Feature, options)
            
            arguments
               app
               Data {mustBeA(Data,'table')}
               Feature {mustBeA(Feature,'table')}
               options.RemoveZeros {mustBeNumericOrLogical} = true
               options.IncludeQCs {mustBeNumericOrLogical} = true
               options.IncludeBlanks {mustBeNumericOrLogical} = false
               options.IncludeRefs {mustBeNumericOrLogical} = false
               options.ImputationType {mustBeMember(options.ImputationType,{'KNNcolumn','KNNrow','blank20'})} = 'KNNcolumn'
               options.Fold {mustBeNumericOrLogical} = false
               options.LogTransform {mustBeNumericOrLogical} = true 
               options.ScaleMethod {mustBeMember(options.ScaleMethod,{'MeanCenter','Autoscale','Paretoscale'})} = 'Autoscale'
               options.BatchScale {mustBeNumericOrLogical} = false
               options.xaxis {mustBeInteger,mustBeGreaterThan(options.xaxis,-1)} = 1
               options.yaxis {mustBeInteger,mustBePositive} = 2
               options.label {mustBeA(options.label,{'char','string'})} = 'SampleType'
               options.plotDataPoints {mustBeNumericOrLogical} = true 
               options.plotCIA {mustBeNumericOrLogical} = true 
               options.plotCIB {mustBeNumericOrLogical} = true 
               options.cmapName = 'lines'
               options.k {mustBeInteger,mustBePositive} = 3  
            end

            % screenSize = get(groot,'ScreenSize');
            % screenWidth = screenSize(3);
            % screenHeight = screenSize(4);
            % 
            % left = screenWidth*0.1;
            % bottom = screenHeight*0.05;
            % width = screenWidth*0.8;
            % height = screenHeight*0.9;            
            % maxheight = screenHeight*0.95 - bottom;            
            % %app.UIFigure.Position = [left bottom width height]; 
            % app.UIFigure.Position = [left bottom 1059 792];
            % if maxheight < 792
            %     app.UIFigure.Position = [left bottom width height];
            %     app.UIFigure.Scrollable = 'on';
            % end

            changeFont(app.UIFigure,'Avenir');

            if strcmp(options.label,'SampleType')
                app.ColormapDropDown.Visible = false;
                app.ColormapDropDownLabel.Visible = false;
            end

            app.Data = Data;
            app.Feature = Feature;
            app.options = options;

            app.ColormapDropDown.Items = {'colorblind7','wesanderson','fermilab5','tolBright7','colorblind12','okabe&ito8','quoka', 'lines', 'colorcube', 'prism', 'moog', 'random'};
            app.ColormapDropDown.Value = options.cmapName;
            app.cmap = mapRcolors(options.cmapName);
            app.filename = 'PCA.png';
            app.NameEditField.Value = app.filename;
            app.bw = false;
            PCAandPlot(app);         
            createTree(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %app.CallingApp.PopOutButton.Enable = 'on';
            delete(app)            
        end

        % Value changed function: TitleFontSizeEditField
        function TitleFontSizeEditFieldValueChanged(app, event)
           redraw(app)
        end

        % Value changed function: AxisFontSizeEditField
        function AxisFontSizeEditFieldValueChanged(app, event)
            redraw(app)
        end

        % Value changed function: MarkerSizeEditField
        function MarkerSizeEditFieldValueChanged(app, event)
            redraw(app)
        end

        % Value changed function: CILineWidthEditField
        function CILineWidthEditFieldValueChanged(app, event)
            redraw(app)
        end

        % Value changed function: LegendonCheckBox
        function LegendonCheckBoxValueChanged(app, event)
            redraw(app)
        end

        % Value changed function: MarkerLineWidthEditField
        function MarkerLineWidthEditFieldValueChanged(app, event)
            redraw(app)
        end

        % Button pushed function: BlackWhiteButton
        function BlackWhiteButtonPushed(app, event)
            if numel(app.handleScatter) > 10
                uialert(app.UIFigure,'Too many groups to sensibly convert the plot to b&w.','Nope!','Icon','warning');
                return;
            end
            markers = {'o','^','s','d','x','>','p','h','*','+'};
            for i = 1:numel(app.handleScatter)
                app.handleScatter(i).Marker = markers{i};
            end
            app.bw = true;
            redraw(app)
        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            set(app.UIFigure, 'Pointer','watch');
            app.ExportPanel.Enable = "off";
            app.OptionsPanel.Enable = "off";
            
           try           
               if strcmp(app.TypeDropDown.Value,'pdf')
                   exportgraphics(app.UIAxes,app.filename,'Resolution',app.dpiDropDown.Value,'ContentType','vector');
               else
                   exportgraphics(app.UIAxes,app.filename,'Resolution',app.dpiDropDown.Value);
               end
               uialert(app.UIFigure,"PCA plot sucessfully exported.",'Export Success!','Icon','success');
           catch
               uialert(app.UIFigure,"PCA plot could not be exported.",'Export Failure!','Icon','error');
           end
           
           set(app.UIFigure, 'Pointer','arrow');
            app.ExportPanel.Enable = "on";
            app.OptionsPanel.Enable = "on";
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            name = split(app.NameEditField.Value,'.');
            if length(name) > 1
                name = join(name(1:end-1),'.'); 
            end
            app.NameEditField.Value = [name{:},'.',app.TypeDropDown.Value];  
            app.filename = app.NameEditField.Value;
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            oldname = app.filename;
            name = split(app.NameEditField.Value,'.');
            if length(name) > 1
                if ismember(name{end},{'jpg','jpeg','png','tiff','tif','pdf'})
                    name2 = join(name(1:end-1),'.');
                    app.NameEditField.Value = [name2{:},'.',name{end}];
                    if strcmp(name{end},'jpeg')
                        app.TypeDropDown.Value = 'jpg';
                    elseif strcmp(name{end},'tiff')
                        app.TypeDropDown.Value = 'tif';
                    else
                        app.TypeDropDown.Value = name{end}(1:3);
                    end
                    app.filename = app.NameEditField.Value;
                else
                    uialert(app.UIFigure,"File extension must either .jpg, .jpeg, .png, .tif, .tiff, or .pdf ",'unknown file extension','Icon','error');
                    app.filename = oldname;
                    app.NameEditField.Value = oldname;
                    return;
                end
            else
                app.NameEditField = [name,'.',app.TypeDropDown.Value]; 
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            for i = 1:length(app.UIAxes.Children)
                app.UIAxes.Children(i).Visible = 'off';
            end
            checkedNodes = app.Tree.CheckedNodes;
            for i = 1:length(checkedNodes)
                %if strcmp(checkedNodes(i).Text,{'Data Points','CI (mean)','CI (pop)'}), continue; end
                checkedNodes(i).NodeData.Visible = 'on';
            end
            drawnow();
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            name = app.ColormapDropDown.Value;
            app.cmap = mapRcolors(name);
            if app.bw
                return;
            end

            for i = 1:length(app.handleScatter)       
                app.handleScatter(i).MarkerFaceColor = app.cmap(i,:); 
                for j = 1:length(app.handleCImean)
                    if strcmp(app.handleCImean(j).UserData{1},app.handleScatter(i).UserData{1})
                        app.handleCImean(j).Color = app.cmap(i,:);
                    end
                end
                for j = 1:length(app.handleCIpop)
                    if strcmp(app.handleCIpop(j).UserData{1},app.handleScatter(i).UserData{1})
                        app.handleCIpop(j).Color = app.cmap(i,:);
                    end
                end
            end     

        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            Score = app.RESULT.Score;
            numPCs = width(Score);
            varNames = cellfun(@(x) strcat('PC',x),string(1:numPCs),'UniformOutput',false);
            RR = array2table(Score,'VariableNames',varNames);
            Meta = app.Data(:,{'SampleID','Order','Batch','SampleType',app.options.label});
            Out = [Meta,RR];
            [file,path]=uiputfile('.csv','Save PCA scores and associated label data','PCAscores.csv');
            ff = fullfile(path,file);
            writetable(Out,ff);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Formatting';
            app.FontSizeLabel = uilabel(app.OptionsPanel);
            app.FontSizeLabel.Text = 'Title FontSize:';
            app.MarkerSizeEditFieldLabel = uilabel(app.OptionsPanel);
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size:';
            app.AxisFontSizeEditFieldLabel = uilabel(app.OptionsPanel);
            app.AxisFontSizeEditFieldLabel.Text = 'Axis FontSize';
            app.CILineWidthEditFieldLabel = uilabel(app.OptionsPanel);
            app.CILineWidthEditFieldLabel.Text = 'CI LineWidth:';
            app.LegendonCheckBox = uicheckbox(app.OptionsPanel);
            app.LegendonCheckBox.ValueChangedFcn = createCallbackFcn(app, @LegendonCheckBoxValueChanged, true);
            app.LegendonCheckBox.Text = 'Legend on';
            app.LegendonCheckBox.Value = true;
            app.CILineWidthEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.CILineWidthEditField.Limits = [0 5];
            app.CILineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @CILineWidthEditFieldValueChanged, true);
            app.CILineWidthEditField.Value = 0.5;
            app.MarkerLineWidthEditFieldLabel = uilabel(app.OptionsPanel);
            app.MarkerLineWidthEditFieldLabel.Text = 'Marker LineWidth:';
            app.MarkerLineWidthEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.MarkerLineWidthEditField.Limits = [0 5];
            app.MarkerLineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerLineWidthEditFieldValueChanged, true);
            app.MarkerLineWidthEditField.Value = 0.75;
            app.MarkerSizeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.MarkerSizeEditField.Limits = [5 1000];
            app.MarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeEditFieldValueChanged, true);
            app.MarkerSizeEditField.Value = 20;
            app.AxisFontSizeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.AxisFontSizeEditField.Limits = [5 50];
            app.AxisFontSizeEditField.ValueDisplayFormat = '%.0f';
            app.AxisFontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @AxisFontSizeEditFieldValueChanged, true);
            app.AxisFontSizeEditField.Value = 14;
            app.TitleFontSizeEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.TitleFontSizeEditField.Limits = [5 50];
            app.TitleFontSizeEditField.RoundFractionalValues = 'on';
            app.TitleFontSizeEditField.ValueDisplayFormat = '%.0f';
            app.TitleFontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @TitleFontSizeEditFieldValueChanged, true);
            app.TitleFontSizeEditField.Value = 16;
            app.ColormapDropDownLabel = uilabel(app.OptionsPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.OptionsPanel);
            app.ColormapDropDown.Items = {'Lines'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Lines';
            app.ExportPanel = uipanel(app.UIFigure);
            app.ExportPanel.Title = 'Export';
            app.ExportFigureButton = uibutton(app.ExportPanel, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            app.dpiDropDownLabel = uilabel(app.ExportPanel);
            app.dpiDropDownLabel.Text = 'dpi:';
            app.dpiDropDown = uidropdown(app.ExportPanel);
            app.dpiDropDown.Items = {'150', '300', '600'};
            app.dpiDropDown.ItemsData = [150 300 600];
            app.dpiDropDown.Value = 300;
            app.TypeDropDownLabel = uilabel(app.ExportPanel);
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.ExportPanel);
            app.TypeDropDown.Items = {'JPEG', 'PNG', 'TIFF', 'PDF'};
            app.TypeDropDown.ItemsData = {'jpg', 'png', 'tif', 'pdf'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'png';
            app.NameEditFieldLabel = uilabel(app.ExportPanel);
            app.NameEditFieldLabel.Text = 'Name:';
            app.NameEditField = uieditfield(app.ExportPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.Value = 'PCA.png';
            app.BlackWhiteButton = uibutton(app.ExportPanel, 'push');
            app.BlackWhiteButton.ButtonPushedFcn = createCallbackFcn(app, @BlackWhiteButtonPushed, true);
            app.BlackWhiteButton.Text = 'Black & White';
            app.ExportDataButton = uibutton(app.ExportPanel, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.VisibilityPanel = uipanel(app.UIFigure);
            app.VisibilityPanel.Title = 'Visibility';
            app.Tree = uitree(app.VisibilityPanel, 'checkbox');
            app.DataPointsNode = uitreenode(app.Tree);
            app.DataPointsNode.Text = 'Data Points';
            app.GroupMeanNode = uitreenode(app.Tree);
            app.GroupMeanNode.Text = 'Group Mean';
            app.CImeanNode = uitreenode(app.Tree);
            app.CImeanNode.Text = 'CI (mean)';
            app.CIpopNode = uitreenode(app.Tree);
            app.CIpopNode.Text = 'CI (pop)';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.WarningLabel = uilabel(app.VisibilityPanel);
            app.WarningLabel.Text = 'Note: Removing data will not rebuild the PCA model. Data points are simply made invisible';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScatterView(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end