classdef StaticForestPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        UIAxesForest        matlab.ui.control.UIAxes
        UIAxesPooledForest  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        plotType % Description
        ForestTable
        yTop
        yBottom % Description
        window_size % Description
        factor
    end
    
    methods (Access = private)
        
        function PlotForest(app)
            %order = app.ForestIdx;
            order = 1:height(app.ForestTable)-2;         
    
            switch app.plotType
                case 'QC'                 
                    sigCol = [0.85,0.1,0.1];
                    tt = 'qcRSD';
                case 'REF'
                    sigCol = [0.83,0.67,0.21];
                    tt = 'referenceRSD';
            end
    
    
            linkaxes([app.UIAxesForest,app.UIAxesPooledForest],'x');
            PoolTable = app.ForestTable(1:2,:);
            FeatureTable = app.ForestTable(3:end,:);
            FeatureTable = FeatureTable(order,:);
            
            xlim_upper = 80;
            xlim_lower = -10;
            hold(app.UIAxesPooledForest,"off");
            hold(app.UIAxesForest,"off");
             
            n_studies = height(FeatureTable);
            
            % Line of no effect (usually 0 for differences, 1 for ratios)
            line_of_no_effect = 0; 
            plot(app.UIAxesForest,[line_of_no_effect, line_of_no_effect], [0, n_studies+2], 'k--', 'LineWidth', 1.5);
            hold(app.UIAxesForest,"on");
            
            maxMarkerSize = 400;
            minMarkerSize = 20;
            
            if ismember('cleanPeaks', FeatureTable.Properties.VariableNames)
                colCode = FeatureTable.cleanPeaks+FeatureTable.sig*10;
            else
                temp = ones(height(FeatureTable),1);
                colCode = temp+FeatureTable.sig*10;   
            end
               
            %green = [46,139,87]/255;
            grey = [47,79,79]/255;

            keys = [0, 1, 10, 11];
            %valuesMFC = {[0,0,0], [0,0.2,1], [0,0,0], sigCol}; % black, blue, sigCol
            valuesMFC = {[0,0,0], grey, [0,0,0], sigCol}; % black, blue, sigCol
            %valuesLC = {[0,0,0,0.1], [0,0.2,1,1], [0,0,0,0.1], [sigCol,1]}; % black, blue, sigCol
            valuesLC = {[0,0,0,0.1], [grey,1], [0,0,0,0.1], [sigCol,1]}; % black, blue, sigCol
            valuesMFA = {0.1, 1, 0.1, 1}; % black, blue, sigCol


            mfcMap = dictionary(keys, valuesMFC);
            lcMap = dictionary(keys, valuesLC);
            mfaMap = dictionary(keys, valuesMFA);
            
            mfc = cell2mat(mfcMap(colCode));
            %lc = cell2mat(lcMap(colCode));
            mfa = cell2mat(mfaMap(colCode));
            
            ylinedata = [2:n_studies+1;2:n_studies+1];
            xlinedata = [FeatureTable.lowerCI, FeatureTable.upperCI]';
            for i = 1:4
                keep = colCode == keys(i);
                if any(keep)
                    tempx = xlinedata(:,keep);
                    tempy = ylinedata(:,keep);
                    plot(app.UIAxesForest,tempx, tempy, '-', 'Color',valuesLC{i}, 'LineWidth', 1.5);
                    keep = tempx(2,:) > xlim_upper;
                    if any(keep)
                        scatter(app.UIAxesForest,xlim_upper, tempy(1,keep),50,valuesMFC{i},'filled','>','AlphaDataMapping','none','AlphaData',0.2,'MarkerFaceAlpha','flat','MarkerFaceColor','flat');
                    end
                    keep = tempx(1,:) < xlim_lower;
                    if any(keep)
                        scatter(app.UIAxesForest,xlim_lower, tempy(1,keep),50,valuesMFC{i},'filled','<','AlphaDataMapping','none','AlphaData',0.2,'MarkerFaceAlpha','flat','MarkerFaceColor','flat');
                    end
                end
            end
            
            %plot(ax,[FeatureTable.lowerCI, FeatureTable.upperCI]', temp, '-', 'Color', 'k', 'LineWidth', 1.5);
            ms = minMarkerSize + (FeatureTable.cvA * (maxMarkerSize - minMarkerSize)) / 100;
            s = scatter(app.UIAxesForest,FeatureTable.meanDeltaCV, 2:n_studies+1, ms, 's','MarkerFaceColor','flat','AlphaDataMapping','none','CData',mfc);
            alpha(s,mfa);
            
            % Plot formatting ---
            %ylim(app.UIAxesForest, [1, 1 + window_size]);
            ylim(app.UIAxesForest,[0, n_studies+2]);
            xlim(app.UIAxesForest,[xlim_lower, xlim_upper]);
            xlabel(app.UIAxesForest,'Decrease in %RSD');
            ylabel(app.UIAxesForest,'');
            yticks(app.UIAxesForest,2:n_studies+1);
            yticklabels(app.UIAxesForest,FeatureTable.ShortName(1:end));
            app.UIAxesForest.YAxis.FontSize = 8;
            %set(app.UIAxesForest, 'YDir', 'reverse'); % Invert Y-axis so Study 1 is at the top
            grid(app.UIAxesForest,'on');
            box(app.UIAxesForest,'on');
            
            
            % Plot the Pooled Effect Diamond ---
            % Diamond coordinates: [Left tip, Center, Right tip, Center, Bottom tip]
            X_diamond = [PoolTable.lowerCI(1), PoolTable.meanDeltaCV(1), PoolTable.upperCI(1), PoolTable.meanDeltaCV(1), PoolTable.lowerCI(1)];
            X_diamondX = [PoolTable.lowerCI(2), PoolTable.meanDeltaCV(2), PoolTable.upperCI(2), PoolTable.meanDeltaCV(2), PoolTable.lowerCI(2)];
            Y_diamond = [1, 1.5, 1, 0.5, 1];
            %Y_diamond = [n_studies+0.5, n_studies+1, n_studies+0.5, n_studies, n_studies+0.5];
            
            % Draw the diamond
            fill(app.UIAxesPooledForest,X_diamond, Y_diamond, 'k', 'FaceAlpha', 0, 'EdgeColor', 'k');
            hold(app.UIAxesPooledForest,"on");
            plot(app.UIAxesPooledForest,[line_of_no_effect, line_of_no_effect], [0, 2], 'k--', 'LineWidth', 1.5);
            if PoolTable.sig(2)
                fill(app.UIAxesPooledForest,X_diamondX, Y_diamond, sigCol, 'FaceAlpha', 0.5, 'EdgeColor', 'k','ButtonDownFcn',@(src,event)app.MouseClickPooled(src,PoolTable));
            else
                fill(app.UIAxesPooledForest,X_diamondX, Y_diamond, [0,0.2,1], 'FaceAlpha', 0.5, 'EdgeColor', 'k','ButtonDownFcn',@(src,event)app.MouseClickPooled(src,PoolTable));
            end
            
            
            xlim(app.UIAxesPooledForest,[xlim_lower, xlim_upper]);
            app.UIAxesPooledForest.XTick = app.UIAxesForest.XTick;
            app.UIAxesPooledForest.XAxis.TickLength = [0 0];
            ylim(app.UIAxesPooledForest,[0,2]);
            yticks(app.UIAxesPooledForest,1);
            yticklabels(app.UIAxesPooledForest,'Pooled Effect');
            grid(app.UIAxesPooledForest,'on');
            box(app.UIAxesPooledForest,'on');
            app.UIAxesPooledForest.PlotBoxAspectRatio = [20 1 1];
            title(app.UIAxesPooledForest,[tt,' : Unweighted Forest Plot']);
            
            app.UIAxesPooledForest.InnerPosition(1) = app.UIAxesForest.InnerPosition(1);
            app.UIAxesPooledForest.InnerPosition(3) = app.UIAxesForest.InnerPosition(3);
            hold(app.UIAxesForest,"off");
            hold(app.UIAxesPooledForest,"off");
            %app.Panel.Scrollable ="off";
            %app.UIAxesForest.Position(2) = app.yTop - n_studies*app.factor - app.yBottom;
            %app.UIAxesForest.Position(4) = n_studies*app.factor;
            app.Panel.Scrollable ="on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ForestTable, plotType)
            disableDefaultInteractivity(app.UIAxesForest);           
            disableDefaultInteractivity(app.UIAxesPooledForest);
            
            app.ForestTable = ForestTable;
            app.plotType = plotType;
            app.yTop = app.UIAxesForest.Position(4);
            app.yBottom = 20;
            app.window_size = 40;
            app.factor = (app.yTop-app.yBottom)/app.window_size;
            PlotForest(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxesPooledForest = uiaxes(app.Panel);
            title(app.UIAxesPooledForest, 'Title')
            app.UIAxesPooledForest.XTickLabel = '';
            app.UIAxesPooledForest.Toolbar = [];
            app.UIAxesForest = uiaxes(app.Panel);
            xlabel(app.UIAxesForest, 'X')
            ylabel(app.UIAxesForest, 'Y')
            zlabel(app.UIAxesForest, 'Z')
            app.UIAxesForest.Toolbar = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StaticForestPlot(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end