classdef TidyFeatureTableView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        FeatureTable    matlab.ui.container.Tab
        UIFeatureTable  matlab.ui.control.Table
    end

    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = public)
        
        function highlightrow(app,name)
            
            idx = find(ismember(app.UIFeatureTable.Data.UID,name));            
            removeStyle(app.UIFeatureTable);
            s3 = uistyle;
            s3.BackgroundColor = [152,251,152]/255;
            addStyle(app.UIFeatureTable,s3,'row',idx)            
            scroll(app.UIFeatureTable,'row',idx)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, FeatureTable, options)
            
            arguments
                app
                mainapp
                FeatureTable
                options.Name = 'Feature Table'
                options.FeatureTableName = 'Feature Table'                
            end
            
            if ismac
                changeFont(app.UIFigure,'Avenir');
            else
                changeFont(app.UIFigure,'MS Sans Serif');
            end
            app.UIFigure.Name = options.Name;
            app.FeatureTable.Title = options.FeatureTableName;
            app.CallingApp = mainapp;
            
            FeatureHeader = FeatureTable.Properties.VariableNames;
            
            app.UIFeatureTable.Data = FeatureTable;
            app.UIFeatureTable.ColumnName = FeatureHeader;
            app.UIFeatureTable.ColumnSortable = true;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            ExternalEnableViewFeatureTable(app.CallingApp)
            %app.CallingApp.ViewFeatureTableButton.Enable = 'on';
            %app.CallingApp.LOADDATAButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FeatureTable = uitab(app.TabGroup);
            app.FeatureTable.Title = 'Feature Table';
            app.UIFeatureTable = uitable(app.FeatureTable);
            app.UIFeatureTable.ColumnName = '';
            app.UIFeatureTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TidyFeatureTableView(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end