classdef TidyPeakTableView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TabGroup     matlab.ui.container.TabGroup
        PeakTab      matlab.ui.container.Tab
        UIPeakTable  matlab.ui.control.Table
    end

    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = public)
        
        function highlightrow(app,name)
            
            idx = find(ismember(app.UIPeakTable.Data.UID,name));            
            removeStyle(app.UIPeakTable);
            s3 = uistyle;
            s3.BackgroundColor = [152,251,152]/255;
            addStyle(app.UIPeakTable,s3,'row',idx)            
            scroll(app.UIPeakTable,'row',idx)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, PeakTable, options)
            
            arguments
                app
                mainapp
                PeakTable
                options.Name = 'Peak Table'
                options.PeakName = 'Peak'                
            end
            
            app.UIFigure.Name = options.Name;
            app.PeakTab.Title = options.PeakName;
            app.CallingApp = mainapp;
            
            PeakHeader = PeakTable.Properties.VariableNames;
            
            app.UIPeakTable.Data = PeakTable;
            app.UIPeakTable.ColumnName = PeakHeader;
            app.UIPeakTable.ColumnSortable = true;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.ViewPeakTableButton.Enable = 'on';
            %app.CallingApp.LOADDATAButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PeakTab = uitab(app.TabGroup);
            app.PeakTab.Title = 'Peak';
            app.UIPeakTable = uitable(app.PeakTab);
            app.UIPeakTable.ColumnName = '';
            app.UIPeakTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TidyPeakTableView(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end